classdef spikesortApp_GIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        rasterYDim                    matlab.ui.control.DropDown
        DropDown                      matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        rasterButton                  matlab.ui.control.Button
        plotSpksInSignalButton        matlab.ui.control.Button
        noiseRemovalModel             matlab.ui.control.Button
        pbRemoveRefractoryViolations  matlab.ui.control.Button
        allframesclustersLabel        matlab.ui.control.Label
        frameclustersLabel            matlab.ui.control.Label
        isolatedLabel                 matlab.ui.control.Label
        asLabel                       matlab.ui.control.Label
        removeclustersLabel           matlab.ui.control.Label
        pbExpAnalysisVars             matlab.ui.control.Button
        reassigntoLabel               matlab.ui.control.Label
        pop15                         matlab.ui.control.EditField
        pbRemoveasNoise_2             matlab.ui.control.Button
        removeList                    matlab.ui.control.EditField
        pbRemoveasNoise               matlab.ui.control.Button
        pbRemoveasIso                 matlab.ui.control.Button
        removeasisolatedfirstLabel    matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        matLabel                      matlab.ui.control.Label
        edFilenameAppend              matlab.ui.control.EditField
        baseName                      matlab.ui.control.Label
        pbRtRS                        matlab.ui.control.Button
        removeasLabel                 matlab.ui.control.Label
        pbRecFr                       matlab.ui.control.Button
        popSrtdLoad                   matlab.ui.control.DropDown
        statsButton                   matlab.ui.control.Button
        pbRecoverSelectedCluster      matlab.ui.control.Button
        multiunitLabel                matlab.ui.control.Label
        pop14                         matlab.ui.control.NumericEditField
        pop13                         matlab.ui.control.NumericEditField
        pop12                         matlab.ui.control.NumericEditField
        pop11                         matlab.ui.control.NumericEditField
        pop10                         matlab.ui.control.NumericEditField
        pop9                          matlab.ui.control.NumericEditField
        pop8                          matlab.ui.control.NumericEditField
        pop7                          matlab.ui.control.NumericEditField
        pop6                          matlab.ui.control.NumericEditField
        pop5                          matlab.ui.control.NumericEditField
        pop4                          matlab.ui.control.NumericEditField
        pop3                          matlab.ui.control.NumericEditField
        pop2                          matlab.ui.control.NumericEditField
        pop1                          matlab.ui.control.NumericEditField
        SelectedClusterIDLabel        matlab.ui.control.Label
        nSpksNoise                    matlab.ui.control.Label
        nSpks14                       matlab.ui.control.Label
        nSpks13                       matlab.ui.control.Label
        nSpks12                       matlab.ui.control.Label
        nSpks11                       matlab.ui.control.Label
        nSpks10                       matlab.ui.control.Label
        nSpks9                        matlab.ui.control.Label
        nSpks8                        matlab.ui.control.Label
        nSpks7                        matlab.ui.control.Label
        nSpks6                        matlab.ui.control.Label
        nSpks5                        matlab.ui.control.Label
        nSpks4                        matlab.ui.control.Label
        nSpks3                        matlab.ui.control.Label
        Ylabel3_2                     matlab.ui.control.Label
        nSpks2                        matlab.ui.control.Label
        nSpks1                        matlab.ui.control.Label
        SelectedSpikeIDLabel          matlab.ui.control.Label
        selectOFFButton               matlab.ui.control.Button
        framenoiseLabel               matlab.ui.control.Label
        pbUndo                        matlab.ui.control.Button
        c2                            matlab.ui.control.NumericEditField
        popSubject                    matlab.ui.control.DropDown
        f2                            matlab.ui.control.NumericEditField
        c1                            matlab.ui.control.NumericEditField
        f1                            matlab.ui.control.NumericEditField
        nClusLabel                    matlab.ui.control.Label
        edElec                        matlab.ui.control.EditField
        nDimLabel                     matlab.ui.control.Label
        popSession                    matlab.ui.control.DropDown
        text116                       matlab.ui.control.Label
        stFrame2                      matlab.ui.control.Label
        text114                       matlab.ui.control.Label
        text113                       matlab.ui.control.Label
        popNDim                       matlab.ui.control.DropDown
        popNClus                      matlab.ui.control.DropDown
        popDim3                       matlab.ui.control.DropDown
        popDim2                       matlab.ui.control.DropDown
        popDim1                       matlab.ui.control.DropDown
        tbQuickSort                   matlab.ui.control.StateButton
        text96                        matlab.ui.control.Label
        pbAssignNear                  matlab.ui.control.Button
        edDimNum                      matlab.ui.control.EditField
        pbSetDim                      matlab.ui.control.Button
        pbManualSort                  matlab.ui.control.Button
        st14                          matlab.ui.control.Label
        st13                          matlab.ui.control.Label
        st12                          matlab.ui.control.Label
        st11                          matlab.ui.control.Label
        st10                          matlab.ui.control.Label
        st9                           matlab.ui.control.Label
        pbLoad                        matlab.ui.control.Button
        st8                           matlab.ui.control.Label
        st7                           matlab.ui.control.Label
        st6                           matlab.ui.control.Label
        st5                           matlab.ui.control.Label
        st4                           matlab.ui.control.Label
        st3                           matlab.ui.control.Label
        st2                           matlab.ui.control.Label
        st1                           matlab.ui.control.Label
        Xlabel3                       matlab.ui.control.Label
        Xlabel2                       matlab.ui.control.Label
        Ylabel2                       matlab.ui.control.Label
        pbExpAppSess                  matlab.ui.control.Button
        pbResort                      matlab.ui.control.Button
        pbPlaySort                    matlab.ui.control.StateButton
        directionsforsetdim           matlab.ui.control.Label
        pbForwardSort                 matlab.ui.control.Button
        pbBackSort                    matlab.ui.control.Button
        pbReassign                    matlab.ui.control.Button
        edIsoWin                      matlab.ui.control.EditField
        PClabel3                      matlab.ui.control.Label
        PClabel2                      matlab.ui.control.Label
        PClabel1                      matlab.ui.control.Label
        Xlabel1                       matlab.ui.control.Label
        Ylabel1                       matlab.ui.control.Label
        pcRaster                      matlab.ui.control.UIAxes
        axRemovedasIsoMeanWfs         matlab.ui.control.UIAxes
        axClusterSpikes               matlab.ui.control.UIAxes
        axWaveforms_3                 matlab.ui.control.UIAxes
        axWaveforms_2                 matlab.ui.control.UIAxes
        axPC3                         matlab.ui.control.UIAxes
        axPC2                         matlab.ui.control.UIAxes
        axPC1                         matlab.ui.control.UIAxes
        axFrWaveform                  matlab.ui.control.UIAxes
        axWaveforms                   matlab.ui.control.UIAxes
        axDim23                       matlab.ui.control.UIAxes
        axDim13                       matlab.ui.control.UIAxes
        axDim12                       matlab.ui.control.UIAxes
    end

    
    %%
    %change dataRootIn and outRoot 
    %dataRootIn char = 'D:\Nelson Lab Data\Intracranial data\Raw Neural Data per Subj\';
    %outRoot char = 'D:\Nelson Lab Data\Intracranial data\Raw Neural Data per Subj\';
    %% 
    
    
    properties (Access = private)
        Layout0
        IsResizing = false
        FrameDataStore
        UndoStack = {} 
        SelectedClusterNum = NaN

        ManualSelectMode logical = false
        ManualSelectX = []
        ManualSelectY = []
        ManualSelectLine = []
        SelectedSpikeRows = []

        SelectedNoiseNumber = NaN
        SelectedSpikeFrame = NaN
        PendingClickAxis = []
        PendingClickPoint = [NaN NaN] 
        SpikeSelectMode logical = false 
        SelectedClusterFrame = NaN 
        LastHoverSpikeFrame = NaN 
        SuppressDimDropdownCallback logical = false
        LastHoverSpikeRow = NaN 
        StatsWindowCount = 0

        CommandDown logical = false
        RasterVerticalZoomMode logical = false

        prevW = NaN 
        prevH = NaN 

        curW = NaN; 
        curH = NaN; 

        FirstSpikeAbsUS = NaN; 
        RecordingStartAbsUS = NaN; 

        DataRoot char = 'PATH_TO_YOUR_RAW_NEURAL_DATA_FOLDER';
        
    end
    methods (Access = private)

        function setRasterWindow(app, ax, chunkLabel, tMin, tMax, chunkSec, stepSec)

            xl = ax.XLim;

            newStart = xl(1) + stepSec;
            newEnd = newStart + chunkSec;

            if newStart < tMin
                newStart = tMin;
                newEnd = min(tMin + chunkSec, tMax);
            end

            if newEnd > tMax
                newEnd = tMax;
                newStart = max(tMin, tMax - chunkSec);
            end

            ax.XLim = [newStart newEnd];

            chunkLabel.Text = sprintf('Chunk %.2f to %.2f s    Full frame %.2f to %.2f s', ...
                newStart, newEnd, tMin, tMax);

            drawnow limitrate

        end


        function DebugSelectedClusterRows(app)
            return 

            FrameData = app.FrameDataStore;

            FrNum = app.SelectedClusterFrame;
            CluNum = app.SelectedClusterNum;

            if isempty(FrameData) || isnan(FrNum) || isnan(CluNum)
                fprintf('\n[DEBUG SELECTED CLUSTER] No selected cluster.\n');
                return
            end

            if FrNum < 1 || FrNum > numel(FrameData)
                fprintf('\n[DEBUG SELECTED CLUSTER] Bad frame number: %g\n', FrNum);
                return
            end

            status = FrameData(FrNum).Status(:);
            rows = find(status == CluNum);

            fprintf('\n================ DEBUG SELECTED CLUSTER ================\n');
            fprintf('Selected frame: %d\n', FrNum);
            fprintf('Selected cluster: %d\n', CluNum);
            fprintf('Rows in Status == cluster: %d\n', numel(rows));
            fprintf('Rows:\n');
            disp(rows(:)');

            if isempty(rows)
                fprintf('No rows found.\n');
                fprintf('========================================================\n\n');
                return
            end

            Wfs = FrameData(FrNum).Wfs;
            Uplot = FrameData(FrNum).Uplot;

            fprintf('Wfs size:   [%d %d]\n', size(Wfs,1), size(Wfs,2));
            fprintf('Uplot size: [%d %d]\n', size(Uplot,1), size(Uplot,2));

            badWfRows = rows(any(~isfinite(Wfs(rows,:)), 2));
            badPcRows = rows(any(~isfinite(Uplot(rows,1:3)), 2));

            fprintf('Bad waveform rows with NaN/Inf: %d\n', numel(badWfRows));
            disp(badWfRows(:)');

            fprintf('Bad Uplot rows with NaN/Inf in dims 1:3: %d\n', numel(badPcRows));
            disp(badPcRows(:)');

            pc123 = Uplot(rows,1:3);

            pcRounded6 = round(pc123, 6);
            [uniquePCs, ~, groupID] = unique(pcRounded6, 'rows');

            fprintf('Unique rounded PC coordinate rows, dims 1:3: %d / %d\n', ...
                size(uniquePCs,1), numel(rows));

            counts = accumarray(groupID, 1);

            stackedGroups = find(counts > 1);

            fprintf('Stacked/duplicate PC coordinate groups: %d\n', numel(stackedGroups));

            for i = 1:numel(stackedGroups)
                g = stackedGroups(i);
                theseRows = rows(groupID == g);

                fprintf('\nSTACK GROUP %d: %d spikes at same rounded PC coordinate\n', ...
                    i, numel(theseRows));
                fprintf('Rows: ');
                disp(theseRows(:)');
                fprintf('PC coord: ');
                disp(uniquePCs(g,:));
            end

            fprintf('\nFirst selected rows PC1-PC3:\n');
            disp(pc123(1:min(20,end),:));


            fprintf('Waveform range per selected row, first 20 rows:\n');

            nShow = min(20, numel(rows));

            wfMin = min(Wfs(rows,:), [], 2, 'omitnan');
            wfMax = max(Wfs(rows,:), [], 2, 'omitnan');

            debugT = table( ...
                rows(1:nShow), ...
                wfMin(1:nShow), ...
                wfMax(1:nShow), ...
                'VariableNames', {'SpikeRow','WfMin','WfMax'} ...
                );

            disp(debugT);

            fprintf('========================================================\n\n');

        end

        function lab = GetCurrentDropdownLabel(app, popH)

            labels = get(popH,'String');

            if ischar(labels)
                labels = cellstr(labels);
            end

            if isempty(labels)
                lab = '';
                return
            end

            v = round(get(popH,'Value'));
            v = min(max(v,1),numel(labels));

            lab = labels{v};

        end

        function UpdateSelectedSpikeRemoveButtons(app)

        end


        function StoreDimDropdownUndoState(app, handles)

            set(handles.popDim1, 'UserData', GetCurrentDropdownLabel(app, handles.popDim1));
            set(handles.popDim2, 'UserData', GetCurrentDropdownLabel(app, handles.popDim2));
            set(handles.popDim3, 'UserData', GetCurrentDropdownLabel(app, handles.popDim3));

            fprintf('[Dim undo state stored] %s / %s / %s\n', ...
                get(handles.popDim1,'UserData'), ...
                get(handles.popDim2,'UserData'), ...
                get(handles.popDim3,'UserData'));

        end


        function RestoreDimDropdownSuppress(app, oldValue)

            if nargin < 2
                oldValue = false;
            end

            app.SuppressDimDropdownCallback = oldValue;

        end

        function NewFrameData = ReframeCurrentSessionFromIsoWin(app, handles, FrameData, IsoWinSec)

            IsoWin = IsoWinSec * 1e6;
        
            AllWfs = [];
            AllSpk = [];
            AllStatus = [];
            AllNoiseNumber = [];
        
            NoiseWfs = [];
        
            for iFr = 1:numel(FrameData)
        
                if isfield(FrameData(iFr),'NoiseWfs') && ~isempty(FrameData(iFr).NoiseWfs) && isempty(NoiseWfs)
                    NoiseWfs = FrameData(iFr).NoiseWfs;
                end
        
                if ~isfield(FrameData(iFr),'Wfs') || isempty(FrameData(iFr).Wfs) || ...
                   ~isfield(FrameData(iFr),'spk') || isempty(FrameData(iFr).spk) || ...
                   ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status)
                    continue
                end
        
                W = FrameData(iFr).Wfs;
                T = FrameData(iFr).spk(:);
                S = FrameData(iFr).Status(:);
        
                n = min([size(W,1), numel(T), numel(S)]);
        
                W = W(1:n,:);
                T = T(1:n);
                S = S(1:n);
        
                if isfield(FrameData(iFr),'NoiseNumber') && ~isempty(FrameData(iFr).NoiseNumber)
                    NN = FrameData(iFr).NoiseNumber(:);
        
                    if numel(NN) >= n
                        NN = NN(1:n);
                    else
                        NN = NaN(n,1);
                    end
                else
                    NN = NaN(n,1);
                    NN(S == 0) = 1;
                end
        
                AllWfs = [AllWfs; W]; %#ok<AGROW>
                AllSpk = [AllSpk; T]; %#ok<AGROW>
                AllStatus = [AllStatus; S]; %#ok<AGROW>
                AllNoiseNumber = [AllNoiseNumber; NN]; %#ok<AGROW>
        
            end
        
            if isempty(AllSpk)
                error('[Reframe] No spikes found in FrameData.')
            end
        
            [AllSpk, sortIdx] = sort(AllSpk(:));
            AllWfs = AllWfs(sortIdx,:);
            AllStatus = AllStatus(sortIdx);
            AllNoiseNumber = AllNoiseNumber(sortIdx);
        
            maxT = max(AllSpk);
            Tedges = 0:IsoWin:maxT;
        
            if isempty(Tedges) || Tedges(end) < maxT
                Tedges = [Tedges maxT + 1];
            else
                Tedges(end) = Tedges(end) + 1;
            end
        
            nFrames = length(Tedges) - 1;
            app.edIsoWin.UserData = Tedges;
        
            NewFrameData(nFrames).U = [];
            pcold = [];
        
            for iFr = 1:nFrames
        
                rows = AllSpk >= Tedges(iFr) & AllSpk < Tedges(iFr+1);
        
                Wseg = AllWfs(rows,:);
                Tseg = AllSpk(rows);
                Sseg = AllStatus(rows);
                NNseg = AllNoiseNumber(rows);
        
                nSpikes = size(Wseg,1);
        
                NewFrameData(iFr).Wfs = Wseg;
                NewFrameData(iFr).spk = Tseg;
                NewFrameData(iFr).Status = Sseg(:);
                NewFrameData(iFr).TempInclude = Sseg(:) > 0;
                NewFrameData(iFr).Clu = abs(Sseg(:));
                NewFrameData(iFr).NoiseNumber = NNseg(:);
        
                NewFrameData(iFr).NoiseWfs = NoiseWfs;
        
                NewFrameData(iFr).RemCell = zeros(1,14);
                NewFrameData(iFr).RemIso = zeros(1,14);
                NewFrameData(iFr).Iso = zeros(14,1);
                NewFrameData(iFr).ReassignMap = 1:14;
                NewFrameData(iFr).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
                NewFrameData(iFr).DimDisplayWf = cell(1,6);
                NewFrameData(iFr).EigFrac = zeros(1,6);
        
                if nSpikes >= 2
        
                    [U_seg, pc, eigvalues] = spikepcsNew(app, Wseg, pcold);
        
                    nPlotDim = min(6, size(U_seg,2));
        
                    NewFrameData(iFr).U = U_seg;
                    NewFrameData(iFr).PC = pc;
                    NewFrameData(iFr).Uplot = NaN(nSpikes,6);
                    NewFrameData(iFr).PCplot = zeros(size(pc,1),6);
        
                    NewFrameData(iFr).Uplot(:,1:nPlotDim) = U_seg(:,1:nPlotDim);
                    NewFrameData(iFr).PCplot(:,1:min(6,size(pc,2))) = pc(:,1:min(6,size(pc,2)));
        
                    if ~isempty(eigvalues) && any(eigvalues)
                        eigFrac = (eigvalues.^2) / sum(eigvalues.^2);
                        NewFrameData(iFr).EigFrac(1:min(6,numel(eigFrac))) = eigFrac(1:min(6,numel(eigFrac)));
                    end
        
                    pcold = pc;
        
                else
        
                    NewFrameData(iFr).U = NaN(nSpikes,6);
                    NewFrameData(iFr).PC = zeros(size(Wseg,2),6);
                    NewFrameData(iFr).Uplot = NaN(nSpikes,6);
                    NewFrameData(iFr).PCplot = zeros(size(Wseg,2),6);
        
                end
        
                Wmetric = double(Wseg);
                nMetricPC = min(6, min(size(Wmetric)));
        
                if size(Wmetric,1) >= 2 && nMetricPC >= 1
                    [MetricPC, MetricX, ~, ~, ~, MetricMu] = pca(Wmetric, 'NumComponents', nMetricPC);
        
                    NewFrameData(iFr).MetricX = MetricX;
                    NewFrameData(iFr).MetricPC = MetricPC;
                    NewFrameData(iFr).MetricMu = MetricMu;
                else
                    NewFrameData(iFr).MetricX = [];
                    NewFrameData(iFr).MetricPC = [];
                    NewFrameData(iFr).MetricMu = [];
                end
        
                if nSpikes > 0
                    NewFrameData(iFr).mWf = NaN(14, size(Wseg,2));
        
                    for c = 1:14
                        if any(Sseg(:) == c)
                            NewFrameData(iFr).Iso(c) = 1;
                            NewFrameData(iFr).mWf(c,:) = mean(Wseg(Sseg(:) == c,:), 1);
                        else
                            NewFrameData(iFr).Iso(c) = 0;
                        end
                    end
                else
                    NewFrameData(iFr).mWf = [];
                end
        
                try
                    NewFrameData = RecomputeOneFrameClusterMeta(app, NewFrameData, iFr);
                catch
                end
        
                try
                    NewFrameData = ComputeQAllClusters(app, NewFrameData, iFr);
                catch
                end
        
            end
        
        end

        function edIsoWinValueChanged(app, event)

            disp('running edIsoWinValueChanged')

            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                fprintf('[Reframe skipped] No FrameData loaded.\n');
                return
            end

            IsoWinSec = str2double(app.edIsoWin.Value);
            if isnan(IsoWinSec) || IsoWinSec <= 0
                fprintf('[Reframe skipped] bad frame size: %s\n', app.edIsoWin.Value);
                return
            end

            PushUndo(app, handles, 'Change Frame Size');

            FrameData = ReframeCurrentSessionFromIsoWin(app, handles, FrameData, IsoWinSec);

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
            set(handles.stFrame2, 'UserData', numel(FrameData));

            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;

            FrNum = 1;
            RedrawSortMovie(app, handles, FrNum, true);
            UpdateAllQLabels(app, handles, FrNum);
            PrintFrameClusters(app, FrameData, FrNum, 'CHANGE FRAME SIZE');

            fprintf('[Reframe done] IsoWinSec = %.6g, nFrames = %d\n', IsoWinSec, numel(FrameData));

        end

        function FrameData = RemoveFSDEPlotDim(app, FrameData, FrNum)
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                return
            end

            fsdeDim = find(strcmp(FrameData(FrNum).DimLabels, 'fsdeZ'), 1);

            if isempty(fsdeDim)
                return
            end

            if isfield(FrameData(FrNum),'Uplot') && size(FrameData(FrNum).Uplot,2) >= fsdeDim
                FrameData(FrNum).Uplot(:,fsdeDim) = [];
            end

            if isfield(FrameData(FrNum),'PCplot') && size(FrameData(FrNum).PCplot,2) >= fsdeDim
                FrameData(FrNum).PCplot(:,fsdeDim) = [];
            end

            FrameData(FrNum).DimLabels(fsdeDim) = [];

            if isfield(FrameData(FrNum),'DimDisplayWf') && numel(FrameData(FrNum).DimDisplayWf) >= fsdeDim
                FrameData(FrNum).DimDisplayWf(fsdeDim) = [];
            end

            if isfield(FrameData(FrNum),'EigFrac') && numel(FrameData(FrNum).EigFrac) >= fsdeDim
                FrameData(FrNum).EigFrac(fsdeDim) = [];
            end
        end

        function FrameData = AddFSDEHillFeatureDim(app, FrameData, FrNum)
            return 
            % OPTIONAL LOAD-TIME fsde FEATURE.
            % Easy removal:
            %   1. delete this function
            %   2. delete calls to AddFSDEHillFeatureDim(...)
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end
            if ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                return
            end
            W = double(FrameData(FrNum).Wfs);
            nSpikes = size(W,1);
            wfLen = size(W,2);
            if nSpikes < 2 || wfLen < 20
                return
            end
        
            % Estimate each spike's baseline using the first 10 and last 10 samples of that waveform.
            baseline = mean([W(:,1:10), W(:,end-9:end)], 2);

            %convert NEGATIVE spikes to positive 'depth signals' 
            % If W goes downward at the spike trough, baseline - W makes that trough become a positive peak.
            D = baseline - W;
        
                        % D is positive negative-depth: bigger D = original W is more negative.
            [amp, troughIdx] = max(D, [], 2);

            width50 = NaN(nSpikes,1);
            width80 = NaN(nSpikes,1);
            curvRaw = NaN(nSpikes,1);
            curvNorm = NaN(nSpikes,1);
            fsdeCorr = NaN(nSpikes,1);
            reboundRatio = NaN(nSpikes,1);
            fsdeRaw = NaN(nSpikes,1);

            x = 1:wfLen;
            sigma = 18;  % bigger = broader fake broad-trough template

            for i = 1:nSpikes

                if ~isfinite(amp(i)) || amp(i) <= 0
                    continue
                end

                d = D(i,:);          % positive negative-depth trace
                w = W(i,:);          % original waveform voltage
                pk = troughIdx(i);   % deepest negative trough index

                % Width at 50% depth.
                % Original-voltage meaning: W <= baseline - 0.5*amp.
                th50 = 0.50 * amp(i);
                deep50 = d >= th50;

                left = pk;
                while left > 1 && deep50(left-1)
                    left = left - 1;
                end

                right = pk;
                while right < wfLen && deep50(right+1)
                    right = right + 1;
                end

                width50(i) = right - left + 1;

                % Width at 80% depth.
                % Original-voltage meaning: W <= baseline - 0.8*amp.
                th80 = 0.80 * amp(i);
                deep80 = d >= th80;

                left = pk;
                while left > 1 && deep80(left-1)
                    left = left - 1;
                end

                right = pk;
                while right < wfLen && deep80(right+1)
                    right = right + 1;
                end

                width80(i) = right - left + 1;

                % Curvature of the positive-depth trough.
                % Sharp real spike = high curvature.
                % slow broad valley= low curvature.
                drv2 = diff(d, 2);

                lo = max(1, pk - 8);
                hi = min(numel(drv2), pk + 8);

                if lo <= hi
                    curvRaw(i) = max(abs(drv2(lo:hi)));
                    curvNorm(i) = curvRaw(i) / max(abs(amp(i)), eps);
                end

                % Correlation with a broad fake broad-trough template centered at pk.
                % High fsdeCorr = negative trough is broad/smooth/gaussian-like.
                fsdeTemplate = exp(-0.5 * ((x - pk) ./ sigma).^2);

                dn = d - mean(d);
                dn = dn ./ max(norm(dn), eps);

                tn = fsdeTemplate - mean(fsdeTemplate);
                tn = tn ./ max(norm(tn), eps);

                fsdeCorr(i) = dn * tn(:);

                % Positive rebound after the trough in original waveform.
                % This catches the huge slow positive hump after the negative valley.
                postWin = pk:min(wfLen, pk + 80);
                posRebound = max(w(postWin) - baseline(i));
                reboundRatio(i) = posRebound / max(amp(i), eps);

                % New raw score:
                % width50/width80 = broad negative valley
                % fsdeCorr = broad smooth trough shape
                % reboundRatio = huge positive hump after trough
                % curvNorm subtracts sharpness
                fsdeRaw(i) = ...
                    1.0  * width50(i) + ...
                    2.0  * width80(i) + ...
                    20.0 * fsdeCorr(i) + ...
                    15.0 * reboundRatio(i) - ...
                    5.0  * curvNorm(i);

            end

        
            % Robust z-score within this frame.
            good = isfinite(fsdeRaw);
        
            fsdeZ = NaN(nSpikes,1);
        
            if sum(good) >= 2
                medFSDE = median(fsdeRaw(good));
                madFSDE = median(abs(fsdeRaw(good) - medFSDE));
                scaleFSDE = 1.4826 * madFSDE;
        
                if scaleFSDE == 0 || isnan(scaleFSDE) || isinf(scaleFSDE)
                    scaleFSDE = std(fsdeRaw(good), 0, 'omitnan');
                end
        
                if scaleFSDE == 0 || isnan(scaleFSDE) || isinf(scaleFSDE)
                    scaleFSDE = 1;
                end
        
                fsdeZ = (fsdeRaw - medFSDE) ./ scaleFSDE;
            else
                fsdeZ(good) = 0;
            end
        
            % Store raw feature fields.
            FrameData(FrNum).fsdeZ = fsdeZ;
            FrameData(FrNum).fsdeRaw = fsdeRaw;
            FrameData(FrNum).fsdeWidth50 = width50;
            FrameData(FrNum).fsdeWidth80 = width80;
            FrameData(FrNum).fsdeCurvRaw = curvRaw;
            FrameData(FrNum).fsdeCurvNorm = curvNorm;
            FrameData(FrNum).fsdeCorr = fsdeCorr;
            FrameData(FrNum).fsdeReboundRatio = reboundRatio;
            FrameData(FrNum).fsdeAmp = amp;
            FrameData(FrNum).fsdeTroughIdx = troughIdx;
        
            % Optional obvious suspicious row list.
            % This does NOT change Status. It only records rows to inspect.
            FrameData(FrNum).fsdeLikelyRows = find(fsdeZ > 3.5);
        
            % Add fsdeZ as a plotting dimension in Uplot.
            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot)
                if isfield(FrameData(FrNum),'U') && ~isempty(FrameData(FrNum).U)
                    FrameData(FrNum).Uplot = FrameData(FrNum).U;
                else
                    FrameData(FrNum).Uplot = NaN(nSpikes,6);
                end
            end
        
            if size(FrameData(FrNum).Uplot,1) ~= nSpikes
                fprintf('[fsdeZ skipped plot dim] Fr %d Uplot row mismatch\n', FrNum);
                return
            end
        
            % Make labels if missing.
            if ~isfield(FrameData(FrNum),'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                FrameData(FrNum).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
            end
        
            % Use existing fsdeZ column if already present; otherwise append.
            existingFsdeDim = find(strcmp(FrameData(FrNum).DimLabels, 'fsdeZ'), 1);
        
            if isempty(existingFsdeDim)
                fsdeDim = size(FrameData(FrNum).Uplot,2) + 1;
            else
                fsdeDim = existingFsdeDim;
            end
        
            if size(FrameData(FrNum).Uplot,2) < fsdeDim
                FrameData(FrNum).Uplot(:,fsdeDim) = NaN;
            end
        
            FrameData(FrNum).Uplot(:,fsdeDim) = fsdeZ;
        
            % PCplot is waveform-basis-like; scalar feature gets placeholder column.
            if ~isfield(FrameData(FrNum),'PCplot') || isempty(FrameData(FrNum).PCplot)
                if isfield(FrameData(FrNum),'PC') && ~isempty(FrameData(FrNum).PC)
                    FrameData(FrNum).PCplot = FrameData(FrNum).PC;
                else
                    FrameData(FrNum).PCplot = zeros(wfLen,6);
                end
            end
        
            if size(FrameData(FrNum).PCplot,2) < fsdeDim
                FrameData(FrNum).PCplot(:,fsdeDim) = 0;
            end
        
            if numel(FrameData(FrNum).DimLabels) < fsdeDim
                FrameData(FrNum).DimLabels{fsdeDim} = 'fsdeZ';
            end
        
            if ~isfield(FrameData(FrNum),'DimDisplayWf') || isempty(FrameData(FrNum).DimDisplayWf)
                FrameData(FrNum).DimDisplayWf = cell(1,fsdeDim);
            end
        
            if numel(FrameData(FrNum).DimDisplayWf) < fsdeDim
                FrameData(FrNum).DimDisplayWf{fsdeDim} = [];
            end
        
            FrameData(FrNum).DimDisplayWf{fsdeDim} = [];
        
            if ~isfield(FrameData(FrNum),'EigFrac') || isempty(FrameData(FrNum).EigFrac)
                FrameData(FrNum).EigFrac = zeros(1,fsdeDim);
            end
        
            if numel(FrameData(FrNum).EigFrac) < fsdeDim
                FrameData(FrNum).EigFrac(fsdeDim) = 0;
            end
        
            FrameData(FrNum).EigFrac(fsdeDim) = 0;
        
            fprintf('[fsdeZ] Fr %d added dim %d; likely fsde events: %d/%d\n', ...
                FrNum, fsdeDim, numel(FrameData(FrNum).fsdeLikelyRows), nSpikes);
        
        end

        function FrameData = RecoverStatusesInFrame(app, handles, FrameData, FrNum, rows, actionName)

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[%s skipped] bad FrameData/FrNum\n', actionName);
                return
            end

            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[%s skipped] frame has no Status\n', actionName);
                return
            end

            status = FrameData(FrNum).Status(:);

            if islogical(rows)
                rows = rows(:);
            else
                idx = rows(:);
                rows = false(numel(status),1);
                rows(idx) = true;
            end

            rows = rows & status < 0;

            if ~any(rows)
                fprintf('[%s skipped] no isolated negative statuses to recover\n', actionName);
                return
            end

            newStatus = abs(status(rows));

            fprintf('[%s] Fr %d recovered %d isolated spikes\n', ...
                actionName, FrNum, sum(rows));

            FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, newStatus, ...
                'CLUSTERpbReassign_Callback');

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        end

        function ResetResizeFlag(app)

            app.IsResizing = false;

        end

        function UpdateRecoverSelectedClusterButton(app)

            % Default: disabled unless current selection is recoverable.
            app.pbRecoverSelectedCluster.Enable = 'off';


            if isprop(app,'pbRemoveRefractoryViolations') && isvalid(app.pbRemoveRefractoryViolations)
                app.pbRemoveRefractoryViolations.Enable = 'off';
            end

            % Recover is allowed for selected noise-number group.
            % That sends Status 0 / NoiseNumber N back to C1.
            if isfinite(app.SelectedNoiseNumber)
                app.pbRecoverSelectedCluster.Enable = 'on';
                return
            end

            % Recover is allowed only for removed/isolated negative clusters.
            cl = app.SelectedClusterNum;
            fr = app.SelectedClusterFrame;

            % Refractory removal is allowed ONLY for selected active clusters C1-C14.
            if isfinite(fr) && isfinite(cl) && cl >= 1 && cl <= 14
                if isprop(app,'pbRemoveRefractoryViolations') && isvalid(app.pbRemoveRefractoryViolations)
                    app.pbRemoveRefractoryViolations.Enable = 'on';
                end

                fprintf('[UI] recover disabled: selected cluster C%d is active/non-removed.\n', round(cl));
                return
            end

            if isfinite(cl) && cl < 0
                app.pbRecoverSelectedCluster.Enable = 'on';
                return
            end

            % Active clusters C1-C14 are not recoverable.
            if isfinite(cl) && cl >= 1 && cl <= 14
                fprintf('[UI] recover disabled: selected cluster C%d is active/non-removed.\n', round(cl));
            end

        end

        function [startPath, spikeFileName, spikeFile, elec, ok] = ResolveSpikeFileFromUI(app, refreshDropdown)

            % Default failed outputs.
            ok = false;
            startPath = '';
            spikeFileName = '';
            spikeFile = '';
            elec = NaN;
        
            % Read selected subject/session.
            dataRoot = app.DataRoot;
            Subj = app.popSubject.Value;
            Sess = app.popSession.Value;
        
            if strcmp(Subj, '-- select subject --') || ...
               strcmp(Sess, '-- select session --') || ...
               strcmp(Sess, 'no sessions')
        
                if isprop(app,'popSrtdLoad') && isvalid(app.popSrtdLoad)
                    app.popSrtdLoad.Items = {'none'};
                    app.popSrtdLoad.Value = 'none';
                end
                return
            end
        
            % Convert displayed subject to NS###.
            tok = regexp(Subj, 'NS\d+', 'match', 'once');
            if ~isempty(tok)
                Subj = tok;
            end
        
            % Find subject/session folder.
            subjFolders = dir(fullfile(dataRoot, [Subj '*']));
            subjFolders = subjFolders([subjFolders.isdir]);
        
            if isempty(subjFolders)
                if isprop(app,'popSrtdLoad') && isvalid(app.popSrtdLoad)
                    app.popSrtdLoad.Items = {'no matching files'};
                    app.popSrtdLoad.Value = 'no matching files';
                end
                return
            end
        
            startPath = fullfile(dataRoot, subjFolders(1).name, Sess);
        
            if ~isfolder(startPath)
                if isprop(app,'popSrtdLoad') && isvalid(app.popSrtdLoad)
                    app.popSrtdLoad.Items = {'no matching files'};
                    app.popSrtdLoad.Value = 'no matching files';
                end
                return
            end
        
            % Read typed electrode number.
            elecStr = strtrim(app.edElec.Value);
            elec = str2double(elecStr);
        
            if isnan(elec) || elec < 1
                if isprop(app,'popSrtdLoad') && isvalid(app.popSrtdLoad)
                    app.popSrtdLoad.Items = {'bad electrode number'};
                    app.popSrtdLoad.Value = 'bad electrode number';
                end
                return
            end
        
            % Optionally refresh dropdown with every matching file for that electrode.
            if refreshDropdown || isempty(app.popSrtdLoad.Items) || ...
                    any(strcmp(app.popSrtdLoad.Value, {'none','no matching files','bad electrode number'}))
        

                % Get all visible .mat files first, then exact-filter electrode number.
                % This prevents electrode 1 from matching electrode 10/11/12/etc.
                matches = dir(fullfile(startPath, '*.mat'));

                matches = matches(~[matches.isdir]);
                matches = matches(~startsWith({matches.name}, '._') & ~startsWith({matches.name}, '.'));

                % Do not show AnalysisVars files in the app-load dropdown.
                matches = matches(~contains({matches.name}, 'AnalysisVars', 'IgnoreCase', true));

                fileNamesAll = {matches.name};

                keep = false(size(fileNamesAll));

                for iFile = 1:numel(fileNamesAll)

                    fn = fileNamesAll{iFile};

                    % Match CSC electrode files exactly:
                    % CSC1spikes.mat, CSC1SortSession.mat, CSC1_SortSession.mat, etc.
                    % Does NOT match CSC10...
                    cscPat = sprintf('(?i)(^|[^A-Za-z0-9])CSC0*%d([^0-9]|$)', elec);

                    % Match E electrode files exactly:
                    % E1..., _E1..., -E1..., etc.
                    % Does NOT match E10...
                    ePat = sprintf('(?i)(^|[^A-Za-z0-9])E0*%d([^0-9]|$)', elec);

                    % Match E electrode files exactly:
                    % ch1..., _ch1..., -ch1..., etc.
                    % Does NOT match E10...
                    chPat = sprintf('(?i)(^|[^A-Za-z0-9])ch0*%d([^0-9]|$)', elec);

                    keep(iFile) = ~isempty(regexp(fn, cscPat, 'once')) || ...
                        ~isempty(regexp(fn, ePat, 'once')) || ~isempty(regexp(fn, chPat, 'once'));
                end

                matches = matches(keep);

                [~, ia] = unique({matches.name}, 'stable');
                matches = matches(ia);


                if isempty(matches)
                    app.popSrtdLoad.Items = {'no matching files'};
                    app.popSrtdLoad.Value = 'no matching files';
                    return
                end
        
                fileNames = {matches.name};
        
                % Put session/sorted files first, then raw.
                isSession = contains(lower(fileNames), 'sortsession');
                isSorted = contains(lower(fileNames), 'srtd') | contains(lower(fileNames), 'sorted');
                priority = isSession | isSorted;
        
                fileNames = [fileNames(priority), fileNames(~priority)];
        
                oldChoice = app.popSrtdLoad.Value;
        
                app.popSrtdLoad.Items = fileNames;
        
                if any(strcmp(oldChoice, fileNames))
                    app.popSrtdLoad.Value = oldChoice;
                else
                    app.popSrtdLoad.Value = fileNames{1};
                end
        
                fprintf('[FILE DEBUG] Matching files for electrode %d:\n', elec);
                disp(fileNames(:));
            end
        
            % Use currently selected dropdown file.
            spikeFileName = app.popSrtdLoad.Value;
        
            if isempty(spikeFileName) || ...
               any(strcmp(spikeFileName, {'none','no matching files','bad electrode number'}))
                return
            end
        
            spikeFile = fullfile(startPath, spikeFileName);
        
            if ~isfile(spikeFile)
                fprintf('[ResolveSpikeFileFromUI] selected file does not exist:\n%s\n', spikeFile);
                return
            end
        
            ok = true;
        end


        function StoreInitialAppLayout(app)

            app.figure1.Units = 'pixels';

            figPos = app.figure1.Position;

            app.Layout0.FigWidth = figPos(3);
            app.Layout0.FigHeight = figPos(4);

            propNames = properties(app);

            objs = {};
            positions = {};
            fontSizes = {};

            for k = 1:numel(propNames)

                try
                    obj = app.(propNames{k});
                catch
                    continue
                end

                if isempty(obj) || isequal(obj, app.figure1)
                    continue
                end

                if ~isobject(obj) || ~isvalid(obj)
                    continue
                end

                if ~isprop(obj, 'Position')
                    continue
                end

                if isprop(obj, 'Units')
                    try
                        obj.Units = 'pixels';
                    catch
                    end
                end

                try
                    p = obj.Position;
                catch
                    continue
                end

                if ~isnumeric(p) || numel(p) ~= 4
                    continue
                end

                objs{end+1} = obj; %#ok<AGROW>
                positions{end+1} = p; %#ok<AGROW>

                if isprop(obj, 'FontSize')
                    fontSizes{end+1} = obj.FontSize; %#ok<AGROW>
                else
                    fontSizes{end+1} = []; %#ok<AGROW>
                end

            end

            app.Layout0.Objs = objs;
            app.Layout0.Positions = positions;
            app.Layout0.FontSizes = fontSizes;

            fprintf('[Resize setup] stored %d resizable components\n', numel(objs));

        end


        function ResizeWholeApp(app) %define method beloning to app object 
            %this runs WHEN the figure window changes size 
            %app.figure1.SizeChangedFcn = @(src,event)ResizeWholeApp(app);
            if app.IsResizing %prevent recursive calls 
                return %does NOT stop from calling many times 
            end %just stops overlapping calls 

            if isempty(app.Layout0) || ~isfield(app.Layout0, 'Objs') || isempty(app.Layout0.Objs)
                fprintf('[Resize skipped] no stored layout objects\n');
                return
            end
            % app.Layout0 is the stored snapshot.
            % app.Layout0.Objs is the list of UI objects to resize.
            % If there is no snapshot, the function cannot know original positions.

            app.IsResizing = true; 

            % This guarantees ResetResizeFlag(app) runs when the function exits, even if it exits early or errors.
            cleanupObj = onCleanup(@()ResetResizeFlag(app)); %—> app.IsResizing = false; 
           
            %dk moving to top 
               %dk new moved to top 
            app.figure1.Units = 'pixels';
            figPos = app.figure1.Position;

            baseW = app.Layout0.FigWidth;
            baseH = app.Layout0.FigHeight;

            if isnan(app.prevW) || isnan(app.prevH) %only on initialization 
                app.prevW = baseW; 
                app.prevH = baseH; 
            end 
            if isnan(app.curW) || isnan(app.curH) %only on initialization  
                app.curW = baseW; 
                app.curH = baseH; 
            end

            app.prevW = app.curW; 
            app.prevH = app.curH; 

            %CANDIDATE cur w/h, IF they pass the dx dy check 
            newW = figPos(3); 
            newH = figPos(4); 

            if newW <= 0 || newH <= 0 || baseW <= 0 || baseH <= 0
                return
            end

            dx = newW - app.prevW;
            dy = newH - app.prevH; 

            % disp('[curW    curH  prevW prevH dx dy]')
            % disp([app.curW app.curH app.prevW app.prevH dx dy])
            % 
            % disp('[newW newH prevW prevH dx dy]')
            % disp([newW newH app.prevW app.prevH dx dy])

            if abs(dx) <= 30 && abs(dy) <= 30 
                % disp('[skip  dx dy]')
                % disp([dx    dy])
                return 
            end 

            app.curW = newW;
            app.curH = newH;

            % disp('[dx    dy]')
            % disp([dx    dy])

            xScale = app.curW / baseW;
            yScale = app.curH / baseH;

            % Chooses one scale for text size.
            % Uses the smaller scale so fonts do not get too huge if the window gets wide but not tall.
            fontScale = min(xScale, yScale);

            for k = 1:numel(app.Layout0.Objs)
                % Loops over every stored UI object.
                % This includes axes, buttons, labels, dropdowns, edit fields, maybe dozens or 100+ objects.

                % Gets the kth UI object.
                obj = app.Layout0.Objs{k};

                if isempty(obj) || ~isvalid(obj) %skip deleted or invalid items 
                    continue
                end %continue goes to next object 

                %og saved position for this object [x y width height] from
                %Layout0 
                p0 = app.Layout0.Positions{k}; 

                %build new resized position 
                pNew = [
                    p0(1) * xScale, ...
                    p0(2) * yScale, ...
                    max(1, p0(3) * xScale), ...
                    max(1, p0(4) * yScale)
                    ];

                %Attempts to set the object’s new position.
                %this is the biggest slow part.
                % Every obj.Position = pNew can force MATLAB to recalculate graphics layout. 
                % For axes, this is especially expensive because axes have ticks, labels, 
                % children, limits, toolbar stuff, etc.
                try
                    obj.Position = pNew;
                catch
                end

                % Checks whether this object has a FontSize property and whether an original font size was saved.
                % Buttons/labels/dropdowns have FontSize. Some objects may not.
                % if isprop(obj, 'FontSize') && ~isempty(app.Layout0.FontSizes{k})
                % 
                % 
                %     % Sets the resized font size.
                %     try
                %         obj.FontSize = max(6, app.Layout0.FontSizes{k} * fontScale);
                %         %max(6, ...) prevents fonts from going below size
                %         6. 
                %     catch
                %     end
                % end

            end


            if abs(dx) <= 45 && abs(dy) <= 45 
                disp('skipped ApplyUILayoutLocks(app);')
                return 
            elseif abs(dx) > 45 || abs(dy) > 45 
                ApplyUILayoutLocks(app);
            end 
            
            % drawnow limitrate

        end


        function ApplyUILayoutLocks(app)
        
            % subject/session/file dropdowns immediately left of axDim12
            gapToAx = 4;
            labelGap = 3;
        
            pAx = app.axDim12.Position;
        
            p = app.popSubject.Position;
            p(1) = pAx(1) - p(3) - gapToAx;
            app.popSubject.Position = p;
        
            p = app.popSession.Position;
            p(1) = pAx(1) - p(3) - gapToAx;
            app.popSession.Position = p;
        
            p = app.popSrtdLoad.Position;
            p(1) = pAx(1) - p(3) - gapToAx;
            app.popSrtdLoad.Position = p;
        
            pDrop = app.popSubject.Position;
            pLab = app.text113.Position;
            pLab(1) = pDrop(1) - pLab(3) - labelGap;
            pLab(2) = pDrop(2);
            app.text113.Position = pLab;
        
            pDrop = app.popSession.Position;
            pLab = app.text116.Position;
            pLab(1) = pDrop(1) - pLab(3) - labelGap;
            pLab(2) = pDrop(2);
            app.text116.Position = pLab;
        
            pDrop = app.popSrtdLoad.Position;
            pLab = app.text114.Position;
            pLab(1) = pDrop(1) - pLab(3) - labelGap;
            pLab(2) = pDrop(2);
            app.text114.Position = pLab;
        
        
            % frame noise labels aligned with axWaveforms_2 top boundary
            pAx = app.axWaveforms_2.Position;
        
            pLab = app.framenoiseLabel.Position;
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.framenoiseLabel.Position = pLab;
        
            pLab = app.nSpksNoise.Position;
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.nSpksNoise.Position = pLab;
        
        
            % selected spike label aligned with axWaveforms_3 top boundary
            pAx = app.axWaveforms_3.Position;
        
            pLab = app.SelectedSpikeIDLabel.Position;
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.SelectedSpikeIDLabel.Position = pLab;
        
        
            % selected cluster label aligned with axClusterSpikes top boundary
            pAx = app.axClusterSpikes.Position;
        
            pLab = app.SelectedClusterIDLabel.Position;
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.SelectedClusterIDLabel.Position = pLab;
        
        
            % main scatter labels aligned to axDim plot boundaries
        
            pAx = app.axDim12.Position;
        
            pLab = app.Ylabel1.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.Ylabel1.Position = pLab;
        
            pLab = app.Xlabel1.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) - pLab(4);
            app.Xlabel1.Position = pLab;
        
        
            pAx = app.axDim13.Position;
        
            pLab = app.Ylabel2.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.Ylabel2.Position = pLab;
        
            pLab = app.Xlabel2.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) - pLab(4);
            app.Xlabel2.Position = pLab;
        
        
            pAx = app.axDim23.Position;
        
            pLab = app.Ylabel3_2.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) + pAx(4) - pLab(4);
            app.Ylabel3_2.Position = pLab;
        
            pLab = app.Xlabel3.Position;
            pLab(1) = pAx(1);
            pLab(2) = pAx(2) - pLab(4);
            app.Xlabel3.Position = pLab;
        
        
            % text114 directly left of edIsoWin
            labelGap = 3;
        
            pEdit = app.edIsoWin.Position;
            pLab = app.text114.Position;
        
            pLab(1) = pEdit(1) - pLab(3) - labelGap;
            pLab(2) = pEdit(2);
        
            app.text114.Position = pLab;
        
        
            % edElec left of popSrtdLoad
            elecGap = 6;
        
            pLoad = app.popSrtdLoad.Position;
            pElec = app.edElec.Position;
        
            pElec(1) = pLoad(1) - pElec(3) - elecGap;
        
            app.edElec.Position = pElec;
        
        
            % PC label/dropdown placement: label touches dropdown
            labelPopGap = 0;
        
            pAx = app.axPC1.Position;
            pLab = app.PClabel1.Position;
            pPop = app.popDim1.Position;
        
            pLab(3) = 0.25 * pAx(3);
            pPop(3) = 0.75 * pAx(3);
        
            pPop(1) = pAx(1) + pAx(3) - pPop(3);
            pPop(2) = pAx(2) + pAx(4) - pPop(4);
        
            pLab(1) = pPop(1) - pLab(3) - labelPopGap;
            pLab(2) = pPop(2);
        
            app.PClabel1.Position = pLab;
            app.popDim1.Position = pPop;
        
        
            pAx = app.axPC2.Position;
            pLab = app.PClabel2.Position;
            pPop = app.popDim2.Position;
        
            pLab(3) = 0.25 * pAx(3);
            pPop(3) = 0.75 * pAx(3);
        
            pPop(1) = pAx(1) + pAx(3) - pPop(3);
            pPop(2) = pAx(2) + pAx(4) - pPop(4);
        
            pLab(1) = pPop(1) - pLab(3) - labelPopGap;
            pLab(2) = pPop(2);
        
            app.PClabel2.Position = pLab;
            app.popDim2.Position = pPop;
        
        
            pAx = app.axPC3.Position;
            pLab = app.PClabel3.Position;
            pPop = app.popDim3.Position;
        
            pLab(3) = 0.25 * pAx(3);
            pPop(3) = 0.75 * pAx(3);
        
            pPop(1) = pAx(1) + pAx(3) - pPop(3);
            pPop(2) = pAx(2) + pAx(4) - pPop(4);
        
            pLab(1) = pPop(1) - pLab(3) - labelPopGap;
            pLab(2) = pPop(2);
        
            app.PClabel3.Position = pLab;
            app.popDim3.Position = pPop;
        
        end

        function SetDropdownValueByLabel(app, popH, wantedLabel, fallbackIdx)

            labels = get(popH,'String');

            if ischar(labels)
                labels = cellstr(labels);
            end

            if isempty(labels)
                return
            end

            fallbackIdx = min(max(fallbackIdx,1),numel(labels));

            % prevent App Designer crash while changing value
            set(popH,'Value',1);

            idx = find(strcmp(labels, wantedLabel), 1);

            if isempty(idx)
                idx = fallbackIdx;
            end

            set(popH,'Value',idx);

        end
        

        function LoadAppSession(app, handles, Sload, spikeFile, startPath, spikeFileName, elec)

            fprintf('[LOAD APP SESSION] loading:\n%s\n', spikeFile);
        
            % Pull FrameData from either direct save or wrapped SaveState.
            if isfield(Sload, 'FrameData')
                FrameData = Sload.FrameData;
            elseif isfield(Sload, 'SaveState') && isfield(Sload.SaveState, 'FrameData')
                FrameData = Sload.SaveState.FrameData;
            else
                error('[LOAD APP SESSION] No FrameData found.')
            end
        
            if isempty(FrameData)
                error('[LOAD APP SESSION] FrameData is empty.')
            end

            % Restore saved frame size / isolation window if this session file has it.
            if isfield(Sload, 'ExportInfo') && ...
                    isfield(Sload.ExportInfo, 'IsoWinSec') && ...
                    isfinite(Sload.ExportInfo.IsoWinSec) && ...
                    Sload.ExportInfo.IsoWinSec > 0

                app.edIsoWin.Value = num2str(Sload.ExportInfo.IsoWinSec);
            end
        
            % Backfill old session files that do not have newer fields.
            for iFr = 1:numel(FrameData)
        
                if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status)
                    continue
                end
        
                nSpikes = numel(FrameData(iFr).Status);
        
                % Old files may not have NoiseNumber.
                if ~isfield(FrameData(iFr),'NoiseNumber') || isempty(FrameData(iFr).NoiseNumber) || ...
                        numel(FrameData(iFr).NoiseNumber) ~= nSpikes
                    FrameData(iFr).NoiseNumber = NaN(nSpikes,1);
                    FrameData(iFr).NoiseNumber(FrameData(iFr).Status(:) == 0) = 1;
                end
        
                % Old files may not have fixed metric space.
                if isfield(FrameData(iFr),'Wfs') && ~isempty(FrameData(iFr).Wfs) && ...
                        (~isfield(FrameData(iFr),'MetricX') || isempty(FrameData(iFr).MetricX))
        
                    Wmetric = double(FrameData(iFr).Wfs);
                    nMetricPC = min(6, min(size(Wmetric)));
        
                    if size(Wmetric,1) >= 2 && nMetricPC >= 1
                        [MetricPC, MetricX, ~, ~, ~, MetricMu] = pca(Wmetric, 'NumComponents', nMetricPC);
        
                        FrameData(iFr).MetricX = MetricX;
                        FrameData(iFr).MetricPC = MetricPC;
                        FrameData(iFr).MetricMu = MetricMu;
                    else
                        FrameData(iFr).MetricX = [];
                        FrameData(iFr).MetricPC = [];
                        FrameData(iFr).MetricMu = [];
                    end
                end
        
                % Old files may not have Uplot/PCplot.
                if isfield(FrameData(iFr),'U') && ~isempty(FrameData(iFr).U) && ...
                        (~isfield(FrameData(iFr),'Uplot') || isempty(FrameData(iFr).Uplot))
                    FrameData(iFr).Uplot = FrameData(iFr).U;
                end
        
                if isfield(FrameData(iFr),'PC') && ~isempty(FrameData(iFr).PC) && ...
                        (~isfield(FrameData(iFr),'PCplot') || isempty(FrameData(iFr).PCplot))
                    FrameData(iFr).PCplot = FrameData(iFr).PC;
                end
        
                % Old files may not have dim labels.
                if ~isfield(FrameData(iFr),'DimLabels') || isempty(FrameData(iFr).DimLabels)
                    FrameData(iFr).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
                end
        
                if ~isfield(FrameData(iFr),'DimDisplayWf') || isempty(FrameData(iFr).DimDisplayWf)
                    FrameData(iFr).DimDisplayWf = cell(1,6);
                end
        
                %FrameData = AddFSDEHillFeatureDim(app, FrameData, iFr); 
                
                FrameData = RemoveFSDEPlotDim(app, FrameData, iFr);

                % Keep legacy fields synced.
                FrameData(iFr).TempInclude = FrameData(iFr).Status(:) > 0;
        
                if ~isfield(FrameData(iFr),'Clu') || isempty(FrameData(iFr).Clu) || ...
                        numel(FrameData(iFr).Clu) ~= nSpikes
                    FrameData(iFr).Clu = abs(FrameData(iFr).Status(:));
                end
            end
        
            % Store loaded session state.
            app.FrameDataStore = FrameData;
            set(handles.frUnits, 'UserData', FrameData);
        
            % Critical: update frame count from loaded session.
            set(handles.stFrame2, 'UserData', numel(FrameData));
            set(handles.stFrame2, 'String', 'Frame 1');
        
            % Store loaded file info for save/export.

            if isfield(Sload, 'ExportInfo') && isfield(Sload.ExportInfo, 'FirstSpikeAbsUS') && isfield(Sload.ExportInfo, 'RecordingStartAbsUS')
                app.FirstSpikeAbsUS = Sload.ExportInfo.FirstSpikeAbsUS;
                app.RecordingStartAbsUS = Sload.ExportInfo.RecordingStartAbsUS;
            else
                warning(['This app-session file predates FirstSpikeAbsUS and RecordingStartAbsUS. ' 'Reload the original unsorted RawSortDat file once, then resave the app session and AnalysisVars.']);
            end
            app.figure1.UserData = struct( ...
                'filepath', startPath, ...
                'inputFileName', spikeFileName, ...
                'inputFile', spikeFile, ...
                'electrode', elec, ...
                'loadedAppSession', true, ... 
                'firstSpikeAbsUS', app.FirstSpikeAbsUS, ... 
                'recordingStartAbsUS', app.RecordingStartAbsUS);

            [~, baseNameNoExt, ~] = fileparts(spikeFileName);
            baseNameNoExt = regexprep(baseNameNoExt, '_\d*SortSession$', '');
            baseNameNoExt = regexprep(baseNameNoExt, '_SortSession$', '');
            app.baseName.Text = baseNameNoExt;
            app.edFilenameAppend.Value = '';
            app.matLabel.Text = '.mat';
        
            % Clear selected/hover state.
            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;
        
            % Redraw loaded session.
            FrNum = 1;


            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            
            RedrawSortMovie(app, handles, FrNum, true);
            StoreDimDropdownUndoState(app, handles);


            UpdateAllQLabels(app, handles, FrNum);
            PrintFrameClusters(app, FrameData, FrNum, 'LOAD APP SESSION');
        
            fprintf('[LOAD APP SESSION] restored %d frames from %s\n', numel(FrameData), spikeFileName);
        
        end

        function [fet, pc, eigs] = spikepcsNew(app, sp, pcold)

            sp = double(sp);

            nSamps = size(sp,2);   % 305 waveform samples / desired PCA width

            [pcRaw, fetRaw, eigsRaw] = pca(sp, ...
                'Algorithm', 'svd', ...
                'Centered', true);

            % Pad PCA outputs back to full waveform width.
            % This is what prevents 204 spikes -> 203 cols from crashing later.
            pc = zeros(nSamps, nSamps);
            fet = NaN(size(sp,1), nSamps);
            eigs = zeros(nSamps, 1);

            nCols = min([size(pcRaw,2), nSamps]);

            pc(:,1:nCols) = pcRaw(:,1:nCols);
            fet(:,1:nCols) = fetRaw(:,1:nCols);
            eigs(1:nCols) = eigsRaw(1:nCols);

            % Sign consistency.
            if nargin < 3 || isempty(pcold)
                for k = 1:min(3,size(pc,2))
                    if size(pc,1) >= 9 && pc(9,k) > 0
                        pc(:,k) = -pc(:,k);
                        fet(:,k) = -fet(:,k);
                    end
                end
            else
                for k = 1:min([3,size(pc,2),size(pcold,2)])
                    if pc(:,k)' * pcold(:,k) < 0
                        pc(:,k) = -pc(:,k);
                        fet(:,k) = -fet(:,k);
                    end
                end
            end

            fprintf('size pc in spikepcsNew\n');
            disp(size(pc));

            fprintf('size scorestmp/fet in spikepcsNew\n');
            disp(size(fet));


            % below here = lab funciton in a speerate mat file that is used
            % in the old version, where it WORKS. 

            % pc is pxp square matrix 
            % [coeff, score, latent, tsquare] = princomp_stolen(x,econFlag)

            % below here = old version that was i guess written into the
            % new app that i fixed 
            % if nargin ==1 || isempty(pcold)
            %     if pc(9,1)>0 pc(:,1) = -pc(:,1); end    %intended to find the minimum...
            %     if pc(9,2)>0 pc(:,2) = -pc(:,2); end
            %     if pc(9,3)>0 pc(:,3) = -pc(:,3); end
            % elseif nargin ==2
            %     if pc(:,1)'*pcold(:,1)<0 pc(:,1) = -pc(:,1); end %pcs are vertical 
            %     if pc(:,2)'*pcold(:,2)<0 pc(:,2) = -pc(:,2); end
            %     if pc(:,3)'*pcold(:,3)<0 pc(:,3) = -pc(:,3); end
            % end
            % 
            % fet = sp*pc;
            % 
            % % above here = lab funciton in a speerate mat file that is used
            % % in the old version, where it WORKS. 
            % sp = double(sp);
            % 
            % [pc, fet, eigs] = pca(sp, 'Algorithm', 'svd', 'Centered', true);
            % 
            % if size(pc,2) < 6
            %     pc(:,end+1:6) = 0;
            %     fet(:,end+1:6) = NaN;
            %     eigs(end+1:6) = 0;
            % end
            % 
            % if nargin < 3 || isempty(pcold)
            %     for k = 1:min(3,size(pc,2))
            %         if size(pc,1) >= 9 && pc(9,k) > 0
            %             pc(:,k) = -pc(:,k);
            %             fet(:,k) = -fet(:,k);
            %         end
            %     end
            % else
            %     for k = 1:min([3,size(pc,2),size(pcold,2)])
            %         if pc(:,k)' * pcold(:,k) < 0
            %             pc(:,k) = -pc(:,k);
            %             fet(:,k) = -fet(:,k);
            %         end
            %     end
            % end

        end

        function LoadUnsortedSpikes(app, handles, RawSortDat, spikeFile, startPath, spikeFileName, elec) 

            NoiseWfs = []; 
            
            fprintf('[LOAD UNSORTED SPIKES] loading:\n%s\n', spikeFile);
            app.edIsoWin.Value = '99999';
        
            if ~isfield(RawSortDat, 'Wfs')
                error('Loaded file does not contain Wfs: %s', spikeFile);
            end
                  
            RawSortDat.spk=RawSortDat.spk-RawSortDat.spk(1); % the code below assumes this- but we'll need to recover this for the translation later   
            app.axDim12.UserData = RawSortDat;
        
            % fprintf('Loaded: %s\n', spikeFile);
            fprintf('Loading finished');
            fprintf('Wfs size: %s\n', mat2str(size(RawSortDat.Wfs)));
            % fprintf('spk size: %s\n', mat2str(size(RawSortDat.spk)));
            disp('Dividing into Frame and calculating PCs')
        
            % Build frame edges from isolation window.
            IsoWinSec = str2double(app.edIsoWin.Value);
            if isnan(IsoWinSec) || IsoWinSec <= 0
                IsoWinSec = 99999;
                set(handles.edIsoWin,'String','99999');
            end
        
            IsoWin = IsoWinSec * 1e6;            
            maxT = max(RawSortDat.spk);
        
            % T = 0:IsoWin:maxT;
            T = RawSortDat.spk(1):IsoWin:maxT; 
        
            if isempty(T) || T(end) < maxT
                T = [T maxT + 1];
            else
                T(end) = T(end) + 1;
            end
        
            nFrames = length(T)-1;
            set(handles.stFrame2, 'UserData', nFrames);
        
            N = zeros(1,nFrames+1);
        
            for i = 2:nFrames+1
                idx = find(RawSortDat.spk < T(i), 1, 'last');
                if isempty(idx)
                    N(i) = N(i-1);
                else
                    N(i) = idx;
                end
            end
        
            N(end) = length(RawSortDat.spk);
        
            app.edIsoWin.UserData = T;
        
            fprintf('\nFRAME DEBUG SUMMARY\n');
            fprintf('nFrames = %d\n', nFrames);
        
            for iFr = 1:nFrames
                nSpikesFrame = N(iFr+1) - N(iFr);
                fprintf('Frame %d: %d spikes\n', iFr, nSpikesFrame);
            end
        
            fprintf('TOTAL SPIKES ACROSS FRAMES = %d\n\n', N(end));
        
            % Build FrameData.
            FrameData(nFrames).U = [];
            pcold = [];
        
            for iFr = 1:nFrames
        
                fprintf('Frame %d has %d spikes\n', iFr, N(iFr+1)-N(iFr));
        
                if N(iFr+1) >= N(iFr)+1
        
                    Wseg = RawSortDat.Wfs(N(iFr)+1:N(iFr+1),:);
                    Tseg = RawSortDat.spk(N(iFr)+1:N(iFr+1));
        
                    [U_seg, pc, eigvalues] = spikepcsNew(app, Wseg, pcold);
        
                    FrameData(iFr).U = U_seg;
                    FrameData(iFr).PC = pc;
                    FrameData(iFr).EigFrac = (eigvalues.^2) / sum(eigvalues.^2);
        
                    FrameData(iFr).Wfs = Wseg;
                    FrameData(iFr).spk = Tseg;
        
                    % Fixed metric space for Q metrics.
                    Wmetric = double(FrameData(iFr).Wfs);
                    nMetricPC = min(6, min(size(Wmetric)));
        
                    if size(Wmetric,1) >= 2 && nMetricPC >= 1
                        [MetricPC, MetricX, ~, ~, ~, MetricMu] = pca(Wmetric, 'NumComponents', nMetricPC);
        
                        FrameData(iFr).MetricX = MetricX;
                        FrameData(iFr).MetricPC = MetricPC;
                        FrameData(iFr).MetricMu = MetricMu;
                    else
                        FrameData(iFr).MetricX = [];
                        FrameData(iFr).MetricPC = [];
                        FrameData(iFr).MetricMu = [];
                    end
        
                    % Initialize sort state.
                    nSpikes = size(U_seg,1);
        
                    FrameData(iFr).Status = ones(nSpikes,1);
                    FrameData(iFr).TempInclude = FrameData(iFr).Status(:) > 0;
                    FrameData(iFr).Clu = ones(nSpikes,1);
        
                    FrameData(iFr).NoiseNumber = NaN(nSpikes,1);
        
                    FrameData(iFr).RemCell = zeros(1,14);
                    FrameData(iFr).RemIso = FrameData(iFr).RemCell;
                    FrameData(iFr).Iso = repmat(0,14,1);
                    FrameData(iFr).Iso(1) = 1;
        
                    FrameData(iFr).ReassignMap = 1:14;
                    FrameData(iFr).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
                    FrameData(iFr).DimDisplayWf = cell(1,6);
                    FrameData(iFr).mWf = mean(FrameData(iFr).Wfs);
        
                    FrameData(iFr).Uplot(:, 1:6) = U_seg(:, 1:6);
                    FrameData(iFr).PCplot(:, 1:6) = pc(:, 1:6);
                    %FrameData = AddFSDEHillFeatureDim(app, FrameData, iFr); 

                    FrameData = RemoveFSDEPlotDim(app, FrameData, iFr);
                    pcold = pc;
                end
            end
        
            % Attach noise references to every frame.
            for iFr = 1:numel(FrameData)
                FrameData(iFr).NoiseWfs = NoiseWfs;
            end


            if ~isfield(FrameData, 'FinalQ')
                [FrameData.FinalQ] = deal(struct([]));
            end

        
            % Store loaded raw session info.
            app.figure1.UserData = struct( ...
                'filepath', startPath, ...
                'inputFileName', spikeFileName, ...
                'inputFile', spikeFile, ...
                'electrode', elec, ...
                'loadedAppSession', false,... 
                'firstSpikeAbsUS', app.FirstSpikeAbsUS,...
                'recordingStartAbsUS', app.RecordingStartAbsUS);
           
       
            [~, baseNameNoExt, ~] = fileparts(spikeFileName);

            baseNameNoExt = regexprep(baseNameNoExt, 'spikes$', 'SortSession', 'ignorecase');
            baseNameNoExt = regexprep(baseNameNoExt, 'SrtdSpikes$', 'SortSession', 'ignorecase');
            baseNameNoExt = regexprep(baseNameNoExt, '_?AnalysisVars$', 'SortSession', 'ignorecase');

            if ~endsWith(baseNameNoExt, 'SortSession', 'IgnoreCase', true)
                baseNameNoExt = [baseNameNoExt 'SortSession'];
            end

            app.baseName.Text = baseNameNoExt;


            app.edFilenameAppend.Value = '';
            app.matLabel.Text = '.mat';


            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        
            % Clear selected/hover state.
            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;
        
            % Redraw first frame.
            FrNum = 1;
            set(handles.stFrame2, 'String', sprintf('Frame %d', FrNum));


            fprintf('\n===== LOAD CHECK =====\n');
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            fprintf('FrameData frames = %d\n', numel(FrameData));
            fprintf('Wfs size   = %s\n', mat2str(size(FrameData(FrNum).Wfs)));
            fprintf('U size     = %s\n', mat2str(size(FrameData(FrNum).U)));
            fprintf('Uplot size = %s\n', mat2str(size(FrameData(FrNum).Uplot)));
            fprintf('PC size    = %s\n', mat2str(size(FrameData(FrNum).PC)));
            fprintf('PCplot size= %s\n', mat2str(size(FrameData(FrNum).PCplot)));
            disp('DimLabels:')
            disp(FrameData(FrNum).DimLabels(:))
            fprintf('std Wfs all    = %.6g\n', std(FrameData(FrNum).Wfs(:), 'omitnan'));
            fprintf('std U PC1      = %.6g\n', std(FrameData(FrNum).U(:,1), 'omitnan'));
            fprintf('std U PC2      = %.6g\n', std(FrameData(FrNum).U(:,2), 'omitnan'));
            fprintf('std Uplot dim1 = %.6g\n', std(FrameData(FrNum).Uplot(:,1), 'omitnan'));
            fprintf('std Uplot dim2 = %.6g\n', std(FrameData(FrNum).Uplot(:,2), 'omitnan'));
            fprintf('first 5 U rows:\n');
            disp(FrameData(FrNum).U(1:min(5,end),1:min(3,end)))
            fprintf('first 5 Uplot rows:\n');
            disp(FrameData(FrNum).Uplot(1:min(5,end),1:min(3,end)))
            fprintf('===== END LOAD CHECK =====\n\n');


            RedrawSortMovie(app, handles, FrNum, true);
            StoreDimDropdownUndoState(app, handles);
  

            UpdateAllQLabels(app, handles, FrNum);
            PrintFrameClusters(app, FrameData, FrNum, 'LOAD UNSORTED SPIKES');
        
            % Save all timestamps separately for legacy code.
            set(handles.frSave,'UserData',RawSortDat.spk);
            RawSortDat = [];
            set(handles.frSort,'UserData',RawSortDat);
        
        end

        function clusList = ParseRemoveList(app)

            txt = string(app.removeList.Value);
            nums = regexp(txt, '\d+', 'match');
            clusList = str2double(nums);
            clusList = clusList(isfinite(clusList));
            clusList = unique(round(clusList));
            clusList = clusList(clusList >= 1 & clusList <= 14);

        end

        function RemoveListedClusters(app, event, removeMode)

            disp(['RUNNING RemoveListedClusters: ' removeMode])

            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                fprintf('[Remove skipped] No FrameData loaded.\n');
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[Remove skipped] Bad current frame.\n');
                return
            end

            clusList = ParseRemoveList(app);

            app.removeList.Value = ''; 
            if isempty(clusList)
                fprintf('[Remove skipped] No valid clusters in removeList.\n');
                return
            end

            status = FrameData(FrNum).Status(:);
            didAny = false;

            PushUndo(app, handles, ['Remove Listed Clusters as ' removeMode]);

            for i = 1:numel(clusList)

                c = clusList(i);
                rows = status == c;

                if ~any(rows)
                    fprintf('[Remove skipped] C%d has no active spikes in Fr %d.\n', c, FrNum);
                    continue
                end

                if strcmpi(removeMode, 'Noise')
                    newStatus = 0;
                else
                    newStatus = -c;
                end

                FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, newStatus, ...
                    'CLUSTERpbReassign_Callback');

                status = FrameData(FrNum).Status(:);
                didAny = true;

            end

            if ~didAny
                fprintf('[Remove skipped] No listed active clusters were changed.\n');
                return
            end

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            RedrawSortMovie(app, handles, FrNum, true);
            UpdateAllQLabels(app, handles, FrNum);
            % PlotSelectedCluster(app, FrameData); new dk comment 
            PrintFrameClusters(app, FrameData, FrNum, ['Remove Listed as ' removeMode]);

        end


        function SelectNoiseNumberMean(app, event, FrNum, noiseNum)

            if ~app.SpikeSelectMode
                return
            end

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[NoiseNumber mean click skipped] bad FrameData/FrNum\n');
                return
            end

            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber) || ...
                    ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                fprintf('[NoiseNumber mean click skipped] missing Status/NoiseNumber/Wfs\n');
                return
            end

            status = FrameData(FrNum).Status(:);
            noiseNumber = FrameData(FrNum).NoiseNumber(:);
            rows = status == 0 & noiseNumber == noiseNum;

            if ~any(rows)
                fprintf('[NoiseNumber mean click skipped] no rows for F%d NoiseNumber %d\n', FrNum, noiseNum);
                return
            end

            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = FrNum;
            app.SelectedClusterNum = NaN;
            app.SelectedNoiseNumber = noiseNum;

            UpdateRecoverSelectedClusterButton(app);

            UpdateSelectedSpikeRemoveButtons(app);

            cla(app.axWaveforms_3);
            SetSelectedSpikeLabel(app, '');

            cla(app.axClusterSpikes);
            hold(app.axClusterSpikes, 'on');
            set(app.axClusterSpikes, 'Color', 'white');

            W_all = FrameData(FrNum).Wfs(rows,:);
            W_plot = W_all;

            maxPlotSpikes = 750;
            if size(W_plot,1) > maxPlotSpikes
                idxPlot = round(linspace(1, size(W_plot,1), maxPlotSpikes));
                W_plot = W_plot(idxPlot,:);
            end

            x = 1:size(W_all,2);

            for i = 1:size(W_plot,1)
                h = line(app.axClusterSpikes, x, W_plot(i,:), ...
                    'Color', [0.5 0.5 0.5], ...
                    'LineWidth', 0.5);
                set(h, 'HitTest','off', 'PickableParts','none');
            end

            mwf = mean(W_all, 1);
            hMean = line(app.axClusterSpikes, x, mwf, ...
                'Color', 'k', ...
                'LineWidth', 2.5);
            set(hMean, 'HitTest','off', 'PickableParts','none');

            set(app.axClusterSpikes, 'XLim', [1 size(W_all,2)]);
            hold(app.axClusterSpikes, 'off');

            nNoise = sum(rows);
            SetSelectedClusterLabel(app, sprintf('F%dN%d %d/%d shown', ...
                FrNum, noiseNum, nNoise, nNoise));

            fprintf('[NoiseNumber mean click] selected F%d NoiseNumber %d, plotted %d/%d gray waveforms on axClusterSpikes\n', ...
                FrNum, noiseNum, size(W_plot,1), size(W_all,1));

        end

        function targetStatus = ParsePop15Target(app)

            txt = strtrim(string(app.pop15.Value));
        
            if txt == "" || txt == "-"
                targetStatus = NaN;
                return
            end
        
            v = str2double(txt);
        
            if isnan(v) || v ~= round(v) || v < 0 || v > 14
                targetStatus = NaN;
                app.pop15.Value = '';
                return
            end
        
                if v == 0
                    v = 1;
                    app.pop15.Value = '1';
                end
            
                targetStatus = v;
            end

        
        function [FrNum, rows, rowList, ok] = GetSelectedSpikeRows(app, FrameData)
        
            ok = false;
            FrNum = NaN;
            rows = [];
            rowList = [];
        
            % Manual multi-spike selection takes priority.
            if ~isempty(app.SelectedSpikeRows)
        
                FrNum = app.SelectedClusterFrame;
        
                if isnan(FrNum)
                    FrNum = app.SelectedSpikeFrame;
                end
        
                if isnan(FrNum)
                    return
                end
        
                FrNum = round(FrNum);
        
                if FrNum < 1 || FrNum > numel(FrameData)
                    return
                end
        
                if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                    return
                end
        
                rowList = unique(round(app.SelectedSpikeRows(:)));
                rowList = rowList(rowList >= 1 & rowList <= numel(FrameData(FrNum).Status));
        
                if isempty(rowList)
                    return
                end
        
                rows = false(numel(FrameData(FrNum).Status),1);
                rows(rowList) = true;
                ok = true;
                return
            end
        
            % Fallback: normal one selected/hovered spike.
            FrNum = app.SelectedSpikeFrame;
            rowIdx = app.SelectedSpikeRows;
        
            if isempty(FrNum) || isempty(rowIdx) || isnan(FrNum) || isnan(rowIdx)
                return
            end
        
            FrNum = round(FrNum);
            rowIdx = round(rowIdx);
        
            if FrNum < 1 || FrNum > numel(FrameData)
                return
            end
        
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                return
            end
        
            if rowIdx < 1 || rowIdx > numel(FrameData(FrNum).Status)
                return
            end
        
            rows = false(numel(FrameData(FrNum).Status),1);
            rows(rowIdx) = true;
            rowList = rowIdx;
            ok = true;
        
        end

        function RasterWindowMouseDown(app, fig, ax)

            if isempty(fig) || ~isvalid(fig) || isempty(ax) || ~isvalid(ax)
                return
            end

            % Right-click / two-finger trackpad click is SelectionType == 'alt'
            if ~strcmp(fig.SelectionType, 'alt')
                return
            end

            % On double-click, force one final nearest-spike update.
            % This makes sure the held spike is the one closest to the double-click point.
            RasterWindowMouseMove(app, ax);

            % Turn select OFF, but DO NOT clear SelectedSpikeFrame / SelectedSpikeRows.
            app.SpikeSelectMode = false;

            if ~isempty(app.selectOFFButton) && isvalid(app.selectOFFButton)
                app.selectOFFButton.Text = 'select: OFF';
            end

            try
                UpdateSelectedSpikeRemoveButtons(app);
            catch
            end

            % Stop continuous hover updates in the raster window.
            fig.WindowButtonMotionFcn = [];

            fprintf('[Raster Select locked] F%d row %d\n', ...
                app.SelectedSpikeFrame, app.SelectedSpikeRows);

        end

        function RemoveSelectedSpikes(app, event, removeMode)
        
            disp(['RUNNING RemoveSelectedSpikes: ' removeMode])
        
            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
        
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
        
            if isempty(FrameData)
                fprintf('[Remove selected skipped] No FrameData loaded.\n');
                return
            end
        
            [FrNum, rows, rowList, ok] = GetSelectedSpikeRows(app, FrameData);
        
            if ~ok
                fprintf('[Remove selected skipped] No selected spike exists.\n');
                app.pop15.Value = '';
                return
            end
        
            if ~strcmpi(removeMode, 'Noise')
                fprintf('[Remove selected skipped] selected-spike isolated removal is disabled. Use whole-cluster isolate instead.\n');
                return
            end

            newStatus = 0;


            PushUndo(app, handles, ['Remove Selected Spike as ' removeMode]);
        
            FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, newStatus, ...
                'SPIKEpbReassign_Callback');
        
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            app.pop15.Value = '';
            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = NaN;
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedSpikeRows = [];
        
            RedrawSortMovie(app, handles, FrNum, true);
            PrintFrameClusters(app, FrameData, FrNum, ['Remove Selected as ' removeMode]);
        
            fprintf('[Remove selected %s] Fr %d rows=%s\n', ...
                removeMode, FrNum, mat2str(rowList(:)'));
        
        end

        function CancelInteractiveModes(app)

            % Turn OFF manual sort UI/state.
            if isvalid(app.pbManualSort)
                app.pbManualSort.Text = 'Manual Sort: OFF';
            end

            % Turn OFF QuickSort UI/state.
            % This is critical because tbQuickSort is a StateButton.
            if isvalid(app.tbQuickSort)
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
            end

            % Turn OFF select mode UI/state.
            app.SpikeSelectMode = false;
            if isvalid(app.selectOFFButton)
                app.selectOFFButton.Text = 'select: OFF';
            end

            % Clear app-level click/hover state.
            app.PendingClickAxis = [];
            app.PendingClickPoint = [NaN NaN];

            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;

            app.ManualSelectMode = false;
            app.ManualSelectX = [];
            app.ManualSelectY = [];
            if ~isempty(app.ManualSelectLine) && isvalid(app.ManualSelectLine)
                delete(app.ManualSelectLine);
            end
            app.ManualSelectLine = [];
            delete(findall(app.figure1, 'Tag', 'ManualSelectTemp'));

            % Clear old figure callbacks.
            app.figure1.WindowButtonDownFcn = [];
            app.figure1.WindowButtonUpFcn = [];
            app.figure1.WindowButtonMotionFcn = [];

            app.figure1.WindowScrollWheelFcn = [];

            % Clear old axis callbacks.
            app.axDim12.ButtonDownFcn = [];
            app.axDim13.ButtonDownFcn = [];
            app.axDim23.ButtonDownFcn = [];

            % Delete leftover temporary manual-sort lines / QuickSort dots.
            delete(findall(app.figure1, 'Tag', 'ManualSortTemp'));
            delete(findall(app.figure1, 'Tag', 'QuickSortTemp'));

            % Restore normal pointer/navigation.
            app.figure1.Pointer = 'arrow';
            drawnow; 
            RestoreNativePanZoom(app);

            drawnow limitrate;

        end


        function available = GetAvailableClusterIDs(app, status, allowCluster1)

            status = status(:);

            usedPositive = unique(status(status > 0));        % active clusters currently occupied
            usedFinalized = abs(unique(status(status < 0)));  % finalized units: -3 reserves 3

            reserved = unique([usedPositive; usedFinalized]);

            if allowCluster1
                possible = 1:14;
            else
                possible = 2:14;
            end

            available = setdiff(possible, reserved);

        end


        function RasterSpikeHover(app, clickedAx)

            [~, ~, handles] = convertToGUIDECallbackArguments(app);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)
                return
            end

            cp = clickedAx.CurrentPoint;
            xHover = cp(1,1);
            yHover = cp(1,2);

            status = FrameData(FrNum).Status(:);

            % Must match PlotRaster scaling
            spkTimes = double(FrameData(FrNum).spk(:)) / 1e6;

            activeClusters = unique(status(status > 0));
            activeClusters = activeClusters(:)';

            if isempty(activeClusters)
                return
            end

            rowNum = round(yHover);

            if rowNum < 1 || rowNum > numel(activeClusters)
                return
            end

            clickedCluster = activeClusters(rowNum);
            candidateRows = find(status == clickedCluster);

            if isempty(candidateRows)
                return
            end

            candidateTimes = spkTimes(candidateRows);

            [~, nearestLocal] = min(abs(candidateTimes - xHover));
            rowIdx = candidateRows(nearestLocal);

            % Do nothing if still hovering same spike
            if app.LastHoverSpikeFrame == FrNum && app.LastHoverSpikeRow == rowIdx
                return
            end

            app.LastHoverSpikeFrame = FrNum;
            app.LastHoverSpikeRow = rowIdx;

            % Preview selected spike
            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            PlotSelectedSpike(app, FrameData);

            drawnow limitrate;

        end

        function PCSpikeHover(app, clickedAx)
            %disp('DEBUG PCSpikeHover running')

            [~, ~, handles] = convertToGUIDECallbackArguments(app);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            if isempty(FrameData)
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum), 'U') || isempty(FrameData(FrNum).U)
                return
            end

            cp = clickedAx.CurrentPoint;
            xHover = cp(1,1);
            yHover = cp(1,2);

            Dim1 = get(handles.popDim1,'Value');
            Dim2 = get(handles.popDim2,'Value');
            Dim3 = get(handles.popDim3,'Value');

            if clickedAx == app.axDim12
                d1 = Dim1;
                d2 = Dim2;
            elseif clickedAx == app.axDim13
                d1 = Dim1;
                d2 = Dim3;
            elseif clickedAx == app.axDim23
                d1 = Dim2;
                d2 = Dim3;
            else
                return
            end

            status = FrameData(FrNum).Status(:);
            candidateRows = find(status > 0);

            if isempty(candidateRows)
                return
            end


            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot)
                return
            end

            if size(FrameData(FrNum).Uplot,2) < max(d1,d2)
                return
            end

            Uxy = FrameData(FrNum).Uplot(candidateRows,[d1 d2]);


            xLim = xlim(clickedAx);
            yLim = ylim(clickedAx);
            xRange = diff(xLim);
            yRange = diff(yLim);
            if xRange == 0 || yRange == 0
                return
            end
            xHoverN = (xHover - xLim(1)) / xRange;
            yHoverN = (yHover - yLim(1)) / yRange;
            XN = (Uxy(:,1) - xLim(1)) / xRange;
            YN = (Uxy(:,2) - yLim(1)) / yRange;
            dist2 = (XN - xHoverN).^2 + (YN - yHoverN).^2;
            if isempty(dist2) || ~any(isfinite(dist2))
                return
            end
            [bestDist2, nearestLocal] = min(dist2);
            maxHoverDist = 0.08;
            

            if sqrt(bestDist2) > maxHoverDist
                app.LastHoverSpikeFrame = NaN;
                app.LastHoverSpikeRow = NaN;
                return
            end
            
            rowIdx = candidateRows(nearestLocal);


            if app.LastHoverSpikeFrame == FrNum && app.LastHoverSpikeRow == rowIdx
                return
            end

            app.LastHoverSpikeFrame = FrNum;
            app.LastHoverSpikeRow = rowIdx;

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            PlotSelectedSpike(app, FrameData);

            drawnow limitrate;

        end

        function ClusterSpikeHover(app, clickedAx)
            %disp('DEBUG ClusterSpikeHover')
            [~, ~, handles] = convertToGUIDECallbackArguments(app);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                return
            end

            if clickedAx == app.axWaveforms_2
                % Noise plot hover: use current frame and status == 0
                FrameString = get(handles.stFrame2,'String');
                FrNum = sscanf(FrameString, 'Frame %d', 1);
                CluNum = 0;
            

            else

                % Cluster-spikes hover: active/isolated cluster OR selected NoiseNumber group.
                FrNum = app.SelectedClusterFrame;
                CluNum = app.SelectedClusterNum;

            end
            
            isNoiseNumberHover = isnan(CluNum) && isfinite(app.SelectedNoiseNumber);

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end
            
            if ~isNoiseNumberHover && (isnan(CluNum) || CluNum < -15 || CluNum > 15)
                return
            end
            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum), 'Wfs') || isempty(FrameData(FrNum).Wfs)
                return
            end

            cp = clickedAx.CurrentPoint;
            xHover = cp(1,1);
            yHover = cp(1,2);

            wfLen = size(FrameData(FrNum).Wfs, 2);

            if isnan(xHover) || xHover < 1 || xHover > wfLen
                return
            end


            if clickedAx == app.axClusterSpikes

                UD = app.axClusterSpikes.UserData;

                if isempty(UD) || ...
                        ~isstruct(UD) || ...
                        ~isfield(UD,'Rows') || ...
                        ~isfield(UD,'Wfs')
                    return
                end

                candidateRows = UD.Rows(:);
                W = UD.Wfs;

            elseif isNoiseNumberHover

                if ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber)
                    return
                end

                status = FrameData(FrNum).Status(:);
                noiseNumber = FrameData(FrNum).NoiseNumber(:);

                candidateRows = find(status == 0 & ...
                    noiseNumber == app.SelectedNoiseNumber);

                W = FrameData(FrNum).Wfs(candidateRows,:);

            else

                candidateRows = find(FrameData(FrNum).Status(:) == CluNum);
                W = FrameData(FrNum).Wfs(candidateRows,:);

            end

            if isempty(candidateRows)
                return
            end

            xCenter = round(xHover);

            xLo = max(1, xCenter - 2);
            xHi = min(size(W,2), xCenter + 2);

            Wlocal = W(:, xLo:xHi);

            distAbs = min(abs(Wlocal - yHover), [], 2);


            if isempty(distAbs) || ~any(isfinite(distAbs))
                return
            end

            [bestDist, nearestLocal] = min(distAbs);

            if clickedAx == app.axClusterSpikes
                % yHoverTol = 1.0;  % uV tolerance for actually being on the line

                yHoverTol = 0.03 * diff(clickedAx.YLim);

                if bestDist > yHoverTol
                    app.LastHoverSpikeFrame = NaN;
                    app.LastHoverSpikeRow = NaN;
                    return
                end
            end

            rowIdx = candidateRows(nearestLocal);

            if app.LastHoverSpikeFrame == FrNum && app.LastHoverSpikeRow == rowIdx
                return
            end

            app.LastHoverSpikeFrame = FrNum;
            app.LastHoverSpikeRow = rowIdx;

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            PlotSelectedSpike(app, FrameData);

            drawnow limitrate;

        end

        function AppRasterSpikeHover(app, ax)

            if ~app.SpikeSelectMode
                return
            end

            if isempty(ax) || ~isvalid(ax) || isempty(ax.UserData)
                return
            end

            UD = ax.UserData;

            if ~isfield(UD, 'FrameData') || ~isfield(UD, 'FrNum') || ...
                    ~isfield(UD, 'status') || ~isfield(UD, 'spkTimes') || ...
                    ~isfield(UD, 'activeClusters')
                return
            end

            FrameData = UD.FrameData;
            FrNum = UD.FrNum;
            status = UD.status(:);
            spkTimes = UD.spkTimes(:);
            activeClusters = UD.activeClusters(:)';

            cp = ax.CurrentPoint;
            xHover = cp(1,1);
            yHover = cp(1,2);

            xl = ax.XLim;
            yl = ax.YLim;

            if xHover < xl(1) || xHover > xl(2) || yHover < yl(1) || yHover > yl(2)
                return
            end


            if isfield(UD, 'yMode') && strcmpi(UD.yMode, 'pc')

                if ~isfield(UD, 'yVals')
                    return
                end

                yVals = UD.yVals(:);

                candidateRows = find(status > 0 & ...
                    spkTimes >= xl(1) & ...
                    spkTimes <= xl(2) & ...
                    yVals >= yl(1) & ...
                    yVals <= yl(2));

                if isempty(candidateRows)
                    return
                end

                candidateTimes = spkTimes(candidateRows);
                candidateY = yVals(candidateRows);

                xRange = max(diff(xl), eps);
                yRange = max(diff(yl), eps);

                dx = (candidateTimes - xHover) ./ xRange;
                dy = (candidateY - yHover) ./ yRange;

                [~, nearestLocal] = min(dx.^2 + dy.^2);
                rowIdx = candidateRows(nearestLocal);

            else

                rowNum = round(yHover);

                if rowNum < 1 || rowNum > numel(activeClusters)
                    return
                end

                hoveredCluster = activeClusters(rowNum);

                candidateRows = find(status == hoveredCluster & ...
                    spkTimes >= xl(1) & ...
                    spkTimes <= xl(2));

                if isempty(candidateRows)
                    return
                end

                candidateTimes = spkTimes(candidateRows);

                [~, nearestLocal] = min(abs(candidateTimes - xHover));
                rowIdx = candidateRows(nearestLocal);

            end

            if isequal(app.LastHoverSpikeFrame, FrNum) && isequal(app.LastHoverSpikeRow, rowIdx)
                return
            end

            app.LastHoverSpikeFrame = FrNum;
            app.LastHoverSpikeRow = rowIdx;

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;
            app.SelectedNoiseNumber = NaN;

            PlotSelectedSpike(app, FrameData, FrNum, rowIdx, 'rasterHover');

            drawnow limitrate nocallbacks

        end
        function AppRasterMouseDown(app, ax, event)

            if ~app.SpikeSelectMode
                return
            end

            % Right-click / two-finger click locks selected spike.
            if ~strcmp(app.figure1.SelectionType, 'alt')
                return
            end

            AppRasterSpikeHover(app, ax);

            app.SpikeSelectMode = false;

            if ~isempty(app.selectOFFButton) && isvalid(app.selectOFFButton)
                app.selectOFFButton.Text = 'select: OFF';
            end

            try
                UpdateSelectedSpikeRemoveButtons(app);
            catch
            end

            fprintf('[pcRaster locked] F%d row %d\n', ...
                app.SelectedSpikeFrame, app.SelectedSpikeRows);

        end

        function SelectSpikeMouseMove(app, src, event)
            %disp('DEBUG SelectSpikeMouseMove')
            if ~app.SpikeSelectMode
                return
            end

            if app.ManualSelectMode
                return
            end
        
            clickedObj = hittest(app.figure1);
            clickedAx = ancestor(clickedObj, 'matlab.ui.control.UIAxes');
        
            if isempty(clickedAx)
                return
            end


            if clickedAx == app.axDim12 || clickedAx == app.axDim13 || clickedAx == app.axDim23
                PCSpikeHover(app, clickedAx);
        
            elseif clickedAx == app.axClusterSpikes
                ClusterSpikeHover(app, clickedAx);

            elseif clickedAx == app.pcRaster 
                AppRasterSpikeHover(app, clickedAx); 

            elseif clickedAx == app.axWaveforms_2
                return 
            
            end 
        
        end

        function RasterSpikeClick(app, clickedAx, event)
            return 
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                fprintf('[Raster Select skipped] No FrameData loaded.\n');
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[Raster Select skipped] Bad frame.\n');
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)

                fprintf('[Raster Select skipped] Missing Status/spk.\n');
                return
            end

            cp = clickedAx.CurrentPoint;
            xClick = cp(1,1);
            yClick = cp(1,2);

            status = FrameData(FrNum).Status(:);
            spkTimes = double(FrameData(FrNum).spk(:)) / 1e6;   % match PlotRaster scaling

            activeClusters = unique(status(status > 0));
            activeClusters = activeClusters(:)';

            if isempty(activeClusters)
                fprintf('[Raster Select skipped] No active clusters.\n');
                return
            end

            rowNum = round(yClick);

            if rowNum < 1 || rowNum > numel(activeClusters)
                fprintf('[Raster Select skipped] Click outside raster rows.\n');
                return
            end

            clickedCluster = activeClusters(rowNum);

            candidateRows = find(status == clickedCluster);

            if isempty(candidateRows)
                fprintf('[Raster Select skipped] No spikes in clicked cluster.\n');
                return
            end

            candidateTimes = spkTimes(candidateRows);

            [~, nearestLocal] = min(abs(candidateTimes - xClick));
            rowIdx = candidateRows(nearestLocal);

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            fprintf('[Raster Select] selected F%d C%d row %d time %.6f\n', ...
                FrNum, clickedCluster, rowIdx, spkTimes(rowIdx));

            PlotSelectedSpike(app, FrameData);

        end

        function PlotRaster(app, FrameData, FrNum)
            return  
          
        end


        function RasterWindowClick(app, ax, event)

            if ~app.SpikeSelectMode
                fprintf('[Raster Select skipped] select mode is OFF.\n');
                return
            end

            if isempty(ax) || ~isvalid(ax) || isempty(ax.UserData)
                return
            end

            UD = ax.UserData;

            FrameData = UD.FrameData;
            FrNum = UD.FrNum;
            status = UD.status(:);
            spkTimes = UD.spkTimes(:);
            activeClusters = UD.activeClusters(:)';

            if isempty(FrameData) || isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            cp = ax.CurrentPoint;
            xClick = cp(1,1);
            yClick = cp(1,2);

            % Must click inside visible axes.
            xl = ax.XLim;
            yl = ax.YLim;

            if xClick < xl(1) || xClick > xl(2) || yClick < yl(1) || yClick > yl(2)
                return
            end

            % Raster row maps to activeClusters index.
            rowNum = round(yClick);

            if rowNum < 1 || rowNum > numel(activeClusters)
                fprintf('[Raster Select skipped] Click outside raster rows.\n');
                return
            end

            clickedCluster = activeClusters(rowNum);

            % Only search actual spikes in the clicked cluster and visible x-window.
            candidateRows = find(status == clickedCluster & spkTimes >= xl(1) & spkTimes <= xl(2));

            if isempty(candidateRows)
                fprintf('[Raster Select skipped] No spikes in clicked cluster/window.\n');
                return
            end

            candidateTimes = spkTimes(candidateRows);

            % Exact nearest spike in time.
            [~, nearestLocal] = min(abs(candidateTimes - xClick));
            rowIdx = candidateRows(nearestLocal);

            % Store selected spike in the main app.
            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;
            app.SelectedNoiseNumber = NaN;

            % Update selected-spike plot in the main app.
            PlotSelectedSpike(app, FrameData, FrNum, rowIdx, 'raster');

            try
                UpdateSelectedSpikeRemoveButtons(app);
            catch
            end

            fprintf('[Raster Select] F%d C%d row %d time %.9f s, clicked %.9f s, err %.9g s\n', ...
                FrNum, clickedCluster, rowIdx, spkTimes(rowIdx), xClick, abs(spkTimes(rowIdx) - xClick));

        end


        function FillFileSelectDropdowns(app, mode) %finds folders/files and fills dropdowns 
            dataRoot = app.DataRoot;
        
            switch mode
        
                case 'subjects'
        
                    subjFolders = dir(fullfile(dataRoot, 'NS*'));
                    subjFolders = subjFolders([subjFolders.isdir]);
        
                    if isempty(subjFolders)
                        app.popSubject.Items = {'-- select subject --'};
                        app.popSubject.Value = '-- select subject --';
        
                        app.popSession.Items = {'-- select session --'};
                        app.popSession.Value = '-- select session --';
        
                        return
                    end
        
                    subjNames = cell(numel(subjFolders), 1);
        
                    for iSubj = 1:numel(subjFolders)
                        tok = regexp(subjFolders(iSubj).name, 'NS\d+', 'match', 'once');
        
                        if isempty(tok)
                            subjNames{iSubj} = subjFolders(iSubj).name;
                        else
                            subjNames{iSubj} = tok;
                        end
                    end
        
                    subjNames = unique(subjNames, 'stable');
                    subjNames = [{'-- select subject --'}; subjNames(:)];
        
                    app.popSubject.Items = subjNames;
                    app.popSubject.Value = '-- select subject --';
        
                    app.popSession.Items = {'-- select session --'};
                    app.popSession.Value = '-- select session --';
        
        
                case 'sessions'
        
                    app.popSession.Items = {'-- select session --'};
                    app.popSession.Value = '-- select session --';
        
                    Subj = app.popSubject.Value;
        
                    if strcmp(Subj, '-- select subject --')
                        return
                    end
        
                    tok = regexp(Subj, 'NS\d+', 'match', 'once');
                    if ~isempty(tok)
                        Subj = tok;
                    end
        
                    subjFolders = dir(fullfile(dataRoot, [Subj '*']));
                    subjFolders = subjFolders([subjFolders.isdir]);
        
                    if isempty(subjFolders)
                        return
                    end
        
                    subjPath = fullfile(dataRoot, subjFolders(1).name);
        
                    sessFolders = dir(subjPath);
                    sessFolders = sessFolders([sessFolders.isdir]);
                    sessFolders = sessFolders(~ismember({sessFolders.name}, {'.','..'}));
        
                    if isempty(sessFolders)
                        return
                    end
        
                    sessNames = {sessFolders.name};

                    app.popSession.Items = sessNames;
                    app.popSession.Value = sessNames{1};
                    
                    FillFileSelectDropdowns(app, 'spikefiles');
                    FillFileSelectDropdowns(app, 'SrtdLoads');
                            
                case 'spikefiles'
                    %read subject and session choices from the UI 
                    Subj = app.popSubject.Value;
                    Sess = app.popSession.Value;
        
                    %stop if subj/sess empty 
                    if strcmp(Subj, '-- select subject --') || strcmp(Sess, '-- select session --')
                        return
                    end
        
                    %make subject name into NS## if folder names are logner
                    tok = regexp(Subj, 'NS\d+', 'match', 'once');
                    if ~isempty(tok)
                        Subj = tok;
                    end
        
                    %find the specific subject's folder under DataRoot 
                    subjFolders = dir(fullfile(dataRoot, [Subj '*'])); 
                    subjFolders = subjFolders([subjFolders.isdir]); %Only folders 
        
                    %build session folder path: DataRoot / subject folder /
                    %session folder ... (1) is to take the first of the
                    %specific subject's folders 
                    startPath = fullfile(dataRoot, subjFolders(1).name, Sess); %session folder path containing actual spike files 

                    %find importable files 
                    files1 = dir(fullfile(startPath, '*CSC*.mat'));
                    files2 = dir(fullfile(startPath, '*spikes*.mat'));
                    files3 = dir(fullfile(startPath, '*Spikes*.mat'));
                    files4 = dir(fullfile(startPath, '*Srtd*.mat'));
                    files5 = dir(fullfile(startPath, '*sorted*.mat'));
                    files6 = dir(fullfile(startPath, '*Sorted*.mat'));
                    
                    files = [files1(:); files2(:); files3(:); files4(:); files5(:); files6(:)];

                    %remove filename duplicates 
                    [~, ia] = unique({files.name}, 'stable');
                    files = files(ia);

                    %remove folders and hidden mac files 
                    files = files(~[files.isdir]);
                    files = files(~startsWith({files.name}, '._') & ~startsWith({files.name}, '.'));

                    %extract names for dropdown 
                    fileNames = {files.name};

                    fprintf('[FILE DEBUG] Dropdown spike files:\n');
                    disp(fileNames(:));

                    fprintf('[FILE DEBUG] Subject=%s Session=%s\n', Subj, Sess);
                    fprintf('[FILE DEBUG] Path=%s\n', startPath);
                    %fprintf('[FILE DEBUG] Found %d spike files\n', numel(fileNames)-1);
        
                case 'SrtdLoads'

                    ResolveSpikeFileFromUI(app, true);

                
                otherwise
        
                    error('Unknown FillFileSelectDropdowns mode: %s', mode);
            end
       end


        function PlotFinalizedUnits(app, FrameData, FrNum)
            return 
            isoAxes = [app.Iso app.Iso_2 app.Iso_3 app.Iso_4];
            isoFields = [app.chooseIso app.chooseIso_2 app.chooseIso_3 app.chooseIso_4];

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                for k = 1:numel(isoAxes)
                    cla(isoAxes(k), 'reset');
                    set(isoAxes(k), 'Color', 'white');
                    title(isoAxes(k), '');
                end
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum), 'Wfs') || isempty(FrameData(FrNum).Wfs)
                for k = 1:numel(isoAxes)
                    cla(isoAxes(k), 'reset');
                    set(isoAxes(k), 'Color', 'white');
                    title(isoAxes(k), '');
                end
                return
            end

            status = FrameData(FrNum).Status(:);

            finalUnits = unique(status(status < 0));
            finalUnits = sort(finalUnits(:)', 'descend');


            for k = 1:numel(isoAxes)

                ax = isoAxes(k);
                targetUnit = round(isoFields(k).Value);

                cla(ax, 'reset');
                hold(ax, 'on');
                set(ax, 'Color', 'white');

                if isnan(targetUnit) || targetUnit == 0
                    title(ax, '');
                    hold(ax, 'off');
                    continue
                end

                % Allow user to type either 10 or -10. Internally finalized units are negative.
                if targetUnit > 0
                    targetStatus = -targetUnit;
                else
                    targetStatus = targetUnit;
                end

                rows = status == targetStatus;

                if ~any(rows)
                    title(ax, '');
                    hold(ax, 'off');
                    continue
                end

                W = FrameData(FrNum).Wfs(rows,:);
                x = 1:size(W,2);

                for i = 1:size(W,1)
                    h = line(ax, x, W(i,:), ...
                        'Color', [0.5 0.5 0.5], ...
                        'LineWidth', 0.5);
                    set(h, 'HitTest', 'off', 'PickableParts', 'none');
                end

                mwf = mean(W, 1);

                hMean = line(ax, x, mwf, ...
                    'Color', 'k', ...
                    'LineWidth', 2);
                set(hMean, 'HitTest', 'off', 'PickableParts', 'none');

                set(ax, 'XLim', [1 size(W,2)]);
                title(ax, '');

                hold(ax, 'off');
            end

            drawnow limitrate;
        end

        function FrameData = SetPlotDimOnly(app, handles, FrameData, FrNum, SourceFr, CluNum, DifFrNum, DifCluNum, DimNum)

            %%GPT CHECKS 
            if isempty(FrameData)
                error('No FrameData loaded')
            end
            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                error('Bad current target frame')
            end
            if isnan(SourceFr) || SourceFr < 1 || SourceFr > numel(FrameData)
                error('Bad f1 source frame')
            end
            if isnan(CluNum) || CluNum < 1 || CluNum > 14
                error('Bad c1 source cluster')
            end
            if isnan(DimNum) || DimNum < 1
                error('Dim number must be positive')
            end
            %%GPT CHECKS 

            dimToReplacewithCust = round(DimNum); 
            useDiff = ~(isempty(DifFrNum) || isempty(DifCluNum) || ...
                        isnan(DifFrNum) || isnan(DifCluNum) || ...
                        DifFrNum <= 0 || DifCluNum <= 0);
            if useDiff
                if DifFrNum < 1 || DifFrNum > numel(FrameData)
                    error('Bad f2 source frame')
                end
                if DifCluNum < 1 || DifCluNum > 14
                    error('Bad c2 source cluster')
                end
            end
        
            [wfA, okA, msgA, FrameData] = GetClusterMeanWf(app, FrameData, SourceFr, CluNum);
            if ~okA
                fprintf('[Set Plot Dim Error] FIRST SOURCE FAILED: %s\n', msgA);
                error('First source cluster has no valid mean waveform: %s', msgA)
            end
            if useDiff
                [wfB, okB, msgB, FrameData] = GetClusterMeanWf(app, FrameData, DifFrNum, DifCluNum);
                if ~okB
                    fprintf('[Set Plot Dim Error] SECOND SOURCE FAILED: %s\n', msgB);
                    error('Second source cluster has no valid mean waveform: %s', msgB)
                end
                custWf = wfA - wfB;
                custWfLabel = sprintf('F%dC%d-F%dC%d', SourceFr, abs(CluNum), DifFrNum, DifCluNum);
            else
                custWf = wfA;
                custWfLabel = sprintf('F%dC%d', SourceFr, abs(CluNum));
            end
            custWfdouble = double(custWf(:));
            custWf = custWfdouble - mean(custWfdouble);
            custWfZ = norm(custWf);
            if custWfZ == 0 || isnan(custWfZ) || isinf(custWfZ)
                error('Selected plotting dimension is flat/invalid')
            end
            custWf = custWf / custWfZ;
            if ~isfield(FrameData(FrNum),'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                FrameData(FrNum).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
            end
            if ~isfield(FrameData(FrNum),'DimDisplayWf') || isempty(FrameData(FrNum).DimDisplayWf)
                FrameData(FrNum).DimDisplayWf = cell(1,6);
            end
            if numel(FrameData(FrNum).EigFrac) < 6
                oldEig = FrameData(FrNum).EigFrac;
                FrameData(FrNum).EigFrac = zeros(1,6);
                FrameData(FrNum).EigFrac(1:numel(oldEig)) = oldEig;
            end
        
            activeRows = FrameData(FrNum).Status(:) > 0;
        
            if ~isfield(FrameData(FrNum),'Uplot') 
                FrameData(FrNum).Uplot(:, 1:6) = FrameData(FrNum).U(:,1:6);
                FrameData(FrNum).PCplot(:, 1:6) = FrameData(FrNum).PC(:,1:6);
            end 

            nOldDims = size(FrameData(FrNum).Uplot, 2);
            newDimNum = nOldDims + 1;

            if size(FrameData(FrNum).PCplot, 2) < newDimNum
                FrameData(FrNum).PCplot(:, newDimNum) = 0;
            end
            if size(FrameData(FrNum).Uplot, 2) < newDimNum
                FrameData(FrNum).Uplot(:, newDimNum) = NaN;
            end
            if ~isfield(FrameData(FrNum), 'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                FrameData(FrNum).DimLabels = arrayfun(@(k)sprintf('PCplot%d', k), 1:newDimNum, 'UniformOutput', false);
            end
            if numel(FrameData(FrNum).DimLabels) < newDimNum
                for k = numel(FrameData(FrNum).DimLabels)+1:newDimNum
                    FrameData(FrNum).DimLabels{k} = sprintf('Dim%d', k);
                end
            end
            for k = 1:6 %OG PCS KEEP LABELS 
                FrameData(FrNum).DimLabels{k} = sprintf('PC%d', k);
            end

            % Make sure display waveforms exist
            if ~isfield(FrameData(FrNum), 'DimDisplayWf') || isempty(FrameData(FrNum).DimDisplayWf)
                FrameData(FrNum).DimDisplayWf = cell(1, newDimNum);
            end
            if numel(FrameData(FrNum).DimDisplayWf) < newDimNum
                FrameData(FrNum).DimDisplayWf{newDimNum} = [];
            end
            if ~isfield(FrameData(FrNum), 'EigFrac') || isempty(FrameData(FrNum).EigFrac)
                FrameData(FrNum).EigFrac = zeros(1, newDimNum);
            end
            if numel(FrameData(FrNum).EigFrac) < newDimNum
                FrameData(FrNum).EigFrac(newDimNum) = 0;
            end

            % Store custom dimension in new appended slot
            FrameData(FrNum).PCplot(:, newDimNum) = custWf;
            FrameData(FrNum).Uplot(:, newDimNum) = NaN;
            FrameData(FrNum).Uplot(activeRows, newDimNum) = FrameData(FrNum).Wfs(activeRows,:) * custWf;

        
            FrameData(FrNum).DimLabels{newDimNum} = custWfLabel;
            FrameData(FrNum).DimDisplayWf{newDimNum} = custWf;
            FrameData(FrNum).EigFrac(newDimNum) = 0;

            % Remember which new dimension was just created
            FrameData(FrNum).LastCustomDim = newDimNum;
            FrameData(FrNum).LastdimToReplacewithCust = dimToReplacewithCust;
 
        
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        
            fprintf('[Set Plot Dim Only] Fr %d Dim %d = %s; projected %d active spikes only\n', ...
                FrNum, DimNum, custWfLabel, sum(activeRows));

            computeAndInsertRPCs(app, handles, FrameData, FrNum)
            RefreshDimDropdownLabels(app, handles, FrameData, FrNum)
        end


        function FrameData = RecomputeFramePCs(app, FrameData, FrNum)
            disp('running RecomputeFramePCs') 
            
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                return
            end

            pcRows = FrameData(FrNum).Status(:) > 0; %we only want new PCs for the not yet locked spikes 

            if sum(pcRows) < 2
                fprintf('[Recompute PCs skipped] Fr %d has fewer than 2 active spikes\n', FrNum);
                return
            end

            pcWfs = FrameData(FrNum).Wfs(pcRows,:); %spikes to do new pca on  

            [U_new, PCold, eigvalues] = spikepcsNew(app, pcWfs, []);
            % [U_new, PCold, eigvalues] = spikepcsNew(pcWfs); 


            fprintf('[NEW PCA] U_new=%s PCold=%s\n', ...
                mat2str(size(U_new)), mat2str(size(PCold))); 

            FrameData(FrNum).U(pcRows,:) = U_new;
            FrameData(FrNum).Uplot(:, 1:6) = FrameData(FrNum).U(:, 1:6); %because all the custom ones are 7:end 

            FrameData(FrNum).PC = PCold;
            FrameData(FrNum).EigFrac = (eigvalues.^2) / sum(eigvalues.^2);

            FrameData(FrNum).PCplot(:, 1:6) = FrameData(FrNum).PC(:, 1:6); %because all the custom ones are 7:end 

            fprintf('[Auto Recompute PCs] Fr %d using %d active spikes\n', FrNum, sum(pcRows));

            fprintf('[AFTER Recompute] U=%s Uplot=%s PC=%s PCplot=%s\n', ...
                mat2str(size(FrameData(FrNum).U)), ...
                mat2str(size(FrameData(FrNum).Uplot)), ...
                mat2str(size(FrameData(FrNum).PC)), ...
                mat2str(size(FrameData(FrNum).PCplot)));
        end

    
        function RasterWindowMouseMove(app, ax)

            if ~app.SpikeSelectMode
                return
            end

            if isempty(ax) || ~isvalid(ax) || isempty(ax.UserData)
                return
            end

            UD = ax.UserData;

            if ~isfield(UD, 'FrameData') || ~isfield(UD, 'FrNum') || ...
                    ~isfield(UD, 'status') || ~isfield(UD, 'spkTimes') || ...
                    ~isfield(UD, 'activeClusters')

                return
            end

            FrameData = UD.FrameData;
            FrNum = UD.FrNum;
            status = UD.status(:);
            spkTimes = UD.spkTimes(:);
            activeClusters = UD.activeClusters(:)';

            cp = ax.CurrentPoint;
            xHover = cp(1,1);
            yHover = cp(1,2);

            xl = ax.XLim;
            yl = ax.YLim;

            if xHover < xl(1) || xHover > xl(2) || yHover < yl(1) || yHover > yl(2)
                return
            end

            rowNum = round(yHover);

            if rowNum < 1 || rowNum > numel(activeClusters)
                return
            end

            hoveredCluster = activeClusters(rowNum);

            candidateRows = find(status == hoveredCluster & ...
                spkTimes >= xl(1) & ...
                spkTimes <= xl(2));

            if isempty(candidateRows)
                return
            end

            candidateTimes = spkTimes(candidateRows);

            [~, nearestLocal] = min(abs(candidateTimes - xHover));
            rowIdx = candidateRows(nearestLocal);

            % This is the only gate. No clock throttle.
            % It only redraws when the nearest actual spike changes.
            if isequal(app.LastHoverSpikeFrame, FrNum) && isequal(app.LastHoverSpikeRow, rowIdx)
                return
            end

            app.LastHoverSpikeFrame = FrNum;
            app.LastHoverSpikeRow = rowIdx;

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;
            app.SelectedNoiseNumber = NaN;

            drawnow limitrate nocallbacks

            % Then update selected waveform plot.
            PlotSelectedSpike(app, FrameData, FrNum, rowIdx, 'rasterHover');

            drawnow limitrate nocallbacks

        end

        function FixWaveformAxesTicks(app)

            axs = [app.axWaveforms app.axFrWaveform app.axWaveforms_2 ...
                app.axWaveforms_3 app.axClusterSpikes app.axRemovedasIsoMeanWfs];

            for ax = axs
                ax.XTickMode = 'auto';
                ax.YTickMode = 'auto';
                ax.XTickLabelMode = 'auto';
                ax.YTickLabelMode = 'auto';
                ax.YLimMode = 'auto';
                ax.FontSize = 8;
                ax.TickLength = [0.01 0.025];
            end

        end


        function FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, newStatus, actionLabel)

            
            if nargin < 7 || isempty(actionLabel)
                actionLabel = 'ChangeStatus';
            end

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[%s skipped] bad FrameData/FrNum\n', actionLabel);
                return
            end

            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[%s skipped] missing Status\n', actionLabel);
                return
            end

            status = FrameData(FrNum).Status(:);
            nSpikes = numel(status);

            if islogical(rows)
                rows = rows(:);
                if numel(rows) ~= nSpikes
                    fprintf('[%s skipped] bad rows length\n', actionLabel);
                    return
                end
            else
                idx = rows(:);
                idx = idx(isfinite(idx) & idx >= 1 & idx <= nSpikes);
                rows = false(nSpikes,1);
                rows(idx) = true;
            end

            if ~any(rows)
                fprintf('[%s skipped] zero rows changed\n', actionLabel);
                return
            end

            oldStatus = status;

            % Make sure every spike has a NoiseNumber field.
            % NaN means "not noise."
            % Positive integers mean "this spike belongs to NoiseNumber-th noise set."
            if ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber) || ...
                    numel(FrameData(FrNum).NoiseNumber) ~= nSpikes

                FrameData(FrNum).NoiseNumber = NaN(nSpikes,1);

                % If this file already had Status==0 before NoiseNumber existed,
                % give all pre-existing noise one starting noise number.
                preExistingNoiseRows = status == 0;
                if any(preExistingNoiseRows)
                    FrameData(FrNum).NoiseNumber(preExistingNoiseRows) = 1;
                end

            else
                FrameData(FrNum).NoiseNumber = FrameData(FrNum).NoiseNumber(:);

                % Non-noise spikes should not have a noise number.
                FrameData(FrNum).NoiseNumber(status ~= 0) = NaN;

                % Any existing noise rows missing a number get one.
                missingNoiseRows = status == 0 & isnan(FrameData(FrNum).NoiseNumber);
                if any(missingNoiseRows)
                    existingNums = FrameData(FrNum).NoiseNumber(isfinite(FrameData(FrNum).NoiseNumber));
                    if isempty(existingNums)
                        fillNoiseNumber = 1;
                    else
                        fillNoiseNumber = max(existingNums) + 1;
                    end
                    FrameData(FrNum).NoiseNumber(missingNoiseRows) = fillNoiseNumber;
                end
            end

            oldNoiseNumber = FrameData(FrNum).NoiseNumber;

           
            if isscalar(newStatus)
                if ~isfinite(newStatus) || newStatus ~= round(newStatus) || newStatus > 14
                    fprintf('[%s skipped] bad scalar newStatus\n', actionLabel);
                    return
                end
                status(rows) = newStatus;
            else
                newStatus = newStatus(:);
                if numel(newStatus) ~= sum(rows)
                    fprintf('[%s skipped] newStatus length does not match changed rows\n', actionLabel);
                    return
                end
                if any(~isfinite(newStatus)) || any(newStatus ~= round(newStatus)) || any(newStatus > 14)
                    fprintf('[%s skipped] bad vector newStatus\n', actionLabel);
                    return
                end
                status(rows) = newStatus;
            end

            FrameData(FrNum).Status = status;


            % If this ChangeStatus call moved spikes into noise, assign those exact
            % newly-noise spikes a new NoiseNumber.
            changedToNoiseRows = rows & status == 0 & oldStatus ~= 0;
            if any(changedToNoiseRows)
                existingNums = FrameData(FrNum).NoiseNumber(isfinite(FrameData(FrNum).NoiseNumber));
                if isempty(existingNums)
                    newNoiseNumber = 1;
                else
                    newNoiseNumber = max(existingNums) + 1;
                end
                FrameData(FrNum).NoiseNumber(changedToNoiseRows) = newNoiseNumber;
                fprintf('[%s] Fr %d assigned NoiseNumber %d to %d newly-noise spikes\n', ...
                    actionLabel, FrNum, newNoiseNumber, sum(changedToNoiseRows));
            end
            % If spikes leave noise, clear their NoiseNumber.
            leftNoiseRows = rows & oldStatus == 0 & status ~= 0;
            FrameData(FrNum).NoiseNumber(leftNoiseRows) = NaN;


            if isfield(FrameData(FrNum),'Clu') && numel(FrameData(FrNum).Clu) == nSpikes
                posRows = status > 0;
                zeroRows = status == 0;
                negRows = status < 0;

                FrameData(FrNum).Clu(posRows) = status(posRows);
                FrameData(FrNum).Clu(zeroRows) = 0;
                FrameData(FrNum).Clu(negRows) = abs(status(negRows));
            end

            if isfield(FrameData(FrNum),'TempInclude') && numel(FrameData(FrNum).TempInclude) == nSpikes
                FrameData(FrNum).TempInclude = status > 0;
            end

            %new dk project the recovered clusters onto the custom dims 
            %makes the recovered clusters plot since their coords weren't
            %originally computed. does NOT touch pcs. 
            recdRows = rows & oldStatus <= 0 & status > 0;
            FrameData = ComputeCustDimCoordsRecdClusts(app, FrameData, FrNum, recdRows);

            %new DK never do pca for reassign 
            skipPCAActions = {'pbManualSort_Callback', ...
                'pbAssignNear_Callback', ...
                'tbQuickSort_Callback', ...
                'RemoveRefractoryViolations', ...
                'noiseRemovalModel'};
            
            isReassignAction = any(strcmpi(actionLabel, ...
                {'SPIKEpbReassign_Callback', 'CLUSTERpbReassign_Callback'}));
            positiveToPositive = all(oldStatus(rows) > 0) && all(status(rows) > 0);
            skipPCA = any(strcmpi(actionLabel, skipPCAActions)) || ...
                (isReassignAction && positiveToPositive);
            if skipPCA
                fprintf('[%s] skipped PCA/rPC recompute\n', actionLabel);
            else
                FrameData = RecomputeFramePCs(app, FrameData, FrNum);
                try
                    [FrameData, ~] = computeAndInsertRPCs(app, handles, FrameData, FrNum);
                    RefreshDimDropdownLabels(app, handles, FrameData, FrNum);
                catch ME
                    fprintf('[ChangeStatus rPC recompute skipped] %s\n', ME.message);
                end
            end


            FrameData = RecomputeOneFrameClusterMeta(app, handles, FrameData, FrNum);


            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            fprintf('[%s] Fr %d changed %d spikes: %s -> %s\n', ...
                actionLabel, FrNum, sum(rows), ...
                mat2str(unique(oldStatus(rows))'), ...
                mat2str(unique(status(rows))'));
        end

        function FrameData = ComputeCustDimCoordsRecdClusts(app, FrameData, FrNum, recdRows)

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~any(recdRows)
                return
            end

            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot) || ...
                    ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs) || ...
                    ~isfield(FrameData(FrNum),'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                return
            end

            nSpikes = size(FrameData(FrNum).Wfs,1);

            recdRows = recdRows(:);
            if numel(recdRows) ~= nSpikes
                return
            end

            recdRows = recdRows & FrameData(FrNum).Status(:) > 0;

            if ~any(recdRows)
                return
            end

            nDims = min(size(FrameData(FrNum).Uplot,2), numel(FrameData(FrNum).DimLabels));

            for d = 7:nDims

                lab = FrameData(FrNum).DimLabels{d};

                if startsWith(lab, 'PC') || startsWith(lab, 'rPC')
                    continue
                end

                custWf = [];

                if isfield(FrameData(FrNum),'DimDisplayWf') && ...
                        numel(FrameData(FrNum).DimDisplayWf) >= d && ...
                        ~isempty(FrameData(FrNum).DimDisplayWf{d})

                    custWf = FrameData(FrNum).DimDisplayWf{d};

                elseif isfield(FrameData(FrNum),'PCplot') && ...
                        size(FrameData(FrNum).PCplot,2) >= d

                    custWf = FrameData(FrNum).PCplot(:,d);

                end

                if isempty(custWf)
                    continue
                end

                custWf = double(custWf(:));

                if numel(custWf) ~= size(FrameData(FrNum).Wfs,2)
                    continue
                end

                FrameData(FrNum).Uplot(recdRows,d) = ...
                    double(FrameData(FrNum).Wfs(recdRows,:)) * custWf;
            end

            fprintf('[ComputeCustDimCoordsRecdClusts] Fr %d filled custom dim coords for %d recovered spikes\n', ...
                FrNum, sum(recdRows));

        end

        function [isoDist, nearestNonClusterMD2] = ComputeIsolationDistance(app, Xspk, status, c)

            isoDist = NaN;
            nearestNonClusterMD2 = NaN;

            if isempty(Xspk) || isempty(status)
                return
            end

            status = status(:);

            % Target cluster: active positive status equal to c
            rowsC = status == c;
            nC = sum(rowsC);

            if nC < 5
                return
            end

            % Outside set: active positive clusters only, excluding c.
            % Ignore status == 0 noise and status < 0 finalized units.
            rowsOutside = status > 0 & status ~= c;

            if sum(rowsOutside) < nC
                return
            end

            Xc = Xspk(rowsC, :);
            Xout = Xspk(rowsOutside, :);

            % Keep dimensions lower than spike count so covariance is stable
            nDim = min([size(Xc, 2), nC - 1, 6]);

            if nDim < 1
                return
            end

            Xc = Xc(:, 1:nDim);
            Xout = Xout(:, 1:nDim);

            mu = mean(Xc, 1);
            Sigma = cov(Xc);

            if isempty(Sigma) || any(~isfinite(Sigma(:)))
                return
            end

            % Regularize covariance
            lambda = 1e-6 * trace(Sigma) / max(size(Sigma, 1), 1);

            if lambda == 0 || ~isfinite(lambda)
                lambda = 1e-6;
            end

            Sigma = Sigma + lambda * eye(size(Sigma));

            D = Xout - mu;

            % Squared Mahalanobis distance from each non-cluster active spike to cluster c
            md2 = sum((D / Sigma) .* D, 2);
            md2 = md2(isfinite(md2));

            if isempty(md2)
                return
            end

            md2 = sort(md2, 'ascend');

            nearestNonClusterMD2 = md2(1);

            % Classic isolation distance = Nth closest non-cluster active spike,
            % where N = target cluster spike count
            if numel(md2) >= nC
                isoDist = md2(nC);
            end
        end

        function sepMulti = ComputeMultiUnitSeparation(app, Wraw, includeMask, clu, c)

            sepMulti = NaN;

            % Cluster 1 is the multiunit cluster, so C1 vs C1 is undefined
            if c == 1
                return
            end

            if isempty(Wraw) || isempty(includeMask) || isempty(clu)
                return
            end

            includeMask = logical(includeMask(:));
            clu = clu(:);

            rowsA = includeMask & clu == c; % current unit
            rowsB = includeMask & clu == 1; % multiunit cluster C1

            if sum(rowsA) < 5 || sum(rowsB) < 5
                return
            end


            % Wraw is already the fixed metric space.
            % Do not use noise rows to normalize; noise should not leak into metrics.
            W = double(Wraw);
            

            [sepMulti, ~, ~] = ProjectionPairMetrics(app, W, rowsA, rowsB);
        end


        function [Xspk, Xnoise] = BuildIsolationPCSpace(app, Wfs, NoiseWfs)

            Xspk = [];
            Xnoise = [];

            if isempty(Wfs) || isempty(NoiseWfs)
                return
            end

            Wfs = double(Wfs);
            NoiseWfs = double(NoiseWfs);

            wfLen = size(Wfs, 2);

            % Resize noise traces into same length as spike waveforms.
            if size(NoiseWfs, 2) ~= wfLen
                xOld = linspace(1, wfLen, size(NoiseWfs, 2));
                xNew = 1:wfLen;
                NoiseWfs = interp1(xOld, NoiseWfs', xNew, 'linear', 'extrap')';
            end

            Xall = [Wfs; NoiseWfs];

            goodCols = all(isfinite(Xall), 1);
            Xall = Xall(:, goodCols);

            if size(Xall, 2) < 2
                return
            end

            mu = mean(Xall, 1);
            sd = std(Xall, 0, 1);
            sd(sd == 0 | isnan(sd) | isinf(sd)) = 1;

            Xall = (Xall - mu) ./ sd;

            [~, ~, V] = svd(Xall, 'econ');

            nDim = min(6, size(V, 2));

            if nDim < 1
                return
            end

            Score = Xall * V(:, 1:nDim);

            nSpk = size(Wfs, 1);

            Xspk = Score(1:nSpk, :);
            Xnoise = Score(nSpk+1:end, :);
        end

        
        function UpdateAllQLabels(app, handles, FrNum)
        
            FrameData = app.FrameDataStore;
        
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
        
            % Old visible Q labels were deleted.
            % Keep old hidden/save quality boxes numeric only if they still exist.
            for c = 1:14
                boxName = ['edIsoQual' num2str(c)];
                if isprop(app, boxName) && ~isempty(app.(boxName)) && isvalid(app.(boxName))
                    app.(boxName).Value = 'NaN';
                end
            end
        
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end
        
            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
               ~isfield(FrameData(FrNum), 'Wfs') || isempty(FrameData(FrNum).Wfs)
                return
            end
        
            status = FrameData(FrNum).Status(:);
        
            % Treat +4 and -4 as the same unit for stats.
            clu = abs(status);
        
            % Include active and isolated units, exclude noise.
            includeMask = status ~= 0;
        
            activeClusts = unique(clu(includeMask));
            activeClusts = activeClusts(activeClusts >= 1 & activeClusts <= 14);
            activeClusts = sort(activeClusts(:))';
        
            Q = repmat(struct( ...
                'cluster', NaN, ...
                'nSpikes', 0, ...
                'violPct3ms', NaN, ...
                'refractoryScore', NaN, ...
                'sepMetric', NaN, ...
                'goodnessFit', NaN, ...
                'isolationDistance', NaN, ...
                'nearestNoiseMD2', NaN, ...
                'sepMulti', NaN, ...
                'isi_ms', [], ...
                'isiEdges', [], ...
                'isiCounts', []), 1, 14);
        
            if isempty(activeClusts)
                FrameData(FrNum).Q = Q;
                set(handles.frUnits,'UserData',FrameData);
                app.FrameDataStore = FrameData;
                return
            end
        
            % Isolation currently uses PCA/plot space.
            

            % Fixed metric space for Q metrics.
            % Do not use U or Uplot here because those can change during plotting/sorting.
            if ~isfield(FrameData(FrNum),'MetricX') || isempty(FrameData(FrNum).MetricX)

                Wmetric = double(FrameData(FrNum).Wfs);
                nMetricPC = min(6, min(size(Wmetric)));

                if size(Wmetric,1) >= 2 && nMetricPC >= 1
                    [MetricPC, MetricX, ~, ~, ~, MetricMu] = pca(Wmetric, 'NumComponents', nMetricPC);

                    FrameData(FrNum).MetricX = MetricX;
                    FrameData(FrNum).MetricPC = MetricPC;
                    FrameData(FrNum).MetricMu = MetricMu;
                else
                    FrameData(FrNum).MetricX = [];
                    FrameData(FrNum).MetricPC = [];
                    FrameData(FrNum).MetricMu = [];
                end
            end

            XisoSpk = FrameData(FrNum).MetricX;

            % Do pairwise projection metrics in the same fixed metric space.
            [SepMat, R2Mat] = ComputeProjectionMatrices(app, double(FrameData(FrNum).MetricX), includeMask, clu, activeClusts);


            for c = activeClusts
        
                rows = includeMask & clu == c;
        
                Q(c).cluster = c;
                Q(c).nSpikes = sum(rows);
        
                [violPct3ms, refractoryScore] = ComputeRefractoryScore(app, FrameData, FrNum, rows);
                [isi_ms, isiEdges, isiCounts] = ComputeISIHistogramData(app, FrameData, FrNum, rows);
        
                Q(c).violPct3ms = violPct3ms;
                Q(c).refractoryScore = refractoryScore;
                Q(c).isi_ms = isi_ms;
                Q(c).isiEdges = isiEdges;
                Q(c).isiCounts = isiCounts;
        
                sepVals = SepMat(c, activeClusts);
                sepVals = sepVals(isfinite(sepVals));
        
                r2Vals = R2Mat(c, activeClusts);
                r2Vals = r2Vals(isfinite(r2Vals));
        
                if isempty(sepVals)
                    Q(c).sepMetric = NaN;
                else
                    Q(c).sepMetric = min(sepVals);
                end
        
                if isempty(r2Vals)
                    Q(c).goodnessFit = NaN;
                else
                    Q(c).goodnessFit = min(r2Vals);
                end


                XisoUse = XisoSpk(includeMask, :);
                cluUse = clu(includeMask);

                [Q(c).isolationDistance, Q(c).nearestNoiseMD2] = ComputeIsolationDistance( ...
                    app, XisoUse, cluUse, c);

                Q(c).sepMulti = ComputeMultiUnitSeparation( ...
                    app, double(FrameData(FrNum).MetricX), includeMask, clu, c);
        
                % Keep old hidden/save quality field numeric only if it still exists.
                boxName = ['edIsoQual' num2str(c)];
        
                if isprop(app, boxName) && ~isempty(app.(boxName)) && isvalid(app.(boxName))
                    if isnan(Q(c).violPct3ms)
                        app.(boxName).Value = 'NaN';
                    else
                        app.(boxName).Value = sprintf('%.2f', Q(c).violPct3ms);
                    end
                end
            end
        
            FrameData(FrNum).Q = Q;
        
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        
            drawnow limitrate;
        end


        function UpdatePcRasterZoomDirection(app)

           
            if isempty(app.pcRaster) || ~isvalid(app.pcRaster)
                return
            end

            ax = app.pcRaster;

            zoomDim = "x";

            try
                yChoice = app.rasterYDim.Value;
            catch
                yChoice = 'clusters';
            end

            if app.RasterVerticalZoomMode && ~strcmpi(yChoice, 'clusters')
                zoomDim = "y";
            end

            try
                ax.InteractionOptions.LimitsDimensions = zoomDim;
            catch ME
                fprintf('[pcRaster zoom direction failed] %s\n', ME.message);
            end

            fprintf('DEBUG raster zoomDim = %s | RasterVerticalZoomMode = %d | rasterYDim = %s\n', ...
                zoomDim, app.RasterVerticalZoomMode, yChoice);


        end


        function PlotISI(app)
            return 

            [~, ~, handles] = convertToGUIDECallbackArguments(app);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            cla(app.axISI, 'reset');
            set(app.axISI, 'Color', 'white');

            FrNum = round(app.SelectedClusterFrame);
            CluNum = round(app.SelectedClusterNum);

            if isempty(FrameData) || isnan(FrNum) || isnan(CluNum) || ...
                    FrNum < 1 || FrNum > numel(FrameData) || ...
                    CluNum < 1 || CluNum > 14

                title(app.axISI, '');
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status)
                title(app.axISI, sprintf('F%dC%d: no status data', FrNum, CluNum));
                return
            end

            rows = FrameData(FrNum).Status(:) == CluNum;

            fprintf('[PlotISI DEBUG] F%d C%d rows=%d spkLen=%d statusLen=%d\n', ...
                FrNum, CluNum, sum(rows), numel(FrameData(FrNum).spk), ...
                numel(FrameData(FrNum).Status));

            if ~any(rows)
                title(app.axISI, sprintf('F%dC%d: 0 spikes', FrNum, CluNum));
                return
            end

            % Always compute current ISI data directly from current rows.
            % Do not depend on stale FrameData(FrNum).Q cache.
            [isi_ms, edges, counts] = ComputeISIHistogramData(app, FrameData, FrNum, rows);

            if isempty(edges) || isempty(counts)
                title(app.axISI, sprintf('F%dC%d: rows=%d, ISIs=%d', ...
                    FrNum, CluNum, sum(rows), max(0, sum(rows)-1)));
                return
            end

            histogram(app.axISI, ...
                'BinEdges', edges, ...
                'BinCounts', counts);

            hold(app.axISI, 'on');
            xline(app.axISI, 3, 'r', 'LineWidth', 1.5);
            hold(app.axISI, 'off');

            ymax = max(counts);
            if ymax <= 0 || isnan(ymax)
                ymax = 1;
            end

            set(app.axISI, 'XLim', [edges(1) edges(end)]);
            set(app.axISI, 'YLim', [0 ymax * 1.15]);

            app.axISI.XLimMode = 'manual';
            app.axISI.YLimMode = 'manual';
            app.axISI.XLimitMethod = 'tight';
            app.axISI.YLimitMethod = 'tight';
            app.axISI.Interactions = [zoomInteraction panInteraction];
            app.axISI.Toolbar.Visible = 'off';

            xlabel(app.axISI, 'ISI (ms)');
            ylabel(app.axISI, 'count');

            if isempty(isi_ms)
                pctViol = NaN;
            else
                pctViol = 100 * sum(isi_ms < 3) / numel(isi_ms);
            end

            title(app.axISI, sprintf('F%dC%d ISI: %.2f%% < 3 ms', FrNum, CluNum, pctViol));

            drawnow;
        end
        
        function [violPct3ms, refractoryScore] = ComputeRefractoryScore(app, FrameData, FrNum, rows)

            % Default outputs if computation fails
            violPct3ms = NaN;
            refractoryScore = NaN;

            % Need spike times for this frame
            if ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)
                return
            end

            % Get all spike times for this frame, in microseconds
            spkFrame_us = double(FrameData(FrNum).spk(:));

            % Keep only the spike times belonging to this cluster/unit
            spkTimes_us = spkFrame_us(rows);

            % Sort spike times so ISIs are computed in time order
            spkTimes_us = sort(spkTimes_us(:));

            % Remove bad timestamp values
            spkTimes_us = spkTimes_us(isfinite(spkTimes_us));

            % Need at least 2 spikes to compute an inter-spike interval
            if numel(spkTimes_us) < 2
                return
            end

            % Compute inter-spike intervals in milliseconds
            isi_ms = diff(spkTimes_us) / 1000;

            % Keep only valid nonnegative ISIs
            isi_ms = isi_ms(isfinite(isi_ms) & isi_ms >= 0);

            % If no usable ISIs remain, stop
            if isempty(isi_ms)
                return
            end

            % Percent of ISIs below 3 ms
            violPct3ms = 100 * sum(isi_ms < 3) / numel(isi_ms);

            % Refractory score is currently just the same percent violation value
            refractoryScore = violPct3ms;


        end

        function [isi_ms, isiEdges, isiCounts] = ComputeISIHistogramData(app, FrameData, FrNum, rows)

            isi_ms = [];
            isiEdges = [];
            isiCounts = [];

            if ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)
                return
            end

            spkFrame_us = double(FrameData(FrNum).spk(:));
            spkTimes_us = spkFrame_us(rows);
            spkTimes_us = sort(spkTimes_us(:));
            spkTimes_us = spkTimes_us(isfinite(spkTimes_us));

            if numel(spkTimes_us) < 2
                return
            end

            isi_ms = diff(spkTimes_us) / 1000;
            isi_ms = isi_ms(isfinite(isi_ms) & isi_ms >= 0);

            if isempty(isi_ms)
                return
            end

            rawEdges = 0:1:100;
            rawCounts = histcounts(isi_ms, rawEdges);

            nz = find(rawCounts > 0);

            if isempty(nz)
                return
            end

            firstBin = max(1, nz(1) - 1);
            lastBin = min(numel(rawCounts), nz(end) + 1);

            isiEdges = rawEdges(firstBin:lastBin+1);
            isiCounts = rawCounts(firstBin:lastBin);
        end


        function [SepMat, R2Mat] = ComputeProjectionMatrices(app, Wraw, includeMask, clu, activeClusts)

            SepMat = NaN(14,14);
            R2Mat = NaN(14,14);

            if numel(activeClusts) < 2
                return
            end

            % Wraw is already the fixed metric space.
            % Do not use noise rows to normalize; noise should not leak into metrics.
            W = double(Wraw);

            for ia = 1:numel(activeClusts)
                cA = activeClusts(ia);

                for ib = ia+1:numel(activeClusts)
                    cB = activeClusts(ib);

                    rowsA = clu == cA;
                    rowsB = clu == cB;

                    [sepDist, r2A, r2B] = ProjectionPairMetrics(app, W, rowsA, rowsB);

                    SepMat(cA,cB) = sepDist;
                    SepMat(cB,cA) = sepDist;

                    R2Mat(cA,cB) = r2A;
                    R2Mat(cB,cA) = r2B;
                end
            end
        end


        function [sepDist, r2A, r2B] = ProjectionPairMetrics(app, W, rowsA, rowsB)

            WA = W(rowsA,:);
            WB = W(rowsB,:);

            if size(WA,1) < 5 || size(WB,1) < 5
                sepDist = NaN;
                r2A = NaN;
                r2B = NaN;
                return
            end

            mA = mean(WA, 1);
            mB = mean(WB, 1);

            direction = mB - mA;
            dNorm = norm(direction);

            if dNorm == 0 || isnan(dNorm) || isinf(dNorm)
                sepDist = NaN;
                r2A = NaN;
                r2B = NaN;
                return
            end

            direction = direction / dNorm;

            projA = WA * direction(:);
            projB = WB * direction(:);

            centerA = mA * direction(:);
            centerB = mB * direction(:);

            % Separation distance in approximate noise-SD units.
            % a distance of >5 guarantees an overlap of less than 1%, a
            % distance >3.2 an overlap less than 5% and a distance of >2.8 an overlap of less than 7.5% 
            sdA = std(projA);
            sdB = std(projB);
            pooledSD = sqrt((sdA^2 + sdB^2) / 2);
            
            if pooledSD == 0 || isnan(pooledSD) || isinf(pooledSD)
                sepDist = NaN;
            else
                sepDist = abs(mean(projB) - mean(projA)) / pooledSD;
            end 

            % Residual projection around each cluster center.
            residA = projA - centerA;
            residB = projB - centerB;

            r2A = GaussianStd1FitR2(app, residA);
            r2B = GaussianStd1FitR2(app, residB);
        end


        function r2 = GaussianStd1FitR2(app, residualProjection)

            x = double(residualProjection(:));
            x = x(isfinite(x));
        
            if numel(x) < 5
                r2 = NaN;
                return
            end
        
            % Center residuals
            x = x - mean(x);
        
            % Normalize residuals to SD = 1 before comparing to N(0,1)
            sx = std(x);
        
            if sx == 0 || isnan(sx) || isinf(sx)
                r2 = NaN;
                return
            end
        
            x = x / sx;
        
            edges = -5:0.5:5;
            centers = edges(1:end-1) + diff(edges)/2;
        
            emp = histcounts(x, edges, 'Normalization', 'pdf');
            ideal = exp(-0.5 * centers.^2) / sqrt(2*pi);
        
            good = isfinite(emp) & isfinite(ideal);
            emp = emp(good);
            ideal = ideal(good);
        
            if numel(emp) < 3 || all(emp == emp(1))
                r2 = NaN;
                return
            end
        
            sse = sum((emp - ideal).^2);
            sst = sum((emp - mean(emp)).^2);
        
            if sst == 0
                r2 = NaN;
            else
                r2 = 1 - sse / sst;
            end
        
            % Keep readable range
            r2 = max(0, min(1, r2));
        end

        function sepScore = ScoreSeparation(app, minSep)

            if isnan(minSep)
                sepScore = NaN;
                return
            end

            % Based on projection-test intuition:
            % 2.8 = weak/borderline lower bound
            % 3.2 = acceptable-ish
            % 5.0 = excellent
            sepScore = 100 * (minSep - 2.8) / (5.0 - 2.8);
            sepScore = max(0, min(100, sepScore));
        end


        function fitScore = ScoreFitR2(app, minR2)

            if isnan(minR2)
                fitScore = NaN;
                return
            end

            % R2 0.70 = weak, 0.90+ = strong.
            fitScore = 100 * (minR2 - 0.70) / (0.90 - 0.70);
            fitScore = max(0, min(100, fitScore));
        end


        function ClusterSpikeClick(app, clickedAx, event)
            disp('DEBUG ClusterSpikeClick')

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                return
            end

            FrNum = app.SelectedClusterFrame;
            CluNum = app.SelectedClusterNum;

            if isnan(FrNum) || isnan(CluNum) || ...
                    FrNum < 1 || FrNum > numel(FrameData) || ...
                    CluNum < 1 || CluNum > 14

                fprintf('[Select Cluster Spike skipped] No selected cluster yet.\n');
                return
            end

            cp = clickedAx.CurrentPoint;
            xClick = cp(1,1);
            yClick = cp(1,2);

            xIdx = round(xClick);
            wfLen = size(FrameData(FrNum).Wfs, 2);

            if isnan(xIdx) || xIdx < 1 || xIdx > wfLen
                fprintf('[Select Cluster Spike skipped] Click outside waveform range.\n');
                return
            end


            UD = app.axClusterSpikes.UserData;

            if isempty(UD) || ...
                    ~isstruct(UD) || ...
                    ~isfield(UD,'Rows') || ...
                    ~isfield(UD,'Wfs')
                return
            end

            candidateRows = UD.Rows(:);
            W = UD.Wfs;

            dist2 = (W(:,xIdx) - yClick).^2;

            [~, nearestLocal] = min(dist2);
            rowIdx = candidateRows(nearestLocal);

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            fprintf('[Select Cluster Spike] selected F%dC%d row %d\n', FrNum, CluNum, rowIdx);

            PlotSelectedSpike(app, FrameData);
        end

        function GeneralSelectClick(app, clickedAx, event)
            disp('DEBUG GeneralSelectClick running')
        

            if clickedAx == app.axDim12 || clickedAx == app.axDim13 || ...
                   clickedAx == app.axDim23 
                SpikePlotClick(app, clickedAx, event);
            elseif clickedAx == app.axFrWaveform || ...
               clickedAx == app.axWaveforms || ...
               clickedAx == app.axRemovedasIsoMeanWfs || ... 
               clickedAx == app.axWaveforms_2
        
                ClusterWaveformClick(app, clickedAx, event);
        
            elseif clickedAx == app.axClusterSpikes
                ClusterSpikeClick(app, clickedAx, event);
            elseif clickedAx == app.axWaveforms_2
                ClusterSpikeClick(app, clickedAx, event);
            else
                fprintf('[Select skipped] unsupported axis\n');
                return
            end
        
        end

        function ClusterWaveformClick(app, clickedAx, event)
            disp('DEBUG ClusterWaveformClick running')
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            cp = clickedAx.CurrentPoint;
            xClick = cp(1,1);
            yClick = cp(1,2);

            xIdx = round(xClick);

            if isnan(xIdx) || xIdx < 1 || xIdx > size(FrameData(FrNum).Wfs,2)
                fprintf('[Select Cluster] click outside waveform range\n');
                return
            end

            bestD = inf;
            bestFr = NaN;
            bestClu = NaN;


            if clickedAx == app.axFrWaveform

                % Current-frame active mean waveform plot
                frList = FrNum;
                searchRemovedIso = false;

            elseif clickedAx == app.axWaveforms

                % Cumulative active mean waveform plot
                frList = 1:FrNum;
                searchRemovedIso = false;

            elseif clickedAx == app.axRemovedasIsoMeanWfs

                % Clicking cumulative isolated-unit mean waveform.
                % The displayed mean is across ALL frames,
                % but the selected spikes shown in axClusterSpikes are ONLY from current frame.
            
                FrameString = get(handles.stFrame2,'String');
                FrNum = sscanf(FrameString, 'Frame %d', 1);
            
                if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                    fprintf('[Iso click skipped] bad current frame\n');
                    return
                end
            
                cp = clickedAx.CurrentPoint;
                xClick = cp(1,1);
                yClick = cp(1,2);
            
                xIdx = round(xClick);
            
                if ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                    return
                end
            
                wfLen = size(FrameData(FrNum).Wfs,2);
            
                if isnan(xIdx) || xIdx < 1 || xIdx > wfLen
                    return
                end
            
                % Collect all isolated unit IDs across all frames
                allIsoUnits = [];
            
                for iFr = 1:numel(FrameData)
            
                    if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status)
                        continue
                    end
            
                    status_i = FrameData(iFr).Status(:);
                    allIsoUnits = [allIsoUnits; unique(status_i(status_i < 0))]; %#ok<AGROW>
                end
            
                allIsoUnits = unique(allIsoUnits);
                allIsoUnits = sort(allIsoUnits(:)');
            
                if isempty(allIsoUnits)
                    fprintf('[Iso click skipped] no isolated units\n');
                    return
                end
            
                % Find which cumulative mean waveform was clicked
                bestIsoStatus = NaN;
                bestDist = inf;
            
                for isoStatus = allIsoUnits
            
                    W_all = [];
            
                    for iFr = 1:numel(FrameData)
            
                        if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status) || ...
                                ~isfield(FrameData(iFr),'Wfs') || isempty(FrameData(iFr).Wfs)
                            continue
                        end
            
                        status_i = FrameData(iFr).Status(:);
                        rows_i = status_i == isoStatus;
            
                        if any(rows_i)
                            W_all = [W_all; FrameData(iFr).Wfs(rows_i,:)]; %#ok<AGROW>
                        end
                    end
            
                    if isempty(W_all)
                        continue
                    end
            
                    mwf = mean(W_all, 1);
            
                    thisDist = abs(mwf(xIdx) - yClick);
            
                    if thisDist < bestDist
                        bestDist = thisDist;
                        bestIsoStatus = isoStatus;
                    end
                end
            
                if isnan(bestIsoStatus)
                    fprintf('[Iso click skipped] could not identify clicked iso unit\n');
                    return
                end
            
                % IMPORTANT:
                % Select current frame, not all frames.
                % PlotSelectedCluster should then plot only current-frame rows
                % where Status == bestIsoStatus.
                app.SelectedClusterFrame = FrNum;
                app.SelectedClusterNum = bestIsoStatus;
                app.SelectedNoiseNumber = NaN;
                UpdateRecoverSelectedClusterButton(app);
            
                SetSelectedClusterLabel(app, sprintf('F%d U%d', FrNum, abs(bestIsoStatus)));
            
                fprintf('[Iso click] selected cumulative U%d, plotting only F%d spikes\n', ...
                    abs(bestIsoStatus), FrNum);
            
                PlotSelectedCluster(app, FrameData);
                return

            elseif clickedAx == app.axWaveforms_2 
                return 
                frList = 1:FrNum;
                searchRemovedIso = false;
                
                % get NoiseNumber


                FrameString = get(handles.stFrame2,'String');
                FrNum = sscanf(FrameString, 'Frame %d', 1);
            
                if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                    fprintf('[NoiseNumber click skipped] bad frame\n');
                    return
                end
            
                if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status) || ...
                        ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs) || ...
                        ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber)
            
                    fprintf('[NoiseNumber click skipped] missing Status/Wfs/NoiseNumber\n');
                    return
                end
            

                status = FrameData(FrNum).Status(:); % get Status
                noiseNumber = FrameData(FrNum).NoiseNumber(:);
            
                % find unique NoiseNumbers where Status == 0 
                noiseNums = unique(noiseNumber(status == 0 & isfinite(noiseNumber)));
                noiseNums = sort(noiseNums(:)');
                if isempty(noiseNums)
                    fprintf('[NoiseNumber click skipped] no noise numbers\n');
                    return
                end
            
                cp = clickedAx.CurrentPoint;
                xClick = cp(1,1);
                yClick = cp(1,2);
            
                bestNoiseNumber = NaN;
                bestDist = inf;
                
                for iN = noiseNums
            
                    rows = status == 0 & noiseNumber == iN;
            
                    if ~any(rows)
                        continue
                    end

                    % compute mean waveform for each NoiseNumber
                    mwf = mean(FrameData(FrNum).Wfs(rows,:), 1);
            
                    % compare click point to each mean waveform
                    xIdx = round(xClick);
                    xIdx = max(1, min(numel(mwf), xIdx));
            
                    dist = abs(mwf(xIdx) - yClick);
            
                    % choose closest NoiseNumber
                    if dist < bestDist
                        bestDist = dist;
                        bestNoiseNumber = iN;
                    end
                end
            
                if isnan(bestNoiseNumber)
                    fprintf('[NoiseNumber click skipped] no closest mean found\n');
                    return
                end
            
                rows = status == 0 & noiseNumber == bestNoiseNumber;
            
                cla(app.axClusterSpikes,'reset');
                hold(app.axClusterSpikes,'on');
                set(app.axClusterSpikes,'Color','white');
            
                W = FrameData(FrNum).Wfs(rows,:);
                x = 1:size(W,2);
            
                for i = 1:size(W,1)
                    line(app.axClusterSpikes, x, W(i,:), ...
                        'Color', [0.5 0.5 0.5], ...
                        'LineWidth', 0.5, ...
                        'HitTest', 'off', ...
                        'PickableParts', 'none');
                end
            
                mwf = mean(W, 1);
            
                % plot all waveforms where Status == 0 AND NoiseNumber == chosen number into axClusterSpikes
                line(app.axClusterSpikes, x, mwf, ...
                    'Color', 'k', ...
                    'LineWidth', 2, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
            
                hold(app.axClusterSpikes,'off');

                % Select closest NoiseNumber mean waveform, then plot all waveforms
                % from that NoiseNumber in axClusterSpikes.
            
                app.SelectedClusterFrame = FrNum;
                app.SelectedClusterNum = NaN;

                UpdateRecoverSelectedClusterButton(app);
            
                rows = FrameData(FrNum).Status(:) == 0 & FrameData(FrNum).NoiseNumber(:) == bestNoiseNumber;
                nNoise = sum(rows);

                SetSelectedClusterLabel(app, sprintf('F%dN%d %d/%d shown', ...
                    FrNum, bestNoiseNumber, nNoise, nNoise));

            
                fprintf('[NoiseNumber click] Fr %d NoiseNumber %d plotted %d waveforms\n', ...
                    FrNum, bestNoiseNumber, sum(rows));

                return
    
            else
                return
            end


            for iFr = frList

                if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status) || ...
                        ~isfield(FrameData(iFr),'Wfs') || isempty(FrameData(iFr).Wfs)
                    continue
                end

                status = FrameData(iFr).Status(:);

                if searchRemovedIso
                    disp('debug searchRemovedIso')
                    % Removed/isolated units are negative statuses: -1, -2, ...
                    cluList = unique(status(status < 0));
                    cluList = sort(cluList(:)');
                    disp(cluList) 

                else

                    % Normal active clusters are positive statuses: 1, 2, ...
                    cluList = 1:14;

                end

                for c = cluList

                    rows = status == c;

                    if ~any(rows)
                        continue
                    end

                    if searchRemovedIso

                        % Removed-isolated units do not use mWf(c,:), because c is negative.
                        % Compute mean directly from the waveforms.
                        yMean = mean(FrameData(iFr).Wfs(rows, xIdx), 1);

                    else

                        if c > size(FrameData(iFr).mWf,1)
                            continue
                        end

                        yMean = FrameData(iFr).mWf(c,xIdx);

                    end

                    if isnan(yMean)
                        continue
                    end

                    d = (yMean - yClick).^2;

                    if d < bestD
                        bestD = d;
                        bestFr = iFr;
                        bestClu = c;   % positive for active, negative for removed-isolated
                    end
                end
            end


            if isnan(bestFr) || isnan(bestClu)
                fprintf('[Select Cluster] no cluster found near click\n');
                return
            end

            app.SelectedClusterFrame = bestFr;
            app.SelectedClusterNum = bestClu;

            fprintf('[Select Cluster] selected F%dC%d\n', bestFr, bestClu);

            PlotSelectedCluster(app, FrameData);
        end

        function FrameData = RevertFrameDataToRawSpikes(app, FrameData)

            if isfield(FrameData,'Q')
                FrameData = rmfield(FrameData,'Q');
            end

            pcold = [];

            for iFr = 1:numel(FrameData)

                if ~isfield(FrameData(iFr),'Wfs') || isempty(FrameData(iFr).Wfs)
                    continue
                end

                nSpikes = size(FrameData(iFr).Wfs, 1);
                wfLen = size(FrameData(iFr).Wfs, 2);

                FrameData(iFr).Status = ones(nSpikes,1);
                FrameData(iFr).TempInclude = true(nSpikes,1);
                FrameData(iFr).Clu = ones(nSpikes,1);
                FrameData(iFr).NoiseNumber = NaN(nSpikes,1);

                FrameData(iFr).RemCell = zeros(1,14);
                FrameData(iFr).RemIso = zeros(1,14);
                FrameData(iFr).Iso = zeros(14,1);
                FrameData(iFr).Iso(1) = 1;
                FrameData(iFr).ReassignMap = 1:14;

                FrameData(iFr).DimLabels = {'PC1','PC2','PC3','PC4','PC5','PC6'};
                FrameData(iFr).DimDisplayWf = cell(1,6);

                [U_seg, pc, eigvalues] = spikepcsNew(app, FrameData(iFr).Wfs, pcold);
                % [U_seg, pc, eigvalues] = spikepcsNew(FrameData(iFr).Wfs, pcold);

                FrameData(iFr).U = U_seg;
                FrameData(iFr).PC = pc;

                FrameData(iFr).Uplot = NaN(nSpikes,6);
                FrameData(iFr).PCplot = zeros(size(pc,1),6);

                nUDim = min(6, size(U_seg,2));
                nPCDim = min(6, size(pc,2));

                FrameData(iFr).Uplot(:,1:nUDim) = U_seg(:,1:nUDim);
                FrameData(iFr).PCplot(:,1:nPCDim) = pc(:,1:nPCDim);

                if ~isempty(eigvalues) && any(eigvalues)
                    eigFrac = (eigvalues.^2) / sum(eigvalues.^2);
                    FrameData(iFr).EigFrac = zeros(1,6);
                    FrameData(iFr).EigFrac(1:min(6,numel(eigFrac))) = eigFrac(1:min(6,numel(eigFrac)));
                else
                    FrameData(iFr).EigFrac = zeros(1,6);
                end

                pcold = pc;

                Wmetric = double(FrameData(iFr).Wfs);
                nMetricPC = min(6, min(size(Wmetric)));

                if size(Wmetric,1) >= 2 && nMetricPC >= 1
                    [MetricPC, MetricX, ~, ~, ~, MetricMu] = pca(Wmetric, 'NumComponents', nMetricPC);

                    FrameData(iFr).MetricX = MetricX;
                    FrameData(iFr).MetricPC = MetricPC;
                    FrameData(iFr).MetricMu = MetricMu;
                else
                    FrameData(iFr).MetricX = [];
                    FrameData(iFr).MetricPC = [];
                    FrameData(iFr).MetricMu = [];
                end

                FrameData(iFr).mWf = NaN(14, wfLen);
                FrameData(iFr).mWf(1,:) = mean(FrameData(iFr).Wfs, 1);

                try
                    FrameData = RecomputeOneFrameClusterMeta(app, FrameData, iFr);
                catch
                end

                try
                    FrameData = ComputeQAllClusters(app, FrameData, iFr);
                catch
                end

            end

        end


        function PlotSelectedCluster(app, FrameData)
             
            disp('DEBUG PlotSelectedCluster')
            %disp('DEBUG PlotSelectedCluster running')

            cla(app.axClusterSpikes);
            hold(app.axClusterSpikes, 'on');
            set(app.axClusterSpikes, 'Color', 'white');
            FrNum = app.SelectedClusterFrame;
            CluNum = app.SelectedClusterNum;
            
            app.SelectedNoiseNumber = NaN;
            UpdateRecoverSelectedClusterButton(app);

            if isnan(FrNum) || isnan(CluNum) || isempty(FrameData) || ...
                    FrNum < 1 || FrNum > numel(FrameData) || ...
                    CluNum == 0 || abs(CluNum) > 14

                SetSelectedClusterLabel(app, '');
                %cla(app.axISI, 'reset');
                %title(app.axISI, '');
                hold(app.axClusterSpikes, 'off');
                return
            end

            rows = FrameData(FrNum).Status(:) == CluNum;

            if ~any(rows)

                if CluNum < 0
                    SetSelectedClusterLabel(app, sprintf('F%dC%d iso 0/0 shown', FrNum, abs(CluNum)));
                else
                    SetSelectedClusterLabel(app, sprintf('F%dC%d 0/0 shown', FrNum, abs(CluNum)));
                end
            
                hold(app.axClusterSpikes, 'off');
                return
            end


            clusterRows = find(rows);

            W_all = FrameData(FrNum).Wfs(clusterRows,:);

            maxPlotSpikes = 1000;

            if numel(clusterRows) > maxPlotSpikes
                pick = randperm(numel(clusterRows), maxPlotSpikes);
                plotRows = clusterRows(pick);
            else
                plotRows = clusterRows;
            end

            W_plot = FrameData(FrNum).Wfs(plotRows,:);

            app.axClusterSpikes.UserData = struct( ...
                'Rows', plotRows, ...
                'Wfs', W_plot);

            x = 1:size(W_all,2);

            for i = 1:size(W_plot,1)
                h = line(app.axClusterSpikes, x, W_plot(i,:), ...
                    'Color', mycolors(app, abs(CluNum)), ...
                    'LineWidth', 0.5);

                set(h, 'HitTest','off', 'PickableParts','none');
            end

            
            mwf = mean(W_all, 1);   % accurate mean from ALL spikes
            hMean = line(app.axClusterSpikes, x, mwf, ...
                'Color', 'k', ...
                'LineWidth', 2);

            set(hMean, 'HitTest','off', 'PickableParts','none');

            if CluNum < 0
                SetSelectedClusterLabel(app, sprintf('F%dC%d iso %d/%d shown', ...
                    FrNum, abs(CluNum), size(W_plot,1), size(W_all,1)));
            else
                SetSelectedClusterLabel(app, sprintf('F%dC%d %d/%d shown', ...
                    FrNum, abs(CluNum), size(W_plot,1), size(W_all,1)));
            end

            title(app.axClusterSpikes, '');

            set(app.axClusterSpikes, 'XLim', [1 size(W_all,2)]);
            FixWaveformAxesTicks(app);
            hold(app.axClusterSpikes, 'off');
            DebugSelectedClusterRows(app);

            app.SelectedClusterFrame = FrNum;
            app.SelectedClusterNum = CluNum;
            app.SelectedNoiseNumber = NaN;

            UpdateRecoverSelectedClusterButton(app);

            %PlotISI(app);
            %if CluNum > 0
                %PlotISI(app);
            %end

            drawnow;
        end

        function SetSelectedSpikeLabel(app, msg)

            if nargin < 2 || isempty(msg)
                msg = '';
            end

            app.SelectedSpikeIDLabel.Text = ['Selected Spikes: ' char(msg)];

        end

        function SetSelectedClusterLabel(app, msg)

            if nargin < 2 || isempty(msg)
                msg = '';
            end

            app.SelectedClusterIDLabel.Text = ['Selected Cluster: ' char(msg)];

        end


        function FixMainAxisTicks(app)

            axs = [app.axDim12 app.axDim13 app.axDim23];

            for a = axs

                % Default MATLAB border, black.
                a.Box = 'on';
                a.XColor = [0 0 0];
                a.YColor = [0 0 0];
                a.LineWidth = 0.85;
                a.Layer = 'top';

                % Only show the zero tick labels.
                a.XTick = 0;
                a.YTick = 0;
                a.XTickLabel = {'0'};
                a.YTickLabel = {'0'};

                % No visible tick marks.
                a.TickLength = [0 0];

                % Lock this behavior during zoom/pan/redraw.
                a.XTickMode = 'manual';
                a.YTickMode = 'manual';
                a.XTickLabelMode = 'manual';
                a.YTickLabelMode = 'manual';

            end

        end

        function [wf, ok, msg, FrameData] = GetClusterMeanWf(app, FrameData, FrNum, CluNum)
            disp('DEBUG GetClusterMeanWf')
            wf = [];
            ok = false;
            msg = '';

            FrNum = round(FrNum);
            CluNum = round(CluNum);

            if isempty(FrameData)
                msg = 'FrameData is empty';
                return
            end

            if FrNum < 1 || FrNum > numel(FrameData)
                msg = sprintf('Frame %d is out of range', FrNum);
                return
            end

            if CluNum < 1 || CluNum > 14
                msg = sprintf('Cluster %d is out of range', CluNum);
                return
            end

            if ~isfield(FrameData(FrNum), 'Wfs') || isempty(FrameData(FrNum).Wfs)
                msg = sprintf('Frame %d has no Wfs', FrNum);
                return
            end

            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status)
                msg = sprintf('Frame %d has no Status labels', FrNum);
                return
            end

            status = FrameData(FrNum).Status(:);
            rows = status == CluNum;

            if ~any(rows)
                msg = sprintf('Frame %d Cluster %d has zero included spikes', FrNum, CluNum);
                return
            end

            wf = mean(FrameData(FrNum).Wfs(rows,:), 1);

            if isempty(wf) || any(isnan(wf))
                msg = sprintf('Frame %d Cluster %d mean waveform is empty/NaN', FrNum, CluNum);
                return
            end

            if ~isfield(FrameData(FrNum), 'mWf') || isempty(FrameData(FrNum).mWf)
                FrameData(FrNum).mWf = NaN(14, size(FrameData(FrNum).Wfs, 2));
            end

            if size(FrameData(FrNum).mWf, 1) < 14
                oldMWf = FrameData(FrNum).mWf;
                FrameData(FrNum).mWf = NaN(14, size(FrameData(FrNum).Wfs, 2));
                FrameData(FrNum).mWf(1:size(oldMWf,1),:) = oldMWf;
            end

            FrameData(FrNum).mWf(CluNum,:) = wf;

            ok = true;
            msg = sprintf('Frame %d Cluster %d mean waveform ok: %d spikes', FrNum, CluNum, sum(rows));
        end

        function RestoreNativePanZoom(app)
            disp('DEBUG RestoreNativePanZoom')
            app.SpikeSelectMode = false;
            app.figure1.Pointer = 'arrow';

            app.figure1.WindowButtonDownFcn = [];
            app.figure1.WindowButtonUpFcn = [];
            app.figure1.WindowScrollWheelFcn = [];
            app.figure1.WindowButtonMotionFcn = [];

            app.axDim12.Interactions = [zoomInteraction panInteraction];
            app.axDim13.Interactions = [zoomInteraction panInteraction];
            app.axDim23.Interactions = [zoomInteraction panInteraction];
            app.axWaveforms_2.Interactions = [zoomInteraction panInteraction];

            if isprop(app, 'pcRaster') && ~isempty(app.pcRaster) && isvalid(app.pcRaster)

                app.pcRaster.ButtonDownFcn = [];

                try
                    enableDefaultInteractivity(app.pcRaster);
                catch
                end

                try
                    app.pcRaster.Interactions = [zoomInteraction panInteraction];
                catch ME
                    fprintf('[pcRaster restore interaction failed] %s\n', ME.message);
                end

                try
                    app.pcRaster.InteractionOptions.LimitsDimensions = "x";
                catch ME
                    fprintf('[pcRaster restore x-only failed] %s\n', ME.message);
                end

                try
                    app.pcRaster.Toolbar.Visible = 'on';
                catch
                end

            end

            app.axDim12.ButtonDownFcn = [];
            app.axDim13.ButtonDownFcn = [];
            app.axDim23.ButtonDownFcn = [];
            app.axWaveforms_2.ButtonDownFcn = [];

            app.axDim12.Toolbar.Visible = 'off';
            app.axDim13.Toolbar.Visible = 'off';
            app.axDim23.Toolbar.Visible = 'off';
            app.axWaveforms_2.Toolbar.Visible = 'off';
            app.axWaveforms.Toolbar.Visible = 'off';
            app.axFrWaveform.Toolbar.Visible = 'off';

            if ~isempty(app.selectOFFButton) && isvalid(app.selectOFFButton)
                app.selectOFFButton.Text = 'select: OFF';
            end
        end


        function SELECTSPIKEButtonPushed(app, event)
            disp('DEBUG SELECTSPIKEButtonPushed running')
            
            % remember whether this click should turn select ON or OFF
            wantSelectOn = ~app.SpikeSelectMode;

            % cancel all other interactive modes first
            CancelInteractiveModes(app);

            if wantSelectOn
                app.SpikeSelectMode = true;
                app.selectOFFButton.Text = 'select: ON';

                app.figure1.Pointer = 'arrow';

                app.axDim12.Interactions = [];
                app.axDim13.Interactions = [];
                app.axDim23.Interactions = [];
                app.axWaveforms_2.Interactions = [];
                app.axRemovedasIsoMeanWfs.Interactions = [];

                app.PendingClickAxis = [];
                app.PendingClickPoint = [NaN NaN];

                app.figure1.WindowButtonDownFcn = @(src,event) FigureMouseDown(app, src, event);
                app.figure1.WindowButtonUpFcn = @(src,event) SelectSpikeMouseUp(app, src, event);
                app.figure1.WindowButtonMotionFcn = @(src,event) SelectSpikeMouseMove(app, src, event);
                app.figure1.WindowScrollWheelFcn = [];
            else
                app.SpikeSelectMode = false;
                app.selectOFFButton.Text = 'select: OFF';
            end
        end

        function selectedRowsManual = UpdateManualSelectSelectedSpikesPlot(app, selectedRowsManual)
        
            selectedRowsManual = selectedRowsManual(:);
        
            [~, ~, handles] = convertToGUIDECallbackArguments(app);
        
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
        
            FrNum = app.SelectedClusterFrame;
            CluNum = app.SelectedClusterNum;
        
            isNoiseNumberHover = isnan(CluNum) && isfinite(app.SelectedNoiseNumber);
        
            if isempty(FrameData) || isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end
        
            if ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs) || ...
                    ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                return
            end
        
            if numel(app.ManualSelectX) < 2
                return
            end
        
            if isNoiseNumberHover
        
                if ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber)
                    return
                end
        
                status = FrameData(FrNum).Status(:);
                noiseNumber = FrameData(FrNum).NoiseNumber(:);
                candidateRows = find(status == 0 & noiseNumber == app.SelectedNoiseNumber);
        
            else
        
                candidateRows = find(FrameData(FrNum).Status(:) == CluNum);
        
            end
        
            if isempty(candidateRows)
                return
            end
        
            W = FrameData(FrNum).Wfs(candidateRows,:);
            xDraw = app.ManualSelectX(:)';
            yDraw = app.ManualSelectY(:)';
        
            x1 = xDraw(end-1);
            y1 = yDraw(end-1);
            x2 = xDraw(end);
            y2 = yDraw(end);
        

            nSamp = size(W, 2);

            % Waveform segments are [k,W(k)] -> [k+1,W(k+1)].
            xMin = max(1, floor(min(x1,x2)));
            xMax = min(nSamp - 1, ceil(max(x1,x2)));

            if xMin > xMax
                return
            end

            localHit = false(size(W,1), 1);

            % Small tolerance only for floating-point / mouse-coordinate error.
            intersectTol = 1e-6;

            for k = xMin:xMax
                cx = k;
                dx = k + 1;

                cy = W(:, k);
                dy = W(:, k + 1);

                % Intersection between drawn segment A-B and waveform segment C-D.
                denom = (x1 - x2) .* (cy - dy) - (y1 - y2) .* (cx - dx);

                nonParallel = abs(denom) > intersectTol;

                t = nan(size(cy));
                u = nan(size(cy));

                t(nonParallel) = ((x1 - cx) .* (cy(nonParallel) - dy(nonParallel)) - ...
                    (y1 - cy(nonParallel)) .* (cx - dx)) ./ denom(nonParallel);

                u(nonParallel) = ((x1 - cx) .* (y1 - y2) - ...
                    (y1 - cy(nonParallel)) .* (x1 - x2)) ./ denom(nonParallel);

                crosses = nonParallel & ...
                    t >= -intersectTol & t <= 1 + intersectTol & ...
                    u >= -intersectTol & u <= 1 + intersectTol;

                % Handle rare case where drawn line lies exactly along waveform segment.
                orientC = (x2 - x1) .* (cy - y1) - (y2 - y1) .* (cx - x1);
                orientD = (x2 - x1) .* (dy - y1) - (y2 - y1) .* (dx - x1);

                colinear = abs(denom) <= intersectTol & ...
                    abs(orientC) <= intersectTol & ...
                    abs(orientD) <= intersectTol;

                overlapX = max(min(x1,x2), min(cx,dx)) <= min(max(x1,x2), max(cx,dx)) + intersectTol;
                overlapY = max(min(y1,y2), min(cy,dy)) <= min(max(y1,y2), max(cy,dy)) + intersectTol;

                crosses = crosses | (colinear & overlapX & overlapY);

                localHit = localHit | crosses;
            end


            newRows = candidateRows(localHit);
        
            selectedRowsManual = unique([selectedRowsManual; newRows(:)]);

            app.SelectedSpikeRows = selectedRowsManual;
            fprintf('[Manual Select rows stored] n=%d rows=%s\n', ...
                numel(app.SelectedSpikeRows), mat2str(app.SelectedSpikeRows(:)'));
        
            cla(app.axWaveforms_3);
            hold(app.axWaveforms_3, 'on');
            set(app.axWaveforms_3, 'Color', 'white');
        
            if ~isempty(selectedRowsManual)
        
                Wsel = FrameData(FrNum).Wfs(selectedRowsManual,:);
                x = 1:size(Wsel,2);
        
                if isNoiseNumberHover
                    plotColor = [0.5 0.5 0.5];
                else
                    plotColor = mycolors(app, abs(CluNum));
                end

                % for i = 1:size(Wsel,1)
                %     line(app.axWaveforms_3, x, Wsel(i,:), ...
                %         'Color', plotColor, ...
                %         'LineWidth', 0.75, ...
                %         'HitTest', 'off', ...
                %         'PickableParts', 'none');
                % end

                %new dk shoudl be faster than the above 
                X = repmat([x NaN], size(Wsel,1), 1);
                Y = [Wsel NaN(size(Wsel,1),1)];
                line(app.axWaveforms_3, X.', Y.', ...
                    'Color', plotColor, ...
                    'LineWidth', 0.75, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
        
                mwf = mean(Wsel, 1);
                line(app.axWaveforms_3, x, mwf, ...
                    'Color', 'k', ...
                    'LineWidth', 2, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');


                SetSelectedSpikeLabel(app, sprintf('%d', numel(selectedRowsManual)));
            else
        
                SetSelectedSpikeLabel(app, '0');
        
            end

            app.axWaveforms_3.YLimMode = 'auto';
            app.axWaveforms_3.YTickMode = 'auto';
            app.axWaveforms_3.YTickLabelMode = 'auto';
            app.axWaveforms_3.FontSize = 8;

            hold(app.axWaveforms_3, 'off');
        
        
        end


        function StartManualSelectClusterSpikes(app, clickedAx)
            if ~app.SpikeSelectMode
                return
            end
            cp = clickedAx.CurrentPoint;
            xFirst = cp(1,1);
            yFirst = cp(1,2);
            xLim = clickedAx.XLim;
            yLim = clickedAx.YLim;
            if xFirst < xLim(1) || xFirst > xLim(2) || yFirst < yLim(1) || yFirst > yLim(2)
                return
            end
            app.ManualSelectMode = true;
            app.ManualSelectX = xFirst;
            app.ManualSelectY = yFirst;
            delete(findall(app.figure1, 'Tag', 'ManualSelectTemp'));
            axes(clickedAx);
            hold(clickedAx, 'on');
            templines = gobjects(0);
            loop = 1;
            templines(loop) = plot(clickedAx, app.ManualSelectX, app.ManualSelectY, ...
                'Color', 'k', ...
                'LineWidth', 1.5, ...
                'Tag', 'ManualSelectTemp');
            selectedRowsManual = [];
            app.SelectedSpikeRows = [];
            fprintf('[Manual Select] START point-click drawing on axClusterSpikes\n');
            ButPress = 1;
            while ButPress == 1
                [xClick, yClick, ButPress] = ginput(1);
                app.figure1.Pointer = 'arrow';
                currentAx = gca;
                if ~app.ManualSelectMode
                    disp('[Manual Select cancelled during drawing]')
                    delete(findall(app.figure1, 'Tag', 'ManualSelectTemp'));
                    app.ManualSelectX = [];
                    app.ManualSelectY = [];
                    app.ManualSelectLine = [];
                    RestoreNativePanZoom(app);
                    return
                end
                

                if isempty(ButPress)
                    break
                end

                if ButPress ~= 1
                    break
                end
                if currentAx ~= clickedAx
                    figPt = app.figure1.CurrentPoint;
                    axPos = clickedAx.Position;
                    xLim0 = clickedAx.XLim;
                    yLim0 = clickedAx.YLim;
        
                    xClick = xLim0(1) + ((figPt(1) - axPos(1)) / axPos(3)) * diff(xLim0);
                    yClick = yLim0(1) + ((figPt(2) - axPos(2)) / axPos(4)) * diff(yLim0);
        
                    axes(clickedAx);
                    currentAx = clickedAx;
                end
        
                xLim = xlim(clickedAx);
                yLim = ylim(clickedAx);
        
                if xClick < xLim(1) || xClick > xLim(2) || yClick < yLim(1) || yClick > yLim(2)
                    ExpandAxisToIncludePoint(app, clickedAx, xClick, yClick);
                    axes(clickedAx);
                end
        
                loop = loop + 1;
        
                app.ManualSelectX(end+1) = xClick;
                app.ManualSelectY(end+1) = yClick;
        
                templines(loop) = plot(clickedAx, app.ManualSelectX, app.ManualSelectY, ...
                    'Color', 'k', ...
                    'LineWidth', 1.5, ...
                    'Tag', 'ManualSelectTemp');
                
                selectedRowsManual = UpdateManualSelectSelectedSpikesPlot(app, selectedRowsManual);
                
                drawnow;
        
               
            end
        
            fprintf('[Manual Select] STOP point-click drawing, nPoints=%d\n', numel(app.ManualSelectX));
        

            app.ManualSelectMode = false;
            app.figure1.Pointer = 'arrow';

            delete(templines(isvalid(templines)));
            delete(findall(app.figure1, 'Tag', 'ManualSelectTemp'));

            app.ManualSelectX = [];
            app.ManualSelectY = [];
            app.ManualSelectLine = [];

            RestoreNativePanZoom(app);
        end


        function StopManualSelectClusterSpikes(app)
        
            if ~app.ManualSelectMode
                return
            end
        
            fprintf('[Manual Select] STOP on axClusterSpikes, nPoints=%d\n', numel(app.ManualSelectX));
        
            app.ManualSelectMode = false;
            app.ManualSelectX = [];
            app.ManualSelectY = [];
        
            if ~isempty(app.ManualSelectLine) && isvalid(app.ManualSelectLine)
                delete(app.ManualSelectLine);
            end
            app.ManualSelectLine = [];
        
            delete(findall(app.figure1, 'Tag', 'ManualSelectTemp'));
        
            app.figure1.Pointer = 'arrow';
        
        end

        function SelectSpikeMouseUp(app, src, event)
            disp('DEBUG SelectSpikeMouseUp')
            try
                

                if app.ManualSelectMode
                    if strcmp(app.figure1.SelectionType, 'alt') || strcmp(app.figure1.SelectionType, 'open')
                        StopManualSelectClusterSpikes(app);
                        return
                    end
                end

                if strcmp(app.figure1.SelectionType, 'alt')
                    app.PendingClickAxis = [];
                    app.PendingClickPoint = [NaN NaN];
                    RestoreNativePanZoom(app);
                    return
                end


                if isempty(app.PendingClickAxis)
                    return
                end

                p0 = app.PendingClickPoint;
                p1 = app.figure1.CurrentPoint;

                movePix = sqrt(sum((p1 - p0).^2));

                clickedAx = app.PendingClickAxis;

                app.PendingClickAxis = [];
                app.PendingClickPoint = [NaN NaN];

                fprintf('[MOUSE UP DEBUG] clickedAx = %s\n', class(clickedAx));
                if clickedAx == app.axDim12
                    fprintf('[MOUSE UP DEBUG] axDim12\n');
                elseif clickedAx == app.axDim13
                    fprintf('[MOUSE UP DEBUG] axDim13\n');
                elseif clickedAx == app.axDim23
                    fprintf('[MOUSE UP DEBUG] axDim23\n');
                elseif clickedAx == app.axRemovedasIsoMeanWfs 
                    fprintf('[MOUSE UP DEBUG] axRemovedasIsoMeanWfs\n');
                elseif clickedAx == app.axWaveforms
                    fprintf('[MOUSE UP DEBUG] axWaveforms\n');
                elseif clickedAx == app.axFrWaveform
                    fprintf('[MOUSE UP DEBUG] axFrWaveform\n');
                elseif clickedAx == app.axClusterSpikes
                    fprintf('[MOUSE UP DEBUG] axClusterSpikes\n');
                elseif clickedAx == app.axWaveforms_2
                    fprintf('[MOUSE UP DEBUG] axWaveforms_2\n');
                else
                    fprintf('[MOUSE UP DEBUG] unknown axis\n');
                end
                if movePix <= 5

                    if clickedAx == app.axClusterSpikes

                        if strcmp(app.figure1.SelectionType, 'open')
                            GeneralSelectClick(app, clickedAx, event);
                            return
                        end

                        if strcmp(app.figure1.SelectionType, 'normal')
                            StartManualSelectClusterSpikes(app, clickedAx);
                            return
                        end

                    end

                    GeneralSelectClick(app, clickedAx, event);
                end

                % IMPORTANT: do NOT restore native pan/zoom here.
                % Stay in spike-select mode until two-finger/right click.

            catch ME
                RestoreNativePanZoom(app);
                rethrow(ME)
            end
        end

        function FigureMouseUpSelectOnly(app, src, event)
            disp('DEBUG FigureMouseUpSelectOnly')
            clickedObj = hittest(app.figure1);
            clickedAx = ancestor(clickedObj, 'matlab.ui.control.UIAxes');

            if isempty(clickedAx)
                return
            end

            if ~(clickedAx == app.axDim12 || clickedAx == app.axDim13 || clickedAx == app.axDim23 || clickedAx == app.axWaveforms_2)
                return
            end

            SpikePlotClick(app, clickedAx, event);
        end


        function FigureMouseDown(app, src, event)
            disp('DEBUG FigureMouseDown')
            clickedObj = hittest(app.figure1);
            clickedAx = ancestor(clickedObj, 'matlab.ui.control.UIAxes');
            
            if isempty(clickedAx)
                figPt = app.figure1.CurrentPoint;

                axList = [app.axDim12 app.axDim13 app.axDim23 app.axWaveforms_2 app.axWaveforms app.axFrWaveform app.axClusterSpikes app.axRemovedasIsoMeanWfs app.pcRaster];

                for k = 1:numel(axList)
                    ax = axList(k);
                    p = ax.Position;

                    if figPt(1) >= p(1) && figPt(1) <= p(1)+p(3) && ...
                            figPt(2) >= p(2) && figPt(2) <= p(2)+p(4)
                        clickedAx = ax;
                        break
                    end
                end
            end

            if isempty(clickedAx)
                app.PendingClickAxis = [];
                app.PendingClickPoint = [NaN NaN];
                return
            end

            if ~(clickedAx == app.axDim12 || clickedAx == app.axDim13 || clickedAx == app.axDim23 || ...
                clickedAx == app.axWaveforms_2 || clickedAx == app.axWaveforms || ...
                clickedAx == app.axFrWaveform || clickedAx == app.axClusterSpikes ... 
                || clickedAx == app.axRemovedasIsoMeanWfs || clickedAx == app.pcRaster)
        
                app.PendingClickAxis = [];
                app.PendingClickPoint = [NaN NaN];
                return
            end

            if clickedAx == app.pcRaster
                AppRasterMouseDown(app, clickedAx, event);
                return
            end

            app.PendingClickAxis = clickedAx;
            app.PendingClickPoint = app.figure1.CurrentPoint;
        end

        function FigureMouseUp(app, src, event)
            disp('DEBUG FigureMouseUp')
            if isempty(app.PendingClickAxis)
                return
            end
            p0 = app.PendingClickPoint;
            p1 = app.figure1.CurrentPoint;
            movePix = sqrt(sum((p1 - p0).^2));
            clickedAx = app.PendingClickAxis;
            app.PendingClickAxis = [];
            app.PendingClickPoint = [NaN NaN];
            if movePix > 5
                return
            end

            SpikePlotClick(app, clickedAx, event);
        end 

        function RefreshDropdownBeforeOpen(app, whichDropdown)

            switch whichDropdown

                case 'subjects'

                    oldSubject = app.popSubject.Value;

                    FillFileSelectDropdowns(app, 'subjects');

                    if any(strcmp(app.popSubject.Items, oldSubject))
                        app.popSubject.Value = oldSubject;
                    end

                    if ~strcmp(app.popSubject.Value, '-- select subject --')
                        FillFileSelectDropdowns(app, 'sessions');
                    end

                case 'sessions'

                    oldSession = app.popSession.Value;

                    FillFileSelectDropdowns(app, 'sessions');

                    if any(strcmp(app.popSession.Items, oldSession))
                        app.popSession.Value = oldSession;
                    end

                    if ~strcmp(app.popSession.Value, '-- select session --')
                        FillFileSelectDropdowns(app, 'spikefiles');
                        FillFileSelectDropdowns(app, 'SrtdLoads');
                    end

                case 'files'

                    oldFile = app.popSrtdLoad.Value;

                    FillFileSelectDropdowns(app, 'spikefiles');
                    FillFileSelectDropdowns(app, 'SrtdLoads');

                    if any(strcmp(app.popSrtdLoad.Items, oldFile))
                        app.popSrtdLoad.Value = oldFile;
                    end

                otherwise

                    error('Unknown dropdown refresh target: %s', whichDropdown);

            end

        end

        function SpikePlotClick(app, src, event)
            disp('DEBUG SpikePlotClick')
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            clickedAx = src;
            fprintf('\n[SpikePlotClick DEBUG]\n');
            fprintf('clickedAx == app.axDim12: %d\n', clickedAx == app.axDim12);
            fprintf('clickedAx == app.axDim13: %d\n', clickedAx == app.axDim13);
            fprintf('clickedAx == app.axDim23: %d\n', clickedAx == app.axDim23);

            fprintf('clickedAx == handles.axDim12: %d\n', clickedAx == handles.axDim12);
            fprintf('clickedAx == handles.axDim13: %d\n', clickedAx == handles.axDim13);
            fprintf('clickedAx == handles.axDim23: %d\n', clickedAx == handles.axDim23);

            fprintf('clickedAx == app.axWaveforms_2: %d\n', clickedAx == app.axWaveforms_2);
            rowIdx = NaN;

            cp = clickedAx.CurrentPoint;
            xClick = cp(1,1);
            yClick = cp(1,2);

            Dim1 = get(handles.popDim1,'Value');
            Dim2 = get(handles.popDim2,'Value');
            Dim3 = get(handles.popDim3,'Value');

            if clickedAx == handles.axDim12 || clickedAx == handles.axDim13 || clickedAx == handles.axDim23

                candidateRows = find(FrameData(FrNum).Status(:) > 0);

                if isempty(candidateRows)
                    fprintf('[Select Spike] no visible spikes\n');
                    return
                end

                if clickedAx == handles.axDim12
                    d1 = Dim1;
                    d2 = Dim2;
                elseif clickedAx == handles.axDim13
                    d1 = Dim1;
                    d2 = Dim3;
                else
                    d1 = Dim2;
                    d2 = Dim3;
                end

                Uxy = FrameData(FrNum).U(candidateRows,[d1 d2]);
                dist2 = (Uxy(:,1) - xClick).^2 + (Uxy(:,2) - yClick).^2;
                sourceType = 'visible';

            elseif clickedAx == app.axWaveforms_2
                return
            else
                fprintf('[Select Spike] click not on selectable spike axis\n');
                return
            end


            fprintf('[SpikePlotClick DEBUG] candidateRows numel = %d\n', numel(candidateRows));
            fprintf('[SpikePlotClick DEBUG] dist2 numel = %d\n', numel(dist2));
            fprintf('[SpikePlotClick DEBUG] any finite dist2 = %d\n', any(isfinite(dist2)));

            if isempty(candidateRows) || isempty(dist2) || ~any(isfinite(dist2))
                fprintf('[Select Spike skipped] No valid distances.\n');
                return
            end

            [~, nearestLocal] = min(dist2);
            rowIdx = candidateRows(nearestLocal);

            fprintf('[SpikePlotClick DEBUG] nearestLocal = %d\n', nearestLocal);
            fprintf('[SpikePlotClick DEBUG] rowIdx after min = %d\n', rowIdx);

            if isempty(rowIdx) || isnan(rowIdx) || rowIdx < 1 || rowIdx > numel(FrameData(FrNum).Status)
                fprintf('[Select Spike skipped] No valid spike selected.\n');
                return
            end

            app.SelectedSpikeFrame = FrNum;
            app.SelectedSpikeRows = rowIdx;

            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);

            fprintf('[Select Spike] selected %s row %d old status %d\n', ...
                sourceType, rowIdx, FrameData(FrNum).Status(rowIdx));

            PlotSelectedSpike(app, FrameData);

        end

        function PlotSelectedSpike(app, FrameData, FrNum, rowIdx, sourceType)
            %disp('DEBUG PlotSelectedSpike')
            cla(app.axWaveforms_3);
            hold(app.axWaveforms_3, 'on'); %new 
            set(app.axWaveforms_3, 'Color', 'white');

            FrNum = app.SelectedSpikeFrame;
            rowIdx = app.SelectedSpikeRows;

            if isnan(FrNum) || isnan(rowIdx) || isempty(FrameData) || ...
                    FrNum < 1 || FrNum > numel(FrameData) || ...
                    rowIdx < 1 || rowIdx > size(FrameData(FrNum).Wfs,1)

                title(app.axWaveforms_3, '');
                SetSelectedSpikeLabel(app, '');
                return
            end

            wf = FrameData(FrNum).Wfs(rowIdx,:);
            statusAll = FrameData(FrNum).Status(:);
            status = statusAll(rowIdx);
            plotRows = rowIdx;


            % status = FrameData(FrNum).Status(rowIdx);
            

            clu = abs(status);

            
            if status == 0 || clu < 1 || clu > 14
                spikeColor = [0.5 0.5 0.5];   % noise/invalid = gray
            else
                spikeColor = mycolors(app, clu);   % positive or negative unit = same cluster color
            end

            if status > 0 && ...
                    isfield(FrameData(FrNum),'RefViolationID') && ...
                    isfield(FrameData(FrNum),'RefViolationReviewCluster') && ...
                    numel(FrameData(FrNum).RefViolationID) == numel(statusAll) && ...
                    numel(FrameData(FrNum).RefViolationReviewCluster) == numel(statusAll)

                refIDs = FrameData(FrNum).RefViolationID(:);
                refClusters = FrameData(FrNum).RefViolationReviewCluster(:);

                gid = refIDs(rowIdx);
                reviewClus = refClusters(rowIdx);

                if isfinite(gid) && gid > 0 && ...
                        isfinite(reviewClus) && status == reviewClus

                    plotRows = find(refIDs == gid & ...
                        refClusters == reviewClus & ...
                        statusAll == status);

                    if isempty(plotRows)
                        plotRows = rowIdx;
                    end
                end
            end

            otherColor = spikeColor + 0.65 * ([1 1 1] - spikeColor);
            otherRows = setdiff(plotRows(:), rowIdx, 'stable');

            % for iOther = 1:numel(otherRows)
            %     plot(app.axWaveforms_3, FrameData(FrNum).Wfs(otherRows(iOther),:), ...
            %         'Color', otherColor, ...
            %         'LineWidth', 0.75);
            % end

            %new dk should be faster than the above 
            if ~isempty(otherRows)
                Wother = FrameData(FrNum).Wfs(otherRows,:);
                x = 1:size(Wother,2);

                X = repmat([x NaN], size(Wother,1), 1);
                Y = [Wother NaN(size(Wother,1),1)];

                line(app.axWaveforms_3, X.', Y.', ...
                    'Color', otherColor, ...
                    'LineWidth', 0.75, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
            end

            plot(app.axWaveforms_3, wf, ...
                'Color', spikeColor, ...
                'LineWidth', 1.5);

            set(app.axWaveforms_3, 'Color', 'white');
            title(app.axWaveforms_3, '');


            noiseScoreText = '';

            if isfield(FrameData(FrNum), 'ModelNoiseScore') && ...
                    numel(FrameData(FrNum).ModelNoiseScore) >= rowIdx

                noiseScore = FrameData(FrNum).ModelNoiseScore(rowIdx);

                if isfinite(noiseScore)
                    noiseScoreText = sprintf(' | noise %.4f', noiseScore);
                end
            end

            if numel(plotRows) > 1
                SetSelectedSpikeLabel(app, sprintf( ...
                    'F%dC%d idx: %d group: %d%s', ...
                    FrNum, status, rowIdx, numel(plotRows), noiseScoreText));
            else
                app.SelectedSpikeIDLabel.Text = sprintf( ...
                    'F%dC%d idx: %d%s', ...
                    FrNum, status, rowIdx, noiseScoreText);

            end


            set(app.axWaveforms_3, 'Color', 'white');
            set(app.axWaveforms_3, 'XLim', [0 size(FrameData(FrNum).Wfs,2)]);
            hold(app.axWaveforms_3, 'off');
            drawnow;
        end

        function FrameData = RecomputeOneFrameClusterMeta(app, handles, FrameData, FrNum)
            disp('DEBUG RecomputeOneFrameClusterMeta')
            for c = 1:14
                rows = FrameData(FrNum).Status(:) == c;

                if any(rows)
                    FrameData(FrNum).mWf(c,:) = mean(FrameData(FrNum).Wfs(rows,:), 1);
                    FrameData(FrNum).Iso(c) = 1;
                else
                    FrameData(FrNum).mWf(c,:) = NaN(1, size(FrameData(FrNum).Wfs,2));
                    FrameData(FrNum).Iso(c) = 0;
                end

                FrameData(FrNum).ReassignMap(c) = c;
                app.(['pop' num2str(c)]).Value = c;
            end
        end


        function figureKeyPress_Callback(app, event)

            isUndoKey = strcmpi(event.Key, 'z') && ...
                (any(strcmpi(event.Modifier, 'command')) || any(strcmpi(event.Modifier, 'control')));

            if isUndoKey
                pbUndoButtonPushed(app, event);
            end
        end


        function PushUndo(app, handles, actionLabel)

            FrameData = app.FrameDataStore;

            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            %new dont save everything from every frame for undo unless
            %needed 
            snap = struct();
            snap.FrNum = FrNum;
            snap.ActionLabel = actionLabel;

            globalUndoActions = {'Change Frame Size', 'Recover Frame'};

            if any(strcmpi(actionLabel, globalUndoActions))
                snap.Scope = 'all';
                snap.FrameData = FrameData;
            else
                snap.Scope = 'frame';
                snap.Frame = FrameData(FrNum);
            end
            %new 

            % Use last committed dropdown labels, not current changed values.
            snap.PopDim1Label = get(handles.popDim1,'UserData');
            snap.PopDim2Label = get(handles.popDim2,'UserData');
            snap.PopDim3Label = get(handles.popDim3,'UserData');

            if isempty(snap.PopDim1Label)
                snap.PopDim1Label = GetCurrentDropdownLabel(app, handles.popDim1);
            end

            if isempty(snap.PopDim2Label)
                snap.PopDim2Label = GetCurrentDropdownLabel(app, handles.popDim2);
            end

            if isempty(snap.PopDim3Label)
                snap.PopDim3Label = GetCurrentDropdownLabel(app, handles.popDim3);
            end

            app.UndoStack{end+1} = snap;

            maxUndo = 3;
            if numel(app.UndoStack) > maxUndo
                app.UndoStack = app.UndoStack(end-maxUndo+1:end);
            end

            fprintf('[UNDO SAVED] before %s on Fr %d | dims: %s / %s / %s\n', ...
                actionLabel, FrNum, snap.PopDim1Label, snap.PopDim2Label, snap.PopDim3Label);

        end


        function popDim_Callback(app, event)

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            if app.SuppressDimDropdownCallback
                fprintf('[popDim skipped] SuppressDimDropdownCallback is true\n');
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData) || isempty(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            % This now saves the PREVIOUS committed labels from dropdown UserData.
            PushUndo(app, handles, 'Dim Dropdown Change');

            labels1 = get(handles.popDim1,'String');
            labels2 = get(handles.popDim2,'String');
            labels3 = get(handles.popDim3,'String');

            if ischar(labels1), labels1 = cellstr(labels1); end
            if ischar(labels2), labels2 = cellstr(labels2); end
            if ischar(labels3), labels3 = cellstr(labels3); end

            val1 = round(get(handles.popDim1,'Value'));
            val2 = round(get(handles.popDim2,'Value'));
            val3 = round(get(handles.popDim3,'Value'));


            dim1Label = labels1{min(max(val1,1),numel(labels1))};
            dim2Label = labels2{min(max(val2,1),numel(labels2))};
            dim3Label = labels3{min(max(val3,1),numel(labels3))};

            % rPC legality rule:
            % Dim1: never rPC.
            % Dim2: rPC allowed only if Dim1 is custom.
            % Dim3: rPC allowed only if Dim1 or Dim2 is custom.
            dim1IsCustom = ~startsWith(dim1Label, 'PC') && ~startsWith(dim1Label, 'rPC');
            dim2IsCustom = ~startsWith(dim2Label, 'PC') && ~startsWith(dim2Label, 'rPC');

            dim2IsRpc = startsWith(dim2Label, 'rPC');
            dim3IsRpc = startsWith(dim3Label, 'rPC');

            changedIllegalRpc = false;

            if dim2IsRpc && ~dim1IsCustom
                dim2Label = 'PC2';
                changedIllegalRpc = true;
            end

            if dim3IsRpc && ~(dim1IsCustom || dim2IsCustom)
                dim3Label = 'PC3';
                changedIllegalRpc = true;
            end

            if changedIllegalRpc
                oldSuppress = app.SuppressDimDropdownCallback;
                app.SuppressDimDropdownCallback = true;
                cleanupSuppress = onCleanup(@() RestoreDimDropdownSuppress(app, oldSuppress));

                SetDropdownValueByLabel(app, handles.popDim2, dim2Label, 2);
                SetDropdownValueByLabel(app, handles.popDim3, dim3Label, 3);

                clear cleanupSuppress

                fprintf('[popDim rPC legality reset] forcing dims to: %s / %s / %s\n', ...
                    dim1Label, dim2Label, dim3Label);
            end

            fprintf('[popDim] Fr %d requested dims: %s / %s / %s\n', ...
                FrNum, dim1Label, dim2Label, dim3Label);


            [FrameData, ~] = computeAndInsertRPCs(app, handles, FrameData, FrNum);

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            RefreshDimDropdownLabels(app, handles, FrameData, FrNum);

            oldSuppress = app.SuppressDimDropdownCallback;
            app.SuppressDimDropdownCallback = true;
            cleanupSuppress = onCleanup(@() RestoreDimDropdownSuppress(app, oldSuppress));

            SetDropdownValueByLabel(app, handles.popDim1, dim1Label, 1);
            SetDropdownValueByLabel(app, handles.popDim2, dim2Label, 2);
            SetDropdownValueByLabel(app, handles.popDim3, dim3Label, 3);

            clear cleanupSuppress

            fprintf('[popDim redraw] values now: %d / %d / %d\n', ...
                get(handles.popDim1,'Value'), ...
                get(handles.popDim2,'Value'), ...
                get(handles.popDim3,'Value'));

            RedrawSortMovie(app, handles, FrNum, true);

            % Commit the new dropdown state AFTER successful redraw.
            StoreDimDropdownUndoState(app, handles);

        end

        function AddPCVarianceText(app, ax, FrameData, FrNum, dimNum)
            %calls: 
            delete(findall(ax, 'Tag', 'PCVarText'));

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if dimNum < 1 || dimNum > 6
                return
            end

            if ~isfield(FrameData(FrNum), 'DimLabels') || numel(FrameData(FrNum).DimLabels) < dimNum
                return
            end

            if ~strcmp(FrameData(FrNum).DimLabels{dimNum}, sprintf('PC%d', dimNum))
                return
            end

            if ~isfield(FrameData(FrNum), 'EigFrac') || numel(FrameData(FrNum).EigFrac) < dimNum
                return
            end

            pctText = regexprep(sprintf('%.2f', FrameData(FrNum).EigFrac(dimNum)), '^0', '');


            text(ax, 0.06, 0.92, pctText, ...
                'Units', 'normalized', ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 10, ...
                'FontWeight', 'normal', ...
                'Color', 'k', ...
                'Tag', 'PCVarText');

        end

        function ExpandAxisToIncludePoint(app, ax, x, y)
            disp('ExpandAxisToIncludePoint')
            if isempty(ax) || ~isvalid(ax) || ~isfinite(x) || ~isfinite(y)
                return
            end

            xl = ax.XLim;
            yl = ax.YLim;

            xmin = min([xl(1), x]);
            xmax = max([xl(2), x]);
            ymin = min([yl(1), y]);
            ymax = max([yl(2), y]);

            xr = xmax - xmin;
            yr = ymax - ymin;

            if xr == 0
                xr = max(abs(xmax), 1);
            end

            if yr == 0
                yr = max(abs(ymax), 1);
            end

            xpad = 0.05 * xr;
            ypad = 0.05 * yr;

            xl = [xmin - xpad, xmax + xpad];
            yl = [ymin - ypad, ymax + ypad];

            ax.XLim = xl;
            ax.YLim = yl;
            ax.XLimMode = 'manual';
            ax.YLimMode = 'manual';

            if xl(1) <= 0 && xl(2) >= 0
                ax.XTick = 0;
                ax.XTickLabel = {'0'};
            else
                ax.XTick = [];
                ax.XTickLabel = {};
            end

            if yl(1) <= 0 && yl(2) >= 0
                ax.YTick = 0;
                ax.YTickLabel = {'0'};
            else
                ax.YTick = [];
                ax.YTickLabel = {};
            end

            drawnow limitrate
        end


        function SetScatterAxisPaddedLimits(app, handles, ax, FrameData, FrNum, slotX, dX, slotY, dY)

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                return
            end

            [xVec, ~, ~] = GetDisplayDim(app, handles, FrameData, FrNum, slotX, dX);
            [yVec, ~, ~] = GetDisplayDim(app, handles, FrameData, FrNum, slotY, dY);

            if isempty(xVec) || isempty(yVec)
                return
            end

            status = FrameData(FrNum).Status(:);
            keep = status > 0;

            x = xVec(keep);
            y = yVec(keep);

            x = x(isfinite(x));
            y = y(isfinite(y));

            if isempty(x) || isempty(y)
                return
            end

            xmin = min(x);
            xmax = max(x);
            ymin = min(y);
            ymax = max(y);

            xr = xmax - xmin;
            yr = ymax - ymin;

            if xr == 0
                xr = max(abs(xmax), 1);
            end

            if yr == 0
                yr = max(abs(ymax), 1);
            end

            xpad = 0.05 * xr;
            ypad = 0.05 * yr;

            xl = [xmin - xpad, xmax + xpad];
            yl = [ymin - ypad, ymax + ypad];

            ax.XLim = xl;
            ax.YLim = yl;
            ax.XLimMode = 'manual';
            ax.YLimMode = 'manual';

            if xl(1) <= 0 && xl(2) >= 0
                ax.XTick = 0;
                ax.XTickLabel = {'0'};
            else
                ax.XTick = [];
                ax.XTickLabel = {};
            end

            if yl(1) <= 0 && yl(2) >= 0
                ax.YTick = 0;
                ax.YTickLabel = {'0'};
            else
                ax.YTick = [];
                ax.YTickLabel = {};
            end

            ax.XTickMode = 'manual';
            ax.YTickMode = 'manual';
            ax.XTickLabelMode = 'manual';
            ax.YTickLabelMode = 'manual';
            ax.FontSize = 6;
            ax.TickLength = [0.005 0.005];

        end

        function [FrameData, rPcIdxs] = computeAndInsertRPCs(app, handles, FrameData, FrNum)
            %called when add new custom dimension or change dropdown s 

            % gaurd 
            if isempty(FrameData) || isempty(FrNum) || FrNum < 1 || FrNum > numel(FrameData) || ...
                    ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                rPcIdxs = [];
                return
            end

            % Remove stale rPC columns from Uplot / PCplot / DimDisplayWf / DimLabels
            rPcIdxs = [];
            if ~isfield(FrameData(FrNum),'DimLabels') || isempty(FrameData(FrNum).DimLabels)
                nDims = size(FrameData(FrNum).Uplot, 2);
                FrameData(FrNum).DimLabels = arrayfun(@(k)sprintf('PC%d', k), 1:nDims, 'UniformOutput', false);
            end
            nDims = size(FrameData(FrNum).Uplot, 2);
            if numel(FrameData(FrNum).DimLabels) < nDims
                for k = numel(FrameData(FrNum).DimLabels)+1:nDims
                    FrameData(FrNum).DimLabels{k} = sprintf('Dim%d', k);
                end
            elseif numel(FrameData(FrNum).DimLabels) > nDims
                FrameData(FrNum).DimLabels = FrameData(FrNum).DimLabels(1:nDims);
            end

            labels = FrameData(FrNum).DimLabels;
            isRpc = startsWith(labels, 'rPC');

            Dim1 = round(get(handles.popDim1,'Value'));
            Dim2 = round(get(handles.popDim2,'Value'));
            Dim3 = round(get(handles.popDim3,'Value'));

            % Save selected dropdown LABELS before deleting stale rPC columns.
            labels1_pre = get(handles.popDim1,'String');
            labels2_pre = get(handles.popDim2,'String');
            labels3_pre = get(handles.popDim3,'String');

            if ischar(labels1_pre), labels1_pre = cellstr(labels1_pre); end
            if ischar(labels2_pre), labels2_pre = cellstr(labels2_pre); end
            if ischar(labels3_pre), labels3_pre = cellstr(labels3_pre); end

            preLab1 = labels1_pre{min(max(Dim1,1),numel(labels1_pre))};
            preLab2 = labels2_pre{min(max(Dim2,1),numel(labels2_pre))};
            preLab3 = labels3_pre{min(max(Dim3,1),numel(labels3_pre))};

            %DELETE OLD RPCS 
            if any(isRpc)
                keepCols = ~isRpc; %KEEP THE COLUMS ATHAT ARE NOT RPCS 
                FrameData(FrNum).Uplot = FrameData(FrNum).Uplot(:, keepCols);
                FrameData(FrNum).DimLabels = labels(keepCols);
                if isfield(FrameData(FrNum),'PCplot') && ...
                        ~isempty(FrameData(FrNum).PCplot) && ...
                        size(FrameData(FrNum).PCplot, 2) == numel(labels)
                    FrameData(FrNum).PCplot = FrameData(FrNum).PCplot(:, keepCols);
                end
                if isfield(FrameData(FrNum),'DimDisplayWf') && ...
                        ~isempty(FrameData(FrNum).DimDisplayWf) && ...
                        numel(FrameData(FrNum).DimDisplayWf) == numel(labels)
                    FrameData(FrNum).DimDisplayWf = FrameData(FrNum).DimDisplayWf(keepCols);
                end
            end
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
            %DELETE OLD RPCS 


            nBaseDims = size(FrameData(FrNum).Uplot, 2);

            slotDims = [Dim1 Dim2 Dim3];
            slotLabs = {preLab1 preLab2 preLab3};

            isRpcSlot = startsWith(slotLabs, 'rPC');

            isCustomSlot = false(1,3);
            for s = 1:3
                isCustomSlot(s) = slotDims(s) > 6 && ...
                    slotDims(s) <= nBaseDims && ...
                    ~startsWith(slotLabs{s}, 'rPC');
            end

            % rPCs are valid only if at least one custom waveform is to the LEFT of an rPC.
            hasCustomLeftOfRpc = false;

            for s = find(isRpcSlot)
                if any(isCustomSlot(1:s-1))
                    hasCustomLeftOfRpc = true;
                end
            end

            % If no rPC is currently selected, still compute rPCs if a custom dim exists,
            % so rPC options can appear after setting a custom dim.
            if ~any(isRpcSlot)

                if isCustomSlot(2)
                    subtrDimIdxs = slotDims(1:2);
                    keepBasis = ~startsWith(slotLabs(1:2), 'rPC') & subtrDimIdxs <= nBaseDims;
                    subtrDimIdxs = subtrDimIdxs(keepBasis);

                elseif isCustomSlot(1)
                    subtrDimIdxs = slotDims(1);

                else
                    rPcIdxs = [];
                    set(handles.frUnits,'UserData',FrameData);
                    app.FrameDataStore = FrameData;
                    return
                end

            else

                if ~hasCustomLeftOfRpc
                    rPcIdxs = [];
                    set(handles.frUnits,'UserData',FrameData);
                    app.FrameDataStore = FrameData;
                    return
                end

                % Build subtraction basis from EVERYTHING to the left of each selected rPC.
                subtrDimIdxs = [];

                for s = find(isRpcSlot)
                    for ls = 1:(s-1)
                        if ~startsWith(slotLabs{ls}, 'rPC') && slotDims(ls) <= nBaseDims
                            subtrDimIdxs(end+1) = slotDims(ls); %#ok<AGROW>
                        end
                    end
                end

                subtrDimIdxs = unique(subtrDimIdxs, 'stable');

            end

            [rUforUplot, rPCsforPCplot] = ComputeResidualPCsFromDims(app, FrameData, FrNum, subtrDimIdxs);


            if isempty(rUforUplot) || isempty(rPCsforPCplot)
                rPcIdxs = [];
                set(handles.frUnits,'UserData',FrameData);
                app.FrameDataStore = FrameData;
                return
            end

            FrameData(FrNum).Uplot = [FrameData(FrNum).Uplot rUforUplot(:,1:3)];

            if isfield(FrameData(FrNum),'PCplot') && ~isempty(FrameData(FrNum).PCplot)
                FrameData(FrNum).PCplot = [FrameData(FrNum).PCplot rPCsforPCplot(:,1:3)];
            else
                FrameData(FrNum).PCplot = rPCsforPCplot(:,1:3);
            end

            if ~isfield(FrameData(FrNum),'DimDisplayWf') || isempty(FrameData(FrNum).DimDisplayWf)
                FrameData(FrNum).DimDisplayWf = cell(1,nBaseDims);
            end

            FrameData(FrNum).DimDisplayWf{nBaseDims+1} = rPCsforPCplot(:,1);
            FrameData(FrNum).DimDisplayWf{nBaseDims+2} = rPCsforPCplot(:,2);
            FrameData(FrNum).DimDisplayWf{nBaseDims+3} = rPCsforPCplot(:,3);

            FrameData(FrNum).DimLabels = [FrameData(FrNum).DimLabels {'rPC1','rPC2','rPC3'}];

            rPcIdxs = nBaseDims + (1:3);

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
            return
        end 

        function RGBout = mycolors(app, colornumber_all,cmapname,ncmapcolors)
            % Outputs the RGB values in a 1x3 row vector associated with the
            % custom set color designed by Matt given an input number. These
            % numbers are based on but are not identical to the colors
            % that matlab uses when adding multiple new plots to an axis at once,
            % and are different than the 'rgbmcyk' colors set as plotting
            % options for color in matlab.
            %
            % See matlabcolordefs for the RGB values associated with 'rgbmcyk'
            % strings in plots.

            % MJN: 140212 Old code for printflag- for colors taht semed good on The Andersen lab printer
            % commenting this code- and that option, but levaing it as a reference
            %if nargin<2 printflag=0; end
            %if printflag
            %    printconvert=[1 5 6 12 15 2 3 4 7 8 9 10 11 13 14];
            %    colornumber=printconvert(colornumber);
            %end

            %disp('Using mycolors.m, but myQualColorScheme.m is recommended')

            if nargin<3 || isempty(cmapname)   % A typical entry for cmapname would be 'jet' for example

                numcolors=14;
                nc=length(colornumber_all);
                RGBout=zeros(nc,3);
                for ic=1:nc
                    colornumber=colornumber_all(ic);
                    if mod(colornumber-1,numcolors)+1==1
                        RGBout(ic,:) = [0 0 0]; %Black
                    elseif mod(colornumber-1,numcolors)+1==2
                        %RGBout(ic,:) = [0 0 1]; %Blue
                        RGBout(ic,:) = [.23,.299, .754]; %Blue
                    elseif mod(colornumber-1,numcolors)+1==3
                        RGBout(ic,:) = [0 .5 0]; %Medium Green, much sexier than the bright green for plot option 'g' which is RGB value [0 1 0]
                    elseif mod(colornumber-1,numcolors)+1==4
                        %RGBout(ic,:) = [1 0 0]; %Red
                        RGBout(ic,:) = [.706, .016,.150]; %Red
                    elseif mod(colornumber-1,numcolors)+1==5
                        RGBout(ic,:) = [0 .75 .75]; %Teal, a hell of lot better than cyan
                    elseif mod(colornumber-1,numcolors)+1==6
                        RGBout(ic,:) = [.75 0 .75]; %Purple
                    elseif mod(colornumber-1,numcolors)+1==7
                        RGBout(ic,:) = [.75 .75 0]; %Marigold
                    elseif mod(colornumber-1,numcolors)+1==8
                        RGBout(ic,:) = [.5 .5 .5]; %Grey
                    elseif mod(colornumber-1,numcolors)+1==9
                        RGBout(ic,:) = [1 0 1]; %magenta
                    elseif mod(colornumber-1,numcolors)+1==10
                        RGBout(ic,:) = [1 .6 .6]; %pink
                    elseif mod(colornumber-1,numcolors)+1==11
                        RGBout(ic,:) = [.75 0 0]; %dark red
                    elseif mod(colornumber-1,numcolors)+1==12
                        RGBout(ic,:) = [1 .5 0]; %orange
                    elseif mod(colornumber-1,numcolors)+1==13
                        RGBout(ic,:) = [0 1 0]; %bright green as a last resort
                    elseif mod(colornumber-1,numcolors)+1==14
                        RGBout(ic,:) = [0 1 1]; %cyan as a last resort
                    elseif mod(colornumber-1,numcolors)+1==15
                        RGBout(ic,:) = [1 1 0]; %yellow
                    end
                end

            else
                if nargin<4 || isempty(ncmapcolors);    ncmapcolors=6;      end
                %%% a fix so that matlab pushes the extreme colors to the edge of the colormap, which I think is better...
                % if colornumber==1 || colornumber==ncmapcolors
                %     nmapcolors=1024;
                % end

                eval( ['cmap=colormap(' cmapname '(' num2str(ncmapcolors) '));'] )
                RGBout=cmap(colornumber,:);
            end

        end

        function [rUforUplot, rPCsforPCplot] = ComputeResidualPCsFromDims(app, FrameData, FrNum, basisDims)

            rUforUplot = [];
            rPCsforPCplot = [];

            if isempty(FrameData) || isempty(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                return
            end

            if ~isfield(FrameData(FrNum),'DimDisplayWf') || isempty(FrameData(FrNum).DimDisplayWf)
                return
            end

            W = double(FrameData(FrNum).Wfs);
            [nSpikes, wfLen] = size(W);

            activeRows = true(nSpikes,1);

            if isfield(FrameData(FrNum),'Status') && ~isempty(FrameData(FrNum).Status)
                activeRows = FrameData(FrNum).Status(:) > 0;
            end

            if sum(activeRows) < 2
                return
            end

            basisDims = basisDims(:)';

            if isempty(basisDims)
                return
            end

            B = [];


            for d = basisDims

                basisWf = [];

                % Custom waveform dim
                if isfield(FrameData(FrNum),'DimDisplayWf') && ...
                        numel(FrameData(FrNum).DimDisplayWf) >= d && ...
                        ~isempty(FrameData(FrNum).DimDisplayWf{d})

                    basisWf = FrameData(FrNum).DimDisplayWf{d};

                    % Regular PC dim
                elseif isfield(FrameData(FrNum),'PCplot') && ...
                        ~isempty(FrameData(FrNum).PCplot) && ...
                        size(FrameData(FrNum).PCplot,2) >= d

                    basisWf = FrameData(FrNum).PCplot(:,d);
                end

                if isempty(basisWf)
                    continue
                end

                basisWf = double(basisWf(:));

                if numel(basisWf) ~= wfLen
                    continue
                end

                basisWf = basisWf - mean(basisWf, 'omitnan');

                basisNorm = norm(basisWf);

                if basisNorm == 0 || isnan(basisNorm) || isinf(basisNorm)
                    continue
                end

                basisWf = basisWf ./ basisNorm;

                B(:,end+1) = basisWf; %#ok<AGROW>
            end


            if isempty(B)
                return
            end

            % Orthonormalize the selected custom waveform basis.
            [Q,~] = qr(B,0);

            % Remove the part of every spike explained by the custom waveform basis.
            Wres = W - (W * Q) * Q';


            % Compute residual PC basis from active/included spikes only
            %[Uactive, PCres, ~] = spikepcsNew(Wres(activeRows,:), []);

            [Uactive, PCres, ~] = spikepcsNew(app, Wres(activeRows,:), []);

            rUforUplot = NaN(nSpikes,3);
            rPCsforPCplot = NaN(wfLen,3);

            nKeep = min(3, size(PCres,2));

            % Store active scores from spikepcsNew
            rUforUplot(activeRows,1:nKeep) = Uactive(:,1:nKeep);

            % Project excluded/noise/isolated spikes into the same residual PC space
            inactiveRows = ~activeRows;
            if any(inactiveRows)
                rUforUplot(inactiveRows,1:nKeep) = Wres(inactiveRows,:) * PCres(:,1:nKeep);
            end

            rPCsforPCplot(:,1:nKeep) = PCres(:,1:nKeep);
        end


        function RefreshDimDropdownLabels(app, handles, FrameData, FrNum)

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot)
                return
            end


            nDims = size(FrameData(FrNum).Uplot, 2);

            % Remove stale rPC labels/columns before rebuilding dropdowns.
            if isfield(FrameData(FrNum),'DimLabels') && ~isempty(FrameData(FrNum).DimLabels)
                labels0 = FrameData(FrNum).DimLabels;
                isRpc = startsWith(labels0, 'rPC');

                if any(isRpc)
                    FrameData(FrNum).Uplot(:,isRpc) = [];

                    if isfield(FrameData(FrNum),'PCplot') && ~isempty(FrameData(FrNum).PCplot) && size(FrameData(FrNum).PCplot,2) >= numel(isRpc)
                        FrameData(FrNum).PCplot(:,isRpc) = [];
                    end

                    if isfield(FrameData(FrNum),'DimDisplayWf') && ~isempty(FrameData(FrNum).DimDisplayWf) && numel(FrameData(FrNum).DimDisplayWf) >= numel(isRpc)
                        FrameData(FrNum).DimDisplayWf(isRpc) = [];
                    end

                    labels0(isRpc) = [];
                    FrameData(FrNum).DimLabels = labels0;
                    nDims = size(FrameData(FrNum).Uplot, 2);
                end
            end

            nDims = size(FrameData(FrNum).Uplot, 2);
            dimLabels = FrameData(FrNum).DimLabels;

            % Current numeric dropdown values.
            cur1 = get(handles.popDim1, 'Value');
            cur2 = get(handles.popDim2, 'Value');
            cur3 = get(handles.popDim3, 'Value');

            cur1 = round(cur1);
            cur2 = round(cur2);
            cur3 = round(cur3);


            % Current raster y dropdown value is a string, not a numeric index.
            curRasterY = 'PC1';
            try
                if ~isempty(app.rasterYDim) && isvalid(app.rasterYDim)
                    curRasterY = app.rasterYDim.Value;
                end
            catch
            end

            % Dim1 can NEVER show rPCs.
            labels1 = dimLabels;


            % Dim2 can show rPCs only if Dim1 is a real custom waveform dim.
            dim1LabelNow = '';
            if cur1 >= 1 && cur1 <= numel(dimLabels)
                dim1LabelNow = dimLabels{cur1};
            end

            dim1IsCustom = ~isempty(dim1LabelNow) && ...
                ~startsWith(dim1LabelNow, 'PC') && ...
                ~startsWith(dim1LabelNow, 'rPC');

            labels2 = dimLabels;
            if dim1IsCustom
                labels2 = [labels2 {'rPC1','rPC2','rPC3'}];
            end

            % Dim3 can show rPCs only if Dim1 or Dim2 is a real custom waveform dim.
            % rPCs do NOT count as custom waveform dimensions.
            dim2LabelNow = '';
            if cur2 >= 1 && cur2 <= numel(dimLabels)
                dim2LabelNow = dimLabels{cur2};
            end

            dim2IsCustom = ~isempty(dim2LabelNow) && ...
                ~startsWith(dim2LabelNow, 'PC') && ...
                ~startsWith(dim2LabelNow, 'rPC');


            labels3 = dimLabels;
            if dim1IsCustom || dim2IsCustom
                labels3 = [labels3 {'rPC1','rPC2','rPC3'}];
            end

            % Raster y dropdown gets clusters + every available plotting dimension.
            % Use union of all dropdown label sets so it has at least the PC/default dims
            % and also rPCs when any slot makes rPCs available.
            
            labels1 = reshape(labels1, 1, []);
            labels2 = reshape(labels2, 1, []);
            labels3 = reshape(labels3, 1, []);
            
            labelsRasterY = [{'clusters'} labels1 labels2 labels3];
            [~, ia] = unique(labelsRasterY, 'stable');
            labelsRasterY = labelsRasterY(sort(ia));


            % Slot-specific fallback if old selected value no longer exists.
            % Dim1 falls back to PC1, Dim2 to PC2, Dim3 to PC3.
            if cur1 < 1 || cur1 > numel(labels1)
                cur1 = min(1, numel(labels1));
            end

            if cur2 < 1 || cur2 > numel(labels2)
                cur2 = min(2, numel(labels2));
            end

            if cur3 < 1 || cur3 > numel(labels3)
                cur3 = min(3, numel(labels3));
            end

            % Raster y fallback.
            % Default should be PC1 if possible; otherwise clusters.
            if ~any(strcmp(curRasterY, labelsRasterY))
                if any(strcmp('PC1', labelsRasterY))
                    curRasterY = 'PC1';
                else
                    curRasterY = 'clusters';
                end
            end

            % Prevent App Designer crash while changing shorter dropdown strings.
            set(handles.popDim1, 'Value', 1);
            set(handles.popDim2, 'Value', 1);
            set(handles.popDim3, 'Value', 1);

            try
                app.rasterYDim.Value = app.rasterYDim.Items{1};
            catch
            end

            set(handles.popDim1, 'String', labels1);
            set(handles.popDim2, 'String', labels2);
            set(handles.popDim3, 'String', labels3);

            try
                app.rasterYDim.Items = labelsRasterY;
            catch ME
                fprintf('[rasterYDim Items failed] %s\n', ME.message);
            end

            set(handles.popDim1, 'Value', cur1);
            set(handles.popDim2, 'Value', cur2);
            set(handles.popDim3, 'Value', cur3);


            try
                app.rasterYDim.Value = curRasterY;
            catch ME
                fprintf('[rasterYDim Value failed] %s\n', ME.message);
            end

        end

        function [yMode, pcDim] = GetRasterYMode(app)

            yMode = 'pc';
            pcDim = 1;

            try
                choice = app.rasterYDim.Value;
            catch
                choice = 'PC1';
            end

            if strcmpi(choice, 'clusters')
                yMode = 'cluster';
                pcDim = 1;
                return
            end

            tok = regexp(choice, '^PC(\d+)$', 'tokens', 'once');

            if ~isempty(tok)
                yMode = 'pc';
                pcDim = str2double(tok{1});
                return
            end

            tok = regexp(choice, '^rPC(\d+)$', 'tokens', 'once');

            if ~isempty(tok)
                yMode = 'rpc';
                pcDim = str2double(tok{1});
                return
            end

            % Any custom scalar dim can be handled later.
            % For now, fallback safely to PC1.
            yMode = 'pc';
            pcDim = 1;

        end

        function figure1WindowKeyRelease(app, event)


            if strcmpi(event.Key, 'v')
                disp('DEBUG: V UP -> back to horizontal raster zoom')
                app.CommandDown = false;
                UpdatePcRasterZoomDirection(app);
                return
            end


        end
       
        function PcRasterCommandScroll(app, src, event)
        
            % Only take over scroll when mouse is over pcRaster.
            clickedObj = hittest(app.figure1);
            clickedAx = ancestor(clickedObj, 'matlab.ui.control.UIAxes');
        
            if isempty(clickedAx) || clickedAx ~= app.pcRaster
                return
            end
        
            ax = app.pcRaster;
        
            % Get raster dropdown value.
            try
                yChoice = app.rasterYDim.Value;
            catch
                yChoice = 'clusters';
            end
        
            isClusterRaster = strcmpi(yChoice, 'clusters');
        
            % ------------------------------------------------------------
            % COMMAND + SCROLL:
            % only when NOT clusters, do vertical-only zoom.
            % ------------------------------------------------------------
            if app.CommandDown && ~isClusterRaster
        
                yl = ax.YLim;
                yCenter = mean(yl);
                yHalfRange = diff(yl) / 2;
        
                if yHalfRange <= 0 || ~isfinite(yHalfRange)
                    return
                end
        
                if event.VerticalScrollCount > 0
                    zoomFactor = 1.20;      % zoom out vertically
                else
                    zoomFactor = 1 / 1.20;  % zoom in vertically
                end
        
                newHalfRange = yHalfRange * zoomFactor;
                ax.YLim = [yCenter - newHalfRange, yCenter + newHalfRange];
        
                UD = ax.UserData;
                if isstruct(UD)
                    UD.LockedYLim = ax.YLim;
                    ax.UserData = UD;
                end
        
                return
            end
        
            % ------------------------------------------------------------
            % NORMAL SCROLL:
            % manual x-only zoom, because WindowScrollWheelFcn stole
            % MATLAB's native scroll event.
            % ------------------------------------------------------------
            xl = ax.XLim;
            xCenter = mean(xl);
            xHalfRange = diff(xl) / 2;
        
            if xHalfRange <= 0 || ~isfinite(xHalfRange)
                return
            end
        
            if event.VerticalScrollCount > 0
                zoomFactor = 1.20;      % zoom out horizontally
            else
                zoomFactor = 1 / 1.20;  % zoom in horizontally
            end
        
            newHalfRange = xHalfRange * zoomFactor;
        
            newXLim = [xCenter - newHalfRange, xCenter + newHalfRange];
        
            % Optional clamp to full raster time range if available.
            UD = ax.UserData;
            if isstruct(UD) && isfield(UD, 'tMin') && isfield(UD, 'tMax') && ...
                    isfinite(UD.tMin) && isfinite(UD.tMax) && UD.tMax > UD.tMin
        
                fullMin = UD.tMin;
                fullMax = UD.tMax;
        
                if newXLim(1) < fullMin
                    newXLim = newXLim + (fullMin - newXLim(1));
                end
        
                if newXLim(2) > fullMax
                    newXLim = newXLim - (newXLim(2) - fullMax);
                end
        
                newXLim(1) = max(newXLim(1), fullMin);
                newXLim(2) = min(newXLim(2), fullMax);
        
                if newXLim(2) <= newXLim(1)
                    newXLim = [fullMin fullMax];
                end
            end
        
            ax.XLim = newXLim;
        
        end

        
        function SetupAppLevelCallbacks(app)

            try
                app.figure1.WindowKeyReleaseFcn = @(src,event)figure1WindowKeyRelease(app, event);
            catch ME
                fprintf('[WindowKeyReleaseFcn setup failed] %s\n', ME.message);
            end

            % Do NOT attach WindowScrollWheelFcn here.
            % Leaving it empty lets MATLAB native axes scroll/zoom work.
            try
                app.figure1.WindowScrollWheelFcn = [];
            catch
            end

        end

        function PlotAppRegularRaster(app, FrameData, FrNum, yMode, pcDim)
        
            ax = app.pcRaster;
        
            if nargin < 4 || isempty(yMode)
                yMode = 'pc';      % default for now
            end
        
            if nargin < 5 || isempty(pcDim)
                pcDim = 1;         % default y = PC1
            end
        
            cla(ax);
            hold(ax, 'on');
        
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                hold(ax, 'off');
                return
            end
        
            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
               ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)
        
                hold(ax, 'off');
                return
            end
        
            status = FrameData(FrNum).Status(:);
            spkTimes = double(FrameData(FrNum).spk(:)) / 1e6;
        
            n = min(numel(status), numel(spkTimes));
            status = status(1:n);
            spkTimes = spkTimes(1:n);
        
            activeMask = status > 0 & isfinite(spkTimes);
        
            if ~any(activeMask)
                title(ax, 'raster: no active spikes');
                hold(ax, 'off');
                return
            end
        
            activeClusters = unique(status(activeMask));
            activeClusters = activeClusters(:)';
        
            yVals = NaN(size(status));
            yLabelText = '';
        
            switch lower(yMode)
        
                case 'cluster'
        
                    for iCl = 1:numel(activeClusters)
                        clu = activeClusters(iCl);
                        yVals(status == clu) = iCl;
                    end
        
                    yLabelText = 'cluster';
        
                case 'pc'
        
                    if isfield(FrameData(FrNum), 'Uplot') && ~isempty(FrameData(FrNum).Uplot)
                        U = FrameData(FrNum).Uplot;
                    elseif isfield(FrameData(FrNum), 'U') && ~isempty(FrameData(FrNum).U)
                        U = FrameData(FrNum).U;
                    else
                        title(ax, 'PC raster: no Uplot/U data');
                        hold(ax, 'off');
                        return
                    end
        
                    if pcDim < 1 || pcDim > size(U,2)
                        title(ax, sprintf('PC raster: PC%d unavailable', pcDim));
                        hold(ax, 'off');
                        return
                    end
        
                    n = min([numel(status), numel(spkTimes), size(U,1)]);
                    status = status(1:n);
                    spkTimes = spkTimes(1:n);
                    yVals = U(1:n, pcDim);
        
                    activeMask = status > 0 & isfinite(spkTimes) & isfinite(yVals);
        
                    if ~any(activeMask)
                        title(ax, sprintf('PC%d raster: no finite active spikes', pcDim));
                        hold(ax, 'off');
                        return
                    end
        
                    activeClusters = unique(status(activeMask));
                    activeClusters = activeClusters(:)';
        
                    yLabelText = sprintf('PC%d', pcDim);
        
                otherwise
        
                    title(ax, sprintf('bad raster yMode: %s', yMode));
                    hold(ax, 'off');
                    return
            end
        
            % Always use DOTS, not vertical lines.
            for iCl = 1:numel(activeClusters)
        
                clu = activeClusters(iCl);
                rows = activeMask & status == clu;
        
                if ~any(rows)
                    continue
                end
        
                plot(ax, spkTimes(rows), yVals(rows), '.', ...
                    'Color', mycolors(app, clu), ...
                    'MarkerSize', 7, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
        
            end
        
            tMin = min(spkTimes(activeMask));
            tMax = max(spkTimes(activeMask));
        
            chunkSec = 60;
            ax.XLim = [tMin min(tMin + chunkSec, tMax)];
        
            if strcmpi(yMode, 'cluster')
        
                lockedYLim = [0.5 numel(activeClusters) + 0.5];
        
                ax.YLim = lockedYLim;
                ax.YTick = 1:numel(activeClusters);
                ax.YTickLabel = arrayfun(@(c)sprintf('C%d', c), activeClusters, 'UniformOutput', false);
        
            else
        
                yMin = min(yVals(activeMask));
                yMax = max(yVals(activeMask));
        
                if yMin == yMax
                    yPad = 1;
                else
                    yPad = 0.05 * (yMax - yMin);
                end
        
                lockedYLim = [yMin - yPad, yMax + yPad];
        
                ax.YLim = lockedYLim;
                ax.YTickMode = 'auto';
                ax.YTickLabelMode = 'auto';
        
            end
        
            xlabel(ax, 'time in frame (s)');
            ylabel(ax, yLabelText);
            title(ax, sprintf('Frame %d raster: y = %s', FrNum, yLabelText));
        
            ax.Box = 'on';
            ax.Layer = 'top';
            ax.Color = 'w';
        
            % Store data for hover/select.
            ax.UserData = struct();
            ax.UserData.FrameData = FrameData;
            ax.UserData.FrNum = FrNum;
            ax.UserData.status = status;
            ax.UserData.spkTimes = spkTimes;
            ax.UserData.yVals = yVals;
            ax.UserData.yMode = lower(yMode);
            ax.UserData.pcDim = pcDim;
            ax.UserData.activeClusters = activeClusters;
            ax.UserData.tMin = tMin;
            ax.UserData.tMax = tMax;
            ax.UserData.chunkSec = chunkSec;
            ax.UserData.LockedYLim = lockedYLim;
        
            % Do not use pcRaster's own ButtonDownFcn.
            app.pcRaster.ButtonDownFcn = [];
        
            ax.YLim = lockedYLim;
            ax.YLimMode = 'manual';
        
            try
                enableDefaultInteractivity(ax);
            catch
            end
        
            try
                ax.Interactions = [zoomInteraction panInteraction];
            catch ME
                fprintf('[pcRaster interaction setup failed] %s\n', ME.message);
            end
           

            UpdatePcRasterZoomDirection(app);
        
            try
                ax.Toolbar.Visible = 'on';
            catch
            end

            % Do not steal scroll-wheel events from MATLAB native axes interaction.
            app.figure1.WindowScrollWheelFcn = [];

            % Backup Y lock.
            UD = ax.UserData;
        
            if isfield(UD, 'YLimListener') && ~isempty(UD.YLimListener)
                try
                    delete(UD.YLimListener);
                catch
                end
            end
        
            if strcmpi(yMode, 'cluster')

                UD.YLimListener = addlistener(ax, 'YLim', 'PostSet', ...
                    @(src,evt)set(evt.AffectedObject, 'YLim', lockedYLim));

            else

                UD.YLimListener = [];

            end

            ax.UserData = UD;
        
            hold(ax, 'off');
        
            drawnow limitrate
        
        end

        function [dimVec, dimWf, dimLabel] = GetDisplayDim(app, handles, FrameData, FrNum, slotNum, dimVal)

            dimVec = [];
            dimWf = [];
            dimLabel = '';

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot)
                return
            end

            nStoredDims = size(FrameData(FrNum).Uplot, 2);

            % ------------------------------------------------------------
            % Normal stored dimension: PC1-PC6 or custom waveform dim.
            % ------------------------------------------------------------
            if dimVal <= nStoredDims

                dimVec = FrameData(FrNum).Uplot(:,dimVal);

                if isfield(FrameData(FrNum),'PCplot') && size(FrameData(FrNum).PCplot,2) >= dimVal
                    dimWf = FrameData(FrNum).PCplot(:,dimVal);
                end

                if isfield(FrameData(FrNum),'DimLabels') && numel(FrameData(FrNum).DimLabels) >= dimVal
                    dimLabel = FrameData(FrNum).DimLabels{dimVal};
                else
                    dimLabel = sprintf('Dim%d', dimVal);
                end

                return
            end

            % ------------------------------------------------------------
            % rPC dimension: dropdown value after stored dims.
            % rPC index is 1,2,3.
            % ------------------------------------------------------------
            rIdx = dimVal - nStoredDims;

            if rIdx < 1 || rIdx > 3
                return
            end

            % Get custom waveform dims to the LEFT of this slot only.
            customDimsLeft = [];

            d1 = get(handles.popDim1, 'Value');
            d2 = get(handles.popDim2, 'Value');

            if slotNum >= 2
                if d1 > 6 && d1 <= nStoredDims
                    customDimsLeft(end+1) = d1; %#ok<AGROW>
                end
            end

            if slotNum >= 3
                if d2 > 6 && d2 <= nStoredDims
                    customDimsLeft(end+1) = d2; %#ok<AGROW>
                end
            end

            % If no custom waveform to the left, rPC is invalid.
            if isempty(customDimsLeft)
                return
            end

            [rPC, rPCwfs] = ComputeResidualPCs(app, FrameData, FrNum, customDimsLeft);

            if isempty(rPC) || size(rPC,2) < rIdx
                return
            end

            dimVec = rPC(:,rIdx);

            if ~isempty(rPCwfs) && size(rPCwfs,2) >= rIdx
                dimWf = rPCwfs(:,rIdx);
            end

            dimLabel = sprintf('rPC%d', rIdx);

        end


        function [rPC, rPCwaveforms, residualW] = ComputeResidualPCs(app, FrameData, FrNum, customDims)

            rPC = [];
            rPCwaveforms = [];
            residualW = [];

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            if ~isfield(FrameData(FrNum),'Wfs') || isempty(FrameData(FrNum).Wfs)
                return
            end

            W = double(FrameData(FrNum).Wfs);
            [nSpikes, wfLen] = size(W);

            if nSpikes < 2
                return
            end

            customDims = customDims(:)';
            customDims = customDims(customDims > 6);   % only custom waveform dims, never PCs/rPCs

            if isempty(customDims)
                residualW = W;
            else

                B = [];

                for d = customDims

                    wf = [];

                    % Preferred source: stored custom waveform display vector.
                    if isfield(FrameData(FrNum),'DimDisplayWf') && ...
                            numel(FrameData(FrNum).DimDisplayWf) >= d && ...
                            ~isempty(FrameData(FrNum).DimDisplayWf{d})

                        wf = FrameData(FrNum).DimDisplayWf{d};
                    end

                    % Fallback: PCplot column, if you stored the custom waveform there.
                    if isempty(wf) && isfield(FrameData(FrNum),'PCplot') && ...
                            size(FrameData(FrNum).PCplot,2) >= d

                        wf = FrameData(FrNum).PCplot(:,d);
                    end

                    wf = double(wf(:));

                    if numel(wf) ~= wfLen
                        fprintf('[rPC skipped dim %d] custom waveform length mismatch\n', d);
                        continue
                    end

                    wf(~isfinite(wf)) = 0;

                    if norm(wf) <= eps
                        fprintf('[rPC skipped dim %d] custom waveform is zero\n', d);
                        continue
                    end

                    B(:,end+1) = wf ./ norm(wf); %#ok<AGROW>

                end

                if isempty(B)
                    residualW = W;
                else
                    % Orthonormalize custom waveform directions.
                    [Q,~] = qr(B,0);

                    % Project every spike onto custom waveform subspace, then subtract.
                    residualW = W - (W * Q) * Q';
                end
            end

            % Reuse existing PCA logic if possible.
            try
                [Ures, PCres, ~] = spikepcsNew(app, residualW, []);
            catch
                [PCres, Ures] = pca(residualW, 'NumComponents', min(3, min(size(residualW))));
            end

            nKeep = min(3, size(Ures,2));

            rPC = NaN(nSpikes,3);
            rPC(:,1:nKeep) = Ures(:,1:nKeep);

            rPCwaveforms = zeros(wfLen,3);
            rPCwaveforms(:,1:min(3,size(PCres,2))) = PCres(:,1:min(3,size(PCres,2)));

        end


        function UpdateSpikeCountLabels(app, FrameData, FrNum)

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                return
            end

            status = FrameData(FrNum).Status(:);
            
            for c = 1:14
                n = sum(status == c);

                if isprop(app, ['nSpks' num2str(c)]) && ...
                        ~isempty(app.(['nSpks' num2str(c)])) && ...
                        isvalid(app.(['nSpks' num2str(c)]))

                    app.(['nSpks' num2str(c)]).Text = num2str(n);
                end
            end

            nNoise = sum(status == 0);

            if isprop(app, 'nSpksNoise') && ~isempty(app.nSpksNoise) && isvalid(app.nSpksNoise)
                app.nSpksNoise.Text = num2str(nNoise);
            end

            drawnow limitrate;
        end

        function PrintFrameClusters(app, FrameData, FrNum, labelText)
            return 

            if nargin < 4 || isempty(labelText)
                labelText = 'PrintFrameClusters';
            end

            if isempty(FrameData)
                fprintf('[%s PrintFrameClusters skipped] FrameData is empty\n', labelText);
                return
            end

            fprintf('[%s PrintFrameClusters DEBUG] FrNum=%d numel(FrameData)=%d class(FrameData)=%s\n', ...
                labelText, FrNum, numel(FrameData), class(FrameData));

            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[%s PrintFrameClusters skipped] bad FrNum=%d for numel(FrameData)=%d\n', ...
                    labelText, FrNum, numel(FrameData));
                return
            end

            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[%s PrintFrameClusters skipped] missing Status in frame %d\n', labelText, FrNum);
                return
            end

            UpdateSpikeCountLabels(app, FrameData, FrNum);

            % header: print to command window and store for app debug window
            lines = {};
            header = sprintf('[%s] Fr %d', labelText, FrNum);
            fprintf('\n%s\n', header);
            lines{end+1,1} = header;

            %only included spikes 
            status = FrameData(FrNum).Status(:);
            activeClusts = unique(status(status > 0));
            activeClusts = sort(activeClusts);

            % color names for clusters 1-14
            colorNames = {'black','blue','green','red','teal','purple','marigold','grey', ...
                'magenta','pink','dark red','orange','bright green','cyan'};

            %if no clusters have spikes  
            if isempty(activeClusts)
                lineText = 'No active clusters';
                fprintf('%s\n', lineText);
                lines{end+1,1} = lineText;
            end

            % print only clusters with atleast one spike 
            for i = 1:numel(activeClusts)
                Cl = activeClusts(i); % actual cluster ID 
                nSpikes = sum(status == Cl);

                if isfield(FrameData(FrNum),'Iso') && numel(FrameData(FrNum).Iso) >= Cl && FrameData(FrNum).Iso(Cl)
                    isoStr = 'iso';
                else
                    isoStr = 'uniso';
                end

                colName = colorNames{min(Cl,numel(colorNames))}; % safe color lookup

                lineText = sprintf('C%d: %s, %d spks, %s', Cl, colName, nSpikes, isoStr);
                fprintf('%s\n', lineText);
                lines{end+1,1} = lineText;
            end

            % blank line 
            fprintf('\n');
            lines{end+1,1} = '';

            % send same lines to app debug window
            % send same lines to app debug window
        end

        function varargout = RecoverCell(app, handles, FrameNumber)
            return 
           
        end
        

        function handles = RedrawSortMovie(app, handles, FrNum, scatterFlag)

            % It pulls the spike/session data. First from the app property, fallback from old GUIDE-style UserData.
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            % Updates the visible frame number.
            set(handles.stFrame2,'String',sprintf('Frame %d / %d', FrNum, numel(FrameData)));
            set(handles.stFrame2,'UserData',numel(FrameData));

            % This updates the dropdown choices like PC1, PC2, custom dims, rPC1, etc.
            RefreshDimDropdownLabels(app, handles, FrameData, FrNum);
            FrameData = app.FrameDataStore;

            Dim1 = get(handles.popDim1,'Value');
            Dim2 = get(handles.popDim2,'Value');
            Dim3 = get(handles.popDim3,'Value');

            % This converts dropdown choices into actual numbers.
            [d1Vec, d1Wf, d1Label] = GetDisplayDim(app, handles, FrameData, FrNum, 1, Dim1);
            [d2Vec, d2Wf, d2Label] = GetDisplayDim(app, handles, FrameData, FrNum, 2, Dim2);
            [d3Vec, d3Wf, d3Label] = GetDisplayDim(app, handles, FrameData, FrNum, 3, Dim3);

            if isempty(d1Vec) || isempty(d2Vec) || isempty(d3Vec)
                fprintf('[Redraw skipped] bad display dim\n');
                return
            end

            % Keep all visible labels static
            % This resets visible label text every redraw. Not the biggest problem, but still UI work.
            set(handles.PClabel1,'String','Dim 1');
            set(handles.PClabel2,'String','Dim 2');
            set(handles.PClabel3,'String','Dim 3');

            set(handles.Xlabel1,'String','Dim 1');
            set(handles.Ylabel1,'String','Dim 2');

            set(handles.Xlabel2,'String','Dim 1');
            set(handles.Ylabel2,'String','Dim 3');

            set(handles.Xlabel3,'String','Dim 2');
            app.Ylabel3_2.Text = 'Dim 3';
            
            %set(handles.edFrameSmall,'String',['Frame ' num2str(FrNum)]);
            
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                disp(['No spikes in Frame: ' num2str(FrNum) '. Not drawing anything.'])
            
                cla(handles.axDim12);
                set(handles.axDim12,'Color','white');
            
                cla(handles.axDim13);
                set(handles.axDim13,'Color','white');
            
                cla(handles.axDim23);
                set(handles.axDim23,'Color','white');
            
                return
            end
            UpdateSpikeCountLabels(app, FrameData, FrNum);
            
            %disp('Assigning U_seg');

            % This means the PC scatter plots only show active positive spikes.
            % Status > 0 gets plotted.
            % Noise Status == 0 and isolated/finalized Status < 0 do not
            % appear on the main PC plots. 
            %NOTE even though setting to 'zero' in the ui sends it to the
            %black multi, 'status zero' under the hood is still noise 
            %ie, writing 'zero' and reassignign just changes the under the
            %hood status to one, not zero under the hood 
            U_seg = FrameData(FrNum).Uplot;
            pcRows = FrameData(FrNum).Status(:) > 0;
            U_seg = U_seg(pcRows,:);
            Clu_seg = FrameData(FrNum).Status(pcRows);
            d1Plot = d1Vec(pcRows);
            d2Plot = d2Vec(pcRows);
            d3Plot = d3Vec(pcRows);


            %%PREP THE PLOTDIM PLOTS 
            % They get cleared and replotted every time, even if they did not change.
            cla(handles.axPC1);
            handles.axPC1.Color = [1 1 1];
            handles.axPC1.XColor = [0 0 0];
            handles.axPC1.YColor = [0 0 0];
            handles.axPC1.XTick = [];
            handles.axPC1.YTick = [];
            handles.axPC1.Box = 'on';
            hold(handles.axPC1,'off');
            
            if ~isempty(d1Wf)
                plot(handles.axPC1, d1Wf, 'Color', [0 0 0], 'LineWidth', 1);
            end
            
            handles.axPC1.Color = [1 1 1];
            handles.axPC1.XColor = [0 0 0];
            handles.axPC1.YColor = [0 0 0];
            handles.axPC1.XLim = [0 size(FrameData(FrNum).Wfs,2)+1];
            AddPCVarianceText(app, handles.axPC1, FrameData, FrNum, Dim1);
            
            
            cla(handles.axPC2);
            handles.axPC2.Color = [1 1 1];
            handles.axPC2.XColor = [0 0 0];
            handles.axPC2.YColor = [0 0 0];
            handles.axPC2.XTick = [];
            handles.axPC2.YTick = [];
            handles.axPC2.Box = 'on';
            hold(handles.axPC2,'off');
            
            if ~isempty(d2Wf)
                plot(handles.axPC2, d2Wf, 'Color', [0 0 0], 'LineWidth', 1);
            end
            
            handles.axPC2.Color = [1 1 1];
            handles.axPC2.XColor = [0 0 0];
            handles.axPC2.YColor = [0 0 0];
            handles.axPC2.XLim = [0 size(FrameData(FrNum).Wfs,2)+1];
            AddPCVarianceText(app, handles.axPC2, FrameData, FrNum, Dim2);
            
            
            cla(handles.axPC3);
            handles.axPC3.Color = [1 1 1];
            handles.axPC3.XColor = [0 0 0];
            handles.axPC3.YColor = [0 0 0];
            handles.axPC3.XTick = [];
            handles.axPC3.YTick = [];
            handles.axPC3.Box = 'on';
            hold(handles.axPC3,'off');
            
            if ~isempty(d3Wf)
                plot(handles.axPC3, d3Wf, 'Color', [0 0 0], 'LineWidth', 1);
            end
            
            handles.axPC3.Color = [1 1 1];
            handles.axPC3.XColor = [0 0 0];
            handles.axPC3.YColor = [0 0 0];
            handles.axPC3.XLim = [0 size(FrameData(FrNum).Wfs,2)+1];
            AddPCVarianceText(app, handles.axPC3, FrameData, FrNum, Dim3);
            
            %%PREP THE PLOTDIM PLOTS 


            if scatterFlag 
                %%PLOT THE PLOTDIM PLOTS 
                % So for each redraw, it loops through clusters 1–14 and creates up to 14 line objects per scatter plot.
                cla(handles.axDim12);
                set(handles.axDim12,'Color','white');
                if ~isempty(d1Plot) && ~isempty(d2Plot)
                    for iClus = 1:14
                        if any(Clu_seg == iClus)
                            ind = find(Clu_seg == iClus);
                            if ~isempty(ind)
                                h = line(handles.axDim12, d1Plot(ind), d2Plot(ind), ... 
                                    'Marker','.', 'LineStyle','none', 'Markersize',2, 'Color',mycolors(app, iClus));
                                
                                set(h, 'HitTest','off', 'PickableParts','none');
                            end
                        end
                    end
                end
    
                cla(handles.axDim13);
                set(handles.axDim13,'Color','white');
                if ~isempty(d1Plot) && ~isempty(d3Plot)
                    for iClus = 1:14
                        if any(Clu_seg == iClus)
                            ind = find(Clu_seg == iClus);
                            if ~isempty(ind)
                                h = line(handles.axDim13, d1Plot(ind), d3Plot(ind), ... 
                                    'Marker','.', 'LineStyle','none', 'Markersize',2, 'Color',mycolors(app, iClus));
                                
                                set(h, 'HitTest','off', 'PickableParts','none');
                            end
                        end
                    end
                end
                
                cla(handles.axDim23);
                set(handles.axDim23,'Color','white');
                if ~isempty(d2Plot) && ~isempty(d3Plot)
                    for iClus = 1:14
                        if any(Clu_seg == iClus)
                            ind = find(Clu_seg == iClus);
                            if ~isempty(ind)
                                h = line(handles.axDim23, d2Plot(ind), d3Plot(ind), ...
                                    'Marker','.', 'LineStyle','none', 'Markersize',2, 'Color',mycolors(app, iClus));
                                
                                set(h, 'HitTest','off', 'PickableParts','none');
                            end
                        end
                    end
                end
                %%PLOT THE PLOTDIM PLOTS 
            end 


% This recalculates axis limits and restyles the plots every redraw.
            SetScatterAxisPaddedLimits(app, handles, app.axDim12, FrameData, FrNum, 1, Dim1, 2, Dim2);
            SetScatterAxisPaddedLimits(app, handles, app.axDim13, FrameData, FrNum, 1, Dim1, 3, Dim3);
            SetScatterAxisPaddedLimits(app, handles, app.axDim23, FrameData, FrNum, 2, Dim2, 3, Dim3);
            % 
            % SetScatterAxisPaddedLimits(app, app.axDim12, FrameData, FrNum, Dim1, Dim2);
            % SetScatterAxisPaddedLimits(app, app.axDim13, FrameData, FrNum, Dim1, Dim3);
            % SetScatterAxisPaddedLimits(app, app.axDim23, FrameData, FrNum, Dim2, Dim3);

            box(app.axDim12, 'on');
            box(app.axDim13, 'on');
            box(app.axDim23, 'on');

            app.axDim12.LineWidth = 0.85;
            app.axDim13.LineWidth = 0.85;
            app.axDim23.LineWidth = 0.85;

            app.axDim12.Layer = 'top';
            app.axDim13.Layer = 'top';
            app.axDim23.Layer = 'top';
            app.axDim12.XColor = 'k'; app.axDim12.YColor = 'k';
            app.axDim13.XColor = 'k'; app.axDim13.YColor = 'k';
            app.axDim23.XColor = 'k'; app.axDim23.YColor = 'k';


            % Even if you only changed current frame, it redraws mean waveforms for all frames up to current frame.

            % If FrNum is large, this gets worse.
            
            %disp('Plotting waveforms') ... this is for the axes that shows wfs in ALL frames
            axes(handles.axWaveforms);
            cla;
            for iFrame = 1:FrNum
                for iClus = 1:14 %size(SortData(iFrame).Wf,1)
                    if sum(FrameData(iFrame).Status(:)==iClus)>0
                        if iClus<=size(FrameData(iFrame).mWf,1)
                            line([1:size(FrameData(iFrame).mWf,2)],...
                                FrameData(iFrame).mWf(iClus,:),...
                                'Color',mycolors(app, iClus));
                        else
                            warning(['On Frame: ' num2str(iFrame) ' found Spks assigned to Clus: ' num2str(iClus) ' but no Wf in SortData!'])
                        end
                    end
                end
            end
            set(gca,'XLim',[0,size(FrameData(1).mWf,2)]);


            %disp('Plotting plotting FrWaveforms') ... this is for the axes that shows wfs in only the current frame
            axes(handles.axFrWaveform); 
            cla;
            hold(handles.axFrWaveform,'on');

            status = FrameData(FrNum).Status(:);

            for iClus = 1:14

                rows = status == iClus;

                if any(rows)

                    if iClus <= size(FrameData(FrNum).mWf,1) && ...
                            ~isempty(FrameData(FrNum).mWf) && ...
                            size(FrameData(FrNum).mWf,2) > 0

                        line(handles.axFrWaveform, 1:size(FrameData(FrNum).mWf,2), ...
                            FrameData(FrNum).mWf(iClus,:), ...
                             'Color', mycolors(app, iClus));

                    else
                        warning(['On Frame: ' num2str(FrNum) ' found Spks assigned to Clus: ' num2str(iClus) ' but no mean Wf in FrameData!'])
                    end

                end
            end

            hold(handles.axFrWaveform,'off');
            set(handles.axFrWaveform,'XLim',[0,size(FrameData(FrNum).mWf,2)]);


            % ---------- PLOT NOISE MEAN WAVEFORMS ONLY ----------
            cla(app.axWaveforms_2,'reset');
            % cla(...,'reset') is heavier than plain cla because it resets more axes properties.
            hold(app.axWaveforms_2,'on');
            set(app.axWaveforms_2,'Color','white');

            noiseNums = [];

            if isfield(FrameData(FrNum),'Status') && ~isempty(FrameData(FrNum).Status) && ...
                    isfield(FrameData(FrNum),'Wfs') && ~isempty(FrameData(FrNum).Wfs) && ...
                    isfield(FrameData(FrNum),'NoiseNumber') && ~isempty(FrameData(FrNum).NoiseNumber)

                status = FrameData(FrNum).Status(:);
                noiseNumber = FrameData(FrNum).NoiseNumber(:);

                noiseNums = unique(noiseNumber(status == 0 & isfinite(noiseNumber)));
                noiseNums = sort(noiseNums(:)');

                x = 1:size(FrameData(FrNum).Wfs,2);

                for iN = noiseNums

                    rows = status == 0 & noiseNumber == iN;
                    if ~any(rows)
                        continue
                    end

                    W_all = FrameData(FrNum).Wfs(rows,:);
                    mwf = mean(W_all, 1);

                    hNoiseMean = line(app.axWaveforms_2, x, mwf, ...
                        'Color', [0.5 0.5 0.5], ...
                        'LineWidth', 2.5, ...
                        'DisplayName', sprintf('N%d', iN), ...
                        'HitTest', 'on', ...
                        'PickableParts', 'all', ...
                        'ButtonDownFcn', @(src,event)SelectNoiseNumberMean(app, event, FrNum, iN));

                    hNoiseMean.UserData = struct( ...
                        'Type', 'NoiseNumberMean', ...
                        'Frame', FrNum, ...
                        'NoiseNumber', iN, ...
                        'Rows', find(rows));

                end
            end

            if ~isempty(noiseNums)
                set(app.axWaveforms_2, 'XLim', [1 size(FrameData(FrNum).Wfs,2)]);
            end

            hold(app.axWaveforms_2,'off');


            % ============================================================
            % PLOT CUMULATIVE MEAN WAVEFORMS OF REMOVED / ISOLATED CELLS
            % Status < 0 means removed as isolated/finalized unit.
            % This plot is cumulative across ALL frames.
            % Example: all Status == -4 spikes across all frames become one mean waveform.
            % ============================================================

            cla(app.axRemovedasIsoMeanWfs, 'reset');
            hold(app.axRemovedasIsoMeanWfs, 'on');
            set(app.axRemovedasIsoMeanWfs, 'Color', 'white');

            % ---- collect all isolated unit IDs across all frames ----
            allIsoUnits = [];

            for iFr = 1:numel(FrameData)

                if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status)
                    continue
                end

                status_i = FrameData(iFr).Status(:);
                allIsoUnits = [allIsoUnits; unique(status_i(status_i < 0))]; %#ok<AGROW>

            end

            allIsoUnits = unique(allIsoUnits);
            allIsoUnits = sort(allIsoUnits(:)');   % e.g. [-5 -4 -2]

            nIsoPlot = 0;

            % ---- for each isolated unit, gather waveforms from ALL frames ----
            for isoStatus = allIsoUnits

                W_all = [];

                for iFr = 1:numel(FrameData)

                    if ~isfield(FrameData(iFr),'Status') || isempty(FrameData(iFr).Status) || ...
                            ~isfield(FrameData(iFr),'Wfs') || isempty(FrameData(iFr).Wfs)
                        continue
                    end

                    status_i = FrameData(iFr).Status(:);
                    rows = status_i == isoStatus;

                    if ~any(rows)
                        continue
                    end

                    W_all = [W_all; FrameData(iFr).Wfs(rows,:)]; %#ok<AGROW>

                end

                if isempty(W_all)
                    continue
                end

                nIsoPlot = nIsoPlot + 1;

                unitID = abs(isoStatus);
                mwf = mean(W_all, 1);
                x = 1:numel(mwf);

                if unitID >= 1 && unitID <= 14
                    plotColor = mycolors(app, unitID);
                else
                    plotColor = [0 0 0];
                end

                hIsoMean = line(app.axRemovedasIsoMeanWfs, x, mwf, ...
                    'Color', plotColor, ...
                    'LineWidth', 2, ...
                    'DisplayName', sprintf('U%d / status %d / n=%d', unitID, isoStatus, size(W_all,1)));

                % Let axis receive clicks, not the line.
                set(hIsoMean, 'HitTest', 'off', 'PickableParts', 'none');

            end

            if nIsoPlot > 0
                set(app.axRemovedasIsoMeanWfs, 'XLim', [1 size(FrameData(1).Wfs,2)]);
            else
                title(app.axRemovedasIsoMeanWfs, '');
            end

            hold(app.axRemovedasIsoMeanWfs, 'off');


            for iClus = 1:14 % Loop through every isolation checkbox
                
                if isfield(FrameData(FrNum),'ReassignMap') && numel(FrameData(FrNum).ReassignMap) >= iClus % Check if this frame has saved dropdown state
                    app.(['pop' num2str(iClus)]).Value = FrameData(FrNum).ReassignMap(iClus); % Restore this frame's pending reassignment target
                else
                    app.(['pop' num2str(iClus)]).Value = iClus; % Default row maps to itself
                end
            end
            % %Display the proper number of trials for each trial type for the current Frame
            % Tr=get(handles.frTrials,'UserData');
            % T=get(handles.edIsoWin,'UserData');
            %
            % %This goes by the start of the trial being within a given window
            % ind = isbetween(Tr.TrialStart,[T(FrNum:FrNum+1)]);
            % set(handles.stFrTotTrials,'String',sum(ind));
            % set(handles.stFrMemTrials,'String',num2str(sum(Tr.MemTrial(ind))));
            % set(handles.stFrSStepTrials,'String',num2str(sum(Tr.SStepTrial(ind))));
            % set(handles.stFrPATrials,'String',num2str(sum(Tr.PATrial(ind))));
            % set(handles.stFrRFFlashTrials,'String',num2str(sum(Tr.RFFlashTrial(ind))));
            % set(handles.stFrJCheckTrials,'String',num2str(sum(Tr.JCheckTrial(ind))));
            
            %Electrode depths
            % disp('plotting electrode depth')
            % ChString = get(handles.popChannel,'String');
            % ChValue = get(handles.popChannel,'Value');
            % ch = str2num(ChString{ChValue});
            %
            % Depth=get(handles.frDepth,'UserData');
            % tempDepth=zeros(length(ind),1);
            % for iTrial=1:length(ind)
            %     tempDepth(iTrial)=-Trials(ind(iTrial)).Depth{Depth.sysnum}(ch);
            % end
            % axes(handles.axDepth)
            % plot(tempDepth)
            % axis([0 length(ind)+1 Depth.gmax Depth.gmin])
            
            PlotSelectedSpike(app, FrameData);
            PlotSelectedCluster(app, FrameData);
            %UpdateAllQLabels(app, handles, FrNum);

            % PlotAppRegularRaster(app, FrameData, FrNum, 'pc', 1);
            % or PlotAppRegularRaster(app, FrameData, FrNum, 'cluster', 1);
            [yMode, pcDim] = GetRasterYMode(app);
            PlotAppRegularRaster(app, FrameData, FrNum, yMode, pcDim);

            drawnow;

            % Keep normal mouse pointer and keep MATLAB built-in axes interactions
            app.figure1.Pointer = 'arrow';

            app.axDim12.Interactions = [zoomInteraction panInteraction];
            app.axDim13.Interactions = [zoomInteraction panInteraction];
            app.axDim23.Interactions = [zoomInteraction panInteraction];
            app.axWaveforms_2.Interactions = [zoomInteraction panInteraction];

            % TEST ONLY axDim12 first
            app.axDim12.ButtonDownFcn = []; 
            app.axDim13.ButtonDownFcn = [];
            app.axDim23.ButtonDownFcn = [];
            app.axWaveforms_2.ButtonDownFcn = [];

            app.axDim12.Toolbar.Visible = 'off';
            app.axDim13.Toolbar.Visible = 'off';
            app.axDim23.Toolbar.Visible = 'off';
            app.axWaveforms_2.Toolbar.Visible = 'off';
            %PlotRaster(app, FrameData, FrNum);
            %PlotFinalizedUnits(app, FrameData, FrNum);

            FixWaveformAxesTicks(app);

            set([app.axDim12 app.axDim13 app.axDim23], ...
                'XTick', 0, 'YTick', 0, ...
                'XTickLabel', {'0'}, 'YTickLabel', {'0'}, ...
                'FontSize', 6, ...
                'TickLength', [0.01 0.01]);

            FixMainAxisTicks(app);
            drawnow limitrate
        end
        
        function varargout = RemoveCell(app, handles, FrameNumber, RemCell)
            return 
            
        end
        
        function edAP_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edAP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edChbr_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edChbr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edDay_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edDay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edDepth_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edDepth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edML_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edAP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popDN_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to popDN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('MattSpikeSortGUI_Human_App.mat');
            
            % Set component properties that require runtime configuration
            app.figure1.PointerShapeCData = componentData.figure1.PointerShapeCData;
            app.figure1.PointerShapeHotSpot = [16 16];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MattSpikeSortGUI_OpeningFcn(app, varargin)
            disp('running MattSpikeSortGUI_OpeningFcn')

            tAll = tic; 
            % --- Executes just before MattSpikeSortGUI is made visible.
            % Add runtime required configuration - Added by Migration Tool
            %addRuntimeConfigurations(app);

            % Use the layout exactly as designed in App Designer canvas
            %resizing 
            app.figure1.Units = 'pixels';
            app.figure1.Resize = 'on';
            app.figure1.Scrollable = 'off';
            app.figure1.AutoResizeChildren = 'off'; 
            movegui(app.figure1, 'center');

            %app.figure1.Position = app.figure1.Position;            % Starting app window size: should match compactified canvas ratio
            app.Layout0.aspect = app.figure1.Position(3) / app.figure1.Position(4);            % Store canvas aspect ratio
            
            % Create old GUIDE-style callback variables from the App Designer app object.
            % This gives old migrated code a `handles` struct, like handles.pbLoad,
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            FillFileSelectDropdowns(app, 'subjects'); %this function populates both dropdowns 
            disp('LOAD DEBUG: entered inside opening fcn, ffsd just ran for subjects')
            % frUnits is not a real visible app component.
            % It is a hidden dummy panel used only as a storage container for FrameData
            % through set(handles.frUnits,'UserData',FrameData).
            % It might not exist because the migrated App Designer component list does not
            % include the old GUIDE panel named frUnits.
            if ~isfield(handles, 'frUnits')
                handles.frUnits = uipanel(app.figure1, 'Visible', 'off', 'Position', [1 1 1 1]);
            end
            
            % frSave is another hidden dummy storage panel.
            % It stores spike timestamps or save-related data through UserData.
            % It might not exist if the original GUIDE object was not migrated as a real
            % App Designer component, so we create it manually.
            if ~isfield(handles, 'frSave')
                handles.frSave = uipanel(app.figure1, 'Visible', 'off', 'Position', [1 1 1 1]);
            end
            
            % frSort is another hidden dummy storage panel.
            % It stores RawSortDat / sort-related compatibility data through UserData.
            % It might not exist for the same reason: it is old GUIDE storage, not a real
            % visible App Designer control
            if ~isfield(handles, 'frSort')
                handles.frSort = uipanel(app.figure1, 'Visible', 'off', 'Position', [1 1 1 1]);
            end

            
            % Choose default command line output for OnlinePlexTempoGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            %set(hObject,'Name','SpikeSort');
            
            set(handles.popDim1,'String',{'PC1','PC2','PC3','PC4','PC5','PC6'});
            set(handles.popDim2,'String',{'PC1','PC2','PC3','PC4','PC5','PC6'});
            set(handles.popDim3,'String',{'PC1','PC2','PC3','PC4','PC5','PC6'});
            set(handles.popDim1,'Value',1);
            set(handles.popDim2,'Value',2);
            set(handles.popDim3,'Value',3);

            % Force all visible dim labels to stay static.
            % Dropdown contents can change, but screen labels stay Dim 1 / Dim 2 / Dim 3.
            set(handles.PClabel1,'String','Dim 1');
            set(handles.PClabel2,'String','Dim 2');
            set(handles.PClabel3,'String','Dim 3');

            set(handles.Xlabel1,'String','Dim 1');
            set(handles.Ylabel1,'String','Dim 2');

            set(handles.Xlabel2,'String','Dim 1');
            set(handles.Ylabel2,'String','Dim 3');

            set(handles.Xlabel3,'String','Dim 2');
            app.Ylabel3_2.Text = 'Dim 3';

            app.popDim1.ValueChangedFcn = @(src,event)popDim_Callback(app,event);
            app.popDim2.ValueChangedFcn = @(src,event)popDim_Callback(app,event);
            app.popDim3.ValueChangedFcn = @(src,event)popDim_Callback(app,event);
            app.popSubject.ValueChangedFcn = @(src,event)popSubjectValueChanged(app,event);
            set(handles.popNClus,'String',{'1','2','3','4','5','6','7','8','9','10','11','12','13','14'});
            set(handles.popNClus,'Value',2);
            set(handles.popNDim,'String',{'1','2','3','4','5','6'});
            set(handles.popNDim,'Value',3);
            app.edIsoWin.Value = '99999';
            app.edIsoWin.ValueChangedFcn = @(src,event)edIsoWinValueChanged(app,event);
            for iCl = 1:14
                app.(['pop' num2str(iCl)]).Limits = [0 14];
                app.(['pop' num2str(iCl)]).RoundFractionalValues = 'on';
                app.(['pop' num2str(iCl)]).Value = iCl;
            end

            %app.rasterAx.Visible = 'off';
            
            app.pop15.Value = '';
            
            FillFileSelectDropdowns(app, 'subjects');
            for iCl = 1:14
                app.(['pop' num2str(iCl)]).Limits = [-14 14]; % allow future negative finalized-unit targets, 0 noise, 1-14 active
                app.(['pop' num2str(iCl)]).RoundFractionalValues = 'on';
                app.(['pop' num2str(iCl)]).Value = iCl;
            end

            app.pop15.Value = '';

                        % Figure-level click tracking for selected-spike picking.
            % This does not attach ButtonDownFcn to the scatter axes.
            % Figure-level click tracking for selected-spike picking.
            % This does not attach ButtonDownFcn to the scatter axes.
            app.figure1.Pointer = 'arrow';

                        % Default mode: pure native MATLAB pan/zoom
            app.figure1.Pointer = 'arrow';
            app.figure1.WindowButtonDownFcn = [];
            app.figure1.WindowButtonUpFcn = [];
            app.figure1.WindowScrollWheelFcn = [];

            app.axDim12.Interactions = [zoomInteraction panInteraction];
            app.axDim13.Interactions = [zoomInteraction panInteraction];
            app.axDim23.Interactions = [zoomInteraction panInteraction];
            app.axWaveforms_2.Interactions = [zoomInteraction panInteraction];

                        % Do not use axes ButtonDownFcn; it blocks native pan/zoom
            app.axDim12.ButtonDownFcn = [];
            app.axDim13.ButtonDownFcn = [];
            app.axDim23.ButtonDownFcn = [];
            app.axWaveforms_2.ButtonDownFcn = [];
            app.selectOFFButton.ButtonPushedFcn = @(src,event)SELECTSPIKEButtonPushed(app,event);
            app.selectOFFButton.Text = 'select: OFF';
            
            app.popSubject.Items = {'-- select subject --'};
            app.popSubject.Value = '-- select subject --';

            app.popSession.Items = {'-- select session --'};
            app.popSession.Value = '-- select session --';

            FillFileSelectDropdowns(app, 'subjects');
            if isprop(app.popSubject, 'DropDownOpeningFcn')
                app.popSubject.DropDownOpeningFcn = @(src,event)RefreshDropdownBeforeOpen(app, 'subjects');
            end
            if isprop(app.popSession, 'DropDownOpeningFcn')
                app.popSession.DropDownOpeningFcn = @(src,event)RefreshDropdownBeforeOpen(app, 'sessions');
            end
            if isprop(app.popSrtdLoad, 'DropDownOpeningFcn')
                app.popSrtdLoad.DropDownOpeningFcn = @(src,event)RefreshDropdownBeforeOpen(app, 'files');
            end

            app.axDim12.Toolbar.Visible = 'off';
            app.axDim13.Toolbar.Visible = 'off';
            app.axDim23.Toolbar.Visible = 'off';
            app.axWaveforms_2.Toolbar.Visible = 'off';

            app.pbExpAnalysisVars.Visible = 'off'; 

            SetSelectedSpikeLabel(app, 'F#C# #');
            SetSelectedClusterLabel(app, 'F#C# #');

            % app.chooseIso.Limits = [-14 14];
            % app.chooseIso_2.Limits = [-14 14];
            % app.chooseIso_3.Limits = [-14 14];
            % app.chooseIso_4.Limits = [-14 14];
            % 
            % app.chooseIso.RoundFractionalValues = 'on';
            % app.chooseIso_2.RoundFractionalValues = 'on';
            % app.chooseIso_3.RoundFractionalValues = 'on';
            % app.chooseIso_4.RoundFractionalValues = 'on';
            % 
            % app.chooseIso.Value = -1;
            % app.chooseIso_2.Value = -2;
            % app.chooseIso_3.Value = -3;
            % app.chooseIso_4.Value = -4;
            % 
            % app.chooseIso.ValueChangedFcn = @(src,event)IsoChooserChanged(app,event);
            % app.chooseIso_2.ValueChangedFcn = @(src,event)IsoChooserChanged(app,event);
            % app.chooseIso_3.ValueChangedFcn = @(src,event)IsoChooserChanged(app,event);
            % app.chooseIso_4.ValueChangedFcn = @(src,event)IsoChooserChanged(app,event);
            
            for c = 1:14
                app.(['pop' num2str(c)]).Value = c;
            end


            app.pop15.Value = '';

            app.tbQuickSort.Text = 'QuickSort: OFF';
            app.tbQuickSort.Value = false;

            app.pbManualSort.Text = 'Manual Sort: OFF';

            app.selectOFFButton.Text = 'select: OFF';

            app.popSrtdLoad.Items = {'-- select file --'};

            set([app.axDim12 app.axDim13 app.axDim23], ...
                'XTick', 0, 'YTick', 0, ...
                'XTickLabel', {'0'}, 'YTickLabel', {'0'}, ...
                'FontSize', 6, ...
                'TickLength', [0.01 0.01]);

            app.axDim12.Color = 'w';
            app.axDim13.Color = 'w';
            app.axDim23.Color = 'w';
            app.axPC1.Color = 'w'; 
            app.axPC2.Color = 'w'; 
            app.axPC3.Color = 'w'; 

            SetupAppLevelCallbacks(app);


            % One-button reassignment workflow:
            % pbReassign handles selected-spike dropdown OR cluster dropdowns.
            % Reassign All and selected-spike-specific button are disabled.
            app.pbReassign.ButtonPushedFcn = @(src,event)pbReassign_Callback(app,event);
            app.pbRemoveasNoise_2.ButtonPushedFcn = @(src,event)pbRemoveasNoise_2ButtonPushed(app,event);
            app.pbSetDim.ButtonPushedFcn = @(src,event)pbSetDim_Callback(app,event);

            if isprop(app,'pbReassignAll') && isvalid(app.pbReassignAll)
                app.pbReassignAll.Enable = 'off';
                app.pbReassignAll.Visible = 'off';
            end

            if isprop(app,'pbReAssignselectedspikegeneral') && isvalid(app.pbReAssignselectedspikegeneral)
                app.pbReAssignselectedspikegeneral.Enable = 'off';
                app.pbReAssignselectedspikegeneral.Visible = 'off';
            end

            % drawnow; 

            UpdateRecoverSelectedClusterButton(app);
            % drawnow; 

            app.figure1.SizeChangedFcn = [];
            ApplyUILayoutLocks(app);
            % drawnow; 
            
            % app.figure1.SizeChangedFcn = @(src,event)ResizeWholeApp(app);
            % drawnow; 

            StoreInitialAppLayout(app);
            tDraw = tic;
            drawnow; 
            pause(1)

            app.figure1.SizeChangedFcn = @(src,event)ResizeWholeApp(app);
            drawnow; 

            % ResizeWholeApp(app); %dk commented for the above instead 
            disp('resize ran INSIDE openingfcn')

            fprintf('tAll: %.4f sec\n', toc(tAll))

            fprintf('[STARTUP] before final drawnow %.4f sec\n', toc(tAll))
            fprintf('[STARTUP] final drawnow %.4f sec\n', toc(tDraw))
            fprintf('[STARTUP] after final drawnow %.4f sec\n', toc(tAll))

        end

        % Button pushed function: pbAssignNear
        function pbAssignNear_Callback(app, event)
            disp('running pbAssignNear_Callback')
            CancelInteractiveModes(app);
            
            % --- Executes on button press in pbAssignNear.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            FrameString = get(handles.stFrame2,'String');
            iFr = sscanf(FrameString, 'Frame %d', 1);

            if isempty(iFr) || isempty(FrameData) || iFr < 1 || iFr > numel(FrameData)
                fprintf('[Assign Nearest skipped] bad FrameData/iFr. FrameString="%s"\n', FrameString);
                RestoreNativePanZoom(app);
                return
            end

            iFr = round(iFr);


            PushUndo(app, handles, 'Assign Nearest');

            disp(['Assigning each Wf to the nearest mean Wf for Frame ' num2str(iFr)])

            %options(14)=0;     %I went with better code than this below
            %[centres,options,post] = Andkmeans(FrameData(iFr).Sp,size(SortData(iFr).Wf,1),SortData(iFr).Wf,options);
            %SortData(iFr).Clu=kmeans(FrameData(iFr).Sp,SortData(iFr).Wf,'Options',statset('MaxIter',0));
            
            %This code comes from Andlabkmeans, which is a copy of an older matlab
            %version of kmeans


            %mostly debug 


            tmpKeepCell = find(arrayfun(@(c) ...
                any(FrameData(iFr).Status(:) == c) && ...
                size(FrameData(iFr).mWf,1) >= c && ...
                all(isfinite(FrameData(iFr).mWf(c,:))), ...
                1:14));
            if isempty(tmpKeepCell)
                fprintf('[Assign Nearest skipped] no active clusters with valid mean waveforms\n');
                return
            end
            FindIncs = find(FrameData(iFr).Status(:) > 0); % Full-frame row indices for active spikes
            oldStatus = FrameData(iFr).Status(:); % Save statuses before Assign Nearest
            A = FrameData(iFr).Wfs(FindIncs,:); % Included spike waveforms, one spike per row
            B = FrameData(iFr).mWf(tmpKeepCell,:); % Active cluster mean waveforms, one cluster per row
            d2 = sum(A.^2,2) + sum(B.^2,2)' - 2*(A*B'); % Squared Euclidean distances from every spike to every mean waveform
            [~, minInds] = min(d2, [], 2); % For each spike, find nearest mean waveform
            

            newStatusForActiveRows = tmpKeepCell(minInds);

            for ii = 1:numel(tmpKeepCell)
                cID = tmpKeepCell(ii);

                rows = false(numel(FrameData(iFr).Status),1);
                rows(FindIncs(newStatusForActiveRows == cID)) = true;
    

                FrameData = ChangeStatus(app, handles, FrameData, iFr, rows, cID, 'pbAssignNear_Callback');
            end

            newStatus = FrameData(iFr).Status(:);
            nChanged = sum(oldStatus(:) ~= newStatus(:));


            for iCl=1:length(tmpKeepCell)
                rows = FrameData(iFr).Status(:) == tmpKeepCell(iCl);
                FrameData(iFr).mWf(tmpKeepCell(iCl),:) = mean(FrameData(iFr).Wfs(rows,:), 1);
            end
            
            set(handles.frUnits,'UserData',FrameData); % Keep legacy storage updated
            app.FrameDataStore = FrameData; % Keep stable app-level storage updated
            RedrawSortMovie(app, handles,iFr, true); % Redraw after reassignment
            PrintFrameClusters(app, FrameData, iFr, 'Assign Nearest'); 
        end

        % Button pushed function: pbBackSort
        function pbBackSort_Callback(app, event)
            % --------------------------------------------------------------------
            CancelInteractiveModes(app);
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            FrameData = app.FrameDataStore; 
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            if isempty(FrameData)
                fprintf('[Back skipped] No FrameData loaded.\n');
                return
            end

            FrameString = get(handles.stFrame2,'String');
            FrameNumber = sscanf(FrameString, 'Frame %d', 1);

            if isnan(FrameNumber)
                fprintf('[Back skipped] Bad frame label: %s\n', FrameString);
                return
            end


            % Save current frame reassignment boxes before moving
            for iClus = 1:14
                FrameData(FrameNumber).ReassignMap(iClus) = app.(['pop' num2str(iClus)]).Value;
            end

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            if FrameNumber > 1
                PrevFrame = FrameNumber - 1;
                disp(['Back sort movie from ' num2str(FrameNumber) ' to ' num2str(PrevFrame)]);
                PrintFrameClusters(app, FrameData, PrevFrame, 'Back Frame');
                RedrawSortMovie(app, handles, PrevFrame, true);
            else
                disp(['At beginning of movie. FrameNumber=' num2str(FrameNumber)]);
            end
        end

        % Callback function
        function pbDefAssign_Callback(app, event)
            return 
            disp('running pbDefAssign_Callback')
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            PushUndo(app, handles, 'Default Assign');
            
            FrameData = app.FrameDataStore; % Get the app-level frame data
            FrameString = app.stFrame2.Text; % Read current frame label, e.g. "Frame 3"
            iFr = sscanf(FrameString, 'Frame %d', 1); % Convert "Frame 3" into numeric 3
        
            for iCl = 1:14 % Loop through cluster rows 1 through 14
                app.(['pop' num2str(iCl)]).Value = iCl; % Reset reassignment dropdown row iCl back to itself
                FrameData(iFr).ReassignMap(iCl) = iCl; % Save dropdown reset into this frame
                newIso = any(FrameData(iFr).Status(:) == iCl); % Checked only if this active cluster currently has spikes
                FrameData(iFr).Iso(iCl) = newIso; % Save checkbox state into this frame
            end
            app.FrameDataStore = FrameData; % Save updated frame state back into app memory
            %UpdateAllQLabels(app, handles, iFr);
            
            PrintFrameClusters(app, FrameData, iFr, 'Default'); 
        end

        % Button pushed function: pbForwardSort
        function pbForwardSort_Callback(app, event)
            CancelInteractiveModes(app);
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                fprintf('[Forward skipped] No FrameData loaded.\n');
                return
            end

            nFrames = numel(FrameData);

            FrameString = get(handles.stFrame2,'String');
            FrameNumber = sscanf(FrameString, 'Frame %d', 1);
            
            if isnan(FrameNumber)
                fprintf('[Forward skipped] Bad frame label: %s\n', FrameString);
                return
            end

            % Save current frame reassignment boxes before moving
            for iClus = 1:14
                FrameData(FrameNumber).ReassignMap(iClus) = app.(['pop' num2str(iClus)]).Value;
            end
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
            if FrameNumber < nFrames
                NextFrame = FrameNumber + 1;
                disp(['Forward sort movie from ' num2str(FrameNumber) ' to ' num2str(NextFrame)]);
                PrintFrameClusters(app, FrameData, NextFrame, 'Forward Frame');
                RedrawSortMovie(app, handles, NextFrame, true);
            else
                disp(['At end of movie. FrameNumber=' num2str(FrameNumber) ', nFrames=' num2str(nFrames)]);
            end
        end

        % Callback function
        function pbGoToSort_Callback(app, event)
            % --- Executes on button press in pbGoToSort.
            fprintf('[pbGoToSort_Callback disabled]\n');
            return

        end

        % Button pushed function: pbLoad
        function pbLoad_Callback(app, event)
            disp('running pbLoad_Callback')
            CancelInteractiveModes(app);
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.UndoStack = {};%clear undo history 

            % Get selected session path and selected spike/sort-session file.
            [startPath, spikeFileName, spikeFile, elec, ok] = ResolveSpikeFileFromUI(app, true);
            if ~ok
                fprintf('[LOAD skipped] no valid selected spike/session file\n');
                return
            end
            fprintf('[LOAD DEBUG] full spike file path = "%s"\n', spikeFile);
            fprintf('[LOAD DEBUG] isfile(spikeFile) = %d\n', isfile(spikeFile));

            %dispatcher, which load to call? 
        
            Sload = load(spikeFile);
        
            if isfield(Sload, 'FrameData') || ...
               (isfield(Sload, 'SaveState') && isfield(Sload.SaveState, 'FrameData'))
        
                LoadAppSession(app, handles, Sload, spikeFile, startPath, spikeFileName, elec);
                app.FirstSpikeAbsUS = double(Sload.ExportInfo.FirstSpikeAbsUS);
                app.RecordingStartAbsUS = double(Sload.ExportInfo.RecordingStartAbsUS);
            elseif isfield(Sload, 'Wfs')
        
                LoadUnsortedSpikes(app, handles, Sload, spikeFile, startPath, spikeFileName, elec);         
                app.FirstSpikeAbsUS = double(Sload.FirstSpikeAbsUS);
                app.RecordingStartAbsUS = double(Sload.RecordingStartAbsUS);
            else
                error('Unknown file type. File has no FrameData and no Wfs: %s', spikeFile)
            end
            
            return
        end

        % Button pushed function: pbManualSort
        function pbManualSort_Callback(app, event)
            disp('running pbManualSort_Callback')
            CancelInteractiveModes(app);
            app.pbManualSort.Text = 'Manual Sort: ON';
            cleanupManualButton = onCleanup(@() set(app.pbManualSort, 'Text', 'Manual Sort: OFF'));
        
        
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
        
            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);
        
            Dim1 = get(handles.popDim1,'Value');
            Dim2 = get(handles.popDim2,'Value');
            Dim3 = get(handles.popDim3,'Value');


            PushUndo(app, handles, 'Manual Sort');

            statusNow = FrameData(FrNum).Status(:);
        
            % active positive unit IDs already used
            usedPositive = unique(statusNow(statusNow > 0));
        
            % finalized negative unit IDs already used
            usedFinalized = abs(unique(statusNow(statusNow < 0)));
        
            % do not reuse active/finalized unit IDs
            reserved = unique([usedPositive; usedFinalized]);
        
            available = setdiff(2:14, reserved);
            if isempty(available)
                fprintf('[Manual Sort skipped] no unused non-finalized cluster IDs left\n');
                return
            end
        
            c = available(1);


            %changed

            % first click chooses the PC plot AND counts as the first polygon point
            [xFirst, yFirst, ButPressFirst] = ginput(1);
            clickedAx = gca;

            % first click must be LEFT click inside one of the three PC plots
            if isempty(ButPressFirst) || ButPressFirst ~= 1 || ...
                    ~(clickedAx == handles.axDim12 || clickedAx == handles.axDim13 || clickedAx == handles.axDim23)

                disp('Manual sort cancelled: first click was not a left-click inside axDim12, axDim13, or axDim23.')
                return
            end

            axes(clickedAx);
            hold(clickedAx, 'on');


            % choose which PCA dimensions match the clicked plot
            if clickedAx == handles.axDim12
                d1 = Dim1;
                d2 = Dim2;
            elseif clickedAx == handles.axDim13
                d1 = Dim1;
                d2 = Dim3;
            else
                d1 = Dim2;
                d2 = Dim3;
            end
        
            % only active positive spikes are editable
            pcRows = FrameData(FrNum).Status(:) > 0;
        
            % fallback if Uplot is missing
            if ~isfield(FrameData(FrNum), 'Uplot') || isempty(FrameData(FrNum).Uplot)
                FrameData(FrNum).Uplot(:,1:6) = FrameData(FrNum).U(:,1:6);
            end
        
            if size(FrameData(FrNum).Uplot,2) < max([d1 d2])
                fprintf('[Manual Sort skipped] selected dims [%d %d] but Uplot only has %d dims\n', ...
                    d1, d2, size(FrameData(FrNum).Uplot,2));
                return
            end
        
            % plotted 2D PC coordinates for active spikes only
            U_seg = FrameData(FrNum).Uplot(pcRows, [d1 d2]);
        

            ButPress = 1;
            UserX = xFirst;
            UserY = yFirst;
            loop = 1;
            templines = gobjects(0);

            templines(loop) = plot(clickedAx, UserX, UserY, ...
                'Color', mycolors(app, c), ...
                'LineWidth', 1.5, ...
                'Tag', 'ManualSortTemp');


            while ButPress == 1

                [xClick, yClick, ButPress] = ginput(1);
                finishAfterThisPoint = ~isempty(ButPress) && ButPress ~= 1;
                app.figure1.Pointer = 'arrow';
                currentAx = gca;

                % If another button cancelled Manual Sort while ginput was active, stop immediately.
                if ~strcmp(app.pbManualSort.Text, 'Manual Sort: ON')
                    disp('[Manual Sort cancelled during drawing]')
                    delete(findall(app.figure1, 'Tag', 'ManualSortTemp'));
                    RestoreNativePanZoom(app);
                    return
                end

                % clicked another plot: cancel manual sort
                if currentAx ~= clickedAx
                    figPt = app.figure1.CurrentPoint;
                    axPos = clickedAx.Position;
                    xLim0 = clickedAx.XLim;
                    yLim0 = clickedAx.YLim;

                    xClick = xLim0(1) + ((figPt(1) - axPos(1)) / axPos(3)) * diff(xLim0);
                    yClick = yLim0(1) + ((figPt(2) - axPos(2)) / axPos(4)) * diff(yLim0);

                    axes(clickedAx);
                    currentAx = clickedAx;
                end

                % clicked inside same axes but outside visible plot region: ignore and keep waiting
                xLim = xlim(clickedAx);
                yLim = ylim(clickedAx);

                if xClick < xLim(1) || xClick > xLim(2) || yClick < yLim(1) || yClick > yLim(2)
                    ExpandAxisToIncludePoint(app, clickedAx, xClick, yClick);
                    axes(clickedAx);
                end

                % right-click / non-left-click on original plot: finish polygon
                if isempty(ButPress)
                    break
                end

                loop = loop + 1;

                UserX(end+1) = xClick;
                UserY(end+1) = yClick;

                templines(loop) = plot(clickedAx, UserX, UserY, ...
                    'Color', mycolors(app, c), ...
                    'LineWidth', 1.5, ...
                    'Tag', 'ManualSortTemp');
                if finishAfterThisPoint
                    break
                end
            end


            % need at least 3 polygon points
            if numel(UserX) < 3
                disp('Manual sort cancelled: fewer than 3 polygon points.')
                delete(templines(isvalid(templines)))
                return
            end
        
            UserX(end+1) = UserX(1);
            UserY(end+1) = UserY(1);
        
            loop = loop + 1;
            templines(loop) = plot(clickedAx, UserX, UserY, ...
                'Color', mycolors(app, c), ...
                'LineWidth', 1.5, ...
                'Tag', 'ManualSortTemp');

            % If another button cancelled Manual Sort after the last click, do not assign anything.
            if ~strcmp(app.pbManualSort.Text, 'Manual Sort: ON')
                disp('[Manual Sort cancelled before assignment]')
                delete(findall(app.figure1, 'Tag', 'ManualSortTemp'));
                RestoreNativePanZoom(app);
                return
            end
        
            disp('Assigning spikes within polygon')
        
            ind = inpolygon(U_seg(:,1), U_seg(:,2), UserX, UserY);
        
            FindIncs = find(pcRows);
        
            if any(ind)
                disp(['Found ' num2str(sum(ind)) ' spikes'])
        
                rows = false(numel(FrameData(FrNum).Status),1);
                rows(FindIncs(ind)) = true;
        
                FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, c, 'pbManualSort_Callback');
            end
        
            FrameData(FrNum).Iso(c) = 1;
        
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        
            RedrawSortMovie(app, handles, FrNum, true);
            PrintFrameClusters(app, FrameData, FrNum, 'Manual Sort');


        end

        % Value changed function: pbPlaySort
        function pbPlaySort_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            nFrames = get(handles.stFrame2,'UserData');
            if get(handles.pbPlaySort,'Value')
                set(handles.pbPlaySort,'String','Ⅱ');
            else
                set(handles.pbPlaySort,'String','▶');
                set(handles.pbPlaySort,'Value',0)
            end
            for iFrame = 1:nFrames
                if get(handles.pbPlaySort,'Value')
                    RedrawSortMovie(app, handles,iFrame, true);
                else
                    set(handles.pbPlaySort,'Value',0)
                    set(handles.pbPlaySort,'String','▶');
                    break
                end
            end
            set(handles.pbPlaySort,'String','▶');
            set(handles.pbPlaySort,'Value',0)
        end

        % Button pushed function: pbReassign
        function pbReassign_Callback(app, event)
            CancelInteractiveModes(app);
            disp('RUNNING pbReassign_Callback')

            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            if isempty(FrameData)
                fprintf('[Reassign skipped] No FrameData loaded.\n');
                return
            end
        
            FrameString = get(handles.stFrame2,'String');
            

            CurFr = sscanf(FrameString, 'Frame %d', 1);


            if isnan(CurFr) || CurFr < 1 || CurFr > numel(FrameData)
                fprintf('[Reassign skipped] Bad current frame.\n');
                return
            end
            keepBlockedIsoSubset = false;
            keepBlockedIsoFr = NaN;
            keepBlockedIsoRows = [];
            keepBlockedIsoStatus = NaN;

            % Ignore negative reassignment entries. Valid targets are 0-14 only.
            for iClus = 1:14
                popName = ['pop' num2str(iClus)];
                v = app.(popName).Value;

                if v < 0
                    if isfield(FrameData(CurFr),'ReassignMap') && numel(FrameData(CurFr).ReassignMap) >= iClus
                        app.(popName).Value = FrameData(CurFr).ReassignMap(iClus);
                    else
                        app.(popName).Value = iClus;
                    end
                end
            end


            % ============================================================
            % PRIORITY 1: SELECTED-SPIKE DROPDOWN
            % ============================================================
        
            spikeTarget = ParsePop15Target(app);

            if ~isnan(spikeTarget)

                [FrNum, rows, rowList, ok] = GetSelectedSpikeRows(app, FrameData);

                if ~ok 
                    return
                end 

                if spikeTarget == 0 
                    spikeTarget = 1; 
                end 

                if any(FrameData(FrNum).Status == -spikeTarget) && spikeTarget > 0
                    disp('continued since cluster has removed as iso in same frame. didnt reassign:')
                    fprintf('selection to %d\n', spikeTarget)
                    app.pop15.Value = '';
                    return
                end 

                if ok

                    oldStatus = FrameData(FrNum).Status(rowList);

                    % Block partial un-isolation:
                    % selected subset of Status -k cannot be reassigned directly to +k.
                    % Whole-cluster recovery must use Recover Selected Cluster instead.
                    blockRows = oldStatus < 0 & spikeTarget == abs(oldStatus);

                    if any(blockRows)
                        fprintf('[Reassign selected skipped for %d row(s)] cannot partially un-isolate -%d -> +%d. Use Recover Selected Cluster for whole cluster.\n', ...
                            sum(blockRows), spikeTarget, spikeTarget);
                    end

                    allowedRowList = rowList(~blockRows);

                    if ~isempty(allowedRowList)

                        allowedRows = false(numel(FrameData(FrNum).Status),1);
                        allowedRows(allowedRowList) = true;

                        PushUndo(app, handles, 'Reassign Selected Spike');

                        FrameData = ChangeStatus(app, handles, FrameData, FrNum, allowedRows, spikeTarget, ...
                            'SPIKEpbReassign_Callback');

                        fprintf('[Reassign Selected Spike(s)] Fr %d rows=%s n=%d -> %d\n', ...
                            FrNum, mat2str(allowedRowList(:)'), numel(allowedRowList), spikeTarget);

                        app.pop15.Value = '';
                        app.SelectedSpikeRows = [];

                        app.SelectedSpikeFrame = NaN;

                        app.SelectedNoiseNumber = NaN;
                        UpdateSelectedSpikeRemoveButtons(app);
                        app.LastHoverSpikeFrame = NaN;
                        app.LastHoverSpikeRow = NaN;

                        set(handles.frUnits,'UserData',FrameData);
                        app.FrameDataStore = FrameData;

                        RedrawSortMovie(app, handles, FrNum, true);

                        cla(app.axWaveforms_3);
                        SetSelectedSpikeLabel(app, '');

                        PrintFrameClusters(app, FrameData, FrNum, 'Reassign Selected Spike');
                        return


                    else

                        % Nothing selected-spike-wise is allowed.
                        % Save this subset so it can be restored AFTER the cluster-dropdown
                        % reassignments redraw the app.
                        app.pop15.Value = '';

                        keepBlockedIsoSubset = true;
                        keepBlockedIsoFr = FrNum;
                        keepBlockedIsoRows = rowList(:);
                        keepBlockedIsoStatus = -spikeTarget;

                        app.SelectedSpikeRows = rowList(:);
                        app.SelectedClusterFrame = FrNum;
                        app.SelectedClusterNum = -spikeTarget;
                        app.SelectedNoiseNumber = NaN;
                        app.SelectedSpikeFrame = FrNum;

                        SetSelectedSpikeLabel(app, sprintf('%d', numel(app.SelectedSpikeRows)));

                        fprintf('[Reassign selected skipped] all selected rows were blocked partial un-isolation. Will restore selected subset after cluster dropdown reassignments.\n');

                    end

                else

                    fprintf('[Reassign] pop15 target ignored because no selected spike exists. Running pop1-pop14 reassign.\n');
                    app.pop15.Value = '';

                end
        
                
            end


            % ============================================================
% PRIORITY 2: CLUSTER DROPDOWNS pop1-pop14 ON CURRENT FRAME ONLY
% If pop15 is NaN, Reassign applies cluster-row dropdowns.
            % ============================================================
        
            FrNum = CurFr;
        
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[Reassign skipped] Current frame has no Status.\n');
                return
            end
        
            OldStatus = FrameData(FrNum).Status(:);
            NewStatus = OldStatus;
        
            anyDropdownChanged = false;
        
            for iCl = 1:14
                newCl = app.(['pop' num2str(iCl)]).Value;
                if newCl == 0
                    newCl = 1; 
                    app.(['pop' num2str(iCl)]).Value = 1;

                end

                %important: you cannot reassign to clusters that are
                %removed as isolated in the same frame
                if any(FrameData(FrNum).Status == -newCl) && newCl > 0 
                    disp('continued since cluster has removed as iso in same frame. didnt reassign:')
                    fprintf('%d to %d\n', iCl, newCl)
                    app.(['pop' num2str(iCl)]).Value = iCl;

                    continue
                end 

                if newCl ~= iCl
                    anyDropdownChanged = true;
                end

                rows = OldStatus == iCl;

                
                if newCl >= 1 && newCl <= 14 && newCl == round(newCl)
                    NewStatus(rows) = newCl;   % Active cluster 1-14
                elseif newCl < 0 && newCl == round(newCl)

                    % If user is reassigning cluster iCl to any negative number,
                    % force it to become -iCl. Example: pop5 = -3 becomes -5.
                    newCl = -iCl;
                
                    NewStatus(rows) = newCl;
                
                    % Show actual locked/finalized unit in the numeric box
                    app.(['pop' num2str(iCl)]).Value = newCl;

                else
                    fprintf('[Reassign skipped] Bad target in pop%d: %g\n', iCl, newCl);
                    return
                end
            end

                    
            changedRows = NewStatus(:) ~= OldStatus(:);
        
            if ~anyDropdownChanged || ~any(changedRows)
                fprintf('[Reassign skipped] No selected-spike target and no cluster dropdown changes.\n');
                return
            end
        
            PushUndo(app, handles, 'Reassign Clusters');
        
            
            FrameData = ChangeStatus(app, handles, FrameData, FrNum, changedRows, NewStatus(changedRows), 'CLUSTERpbReassign_Callback');

            fprintf('[Reassign Clusters] Fr %d changed %d spikes\n', FrNum, sum(changedRows));
        
            FrameData(FrNum).ReassignMap = 1:14;
            
            for iClus = 1:14
                app.(['pop' num2str(iClus)]).Value = iClus;
            
                labelName = ['st' num2str(iClus)];
                if isprop(app, labelName)
                    app.(labelName).Text = sprintf('%d', iClus);
                end
            end
            
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;


            RedrawSortMovie(app, handles, CurFr, true);

            if keepBlockedIsoSubset

                FrameData = app.FrameDataStore;
                if isempty(FrameData)
                    FrameData = get(handles.frUnits,'UserData');
                end

                validRows = keepBlockedIsoRows(:);
                validRows = validRows(validRows >= 1 & validRows <= numel(FrameData(keepBlockedIsoFr).Status));
                validRows = validRows(FrameData(keepBlockedIsoFr).Status(validRows) == keepBlockedIsoStatus);

                app.SelectedSpikeRows = validRows(:);
                app.SelectedClusterFrame = keepBlockedIsoFr;
                app.SelectedClusterNum = keepBlockedIsoStatus;
                app.SelectedNoiseNumber = NaN;
                app.SelectedSpikeFrame = keepBlockedIsoFr;
                app.SelectedSpikeRows = [];
                app.LastHoverSpikeFrame = NaN;
                app.LastHoverSpikeRow = NaN;

                PlotSelectedCluster(app, FrameData);

                cla(app.axWaveforms_3);
                hold(app.axWaveforms_3, 'on');

                if ~isempty(validRows)
                    Wsel = FrameData(keepBlockedIsoFr).Wfs(validRows,:);
                    x = 1:size(Wsel,2);

                    for iSel = 1:size(Wsel,1)
                        line(app.axWaveforms_3, x, Wsel(iSel,:), ...
                            'Color', mycolors(app, abs(keepBlockedIsoStatus)), ...
                            'LineWidth', 0.75, ...
                            'HitTest', 'off', ...
                            'PickableParts', 'none');
                    end

                    SetSelectedSpikeLabel(app, sprintf('%d', numel(validRows)));
                else
                    SetSelectedSpikeLabel(app, '0');
                end

                app.axWaveforms_3.YLimMode = 'auto';
                app.axWaveforms_3.YTickMode = 'auto';
                app.axWaveforms_3.YTickLabelMode = 'auto';
                app.axWaveforms_3.FontSize = 8;

                hold(app.axWaveforms_3, 'off');


            end

            PrintFrameClusters(app, FrameData, CurFr, 'Reassign Clusters');
        end

        % Button pushed function: pbResort
        function pbResort_Callback(app, event)
            disp('RUNNING pbResort_Callback')
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % This resorts only the current frame using a kmeans algorithm
            
            NDim = get(handles.popNDim,'Value');
            NClus = get(handles.popNClus,'Value');
            FrameData = app.FrameDataStore; % Use stable app-level FrameData instead of old GUIDE UserData storage
                        
            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            fprintf('[KMeans Resort DEBUG before PrintFrameClusters] FrNum=%d numel(FrameData)=%d class(FrameData)=%s\n', ...
                FrNum, numel(FrameData), class(FrameData));
            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[KMeans Resort ERROR] bad FrameData/FrNum before PrintFrameClusters\n');
                return
            end

            PrintFrameClusters(app, FrameData, FrNum, 'KMeans Resort'); 
            if isempty(FrameData) % Fallback only if app-level storage has not been filled yet
                FrameData = get(handles.frUnits,'UserData'); % Old migrated GUIDE storage fallback
            end 


            % IncCells=[];
            % for c=1:NClus
            %     if ~FrameData(FrNum).RemCell(c)
            %         IncCells=[IncCells c];
            %     end
            % end


            statusNow = FrameData(FrNum).Status(:);

            usedFinalized = abs(unique(statusNow(statusNow < 0)));
            available = setdiff(1:14, usedFinalized);

            if numel(available) < NClus
                fprintf('[KMeans skipped] need %d cluster IDs but only %d non-finalized IDs available\n', ...
                    NClus, numel(available));
                return
            end

            IncCells = available(1:NClus);
            NClus = numel(IncCells);
            
            %IncSpikes=find(FrameData(FrNum).TempInclude);
            pcRows = FrameData(FrNum).Status(:) > 0;
            

            NDim = min(NDim, size(FrameData(FrNum).Uplot,2));
            U_seg = FrameData(FrNum).Uplot(pcRows,1:NDim);


            %U_seg = U_seg(IncSpikes,:);
            FrameData(FrNum).NDim = NDim;
            
            % [centres,options,post] = Andkmeans(U_seg,NClus);
            % [dum,cind] = sort(sqrt(sum(centres'.^2)));
            % centres = centres(cind,:);
            % options(14)=1;
            % [centres,options,post] = Andkmeans(U_seg,NClus,centres,options);

            [idx, centres] = kmeans(U_seg, NClus, 'Replicates', 5, 'MaxIter', 200); % Run MATLAB k-means on current-frame PC points
            [~, cind] = sort(sqrt(sum(centres.^2, 2))); % Sort centers by distance from origin for stable cluster numbering
            remap = zeros(size(cind)); % Create lookup table for remapping raw k-means cluster labels
            remap(cind) = 1:numel(cind); % Closest center becomes cluster 1, next becomes cluster 2, etc.
            idx = remap(idx); % Apply remapped labels to every spike
            post = false(size(U_seg,1), NClus); % Recreate old Andkmeans-style one-hot membership matrix
            for ii = 1:NClus % Loop through clusters
                post(:,ii) = idx == ii; % Mark spikes belonging to cluster ii
            end

            PushUndo(app, handles, 'KMeans Resort');
            
            FindIncs = find(pcRows); % Convert active-status mask into full-frame spike row indices
            for c = 1:NClus % Loop through each k-means cluster
                ind = find(post(:,c)); % Get U_seg rows assigned to cluster c
                if ~isempty(ind) % Only process clusters with assigned spikes
                    realIdx = FindIncs(ind); % Convert U_seg row indices back to full-frame spike indices
                    FrameData(FrNum).Status(realIdx) = IncCells(c); % Store active cluster status in the correct full-frame rows
                    FrameData(FrNum).Clu(realIdx) = IncCells(c); % temporary legacy sync
                    FrameData(FrNum).mWf(IncCells(c),:) = mean(FrameData(FrNum).Wfs(realIdx,:), 1); % Update this cluster's mean waveform
                end
            end

            %DEBUG 
            for c = 1:NClus % Loop over each requested cluster
                fprintf('cluster %d assigned %d spikes\n', c, sum(post(:,c))); % Prints spike count in each k-means cluster
            end


            % FrameData(FrNum).mWf( IncCells( IncCells>NClus ),: ) % Debug print only; can error from bad indices


            FrameData(FrNum).Iso(:) = 0;

            for ii = 1:numel(IncCells)
                cID = IncCells(ii);

                if any(FrameData(FrNum).Status(:) == cID)
                    FrameData(FrNum).Iso(cID) = 1;
                    FrameData(FrNum).mWf(cID,:) = mean(FrameData(FrNum).Wfs(FrameData(FrNum).Status(:) == cID,:), 1);
                else
                    FrameData(FrNum).Iso(cID) = 0;
                    FrameData(FrNum).mWf(cID,:) = NaN(1, size(FrameData(FrNum).Wfs,2));
                end
            end


            set(handles.frUnits,'UserData',FrameData);      % Keep old GUIDE-style storage updated for legacy callbacks
            app.FrameDataStore = FrameData;                 % Keep real app-level storage updated after k-means changes clusters
            handles = RedrawSortMovie(app, handles,FrNum, true);  % Redraw PC plots and mean waveforms using the updated clusters
            PrintFrameClusters(app, FrameData, FrNum, 'kmeans'); 
            %UpdateAllQLabels(app, handles, FrNum);
        end

        % Button pushed function: pbExpAppSess
        function pbExpAppSess_Callback(app, event)
            disp('running pbExpAppSess_Callback')
            % ------------------------------------------------------------
            % 1. GUIDE-style handles + output folder
            % ------------------------------------------------------------
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
        
            if isempty(app.figure1.UserData) || ...
               ~isstruct(app.figure1.UserData) || ...
               ~isfield(app.figure1.UserData, 'filepath')
        
                error('No loaded spike-file folder found. Load a spike file before saving.')
            end
        
            filepath = app.figure1.UserData.filepath;
        
            if isempty(filepath) || ~isfolder(filepath)
                error('Output filepath is empty or not a valid folder: %s', filepath)
            end
        
            disp(['SAVE DEBUG output filepath = ' filepath])
        
        
            % ------------------------------------------------------------
            % 2. Get current FrameData
            % ------------------------------------------------------------
            FrameData = app.FrameDataStore;
        
            if isempty(FrameData)
                FrameData = get(handles.frUnits, 'UserData');
            end
        
            if isempty(FrameData)
                error('No FrameData loaded. Nothing to save.')
            end
        
        
            % ------------------------------------------------------------
            % 3. Get selected subject/session/spike file/electrode
            % ------------------------------------------------------------
        
            LoadInfo = app.figure1.UserData;

            if isfield(LoadInfo, 'inputFileName') && ~isempty(LoadInfo.inputFileName)
                spikeFileName = LoadInfo.inputFileName;
            else
                [~, spikeFileName, ~, ~, ok] = ResolveSpikeFileFromUI(app, false);
                if ~ok
                    fprintf('[SAVE SESSION skipped] no valid input file name\n');
                    return
                end
            end
            if isempty(spikeFileName)
                fprintf('[SAVE skipped] no spike file selected\n');
                return
            end


            if strcmp(spikeFileName, '-- select spike file --') || strcmp(spikeFileName, 'no files')
                fprintf('[SAVE skipped] no spike file selected\n');
                return
            end
        
            Subj = app.popSubject.Value;
            Sess = app.popSession.Value;
        
            elec = str2double(app.edElec.Value);
            if isnan(elec)
                elec = 1;
            end


            % ------------------------------------------------------------
            % 5. Save + verify
            % ------------------------------------------------------------
            % ------------------------------------------------------------
            % 5. Save exported sorted-spikes file
            % This file can be imported back into the app because it includes FrameData.
            % ------------------------------------------------------------


            % Build output filename from loaded input file name
            LoadInfo = app.figure1.UserData;
            filepath = LoadInfo.filepath;

            inputFileName = LoadInfo.inputFileName;   % e.g. CSC25spikes.mat


            [~, loadedBaseName, ~] = fileparts(inputFileName);

            % NEW DK: never append SortSession.
            % Canonical base = raw OSort name, without app append strings.
            % Preserves optional OSort duplicate suffix like _1 if present.
            tok = regexp(loadedBaseName, ...
                '^(RawSortDat_NS\d+_sess\d+_ch\d+_Thresh[^_]+(?:_\d+)?)', ...
                'tokens', 'once');

            if ~isempty(tok)
                baseName = tok{1};
            else
                baseName = loadedBaseName;
            end


            appendStr = '';

            overwriteCurrentOnly = false;

            if isvalid(app.edFilenameAppend)
                appendStrRaw = strtrim(app.edFilenameAppend.Value);
                overwriteCurrentOnly = strcmp(appendStrRaw, '*');
                appendStr = appendStrRaw;
            end

            %%
            % if _kilsort.mat (kilosort output) is loaded, 
                % save typing box empty and hit save —> saves ...Thresh0.mat 
                % save box has * —> same thing as above 
                % so there is no way to overwrite the _kilosort outputs 
                % ALSO: doing * saves the anlayssis vars too if it's a
                % kilsort thats laoded fix this later 
            loadedIsKilosortRawSortDat = contains(loadedBaseName, '_kilosort');

            if overwriteCurrentOnly
                appendStr = '';
                [~, baseName, ~] = fileparts(inputFileName);  % overwrite exact loaded file
            end

            % Never overwrite a loaded RawSortDat_*_kilosort.mat from the app.
            % If the filename box is empty or "*", save to the canonical non-kilosort
            % RawSortDat name instead.
            if loadedIsKilosortRawSortDat && isempty(appendStr)
                nonKilosortBaseName = erase(baseName, '_kilosort');

                fprintf('''_kilosort.mat'' cannot be overwritten. Created non-kilosort RawSortDat instead:\n');
                fprintf('%s.mat\n', fullfile(filepath, nonKilosortBaseName));

                baseName = nonKilosortBaseName;
                overwriteCurrentOnly = false;
            end

            appendStr = regexprep(appendStr, '[^\w-]', '_');
            %%

            if ~isempty(appendStr) && ~startsWith(appendStr, '_')
                appendStr = ['_' appendStr];
            end

            SortSessionFile = fullfile(filepath, [baseName appendStr '.mat']);
            SrtdSpikesFile = SortSessionFile;
   

            ExportInfo = struct();
            ExportInfo.FileType = 'AppSortSession';
            ExportInfo.AppName = 'COPYMattSpikeSortGUI_Human_App';
            ExportInfo.Subject = Subj;
            ExportInfo.Session = Sess;
            ExportInfo.Electrode = elec;
            ExportInfo.SavedAt = datestr(now);
            ExportInfo.ContainsFrameData = true;
            ExportInfo.ContainsAllFrames = true;
            ExportInfo.StatusMeaning = '+u active unit, -u isolated/finalized unit, 0 noise';
            ExportInfo.FirstSpikeAbsUS = app.FirstSpikeAbsUS; 
            ExportInfo.RecordingStartAbsUS = app.RecordingStartAbsUS; 

            %save the frame size to auto populate when import 
            ExportInfo.IsoWinSec = str2double(app.edIsoWin.Value);
            ExportInfo.nFrames = numel(FrameData);
            ExportInfo.FilenameAppend = appendStr;
            ExportInfo.OutputSortSessionFile = SortSessionFile;

            FrameData = app.FrameDataStore;

            save(SortSessionFile, ...
                'ExportInfo', ...
                'FrameData', ...
                '-v7.3');

            fprintf('[Save SortSession] saved:\n%s\n', SortSessionFile);

            fprintf('[Save SrtdSpikes] saved:\n%s\n', SrtdSpikesFile);       


            if isempty(appendStr) && ~overwriteCurrentOnly
                pbExpAnalysisVarsButtonPushed(app, event);
            else
                fprintf('[Save/Export] append string is non-empty, skipped separate AnalysisVars export.\n');
            end


            % %First save the sort info for future SpikeSortSessions... just save FrameData b/c of denoise spikes GUI, I decided we need to save the corresponding RawSortDat (which might change later) too
            % RawSortDat=get(handles.frSort,'UserData');
            % %save(SvDat.Sfilename,'FrameData','T','RawSortDat','spkTimes')  %RawSortDat is being saved as empty?...
            % 
            % %Now save sorted spike times (and isos) per unit for other programs
            % tic
            % disp('Assigning spikes to individual units within trial structure for session')
            % 
            % %Strategy below- first gather spikes and clus from all the frames into one, then assign spikes by trial type and cell over the entire session
            % Tr=get(handles.frTrials,'UserData');
            % SpkSavOpt=get(handles.pbSave,'UserData');
            % 
            % %load CurTS to add spikes to it for OfflineRFFlashCalc
            % if sum(Tr.RFFlashTrial)
            %     if exist([SvDat.filepath 'RFFlashRawSrtdFEF' day '.mat'],'file')
            %         load([SvDat.filepath 'RFFlashRawSrtdFEF' day],'CurTs')
            %         if isempty(CurTs) || ~any(CurTs(:,1)==1 & CurTs(:,2)==RFElec)      %checking for previous spikes from this elec
            %             SaveRFFlag=1;
            %         else
            %             OverRideFlag=1; %adjust this value manually in the code if you do or do not want to override saving over spikes to the RFFlash data
            %                         %why wouldn't you want to override the previously saved data?... not sure why I bothered to include this check before overriding features, but oh well
            %             if OverRideFlag
            %                 SaveRFFlag=1;
            %                 CurTs( CurTs(:,1)==1 & CurTs(:,2)==RFElec,: )=[];    %removes all spikes for this channel from CurTS so we can add all new spikes below
            %             else
            %                 disp(['Found spikes in existing RF Raw file; new spikes not being saved to that.'])
            %                 disp(['Set OverRideFlag to 1 in pbSave code in MattSpikeSortGUI to instead remove existing saved Spks and re-save new spikes'])
            % 
            %                 SaveRFFlag=0;   %this will make it so no RFDat will be saved, b/c presumably the spikes for this elec have already been saved
            %                 clear CurTs
            %             end
            %         end
            %     else
            %         disp(['Beh. Raw RF file not found. Not saving RF data. Check filepath or retranslate to create new Beh. Raw RF file.'])
            %         SaveRFFlag=0;
            %     end
            % else
            %     SaveRFFlag=0;
            % end
            % 
            % SaveJCheckFlag=0;
            % if sum(Tr.JCheckTrial)
            %     disp('Found JCheck trials. Loading separately saved JCheck data.')
            %     try
            %         load(SvDat.JCBehfilepathnname,'JCheck')
            %         SaveJCheckFlag=1;
            %     catch
            %         disp('Couldn''t find separately saved JCheck data, so not loading it.')
            %     end
            % end
            % 
            % if SaveRFFlag
            %     iRFEvInd=1;     %the strategy below is to as we go through each spike to compare the ts's of each incoming spike and event, and put the smaler ts in the next position of a new CurTs matrix that starts off empty...
            %     CurTsOld=CurTs;
            %     CurTsOld=[CurTsOld; 0 0 0 spkTimes(end)+1];  %put a fake event at the end that comes after any spike for copmaring to spikes of the last RFFlash Tr in the while loop
            %     %CurTs=[];  %we actually want to hang on to CurTs and pre-allocate it's size as much as possible, though that is tricky since we can't know how mamy spike ts's we'll need to add to it...
            % end
            % 
            % %determine which trial numbers of which type each overall trial belongs to
            % nTrByType=sum([Tr.MemTrial Tr.SStepTrial Tr.PATrial Tr.RFFlashTrial Tr.JCheckTrial]);
            % TrTypeList={'Mem','SStep','PA','RFFlash','JCheck'};  %Define type 1 for mem trial, 2 for sstep trial, 3 for PA trial, 4 for RFFlash trial
            % TaskType=repmat(NaN,length(Tr.TrialStart),1);
            % TaskTrInds=repmat(NaN,length(Tr.TrialStart),1); %This will be the TrInd for each respective Task
            % for iType=1:length(TrTypeList)
            %     curTrial=Tr.([TrTypeList{iType} 'Trial']);
            %     cursum=cumsum(curTrial);
            %     TaskTrInds(curTrial)=cursum(curTrial);
            % 
            %     TaskType(curTrial)=iType;
            % end
            % 
            % 
            % iSpk=1;
            % Totu=[];
            % CumIso=repmat(0,length(FrameData),14);
            % 
            % 
            % 
            % for iFr=1:length(FrameData)
            %     %disp(['On Frame ' num2str(iFr)])
            %     %OvTrInds = find(isbetween(Tr.TrialStart,[T(FrNum:FrNum+1)]));
            % 
            %     curu=find(FrameData(iFr).Iso);  %this will be all the cells present and isolated within the frame
            %     newCellInds=find(~ismember(curu,Totu));     %this will look for new cells to add to teh total list across frames
            %     for inC=1:length(newCellInds)
            %         Totu=[Totu; curu(newCellInds(inC))];
            %         for iType=1:length(TrTypeList)
            %             TotSpk(Totu(end)).(TrTypeList{iType}).spk=repmat(NaN,nTrByType(iType),5);   %for speed's sake, assume at least 5 spikes in one trial as the maximum over the whole session for that task
            %             TotSpk(Totu(end)).(TrTypeList{iType}).Iso=repmat(0,nTrByType(iType),1);
            %         end
            %         TotSpk(Totu(end)).day=day;
            %         TotSpk(Totu(end)).chbr=chbr;
            %         TotSpk(Totu(end)).elec=elec;
            %         TotSpk(Totu(end)).AP=AP;
            %         TotSpk(Totu(end)).ML=ML;
            %         TotSpk(Totu(end)).depth=depth;
            %     end
            %     spkcluLabsAll(iSpk:iSpk+length(FrameData(iFr).Clu)-1 )=FrameData(iFr).Clu;
            %     iSpk=iSpk+length(FrameData(iFr).Clu);
            % 
            %     CumIso(iFr,curu)=1;
            % end
            % 
            % %Now assign clus and isos for whole sess
            % %Going Trial by trial seems to be the most sensible way to do this
            % iSpk=1;   %embed a while loop of spikes within
            % iFr=0;
            % nTr=length(Tr.TrialStart);
            % iCurTs=1;
            % iJCT=0; %need to keep track of the number of JCheck Trs...
            % for iTr=1:nTr
            %     if mod(iTr,100)==0
            %         disp(['On Tr: ' num2str(iTr)])
            %     end
            % 
            %     %check to see if we're in a new Frame and need to update the curu list from the Iso field for this frame
            %     if Tr.TrialStart(iTr)>T(iFr+1)
            %         iFr=iFr+1;
            %         curu=find(FrameData(iFr).Iso);
            %     end
            % 
            %     %assign the isos for this trial
            %     for iu=1:length(curu)
            %         TotSpk(curu(iu)).(TrTypeList{ TaskType(iTr) }).Iso( TaskTrInds(iTr) )=1;    %the isos for any units missing this frame will be zero already because of how this was initialized
            %     end
            % 
            %     %assign spikes
            %     %get the Tr Lims for this kind of trial
            %     if TaskType(iTr)==4
            %         if SaveRFFlag
            %             if iTr<nTr
            %                 TrLims=[Tr.TrialStart(iTr)    Tr.TrialStart(iTr+1)];    %go from the start of this Tr to teh start of the next Tr...
            %             else
            %                 TrLims=[Tr.TrialStart(iTr)    min(spkTimes(end)+1,Tr.TrialStart(iTr)+4500)];   %if this is the last trial of the exp, go to the last spike of the exp (within reason... in case the exp was left recording for too long...)...
            %                 %This line was moved to belwo for all TrTypes
            %                 %spkTimes(end+1)=TrLims(2)+1000;  %For this case, add an extra fake spike beyond the end of the last trial so the while loops belwo don't run into problems with a 'Index exceeds matrix dimensions' error
            %             end
            %             CurTs( end+1:end+sum(isbetween(spkTimes,TrLims)),1:4 )=NaN;
            %         end
            %     elseif TaskType(iTr)==5     %Diff TrLims for JCheck Trs
            %         %TrLims=[Tr.TrialStart(iTr)+SpkSavOpt.JCminTrTime    Tr.TrialStart(iTr)+SpkSavOpt.JCmaxTrTime];
            %         iJCT=iJCT+1;
            %         if ~isnan(JCheck.nAlts(iJCT)) && JCheck.nAlts(iJCT)>0
            %             TrLims=[Tr.TrialStart(iTr)+SpkSavOpt.JCminTrTime    Tr.TrialStart(iTr)+JCheck.Alternate( iJCT,JCheck.nAlts(iJCT) )+300];
            %         else
            %             TrLims=[Tr.TrialStart(iTr)+SpkSavOpt.JCminTrTime    Tr.TrialStart(iTr)+300];    %these are probably bad trials, but record a few spks anyway
            %         end
            %     elseif TaskType(iTr)==1     %Diff TrLims for Mem Trs b/c of the Hold period
            %         TrLims=[Tr.TrialStart(iTr)+SpkSavOpt.MemminTrTime    Tr.TrialStart(iTr)+SpkSavOpt.MemmaxTrTime];
            %     else
            %         %TrLims is thus w/ respect to absolute time, so we don't have to subtract the TrialStartTime from every spike in the frame for every trial
            %         TrLims=[Tr.TrialStart(iTr)+SpkSavOpt.minTrTime    Tr.TrialStart(iTr)+SpkSavOpt.maxTrTime];
            %     end
            % 
            %     %if iTr==nTr;      spkTimes(end+1)=TrLims(2)+1000;     end     %For this case, add an extra fake spike beyond the end of the last trial so the while loops belwo don't run into problems with a 'Index exceeds matrix dimensions' error
            %     %even better than above is to do the same based on the time of the last spike for the elec, regardless of the trial number
            %     if spkTimes(end)<=TrLims(2);
            %         spkTimes(end+1)=TrLims(2)+9999999999999;   %make this a very large number so this will be larger than anything...
            %     end     %For this case, add an extra fake spike beyond the end of the last trial so the while loops belwo don't run into problems with a 'Index exceeds matrix dimensions' error
            % 
            % 
            %     %adjust the spike counter as needed to the first spike of this trial unless we're nto recording this trial (i.e. if this is an RF trial but we're not saving RF trials
            %     if ~(TaskType(iTr)==4 && ~SaveRFFlag)
            %         if spkTimes(iSpk)>TrLims(1)   %this may be the case if the end of the last trial period overlaps with the start of this trial period...
            %             if iSpk>1
            %                 while spkTimes(iSpk-1)>=TrLims(1)    %i.e. this would happen if the distance between the min and max Spk TrTim were large enough relative to the intertrial interval
            %                     iSpk=iSpk-1;        %so we go backwords until we find the first spike in the
            %                 end
            %             end
            %         else
            %             while spkTimes(iSpk)<=TrLims(1)    %this will happen if the start of this trial is past the end of the last trial
            %                 iSpk=iSpk+1;
            %                 %if iSpk>length(spkTimes)    %this shouldn't be needed with the added spike above
            %                 %    spkTimes(end+1)=TrLims(2)+1000;
            %                 %end
            %             end
            %         end
            %     end
            % 
            % 
            %     if TaskType(iTr)==4  %RF Flash Trs
            %         if SaveRFFlag
            %             while spkTimes(iSpk)<=TrLims(2)
            %                 while CurTsOld(iRFEvInd,4)<spkTimes(iSpk)
            %                     CurTs(iCurTs,:)=CurTsOld(iRFEvInd,:);
            %                     iRFEvInd=iRFEvInd+1;
            %                     iCurTs=iCurTs+1;
            %                 end
            %                 %we only want to add spikes for cells that are considered to be isolated...
            %                 %if iso         %NOTE- non-isolated spike should automatically be set cluster number 1 anyway...
            %                 CurTs(iCurTs,1:4)=[1 RFElec spkcluLabsAll(iSpk) spkTimes(iSpk)];
            %                 iSpk=iSpk+1;
            %                 iCurTs=iCurTs+1;
            %                 %end
            %             end
            %         end
            %     else
            %         %Now assign spikes... iSpk should at this point be on the first spk after teh start of this trial
            %         nSpks=zeros(14,1);    %zeros(max(curu),1);... counts the spikes from each unit for this trial
            %         while spkTimes(iSpk)<=TrLims(2)      %iSpk<=length(spkTimes) &&
            %             nSpks(spkcluLabsAll(iSpk))=nSpks(spkcluLabsAll(iSpk))+1;
            % 
            %             if spkcluLabsAll(iSpk)<=length(TotSpk)
            %                 if isfield( TotSpk(spkcluLabsAll(iSpk)).( TrTypeList{TaskType(iTr)} ),'spk' ) && nSpks(spkcluLabsAll(iSpk))>size(TotSpk(spkcluLabsAll(iSpk)).( TrTypeList{TaskType(iTr)} ).spk,2)     %Do the NaN-padding instead of zero-padding here
            %                     TotSpk(spkcluLabsAll(iSpk)).( TrTypeList{TaskType(iTr)} ).spk=[...
            %                         TotSpk(spkcluLabsAll(iSpk)).( TrTypeList{TaskType(iTr)} ).spk  repmat(NaN,nTrByType(TaskType(iTr)),1)];
            %                 end
            %                 TotSpk(spkcluLabsAll(iSpk)).( TrTypeList{TaskType(iTr)} ).spk( TaskTrInds(iTr),...
            %                     nSpks(spkcluLabsAll(iSpk)) ) =spkTimes(iSpk) - Tr.TrialStart(iTr);
            %             end
            %             iSpk=iSpk+1;
            %         end
            %     end
            % end
            % 
            % %check to see if the isos were lost from one frame to the next in any cell
            % %and the last trial of a frame needs to be considered not isolated
            % [Lf,Lu]=find(diff(CumIso)==-1);
            % for ilf=1:length(Lf)    %Lf(ilf) will be the frame number where iso was one, but became 0 on the next frame
            %      LastTrInd=find(Tr.TrialStart<T(Lf(ilf)+1),1,'last');
            %      if Tr.TrialStart(LastTrInd)+SpkSavOpt.maxTrTime>T(Lf(ilf)+1)   %if this is the case we want to set the iso of that trial to 0 for this cell, b/c it's isolation was lost during the trial
            %          TotSpk(Lu(ilf)).(TrTypeList{ TaskType(LastTrInd) }).Iso( TaskTrInds(LastTrInd) )=0;
            %      end
            % end
            % 
            % %Now save everything else...
            % if SaveRFFlag
            %     save([SvDat.filepath 'RFFlashRawSrtdFEF' day],'CurTs')
            %     %After sorting all units for that day, run OfflineRFFlashCalc with the variable day loaded into the workspace
            % end
            % 
            % %save JCheck data ... below commented b/c I don't think we need to save the JCheck spk data any differently
            % % if SaveJCheckFlag
            % %     save([SvDat.filepath 'RFFlashRawSrtdFEF' day],'CurTs')
            % %     %After sorting all units for that day, run OfflineRFFlashCalc with the variable day loaded into the workspace
            % % end
            % 
            % %save Elec Info to behavioral data file....
            % %we need to delete units previously in ElecInfo for this elec if they don't exist in the sort we're presently saving
            % %below- just remove all the old units for this elec, then add all units from Totu, and sort the final AllUnits matrix by chbr and elec
            % if exist('ElecInfo','var') && isfield(ElecInfo,'AllUnits')
            %     if ~isempty(ElecInfo.AllUnits)
            %         OldCurElecUnitInds=ismember(ElecInfo.AllUnits(:,1:2),[chbr elec],'rows');
            %         ElecInfo.AllUnits( OldCurElecUnitInds,: )=[];
            %     end
            % else
            %     ElecInfo.AllUnits=[];
            % end
            % ElecInfo.AllUnits=sortrows([ElecInfo.AllUnits; [repmat([chbr elec],length(Totu),1) Totu]]);
            % 
            % %below old way of doing things in 1 line, but without removing old units if needed
            % %ElecInfo.AllUnits=unique([ElecInfo.AllUnits; [repmat([chbr elec],length(Totu),1) Totu]],'rows'); %Totu is the unit # for  this elec... remember we can only sort 1 elec at a time, so it must be for this elec only
            % %so ElecInfo.AllUnits is in the format of nUnits x 3, where col 1 is the chbr, col 2 is the elec num, and col 3 is the unit num
            % 
            % 
            % disp(['Saving elec Info to ' SvDat.BehFName])   %ElecInfo is saved to the behavioral file
            % save(SvDat.BehFName,'ElecInfo','-append')
            % 
            % %load the AllSpk file for this session to add to that.
            % if exist([SvDat.filepath 'AllSrtdFEF' day '.mat'],'file')
            %     load([SvDat.filepath 'AllSrtdFEF' day],'AllSpk')
            %     MaxUnNum=max(ElecInfo.AllUnits(:,3));
            %     AllSpk(:,:,MaxUnNum+1:end)=[];        %remove unneccessary positions in MaxUnNum in case we removed units with this sort and the max number of units on an elec has decreased
            % else
            %     clear AllSpk     %we actually we don't want AllSpk to exist at all here if we're starting a new structure
            % end
            % 
            % % TotSpk.day=day;
            % % TotSpk.chbr=chbr;
            % % TotSpk.elec=elec;
            % 
            % %save each individual neuron
            % disp('Saving final spike files')
            % for iC=1:length(TotSpk)
            %     if ~isempty(TotSpk(iC).(TrTypeList{1}))  %for some reason, for sme cells (with no real data) they get assigned empty structures... so find and skip those
            %         %make the Iso's logicals to help with faster trial indexing
            %         for iT=1:length(TrTypeList)
            %             TotSpk(iC).(TrTypeList{iT}).Iso=TotSpk(iC).(TrTypeList{iT}).Iso==1;
            %         end
            % 
            %         TotSpk(iC).IsoQual=FrameData(1).IsoQual(iC);
            %         CurSpk=TotSpk(iC);
            %         CurSpk.unit=iC;
            % 
            %         save([SvDat.filepath 'FEF' day 'Chbr' num2str(chbr) 'E' num2str(elec) 'U' num2str(iC)],'CurSpk')
            % 
            %         %we only want to assign to AllSpk the fields that it has in common with TotSpk, so we have to cycle through the fields of TotSpk and assign those one by one
            %         %AllSpk(chbr,elec,iC)=TotSpk(iC);
            %         fNames=fieldnames(TotSpk);
            %         for ifN=1:length(fNames)
            %             AllSpk(chbr,elec,iC).(fNames{ifN})=TotSpk(iC).(fNames{ifN});
            %         end
            %     end
            % end
            % 
            % save([SvDat.filepath 'AllSrtdFEF' day],'AllSpk')
            % 
            % tmpT=toc;
            % disp(['Done saving data. ' num2str(tmpT) ' seconds required to put spikes into trial structure.'])
            % 
            % FrameString = get(handles.stFrame2,'String');
            % FrNum = sscanf(FrameString, 'Frame %d', 1);
            % FrameData = app.FrameDataStore;
            % if isempty(FrameData)
            %     FrameData = get(handles.frUnits,'UserData');
            % end
            % PrintFrameClusters(app, FrameData, FrNum, 'Save');
            % AddDebugLine(app, {'Saved output.mat and tmpSort.mat'});
        end

        % Button pushed function: pbSetDim
        function pbSetDim_Callback(app, event)
            CancelInteractiveModes(app);
            disp('RUNNING pbSetDim_Callback')
         
            % Button pushed function: pbSetDim

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            SourceFr  = app.f1.Value;
            CluNum    = app.c1.Value;
            DifFrNum  = app.f2.Value;
            DifCluNum = app.c2.Value;
            DimNum    = str2double(app.edDimNum.Value);

            FrameString = app.stFrame2.Text;
            FrNum = sscanf(char(FrameString), 'Frame %d', 1);

            if isempty(FrNum) || isnan(FrNum)
                error('Could not read current frame from label: %s', char(FrameString))
            end

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            % Save current dropdown LABELS, not numeric indices
            labels1 = get(handles.popDim1,'String');
            labels2 = get(handles.popDim2,'String');
            labels3 = get(handles.popDim3,'String');

            if ischar(labels1), labels1 = cellstr(labels1); end
            if ischar(labels2), labels2 = cellstr(labels2); end
            if ischar(labels3), labels3 = cellstr(labels3); end

            val1 = round(get(handles.popDim1,'Value'));
            val2 = round(get(handles.popDim2,'Value'));
            val3 = round(get(handles.popDim3,'Value'));

            oldDim1Label = labels1{min(max(val1,1),numel(labels1))};
            oldDim2Label = labels2{min(max(val2,1),numel(labels2))};
            oldDim3Label = labels3{min(max(val3,1),numel(labels3))};

            % Remove stale rPCs BEFORE adding a new custom dim
            if isfield(FrameData(FrNum),'DimLabels') && ~isempty(FrameData(FrNum).DimLabels)

                oldLabels = FrameData(FrNum).DimLabels;
                isRpc = startsWith(oldLabels, 'rPC');

                if any(isRpc)
                    keepCols = ~isRpc;

                    FrameData(FrNum).Uplot = FrameData(FrNum).Uplot(:,keepCols);
                    FrameData(FrNum).DimLabels = oldLabels(keepCols);

                    if isfield(FrameData(FrNum),'PCplot') && ~isempty(FrameData(FrNum).PCplot) && ...
                            size(FrameData(FrNum).PCplot,2) == numel(oldLabels)
                        FrameData(FrNum).PCplot = FrameData(FrNum).PCplot(:,keepCols);
                    end

                    if isfield(FrameData(FrNum),'DimDisplayWf') && ~isempty(FrameData(FrNum).DimDisplayWf) && ...
                            numel(FrameData(FrNum).DimDisplayWf) == numel(oldLabels)
                        FrameData(FrNum).DimDisplayWf = FrameData(FrNum).DimDisplayWf(keepCols);
                    end
                end
            end

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            % Now append the new custom dim into clean real-dim space
            FrameData = SetPlotDimOnly(app, handles, FrameData, FrNum, SourceFr, CluNum, DifFrNum, DifCluNum, DimNum);

            newDimNum = FrameData(FrNum).LastCustomDim;
            newCustomLabel = FrameData(FrNum).DimLabels{newDimNum};

            % Put dropdowns temporarily on real labels so DimNum can be set before rPC recompute
            realLabels = FrameData(FrNum).DimLabels;

            set(handles.popDim1,'Value',1);
            set(handles.popDim2,'Value',1);
            set(handles.popDim3,'Value',1);

            set(handles.popDim1,'String',realLabels);
            set(handles.popDim2,'String',realLabels);
            set(handles.popDim3,'String',realLabels);

            if DimNum == 1
                set(handles.popDim1,'Value',newDimNum);
                oldDim1Label = newCustomLabel;
            elseif DimNum == 2
                set(handles.popDim2,'Value',newDimNum);
                oldDim2Label = newCustomLabel;
            elseif DimNum == 3
                set(handles.popDim3,'Value',newDimNum);
                oldDim3Label = newCustomLabel;
            end

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            % Recompute rPCs from the new current left/middle custom dims
            [FrameData, ~] = computeAndInsertRPCs(app, handles, FrameData, FrNum);
            RefreshDimDropdownLabels(app, handles, FrameData, FrNum);


            % Auto-select rPCs after setting a custom dim.
            % If custom dim is set as Dim1: show Custom / rPC1 / rPC2.
            % If custom dim is set as Dim2: keep Dim1, show Custom / rPC1.
            % Dim2 cannot be BOTH custom and rPC, so only Dim3 becomes rPC in that case.

            if DimNum == 1

                restoreDim1Label = newCustomLabel;
                restoreDim2Label = 'rPC1';
                restoreDim3Label = 'rPC2';

            elseif DimNum == 2

                restoreDim1Label = oldDim1Label;
                restoreDim2Label = newCustomLabel;
                restoreDim3Label = 'rPC1';

            else

                restoreDim1Label = oldDim1Label;
                restoreDim2Label = oldDim2Label;
                restoreDim3Label = newCustomLabel;

            end

            oldSuppress = app.SuppressDimDropdownCallback;
            app.SuppressDimDropdownCallback = true;
            cleanupSuppress = onCleanup(@() RestoreDimDropdownSuppress(app, oldSuppress));

            SetDropdownValueByLabel(app, handles.popDim1, restoreDim1Label, 1);
            SetDropdownValueByLabel(app, handles.popDim2, restoreDim2Label, 2);
            SetDropdownValueByLabel(app, handles.popDim3, restoreDim3Label, 3);

            clear cleanupSuppress


            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;


            RedrawSortMovie(app, handles, FrNum, true);
            StoreDimDropdownUndoState(app, handles);
            
        end

        % Value changed function: tbQuickSort
        function tbQuickSort_Callback(app, event)

            disp('running tbQuickSort_Callback')

            % Save requested toggle state BEFORE CancelInteractiveModes kills it.
            wantQuickSortOn = app.tbQuickSort.Value;

            % Kill all old interactive junk.
            CancelInteractiveModes(app);

            % If user was turning QuickSort off, stay off and leave.
            if ~wantQuickSortOn
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                disp('[QuickSort] off')
                RestoreNativePanZoom(app);
                return
            end

            % User clicked QuickSort ON, so restore ON state after cleanup.
            app.tbQuickSort.Value = true;
            app.tbQuickSort.Text = 'QuickSort: ON';

            cleanupQuickSortButton = onCleanup(@() set(app.tbQuickSort, ...
                'Value', false, ...
                'Text', 'QuickSort: OFF'));

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
        
            drawnow;

            % 2) CLEAN MOUSE STATE BEFORE QUICK SORT
            % QuickSort uses ginput. Old select/manual/pan/zoom callbacks can
            % interfere, so wipe them before collecting centers.
            app.SpikeSelectMode = false;
            app.PendingClickAxis = [];
            app.PendingClickPoint = [NaN NaN];

            app.figure1.WindowButtonDownFcn = [];
            app.figure1.WindowButtonUpFcn = [];
            app.figure1.WindowButtonMotionFcn = [];
            app.figure1.WindowScrollWheelFcn = [];

            app.axDim12.ButtonDownFcn = [];
            app.axDim13.ButtonDownFcn = [];
            app.axDim23.ButtonDownFcn = [];

            app.axDim12.Interactions = [];
            app.axDim13.Interactions = [];
            app.axDim23.Interactions = [];

            app.figure1.Pointer = 'crosshair';
            drawnow;


            % ============================================================
            % 2) GET CURRENT FRAME + CURRENT DISPLAYED PC DIMENSIONS
            % ============================================================
            FrameData = app.FrameDataStore; % Get current app-level frame data
            FrameString = get(handles.stFrame2,'String'); % read current frame label 
            FrNum = sscanf(FrameString, 'Frame %d', 1); % Extract current frame number
        
            Dim1 = get(handles.popDim1,'Value'); % Read first displayed PC dimension
            Dim2 = get(handles.popDim2,'Value'); % Read second displayed PC dimension
            Dim3 = get(handles.popDim3,'Value'); % Read third displayed PC dimension
        
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[QuickSort skipped] bad FrameData/FrNum\n');
                RestoreNativePanZoom(app);
                return
            end

            Dim1 = get(handles.popDim1,'Value');
            Dim2 = get(handles.popDim2,'Value');
            Dim3 = get(handles.popDim3,'Value');


            fprintf('\n[QuickSort] FRAME %d\n', FrNum); % Print current frame
            fprintf('Click centers on one PC scatter plot. Left-click centers, right-click or Enter to run.\n'); % Print usage
        
            % ============================================================
            % 3) GET FIRST CENTER CLICK AND IDENTIFY WHICH PC AXIS WAS USED
            % % ============================================================
            % [x,y,button] = ginput(1); % Get first click, which must be first cluster center
            % clickedObj = hittest(app.figure1);
            % clickedAx = ancestor(clickedObj, 'matlab.ui.control.UIAxes');
            % if isempty(clickedAx)
            %     clickedAx = gca; % fallback only
            % end

            %try this instead 
            [x,y,button] = ginput(1); % Get first click, which must be first cluster center
            clickedAx = gca;


            fprintf('[QuickSort DEBUG] first click button = %s\n', mat2str(button));
            fprintf('[QuickSort DEBUG] clickedAx == app.axDim12: %d\n', clickedAx == app.axDim12);
            fprintf('[QuickSort DEBUG] clickedAx == app.axDim13: %d\n', clickedAx == app.axDim13);
            fprintf('[QuickSort DEBUG] clickedAx == app.axDim23: %d\n', clickedAx == app.axDim23);

            % ============================================================
            % DEBUG: prove which axis ginput/gca thinks was clicked.
            % % This is the key debug for the axDim13/second-plot bug.
            % If plot 2 fails, this should print:
            % 
            % clickedAx == app.axDim13: 1
            % 
            % If it prints all zeros, QuickSort is not recognizing plot 2 as the clicked axis.
            % ============================================================


            if isempty(button) || button ~= 1
                fprintf('[QuickSort cancelled] No first center selected.\n');
                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end
        

            if ~(clickedAx == app.axDim12 || clickedAx == app.axDim13 || clickedAx == app.axDim23)
                fprintf('[QuickSort cancelled] First click was not inside axDim12, axDim13, or axDim23.\n');
                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end

            if clickedAx == app.axDim12
                d1 = Dim1;
                d2 = Dim2;
                axName = 'axDim12';
            elseif clickedAx == app.axDim13
                d1 = Dim1;
                d2 = Dim3;
                axName = 'axDim13';
            else
                d1 = Dim2;
                d2 = Dim3;
                axName = 'axDim23';
            end

            labels1 = get(handles.popDim1,'String');
            labels2 = get(handles.popDim2,'String');
            labels3 = get(handles.popDim3,'String');

            if ischar(labels1), labels1 = cellstr(labels1); end
            if ischar(labels2), labels2 = cellstr(labels2); end
            if ischar(labels3), labels3 = cellstr(labels3); end

            lab1 = labels1{min(max(get(handles.popDim1,'Value'),1),numel(labels1))};
            lab2 = labels2{min(max(get(handles.popDim2,'Value'),1),numel(labels2))};
            lab3 = labels3{min(max(get(handles.popDim3,'Value'),1),numel(labels3))};

            if clickedAx == app.axDim12
                labX = lab1;
                labY = lab2;
            elseif clickedAx == app.axDim13
                labX = lab1;
                labY = lab3;
            else
                labX = lab2;
                labY = lab3;
            end

            fprintf('[QuickSort DEBUG] using %s, displayed dims %s vs %s, Uplot cols %d vs %d\n', ...
                axName, labX, labY, d1, d2);

            % ============================================================
            % 4) COLLECT CLICKED CENTERS UNTIL RIGHT-CLICK / ENTER
            % ============================================================
            axes(clickedAx); % Make clicked PC plot active
            hold(clickedAx, 'on'); 

            quickSortDots = gobjects(0);
            centers = [x y]; % First left-click is first center
            quickSortDots(end+1) = line(clickedAx, x, y, ...
                'Marker','o', ...
                'LineStyle','none', ...
                'Color','k', ...
                'MarkerSize',6, ...
                'LineWidth',0.75, ...
                'Tag','QuickSortTemp');
            set(clickedAx,'Color','white');
            fprintf('[QuickSort] center 1 using PC%d vs PC%d = [%g %g]\n', d1, d2, x, y);

            while app.tbQuickSort.Value % Keep collecting centers until right-click or Enter

                [x,y,button] = ginput(1); % Get next click

                finishAfterThisCenter = ~isempty(button) && button ~= 1;

                % Right-click / two-finger click / Enter ends QuickSort.
                if isempty(button)
                    break
                end

                % Check what axes was actually clicked.
                thisObj = hittest(app.figure1);
                thisAx = ancestor(thisObj, 'matlab.ui.control.UIAxes');

                % If click was not on the original QuickSort axes, ignore it.
                % Do NOT add a center and do NOT draw a dot.
                
                if isempty(thisAx) || thisAx ~= clickedAx
                    figPt = app.figure1.CurrentPoint;
                    axPos = clickedAx.Position;
                    xLim0 = clickedAx.XLim;
                    yLim0 = clickedAx.YLim;

                    x = xLim0(1) + ((figPt(1) - axPos(1)) / axPos(3)) * diff(xLim0);
                    y = yLim0(1) + ((figPt(2) - axPos(2)) / axPos(4)) * diff(yLim0);

                    ExpandAxisToIncludePoint(app, clickedAx, x, y);
                    axes(clickedAx);
                    hold(clickedAx, 'on');

                    centers(end+1,:) = [x y]; %#ok<AGROW>

                    quickSortDots(end+1) = line(clickedAx, x, y, ...
                        'Marker','o', ...
                        'LineStyle','none', ...
                        'Color','k', ...
                        'MarkerSize',6, ...
                        'LineWidth',0.75, ...
                        'Tag','QuickSortTemp');

                    set(clickedAx,'Color','white');

                    if finishAfterThisCenter
                        break
                    end

                    continue

                end
                % Only valid clicks on the original axes reach this point.
                centers(end+1,:) = [x y]; %#ok<AGROW>

                quickSortDots(end+1) = line(clickedAx, x, y, 'Marker','o', 'LineStyle','none', 'Color','k', 'MarkerSize',6, 'LineWidth',0.75, 'Tag','QuickSortTemp');

                set(clickedAx,'Color','white');

                fprintf('[QuickSort] center %d using PC%d vs PC%d = [%g %g]\n', ...
                    size(centers,1), d1, d2, x, y);

                if finishAfterThisCenter
                    break
                end
            end


            % If another button cancelled QuickSort while ginput was active, do not assign anything.
            if ~app.tbQuickSort.Value
                delete(findall(app.figure1, 'Tag', 'QuickSortTemp'));
                RestoreNativePanZoom(app);
                app.tbQuickSort.Text = 'QuickSort: OFF';
                return
            end
            
            nCenters = size(centers,1);
            
            % ============================================================
            % 5) GET INCLUDED SPIKES AND VALIDATE PC MATRIX
            % ============================================================
           

            included = FrameData(FrNum).Status(:) > 0; % Active sortable spikes only
            FindIncs = find(included); % Full-frame row indices of active spikes

            % QuickSort must sort in the SAME coordinate space that is displayed.
            % That means Uplot, not raw U. Custom dims and rPCs live in Uplot.
            if ~isfield(FrameData(FrNum),'Uplot') || isempty(FrameData(FrNum).Uplot)
                if ~isempty(quickSortDots)
                    delete(quickSortDots(isvalid(quickSortDots)));
                end
                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end

            U_seg = FrameData(FrNum).Uplot(included,:);

            if isempty(U_seg)
                if ~isempty(quickSortDots)
                    delete(quickSortDots(isvalid(quickSortDots)));
                end
                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end

            if size(U_seg,2) < max([d1 d2])
                fprintf('[QuickSort skipped] Selected displayed dimensions do not exist in Uplot.\n');
                if ~isempty(quickSortDots)
                    delete(quickSortDots(isvalid(quickSortDots)));
                end
                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end

        
            % ============================================================
            % 6) ASSIGN EACH INCLUDED SPIKE TO NEAREST CLICKED CENTER
            % ============================================================
            X = U_seg(:,[d1 d2]); % Coordinates of included spikes in selected PC plot
            d2mat = sum(X.^2,2) + sum(centers.^2,2)' - 2*(X*centers'); % Squared distance from each spike to each clicked center
            [~, idx] = min(d2mat, [], 2); % Assign each spike to nearest clicked center


            statusNow = FrameData(FrNum).Status(:);

            usedFinalized = abs(unique(statusNow(statusNow < 0)));
            available = setdiff(1:14, usedFinalized);


            if numel(available) < nCenters
                fprintf('[QuickSort skipped] need %d cluster IDs but only %d non-finalized IDs available\n', ...
                    nCenters, numel(available));

                if ~isempty(quickSortDots)
                    delete(quickSortDots(isvalid(quickSortDots)));
                end

                RestoreNativePanZoom(app);
                app.tbQuickSort.Value = false;
                app.tbQuickSort.Text = 'QuickSort: OFF';
                drawnow;
                return
            end

            PushUndo(app, handles, 'QuickSort');

            targetClusters = available(1:nCenters);
            for ii = 1:nCenters
                targetClu = targetClusters(ii);
            
                rows = false(numel(FrameData(FrNum).Status),1);
                rows(FindIncs(idx == ii)) = true;
            
                FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, targetClu, 'tbQuickSort_Callback');
            end

            % Do NOT recompute PCA after QuickSort.
            % QuickSort only creates/relabels clusters in existing PC space.
            % FrameData = RecomputeFramePCs(app, FrameData, FrNum);

            FrameData(FrNum).Clu(FrameData(FrNum).Status(:) == 0) = 0; % temporary legacy sync for noise
            FrameData(FrNum).Clu(FrameData(FrNum).Status(:) < 0) = abs(FrameData(FrNum).Status(FrameData(FrNum).Status(:) < 0)); % temporary legacy sync for finalized units
        
            % ============================================================
            % 7) UPDATE ISO FLAGS, CHECKBOXES, DROPDOWNS, AND MEAN WAVEFORMS
            % ============================================================
            for c = 1:14
                if any(FrameData(FrNum).Status(:) == c)
                    FrameData(FrNum).Iso(c) = 1;
                    FrameData(FrNum).mWf(c,:) = mean(FrameData(FrNum).Wfs(FrameData(FrNum).Status(:) == c,:), 1);
                else
                    FrameData(FrNum).Iso(c) = 0;
                    FrameData(FrNum).mWf(c,:) = NaN(1, size(FrameData(FrNum).Wfs,2));
                end
            
                FrameData(FrNum).ReassignMap(c) = c;
                app.(['pop' num2str(c)]).Value = c;
            end
                    
            % ============================================================
            % 8) SAVE STATE
            % ============================================================
            set(handles.frUnits,'UserData',FrameData); % Update legacy storage
            app.FrameDataStore = FrameData; % Update app-level storage
       
            % ============================================================
            % 9) REDRAW AND TURN QUICK SORT OFF
            RedrawSortMovie(app, handles, FrNum, true);
            PrintFrameClusters(app, FrameData, FrNum, 'QuickSort')
            disp('[QuickSort] done')

            RestoreNativePanZoom(app);
            app.tbQuickSort.Value = false;
            app.tbQuickSort.Text = 'QuickSort: OFF';
            drawnow; 

        end

        % Button pushed function: pbUndo
        function pbUndoButtonPushed(app, event)

            disp('running pbUndoButtonPushed')

            CancelInteractiveModes(app);

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            if isempty(app.UndoStack)
                disp('[Undo] nothing to undo')
                return
            end

            snap = app.UndoStack{end};
            app.UndoStack(end) = [];

            % FrameData = snap.FrameData;

            %new 
            if isfield(snap, 'Scope') && strcmp(snap.Scope, 'frame')
                FrameData = app.FrameDataStore;
                FrameData(snap.FrNum) = snap.Frame;
            else
                FrameData = snap.FrameData;
            end

            FrNum = snap.FrNum;

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            oldSuppress = app.SuppressDimDropdownCallback;
            app.SuppressDimDropdownCallback = true;
            cleanupSuppress = onCleanup(@() RestoreDimDropdownSuppress(app, oldSuppress));

            RefreshDimDropdownLabels(app, handles, FrameData, FrNum);

            if isfield(snap,'PopDim1Label')
                SetDropdownValueByLabel(app, handles.popDim1, snap.PopDim1Label, 1);
                SetDropdownValueByLabel(app, handles.popDim2, snap.PopDim2Label, 2);
                SetDropdownValueByLabel(app, handles.popDim3, snap.PopDim3Label, 3);
            end

            StoreDimDropdownUndoState(app, handles);

            clear cleanupSuppress

            RedrawSortMovie(app, handles, FrNum, true);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            PrintFrameClusters(app, FrameData, FrNum, ['Undo before ' snap.ActionLabel]);


        end

        % Window key press function: figure1
        function figure1WindowKeyPress(app, event)
          

            key = event.Key;

            if strcmpi(key, 'v')

                app.RasterVerticalZoomMode = ~app.RasterVerticalZoomMode;

                fprintf('DEBUG: V TOGGLE -> RasterVerticalZoomMode = %d\n', ...
                    app.RasterVerticalZoomMode);

                UpdatePcRasterZoomDirection(app);

                return
            end

            isUndoKey = strcmpi(event.Key, 'z') && ...
                (any(strcmpi(event.Modifier, 'command')) || any(strcmpi(event.Modifier, 'control')));

            if isUndoKey
                pbUndoButtonPushed(app, event);
            end
        end

        % Value changed function: popSubject
        function popSubjectValueChanged(app, event)
            fprintf('[SUBJECT CHANGED] %s\n', string(app.popSubject.Value));
            FillFileSelectDropdowns(app, 'sessions');
        end

        % Value changed function: popSession
        function popSessionValueChanged(app, event)
            fprintf('[SESSION CHANGED] %s\n', string(app.popSession.Value));

            FillFileSelectDropdowns(app, 'spikefiles');
        end

        % Button pushed function: pbRecoverSelectedCluster
        function pbRecoverSelectedClusterButtonPushed(app, event)
                   
            disp('RUNNING pbRecoverSelectedClusterButtonPushed')
        
            % Save selection BEFORE CancelInteractiveModes in case it clears temp state.
            selectedFrBefore = app.SelectedClusterFrame;
            selectedClBefore = app.SelectedClusterNum;
            selectedNoiseBefore = app.SelectedNoiseNumber;
        
            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
        
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
        
            if isempty(FrameData)
                fprintf('[Recover skipped] No FrameData loaded.\n');
                return
            end
        
            FrNum = round(selectedFrBefore);
            oldStatus = round(selectedClBefore);
            noiseNum = round(selectedNoiseBefore);

            if isfinite(oldStatus) && oldStatus >= 1 && oldStatus <= 14
                fprintf('[Recover skipped] selected cluster C%d is active/non-removed. Nothing to recover.\n', oldStatus);
                app.pbRecoverSelectedCluster.Enable = 'off';
                return
            end
        
            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[Recover skipped] Bad/no selected frame: %g\n', FrNum);
                return
            end
        
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[Recover skipped] selected frame has no Status.\n');
                return
            end
        
            % ============================================================
            % CASE 1: selected noise-number cluster -> recover to C1
            % ============================================================
            if isnan(oldStatus) && isfinite(noiseNum)
        
                if ~isfield(FrameData(FrNum),'NoiseNumber') || isempty(FrameData(FrNum).NoiseNumber)
                    fprintf('[Recover noise skipped] selected frame has no NoiseNumber.\n');
                    return
                end
        
                status = FrameData(FrNum).Status(:);
                noiseNumber = FrameData(FrNum).NoiseNumber(:);
        
                rows = status == 0 & noiseNumber == noiseNum;
        
                if ~any(rows)
                    fprintf('[Recover noise skipped] no spikes with NoiseNumber %d in Fr %d\n', noiseNum, FrNum);
                    return
                end
        
                PushUndo(app, handles, 'Recover Selected Noise Cluster to Multiunit');
        
                FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, 1, ...
                    'Recover Selected Noise Cluster');
        
                if isfield(FrameData(FrNum),'NoiseNumber') && ~isempty(FrameData(FrNum).NoiseNumber)
                    FrameData(FrNum).NoiseNumber(rows) = NaN;
                end
        
                set(handles.frUnits,'UserData',FrameData);
                app.FrameDataStore = FrameData;
        
                % Keep recovered spikes selected as C1/multiunit.
                app.SelectedClusterFrame = FrNum;
                app.SelectedClusterNum = 1;
                app.SelectedNoiseNumber = NaN;
                UpdateRecoverSelectedClusterButton(app);
                app.SelectedSpikeFrame = NaN;
                app.SelectedSpikeRows = [];
                app.SelectedNoiseNumber = NaN;
                UpdateSelectedSpikeRemoveButtons(app);
                app.LastHoverSpikeFrame = NaN;
                app.LastHoverSpikeRow = NaN;
        
                RedrawSortMovie(app, handles, FrNum, true);
                % PlotSelectedCluster(app, FrameData); new dk comment 
                PrintFrameClusters(app, FrameData, FrNum, 'Recover Selected Noise Cluster to C1');
        
                fprintf('[Recover noise] Fr %d NoiseNumber %d -> C1, n=%d\n', ...
                    FrNum, noiseNum, sum(rows));
        
                return
            end
        
            % ============================================================
            % CASE 2: selected isolated negative cluster -> recover normally
            % ============================================================
            if isnan(oldStatus)
                fprintf('[Recover skipped] No selected cluster.\n');
                return
            end
        
            if oldStatus >= 0
                fprintf('[Recover skipped] selected cluster is not negative: %d\n', oldStatus);
                return
            end
        
            if oldStatus < -14
                fprintf('[Recover skipped] selected cluster status too negative: %d\n', oldStatus);
                return
            end
        
            rows = FrameData(FrNum).Status(:) == oldStatus;
        
            if ~any(rows)
                fprintf('[Recover skipped] no spikes with status %d\n', oldStatus);
                return
            end
        
            PushUndo(app, handles, 'Recover Selected Isolated Cluster');
        
            FrameData = RecoverStatusesInFrame(app, handles, FrameData, FrNum, rows, ...
                'Recover Selected Cluster');
        
            app.SelectedClusterFrame = FrNum;
            app.SelectedClusterNum = abs(oldStatus);
            app.SelectedNoiseNumber = NaN;
        
            RedrawSortMovie(app, handles, FrNum, true);
            % PlotSelectedCluster(app, FrameData); new dk comment 
            PrintFrameClusters(app, FrameData, FrNum, 'Recover Selected Cluster');


        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)

            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);

            UpdateAllQLabels(app, handles, FrNum);

            FrameData = app.FrameDataStore;
            Q = FrameData(FrNum).Q;

            rows = {};
            for c = 1:14
                if Q(c).nSpikes > 0
                    rows(end+1,:) = { ...
                        c, ...
                        Q(c).nSpikes, ...
                        Q(c).violPct3ms, ...
                        Q(c).goodnessFit, ...
                        Q(c).isolationDistance, ...
                        Q(c).sepMetric, ...
                        Q(c).sepMulti}; %#ok<AGROW>
                end
            end

            T = cell2table(rows, 'VariableNames', { ...
                'Unit', ...
                'nSpikes', ...
                'RefractoryPct3ms', ...
                'GoodnessFit', ...
                'IsolationDistance', ...
                'SepMin', ...
                'SepMulti'});


            nRows = height(T);

            rowH = 24;
            headerH = 28;
            tableH = headerH + rowH*nRows;

            textH = 115;
            gap = 12;
            margin = 20;

            figW = 900;
            figH = margin + textH + gap + tableH + margin;

            figH = min(max(figH, 300), 700);

            app.StatsWindowCount = app.StatsWindowCount + 1;
            fig = uifigure( ...
                'Name', sprintf('Q Stats %d - Frame %d', app.StatsWindowCount, FrNum), ...
                'Position', [300 250 figW figH]);

            uitextarea(fig, ...
                'Editable', 'off', ...
                'Position', [20 20 860 textH], ...
                'Value', { ...
                'RefractoryPct3ms = lower is better. percent of ISIs < 3 ms. well seperated: RefractoryPct3ms < 3%', ...
                'GoodnessFit = higher is better. how closely the cluster’s projected residuals match the expected Gaussian white noise shape', ...
                'IsolationDistance = higher is better. The cluster has N spikes. The isolation distance is the N-th closest (by Mahalanobis squared) non-cluster spike' ...
                'seperation distance: higher is better. in units of pooled SDs, how far apart 2 clusters are'...
                '    SepMin = smallest separation distance over all of the possible separation distances for that cluster ' ...
                '    SepMulti = separation distance of that cluster to the multi-unit cluster' ...
                });

            tableY = 20 + textH + gap;
            tableHVisible = figH - tableY - margin;

            uitable(fig, ...
                'Data', T, ...
                'Position', [20 tableY 860 tableHVisible]);
        end

        % Value changed function: popSrtdLoad
        function popSrtdLoadValueChanged(app, event)

            if isempty(app.popSrtdLoad.Value)
                return
            end

            fprintf('[SrtdLoad] selected file: %s\n', app.popSrtdLoad.Value);
        end

        % Value changed function: edElec
        function edElecValueChanged(app, event)


            ResolveSpikeFileFromUI(app, true);


        end

        % Button pushed function: pbExpAnalysisVars
        function pbExpAnalysisVarsButtonPushed(app, event)


            disp('running pbExpAnalysisVarsButtonPushed')

            % Get GUIDE-style handles.
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % Make sure a loaded file/session folder exists.
            if isempty(app.figure1.UserData) || ...
                    ~isstruct(app.figure1.UserData) || ...
                    ~isfield(app.figure1.UserData, 'filepath')

                error('No loaded spike-file folder found. Load a spike file before exporting analysis variables.')
            end


            filepath = app.figure1.UserData.filepath;
        
            if isempty(filepath) || ~isfolder(filepath)
                error('Output filepath is empty or not a valid folder: %s', filepath)
            end
        
            % Get current FrameData from app memory.
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits, 'UserData');
            end
            if isempty(FrameData)
                error('No FrameData loaded. Nothing to export.')
            end

            % Make sure Q metrics are computed for every frame before export.
            for qFr = 1:numel(FrameData)
                UpdateAllQLabels(app, handles, qFr);
                FrameData = app.FrameDataStore;
                if isempty(FrameData)
                    FrameData = get(handles.frUnits, 'UserData');
                end
            end

            set(handles.frUnits, 'UserData', FrameData);
            app.FrameDataStore = FrameData;
        
            % Get subject/session/electrode metadata.
            Subj = app.popSubject.Value;
            Sess = app.popSession.Value;
            elec = str2double(app.edElec.Value);
            if isnan(elec)
                elec = 1;
            end
        
            % Build output filename from originally loaded file.
            LoadInfo = app.figure1.UserData;
            if isfield(LoadInfo, 'inputFileName') && ~isempty(LoadInfo.inputFileName)
                inputFileName = LoadInfo.inputFileName;
            else
                [~, inputFileName, ~, ~, ok] = ResolveSpikeFileFromUI(app, false);

                if ~ok || isempty(inputFileName)
                    inputFileName = sprintf('CSC%dspikes.mat', elec);
                end
            end


            [~, loadedBaseName, ~] = fileparts(inputFileName);

            % NEW DK: AnalysisVars follows canonical OSort base and never includes app append strings.
            tok = regexp(loadedBaseName, ...
                '^(RawSortDat_NS\d+_sess\d+_ch\d+_Thresh[^_]+(?:_\d+)?)', ...
                'tokens', 'once');

            if ~isempty(tok)
                analysisBaseName = ['AnalysisVars_' tok{1}(length('RawSortDat_')+1:end)];
            else
                analysisBaseName = sprintf('AnalysisVars_NS%s_sess%s_ch%d', ...
                    regexprep(Subj,'\D',''), regexprep(Sess,'\D',''), elec);
            end

            AnalysisVarsFile = fullfile(filepath, [analysisBaseName '.mat']);


            %% NEW DK save units only up to highest used cluster
            usedUnits = [];

            for iFr = 1:numel(FrameData)
                if isfield(FrameData(iFr), 'Status') && ~isempty(FrameData(iFr).Status)
                    s = FrameData(iFr).Status(:);
                    s = abs(s(s ~= 0));
                    usedUnits = [usedUnits; s(:)]; %#ok<AGROW>
                end
            end

            usedUnits = usedUnits(usedUnits >= 1 & usedUnits <= 14);

            if isempty(usedUnits)
                maxUnitToSave = 0;
            else
                maxUnitToSave = max(usedUnits);
            end

            UnitData = struct();
            SortedSpk = cell(1, maxUnitToSave);

            % Export all real units, whether active positive or finalized negative.
            % Noise/excluded spikes are Status == 0 and are NOT included as units.
            for unitNum = 1:maxUnitToSave
        
                allSpikeTimes = [];
                allWaveforms = [];
                allFrames = [];
                allStatusValues = [];
        
                % Collect this finalized unit across all frames.
                for iFr = 1:numel(FrameData)
        
                    if ~isfield(FrameData(iFr), 'Status') || isempty(FrameData(iFr).Status) || ...
                       ~isfield(FrameData(iFr), 'spk') || isempty(FrameData(iFr).spk) || ...
                       ~isfield(FrameData(iFr), 'Wfs') || isempty(FrameData(iFr).Wfs)
                        continue
                    end
        
                    status = FrameData(iFr).Status(:);
                    spkTimes = FrameData(iFr).spk(:);
        
                    n = min([numel(status), numel(spkTimes), size(FrameData(iFr).Wfs,1)]);
                    status = status(1:n);
                    spkTimes = spkTimes(1:n);
                    Wfs = FrameData(iFr).Wfs(1:n,:);


                    % Final output includes this unit whether active (+unitNum) or isolated (-unitNum).
                    % Noise is excluded.
                    rows = abs(status) == unitNum & status ~= 0;
        
                    if ~any(rows)
                        continue
                    end
        
                    rowIdxs = find(rows);
        
                    allSpikeTimes = [allSpikeTimes; spkTimes(rows)]; %#ok<AGROW>
                    allWaveforms = [allWaveforms; Wfs(rows,:)]; %#ok<AGROW>
                    allFrames = [allFrames; repmat(iFr, sum(rows), 1)]; %#ok<AGROW>
                    allStatusValues = [allStatusValues; status(rows)]; %#ok<AGROW>
                end
        

                if isempty(allSpikeTimes)
                    unitField = sprintf('E%dU%d_noTrInfo', elec, unitNum);

                    unitOut = struct();
                    unitOut.noTrInfo = true;
                    unitOut.unitNum = unitNum;
                    unitOut.statusValues = [];
                    unitOut.spikeTimes = [];
                    unitOut.nSpikes = 0;
                    unitOut.meanWaveform = [];
                    unitOut.Q = [];

                    UnitData.(unitField) = unitOut;
                    SortedSpk{unitNum} = [];

                    continue
                end
                
                % Sort this unit by spike time.
                [allSpikeTimes, sortIdx] = sort(allSpikeTimes(:));
                allWaveforms = allWaveforms(sortIdx,:);
                allFrames = allFrames(sortIdx);
                allStatusValues = allStatusValues(sortIdx);
        
                % Build one analysis struct for this electrode/unit.
                unitField = sprintf('E%dU%d_noTrInfo', elec, unitNum);
        
                unitOut = struct();
                unitOut.noTrInfo = true;
                unitOut.unitNum = unitNum;
                unitOut.statusValues = unique(allStatusValues(:))';
                unitOut.spikeTimes = allSpikeTimes(:);
                unitOut.nSpikes = numel(allSpikeTimes);
                unitOut.meanWaveform = mean(allWaveforms, 1);
        
                % Export actual computed Q metrics from FrameData.
                qList = [];
                for qFr = unique(allFrames(:))'
                    if qFr >= 1 && qFr <= numel(FrameData) && ...
                            isfield(FrameData(qFr), 'Q') && ...
                            numel(FrameData(qFr).Q) >= unitNum && ...
                            FrameData(qFr).Q(unitNum).nSpikes > 0

                        qList = [qList FrameData(qFr).Q(unitNum)]; %#ok<AGROW>
                    end
                end
                if isempty(qList)
                    unitOut.Q = struct( ...
                        'cluster', unitNum, ...
                        'nSpikes', unitOut.nSpikes, ...
                        'violPct3ms', NaN, ...
                        'refractoryScore', NaN, ...
                        'sepMetric', NaN, ...
                        'goodnessFit', NaN, ...
                        'isolationDistance', NaN, ...
                        'nearestNoiseMD2', NaN, ...
                        'sepMulti', NaN, ...
                        'isi_ms', [], ...
                        'isiEdges', [], ...
                        'isiCounts', []);
                else
                    weights = [qList.nSpikes];
                    weights(~isfinite(weights) | weights <= 0) = 1;

                    unitOut.Q = qList(1);
                    unitOut.Q.cluster = unitNum;
                    unitOut.Q.nSpikes = unitOut.nSpikes;

                    unitOut.Q.violPct3ms = sum([qList.violPct3ms] .* weights, 'omitnan') / sum(weights(isfinite([qList.violPct3ms])));
                    unitOut.Q.refractoryScore = sum([qList.refractoryScore] .* weights, 'omitnan') / sum(weights(isfinite([qList.refractoryScore])));
                    unitOut.Q.sepMetric = min([qList.sepMetric], [], 'omitnan');
                    unitOut.Q.goodnessFit = sum([qList.goodnessFit] .* weights, 'omitnan') / sum(weights(isfinite([qList.goodnessFit])));
                    unitOut.Q.isolationDistance = min([qList.isolationDistance], [], 'omitnan');
                    unitOut.Q.nearestNoiseMD2 = min([qList.nearestNoiseMD2], [], 'omitnan');
                    unitOut.Q.sepMulti = min([qList.sepMulti], [], 'omitnan');

                    unitOut.Q.isi_ms = vertcat(qList.isi_ms);
                    unitOut.Q.isiEdges = [];
                    unitOut.Q.isiCounts = [];
                end
        
                UnitData.(unitField) = unitOut;
                SortedSpk{unitNum} = allSpikeTimes(:);
        
                fprintf('[AnalysisVars] added %s with %d unit spikes\n', ...
                    unitField, unitOut.nSpikes);
            end
        
            % Store metadata describing this as analysis-only export.
            ExportInfo = struct();
            ExportInfo.FileType = 'AnalysisVars';
            ExportInfo.AppName = 'COPYMattSpikeSortGUI_Human_App';
            ExportInfo.Subject = Subj;
            ExportInfo.Session = Sess;
            ExportInfo.Electrode = elec;
            ExportInfo.SavedAt = datestr(now);
            ExportInfo.ContainsFrameData = false;
            ExportInfo.ContainsAllFrames = false;        
            ExportInfo.StatusMeaning = '+u active unit exported, -u isolated/finalized unit exported, 0 noise excluded';
            assert(isfinite(double(app.FirstSpikeAbsUS)),'app.FirstSpikeAbsUS is invalid.');
            assert(isfinite(double(app.RecordingStartAbsUS)),'app.RecordingStartAbsUS is invalid.');
            
            ExportInfo.FirstSpikeAbsUS = app.FirstSpikeAbsUS;
            ExportInfo.RecordingStartAbsUS = app.RecordingStartAbsUS;

            % Save only analysis variables, not FrameData.
            save(AnalysisVarsFile, ...
                'ExportInfo', ...
                'UnitData', ...
                'SortedSpk', ...
                '-v7.3');
        
            fprintf('[Export AnalysisVars] saved:\n%s\n', AnalysisVarsFile);


        end

        % Button pushed function: pbRecFr
        function pbRecFrButtonPushed(app, event)

            disp('running pbRecFrButtonPushed')
            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
    
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
    
            if isempty(FrameData)
                fprintf('[Recover Frame skipped] No FrameData loaded.\n');
                return
            end
    
            FrameString = get(handles.stFrame2,'String');
            FrNum = sscanf(FrameString, 'Frame %d', 1);
    
            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[Recover Frame skipped] Bad frame.\n');
                return
            end
    
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status)
                fprintf('[Recover Frame skipped] frame has no Status.\n');
                return
            end
    
            rows = FrameData(FrNum).Status(:) < 0;
    
            if ~any(rows)
                fprintf('[Recover Frame skipped] no isolated units in frame %d\n', FrNum);
                return
            end
    
            PushUndo(app, handles, 'Recover Frame');
    
            FrameData = RecoverStatusesInFrame(app, handles, FrameData, FrNum, rows, ...
                'Recover Frame');
    
            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = [];
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            
            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;
    
            RedrawSortMovie(app, handles, FrNum, true);
            % PlotSelectedCluster(app, FrameData); new dk comment 
            % PlotSelectedSpike(app, FrameData); new dk comment 
            PrintFrameClusters(app, FrameData, FrNum, 'Recover Frame');


        end

        % Button pushed function: pbRtRS
        function pbRtRSButtonPushed(app, event)

            disp('running pbRtRSButtonPushed')

            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end

            if isempty(FrameData)
                fprintf('[Revert Raw skipped] No FrameData loaded.\n');
                return
            end

            PushUndo(app, handles, 'Revert to Raw Spikes');

            app.edIsoWin.Value = '99999';
            FrameData = ReframeCurrentSessionFromIsoWin(app, handles, FrameData, 99999);
            FrameData = RevertFrameDataToRawSpikes(app, FrameData);

            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;

            app.SelectedSpikeFrame = NaN;
            app.SelectedSpikeRows = [];
            app.SelectedNoiseNumber = NaN;
            UpdateSelectedSpikeRemoveButtons(app);
            app.SelectedClusterFrame = NaN;
            app.SelectedClusterNum = NaN;
            UpdateRecoverSelectedClusterButton(app);
            app.LastHoverSpikeFrame = NaN;
            app.LastHoverSpikeRow = NaN;

            FrNum = 1;
            set(handles.stFrame2, 'String', sprintf('Frame %d / %d', FrNum, numel(FrameData)));
            set(handles.stFrame2, 'UserData', numel(FrameData));

            if numel(app.popDim1.Items) >= 1
                app.popDim1.Value = app.popDim1.Items{1};
            end
            if numel(app.popDim2.Items) >= 2
                app.popDim2.Value = app.popDim2.Items{2};
            end
            if numel(app.popDim3.Items) >= 3
                app.popDim3.Value = app.popDim3.Items{3};
            end

            RedrawSortMovie(app, handles, FrNum, true);
            UpdateAllQLabels(app, handles, FrNum);
            PrintFrameClusters(app, FrameData, FrNum, 'REVERT RAW SPIKES');

            fprintf('[Revert Raw done] reset all frames to C1 multiunit cloud.\n');

        end

        % Button pushed function: pbRemoveasNoise
        function pbRemoveasNoiseButtonPushed(app, event)
            RemoveListedClusters(app, event, 'Noise');
        end

        % Button pushed function: pbRemoveasIso
        function pbRemoveasIsoButtonPushed(app, event)
            RemoveListedClusters(app, event, 'Iso');
        end

        % Button pushed function: pbRemoveasNoise_2
        function pbRemoveasNoise_2ButtonPushed(app, event)
            RemoveSelectedSpikes(app, event, 'Noise');
        end

        % Button pushed function: pbRemoveRefractoryViolations
        function pbRemoveRefractoryViolationsButtonPushed(app, event)
            refMs = 3.0;
            maxClusters = 14;
        
            CancelInteractiveModes(app);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits,'UserData');
            end
            if isempty(FrameData)
                fprintf('[Remove refractory skipped] No FrameData loaded.\n');
                return
            end
            FrNum = app.SelectedClusterFrame;
            srcClus = app.SelectedClusterNum;
            if isnan(FrNum)
                FrameString = get(handles.stFrame2,'String');
                FrNum = sscanf(FrameString, 'Frame %d', 1);
            end
            if isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                fprintf('[Remove refractory skipped] Bad current frame.\n');
                return
            end
        
            if isnan(srcClus) || srcClus <= 0 || srcClus > maxClusters
                fprintf('[Remove refractory skipped] Select a positive cluster first.\n');
                return
            end
        
            srcClus = round(srcClus);
        
            if ~isfield(FrameData(FrNum),'Status') || isempty(FrameData(FrNum).Status) || ...
                    ~isfield(FrameData(FrNum),'spk') || isempty(FrameData(FrNum).spk)
        
                fprintf('[Remove refractory skipped] Missing Status or spk times.\n');
                return
            end
        
            status = FrameData(FrNum).Status(:);
            spkUs = double(FrameData(FrNum).spk(:));
        
            if numel(status) ~= numel(spkUs)
                fprintf('[Remove refractory skipped] Status and spk length mismatch.\n');
                return
            end
        
            available = GetAvailableClusterIDs(app, status, true);
        
            if isfield(FrameData(FrNum),'Iso') && ~isempty(FrameData(FrNum).Iso)
                iso = FrameData(FrNum).Iso(:);
                available = available(arrayfun(@(c) c <= numel(iso) && iso(c) == 0, available));
            end
        
            if isempty(available)
                fprintf('[Remove refractory skipped] All 14 clusters are used or isolated.\n');
                return
            end
        
            dstClus = available(1);
        
            srcRows = find(status == srcClus);
        
            if numel(srcRows) < 2
                fprintf('[Remove refractory skipped] C%d has fewer than 2 spikes.\n', srcClus);
                return
            end
        
            [sortedTimesUs, order] = sort(spkUs(srcRows));
            sortedRows = srcRows(order);
        
            refUs = refMs * 1000;
            tooClose = diff(sortedTimesUs) < refUs;

            if isfield(FrameData(FrNum),'RefViolationID') && ...
                    numel(FrameData(FrNum).RefViolationID) == numel(status)

                oldIDs = FrameData(FrNum).RefViolationID(:);
                nextViolationID = max(oldIDs(isfinite(oldIDs) & oldIDs > 0), [], 'omitnan');
                if isempty(nextViolationID) || isnan(nextViolationID)
                    nextViolationID = 0;
                end
            else
                nextViolationID = 0;
            end

            violationIDSorted = zeros(size(sortedRows));

            i = 1;
            while i <= numel(sortedRows)

                if i < numel(sortedRows) && tooClose(i)

                    j = i + 1;

                    while j < numel(sortedRows) && tooClose(j)
                        j = j + 1;
                    end

                    nextViolationID = nextViolationID + 1;
                    violationIDSorted(i:j) = nextViolationID;
                    i = j + 1;

                else

                    i = i + 1;

                end
            end

            violRows = sortedRows(violationIDSorted > 0);
            violIDs = violationIDSorted(violationIDSorted > 0);


            if isempty(violRows)
                fprintf('[Remove refractory skipped] C%d has no ISIs under %.3f ms.\n', srcClus, refMs);
                return
            end
        
            rows = false(numel(status),1);
            rows(violRows) = true;
        
            PushUndo(app, handles, 'Remove Refractory Violations');
        
            FrameData = ChangeStatus(app, handles, FrameData, FrNum, rows, dstClus, ...
                'RemoveRefractoryViolations');

            nSpikes = numel(FrameData(FrNum).Status);

            if ~isfield(FrameData(FrNum),'RefViolationID') || ...
                    numel(FrameData(FrNum).RefViolationID) ~= nSpikes
                FrameData(FrNum).RefViolationID = zeros(nSpikes,1);
            else
                FrameData(FrNum).RefViolationID = FrameData(FrNum).RefViolationID(:);
            end

            if ~isfield(FrameData(FrNum),'RefViolationReviewCluster') || ...
                    numel(FrameData(FrNum).RefViolationReviewCluster) ~= nSpikes
                FrameData(FrNum).RefViolationReviewCluster = NaN(nSpikes,1);
            else
                FrameData(FrNum).RefViolationReviewCluster = FrameData(FrNum).RefViolationReviewCluster(:);
            end

            FrameData(FrNum).RefViolationID(violRows) = violIDs;
            FrameData(FrNum).RefViolationReviewCluster(violRows) = dstClus;
        
            set(handles.frUnits,'UserData',FrameData);
            app.FrameDataStore = FrameData;
        
            app.SelectedClusterFrame = FrNum;
            app.SelectedClusterNum = dstClus;
            app.SelectedSpikeFrame = NaN;
            app.SelectedNoiseNumber = NaN;
            app.SelectedSpikeRows = [];
        
            UpdateSelectedSpikeRemoveButtons(app);
            UpdateRecoverSelectedClusterButton(app);
            RedrawSortMovie(app, handles, FrNum, true);
            UpdateAllQLabels(app, handles, FrNum);
            % PlotSelectedCluster(app, FrameData); new dk comment 
            PrintFrameClusters(app, FrameData, FrNum, 'Remove Refractory Violations');
        
            fprintf('[Remove refractory] Fr %d C%d -> C%d, moved %d spikes with ISI < %.3f ms.\n', ...
                FrNum, srcClus, dstClus, numel(violRows), refMs);
        end

        % Button pushed function: noiseRemovalModel
        function noiseRemovalModelPushed(app, event)
            

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            disp('running NNButtonPushed')

            %% Find available models using the computer-specific lab path

            modelPath = fileparts(which('predictModel'));

            if isempty(modelPath)
                error('predictModel.m is not on the MATLAB path.');
            end

            modelFiles = dir(fullfile(modelPath, 'model*.mat'));
            
            if isempty(modelFiles)
                fprintf('No model files found in:\n%s\n', modelPath);
                return
            end

            [~, sortIdx] = sort(lower(string({modelFiles.name})));
            modelFiles = modelFiles(sortIdx);

            %% Let user select model in Command Window
            fprintf('\nAVAILABLE MODELS\n');

            for iModel = 1:numel(modelFiles)
                fprintf('%d | %s\n', ...
                    iModel, erase(modelFiles(iModel).name, '.mat'));
            end

            modelNum = input('\nEnter model number: ');

            if isempty(modelNum) || ~isscalar(modelNum) || ...
                    modelNum ~= floor(modelNum) || ...
                    modelNum < 1 || modelNum > numel(modelFiles)

                fprintf('Invalid model selection. Nothing changed.\n');
                return
            end

            selectedModelFile = fullfile( ...
                modelFiles(modelNum).folder, ...
                modelFiles(modelNum).name);

            setappdata(groot, 'SpikeSortModelFile', selectedModelFile);

            fprintf('Using model: %s\n', modelFiles(modelNum).name);

            %% Run selected model
            FrameData = app.FrameDataStore;
            FrNum = 1;

            status = FrameData(FrNum).Status(:);

            [~, score] = predictModel( ...
                FrameData(FrNum).Wfs, ...
                selectedModelFile);

            % Columns correspond to ClassNames categorical([0 1]):
            % score(:,1) = noise score
            % score(:,2) = non-noise/spike score
            noiseScores = score(:, 1);

            FrameData(FrNum).ModelNoiseScore = noiseScores;
            FrameData(FrNum).ModelScoreFile = string(selectedModelFile);

            fprintf('Noise score: min %.4f | median %.4f | mean %.4f | max %.4f\n', ...
                min(noiseScores), median(noiseScores), mean(noiseScores), max(noiseScores));

            fprintf('>=0.3: %d/%d | >=0.5: %d/%d | >=0.9: %d/%d\n', ...
                sum(noiseScores >= 0.3), numel(noiseScores), ...
                sum(noiseScores >= 0.5), numel(noiseScores), ...
                sum(noiseScores >= 0.9), numel(noiseScores));

            
            noiseThreshold = 0.45;

            activeRows = status ~= 0;   % only rows currently assigned to a cluster
            noiseRows = activeRows & noiseScores >= noiseThreshold;

            % Move predicted noise from active clusters into a new cluster.
            % Status==0 rows stay Status==0 and are never re-added.
            nextCluster = max(status(status ~= 0)) + 1;

            FrameData = ChangeStatus( ...
                app, handles, FrameData, FrNum, noiseRows, nextCluster, ...
                'noiseRemovalModel');

            fprintf('Moved %d of %d active waveforms to cluster %d. Status==0 rows were ignored.\n', ...
                sum(noiseRows), sum(activeRows), nextCluster);

            set(handles.frUnits, 'UserData', FrameData);
            app.FrameDataStore = FrameData;

            RedrawSortMovie(app, handles, FrNum, true);
            
        end

        % Button pushed function: plotSpksInSignalButton
        function plotSpksInSignalButtonPushed(app, event)

    % Button pushed function: plotSpksInSignalButton

        fprintf(['\nPLOT SPIKES IN SIGNAL\n' ...
            'This feature originally relied on a separate neural signal ' ...
            'extraction and filtering pipeline, which is not included ' ...
            'in this public repository.\n\n' ...
            'Here is a general skeleton for implementing it with ' ...
            'any recording/extraction pipeline:\n\n']);

        fprintf(['1. Load the continuous neural recording and sampling rate.\n' ...
            '2. Apply the same filtering used during spike extraction.\n' ...
            '3. Retrieve the timestamps of the selected spikes.\n' ...
            '4. Align those timestamps with the continuous signal.\n' ...
            '5. Plot the continuous signal and mark selected spikes.\n' ...
            '6. Optionally add navigation between selected spikes.... see the comments in the app code in this function for the actual code skeleton\n\n']);

        % Generic implementation skeleton:
        %
        % [signal, Fs, recordingStartTime] = loadYourRecording(...);
        %
        % filteredSignal = applyYourExtractionFilter(signal, Fs);
        %
        % spikeTimes = getSelectedSpikeTimes(...);
        %
        % t = (0:numel(filteredSignal)-1) / Fs + recordingStartTime;
        %
        % figure;
        % plot(t, filteredSignal);
        % hold on;
        %
        % for i = 1:numel(spikeTimes)
        %     xline(spikeTimes(i), 'r');
        % end
        %
        % xlabel('Time (s)');
        % ylabel('Amplitude');


        end

        % Button pushed function: rasterButton
        function rasterButtonPushed(app, event)
        
            disp('running rasterButtonPushed')
        
            FrameData = app.FrameDataStore;
        
            if isempty(FrameData)
                fprintf('[Raster skipped] No FrameData loaded.\n');
                return
            end
        
            FrameString = app.stFrame2.Text;
            FrNum = sscanf(FrameString, 'Frame %d', 1);
        
            if isempty(FrNum) || isnan(FrNum) || FrNum < 1 || FrNum > numel(FrameData)
                FrNum = 1;
            end
        
            if ~isfield(FrameData(FrNum), 'Status') || isempty(FrameData(FrNum).Status) || ...
               ~isfield(FrameData(FrNum), 'spk') || isempty(FrameData(FrNum).spk)
        
                fprintf('[Raster skipped] Frame %d missing Status/spk.\n', FrNum);
                return
            end
        
            status = FrameData(FrNum).Status(:);
            spkTimes = double(FrameData(FrNum).spk(:)) / 1e6;  % seconds
        
            n = min(numel(status), numel(spkTimes));
            status = status(1:n);
            spkTimes = spkTimes(1:n);
        
            activeMask = status > 0;
        
            if ~any(activeMask)
                fprintf('[Raster skipped] No active spikes in frame %d.\n', FrNum);
                return
            end
        
            tMin = min(spkTimes(activeMask));
            tMax = max(spkTimes(activeMask));
        
            chunkSec = 60;     % visible window size
            stepSec = 60;      % arrow step size
        
            fig = uifigure( ...
                'Name', sprintf('Raster Viewer - Frame %d', FrNum), ...
                'Position', [200 200 1000 500]);
        
            ax = uiaxes(fig);
            ax.Position = [70 80 880 360];
            ax.Color = 'w';
            hold(ax, 'on');
        
            leftButton = uibutton(fig, 'push');
            leftButton.Text = '<';
            leftButton.Position = [70 25 60 30];
        
            rightButton = uibutton(fig, 'push');
            rightButton.Text = '>';
            rightButton.Position = [890 25 60 30];
        
            chunkLabel = uilabel(fig);
            chunkLabel.Position = [155 25 700 30];
            chunkLabel.HorizontalAlignment = 'center';
            chunkLabel.Text = '';
        
            activeClusters = unique(status(activeMask));
            activeClusters = activeClusters(:)';
        
            tickHalfHeight = 0.35;
        
            % Plot ONE vectorized vertical-tick object per cluster.
            % This is the important speed fix.
            for iCl = 1:numel(activeClusters)
        
                clu = activeClusters(iCl);
                rows = activeMask & status == clu;
                t = spkTimes(rows);
        
                if isempty(t)
                    continue
                end
        
                y = iCl;
        
                line(ax, [t(:)'; t(:)'], ...
                    [ones(1,numel(t))*(y - tickHalfHeight); ...
                     ones(1,numel(t))*(y + tickHalfHeight)], ...
                    'Color', mycolors(app, clu), ...
                    'LineWidth', 0.75, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
        
            end
        
            ax.YLim = [0.5 numel(activeClusters) + 0.5];
            ax.YTick = 1:numel(activeClusters);
            ax.YTickLabel = arrayfun(@(c)sprintf('C%d', c), activeClusters, 'UniformOutput', false);
        
            ax.XLim = [tMin min(tMin + chunkSec, tMax)];

            % Lock vertical raster rows, but keep normal MATLAB x zoom/pan behavior.
            lockedYLim = ax.YLim;

            ax.YLimMode = 'manual';
            ax.YTickMode = 'manual';
            ax.YTickLabelMode = 'manual';

            % Keep normal interactions. Do NOT call disableDefaultInteractivity.
            ax.Interactions = [zoomInteraction panInteraction];

            % If user zooms/pans vertically, immediately snap Y back.
            fig.UserData.LockedYLim = lockedYLim;
            fig.UserData.YLimListener = addlistener(ax, 'YLim', 'PostSet', ...
                @(src,evt)set(evt.AffectedObject, 'YLim', lockedYLim));

            %lock zoom 
        
            ax.XLabel.String = 'Time in frame (s)';
            ax.YLabel.String = 'Cluster';
            ax.Title.String = sprintf('Frame %d Raster', FrNum);
            ax.Box = 'on';
            ax.Layer = 'top';
            ax.FontSize = 11;

            % Let raster axes handle spike selection.
            ax.ButtonDownFcn = @(src,event)RasterWindowClick(app, src, event);

            % Make sure the raster line objects do not steal clicks from the axes.
            set(findall(ax, 'Type', 'Line'), ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            % Store exact source data on the raster axes for click selection.
            ax.UserData.FrameData = FrameData;
            ax.UserData.FrNum = FrNum;
            ax.UserData.status = status;
            ax.UserData.spkTimes = spkTimes;
            ax.UserData.activeClusters = activeClusters;

            % Continuous hover selection for raster window.
            fig.WindowButtonMotionFcn = @(src,event)RasterWindowMouseMove(app, ax);
        
            fig.WindowButtonDownFcn = @(src,event)RasterWindowMouseDown(app, src, ax);

            chunkLabel.Text = sprintf('Chunk %.2f to %.2f s    Full frame %.2f to %.2f s', ...
                ax.XLim(1), ax.XLim(2), tMin, tMax);

            leftButton.ButtonPushedFcn = @(src,event) ...
                setRasterWindow(app, ax, chunkLabel, tMin, tMax, chunkSec, -stepSec);

            rightButton.ButtonPushedFcn = @(src,event) ...
                setRasterWindow(app, ax, chunkLabel, tMin, tMax, chunkSec, stepSec);
        
            hold(ax, 'off');
            drawnow limitrate          
        end

        % Button down function: pcRaster
        function pcRasterButtonDown(app, event)


            % When select mode is OFF, let normal zoom/pan/scroll behavior happen.
            if ~app.SpikeSelectMode
                return
            end

            cp = app.pcRaster.CurrentPoint;
            fprintf('[pcRaster click] x %.6f y %.6f\n', cp(1,1), cp(1,2));

            % Only lock on right-click / two-finger click.
            if ~strcmp(app.figure1.SelectionType, 'alt')
                return
            end

            AppRasterSpikeHover(app, app.pcRaster);

            app.SpikeSelectMode = false;

            if ~isempty(app.selectOFFButton) && isvalid(app.selectOFFButton)
                app.selectOFFButton.Text = 'select: OFF';
            end

            try
                UpdateSelectedSpikeRemoveButtons(app);
            catch
            end

            fprintf('[pcRaster locked] F%d row %d\n', ...
                app.SelectedSpikeFrame, app.SelectedSpikeRows);


        end

        % Value changed function: rasterYDim
        function rasterYDimValueChanged(app, event)

            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            FrameData = app.FrameDataStore;
            if isempty(FrameData)
                FrameData = get(handles.frUnits, 'UserData');
            end

            if isempty(FrameData)
                return
            end

            FrNum = get(handles.stFrame2, 'UserData');
            if isempty(FrNum) || ~isnumeric(FrNum) || FrNum < 1
                FrNum = 1;
            end

            [yMode, pcDim] = GetRasterYMode(app);
            PlotAppRegularRaster(app, FrameData, FrNum, yMode, pcDim);

            UpdatePcRasterZoomDirection(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MattSpikeSortGUI_Human';
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1WindowKeyPress, true);
            app.figure1.Tag = 'figure1';
            app.axDim12 = uiaxes(app.figure1);
            app.axDim12.XTick = 0;
            app.axDim12.YTick = 0;
            app.axDim12.Tag = 'axDim12';
            app.axDim13 = uiaxes(app.figure1);
            app.axDim13.XTick = 0;
            app.axDim13.XTickLabel = {'0'};
            app.axDim13.YTick = 0;
            app.axDim13.Tag = 'axDim13';
            app.axDim23 = uiaxes(app.figure1);
            app.axDim23.XTick = 0;
            app.axDim23.XTickLabel = {'0'};
            app.axDim23.YTick = 0;
            app.axDim23.Tag = 'axDim23';
            app.axWaveforms = uiaxes(app.figure1);
            app.axWaveforms.YTick = [0 0.5 1];
            app.axWaveforms.Tag = 'axWaveforms';
            app.axFrWaveform = uiaxes(app.figure1);
            app.axFrWaveform.YTick = [0 0.5 1];
            app.axFrWaveform.Tag = 'axFrWaveform';
            app.axPC1 = uiaxes(app.figure1);
            app.axPC1.XTick = [];
            app.axPC1.XTickLabel = '';
            app.axPC1.YTick = [];
            app.axPC1.Tag = 'axPC1';
            app.axPC2 = uiaxes(app.figure1);
            app.axPC2.XTick = [];
            app.axPC2.XTickLabel = '';
            app.axPC2.YTick = [];
            app.axPC2.Tag = 'axPC2';
            app.axPC3 = uiaxes(app.figure1);
            app.axPC3.XTick = [];
            app.axPC3.XTickLabel = '';
            app.axPC3.YTick = [];
            app.axPC3.Tag = 'axPC3';
            app.axWaveforms_2 = uiaxes(app.figure1);
            app.axWaveforms_2.YTick = [0 0.5 1];
            app.axWaveforms_2.Tag = 'axWaveforms';
            app.axWaveforms_3 = uiaxes(app.figure1);
            app.axWaveforms_3.YTick = [0 0.5 1];
            app.axWaveforms_3.Tag = 'axWaveforms';
            app.axClusterSpikes = uiaxes(app.figure1);
            app.axClusterSpikes.YTick = [0 0.5 1];
            app.axClusterSpikes.Tag = 'axWaveforms';
            app.axRemovedasIsoMeanWfs = uiaxes(app.figure1);
            app.axRemovedasIsoMeanWfs.YTick = [0 0.5 1];
            app.axRemovedasIsoMeanWfs.Tag = 'axFrWaveform';
            app.pcRaster = uiaxes(app.figure1);
            title(app.pcRaster, 'Title')
            zlabel(app.pcRaster, 'Z')
            app.pcRaster.ButtonDownFcn = createCallbackFcn(app, @pcRasterButtonDown, true);
            app.Ylabel1 = uilabel(app.figure1);
            app.Ylabel1.Tag = 'Ylabel1';
            app.Ylabel1.Text = 'dim2';
            app.Xlabel1 = uilabel(app.figure1);
            app.Xlabel1.Tag = 'Xlabel1';
            app.Xlabel1.Text = 'dim3';
            app.PClabel1 = uilabel(app.figure1);
            app.PClabel1.Tag = 'PClabel1';
            app.PClabel1.Text = 'dim1';
            app.PClabel2 = uilabel(app.figure1);
            app.PClabel2.Tag = 'PClabel2';
            app.PClabel2.Text = 'dim2';
            app.PClabel3 = uilabel(app.figure1);
            app.PClabel3.Tag = 'PClabel3';
            app.PClabel3.Text = 'dim3';
            app.edIsoWin = uieditfield(app.figure1, 'text');
            app.edIsoWin.Tag = 'edIsoWin';
            app.edIsoWin.Value = '99999';
            app.pbReassign = uibutton(app.figure1, 'push');
            app.pbReassign.ButtonPushedFcn = createCallbackFcn(app, @pbReassign_Callback, true);
            app.pbReassign.Tag = 'pbReassign';
            app.pbReassign.Text = 'Reassign';
            app.pbBackSort = uibutton(app.figure1, 'push');
            app.pbBackSort.ButtonPushedFcn = createCallbackFcn(app, @pbBackSort_Callback, true);
            app.pbBackSort.Tag = 'pbBackSort';
            app.pbBackSort.Text = '<';
            app.pbForwardSort = uibutton(app.figure1, 'push');
            app.pbForwardSort.ButtonPushedFcn = createCallbackFcn(app, @pbForwardSort_Callback, true);
            app.pbForwardSort.Tag = 'pbForwardSort';
            app.pbForwardSort.Text = '>';
            app.directionsforsetdim = uilabel(app.figure1);
            app.directionsforsetdim.Tag = 'text8';
            app.directionsforsetdim.Text = '(frame, cluster) - (frame, cluster)';
            app.pbPlaySort = uibutton(app.figure1, 'state');
            app.pbPlaySort.ValueChangedFcn = createCallbackFcn(app, @pbPlaySort_Callback, true);
            app.pbPlaySort.Tag = 'pbPlaySort';
            app.pbPlaySort.Text = '▶';
            app.pbResort = uibutton(app.figure1, 'push');
            app.pbResort.ButtonPushedFcn = createCallbackFcn(app, @pbResort_Callback, true);
            app.pbResort.Tag = 'pbResort';
            app.pbResort.Text = 'KMeans';
            app.pbExpAppSess = uibutton(app.figure1, 'push');
            app.pbExpAppSess.ButtonPushedFcn = createCallbackFcn(app, @pbExpAppSess_Callback, true);
            app.pbExpAppSess.Tag = 'pbSave';
            app.pbExpAppSess.Text = 'save';
            app.Ylabel2 = uilabel(app.figure1);
            app.Ylabel2.Tag = 'Ylabel2';
            app.Ylabel2.Text = 'dim3';
            app.Xlabel2 = uilabel(app.figure1);
            app.Xlabel2.Tag = 'Xlabel2';
            app.Xlabel2.Text = 'dim1';
            app.Xlabel3 = uilabel(app.figure1);
            app.Xlabel3.Tag = 'Xlabel3';
            app.Xlabel3.Text = 'dim2';
            app.st1 = uilabel(app.figure1);
            app.st1.Tag = 'st1';
            app.st1.Text = '1';
            app.st2 = uilabel(app.figure1);
            app.st2.Tag = 'st2';
            app.st2.Text = '2';
            app.st3 = uilabel(app.figure1);
            app.st3.Tag = 'st3';
            app.st3.Text = '3';
            app.st4 = uilabel(app.figure1);
            app.st4.Tag = 'st4';
            app.st4.Text = '4';
            app.st5 = uilabel(app.figure1);
            app.st5.Tag = 'st5';
            app.st5.Text = '5';
            app.st6 = uilabel(app.figure1);
            app.st6.Tag = 'st6';
            app.st6.Text = '6';
            app.st7 = uilabel(app.figure1);
            app.st7.Tag = 'st7';
            app.st7.Text = '7';
            app.st8 = uilabel(app.figure1);
            app.st8.Tag = 'st8';
            app.st8.Text = '8';
            app.pbLoad = uibutton(app.figure1, 'push');
            app.pbLoad.ButtonPushedFcn = createCallbackFcn(app, @pbLoad_Callback, true);
            app.pbLoad.Tag = 'pbLoad';
            app.pbLoad.Text = 'Load + Project';
            app.st9 = uilabel(app.figure1);
            app.st9.Tag = 'st9';
            app.st9.Text = '9';
            app.st10 = uilabel(app.figure1);
            app.st10.Tag = 'st10';
            app.st10.Text = '10';
            app.st11 = uilabel(app.figure1);
            app.st11.Tag = 'st11';
            app.st11.Text = '11';
            app.st12 = uilabel(app.figure1);
            app.st12.Tag = 'st12';
            app.st12.Text = '12';
            app.st13 = uilabel(app.figure1);
            app.st13.Tag = 'st13';
            app.st13.Text = '13';
            app.st14 = uilabel(app.figure1);
            app.st14.Tag = 'st14';
            app.st14.Text = '14';
            app.pbManualSort = uibutton(app.figure1, 'push');
            app.pbManualSort.ButtonPushedFcn = createCallbackFcn(app, @pbManualSort_Callback, true);
            app.pbManualSort.Tag = 'pbManualSort';
            app.pbManualSort.Text = 'Add Manual Cluster';
            app.pbSetDim = uibutton(app.figure1, 'push');
            app.pbSetDim.ButtonPushedFcn = createCallbackFcn(app, @pbSetDim_Callback, true);
            app.pbSetDim.Tag = 'pbSetPC';
            app.pbSetDim.Text = 'set as dim';
            app.edDimNum = uieditfield(app.figure1, 'text');
            app.edDimNum.Tag = 'edPCNum';
            app.edDimNum.Value = '1';
            app.pbAssignNear = uibutton(app.figure1, 'push');
            app.pbAssignNear.ButtonPushedFcn = createCallbackFcn(app, @pbAssignNear_Callback, true);
            app.pbAssignNear.Tag = 'pbAssignNear';
            app.pbAssignNear.Text = 'Assign Nearest';
            app.text96 = uilabel(app.figure1);
            app.text96.Tag = 'text96';
            app.text96.Text = 'Elec';
            app.tbQuickSort = uibutton(app.figure1, 'state');
            app.tbQuickSort.ValueChangedFcn = createCallbackFcn(app, @tbQuickSort_Callback, true);
            app.tbQuickSort.Tag = 'tbQuickSort';
            app.tbQuickSort.Text = 'Quick Sort: OFF';
            app.popDim1 = uidropdown(app.figure1);
            app.popDim1.Items = {'1', '2', '3', '4', '5', '6'};
            app.popDim1.Tag = 'popDim1';
            app.popDim1.Value = '1';
            app.popDim2 = uidropdown(app.figure1);
            app.popDim2.Items = {'1', '2', '3', '4', '5', '6'};
            app.popDim2.Tag = 'popDim2';
            app.popDim2.Value = '2';
            app.popDim3 = uidropdown(app.figure1);
            app.popDim3.Items = {'1', '2', '3', '4', '5', '6'};
            app.popDim3.Tag = 'popDim3';
            app.popDim3.Value = '3';
            app.popNClus = uidropdown(app.figure1);
            app.popNClus.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.popNClus.Tag = 'popNClus';
            app.popNClus.Value = '2';
            app.popNDim = uidropdown(app.figure1);
            app.popNDim.Items = {'1', '2', '3', '4', '5', '6'};
            app.popNDim.Tag = 'popNDim';
            app.popNDim.Value = '3';
            app.text113 = uilabel(app.figure1);
            app.text113.Tag = 'text113';
            app.text113.Text = 'Subj';
            app.text114 = uilabel(app.figure1);
            app.text114.Tag = 'text114';
            app.text114.Text = 'Frame Size';
            app.stFrame2 = uilabel(app.figure1);
            app.stFrame2.Tag = 'stFrame2';
            app.stFrame2.Text = 'Frame 1';
            app.text116 = uilabel(app.figure1);
            app.text116.Tag = 'text116';
            app.text116.Text = 'Session';
            app.popSession = uidropdown(app.figure1);
            app.popSession.Items = {'--select subject--'};
            app.popSession.ValueChangedFcn = createCallbackFcn(app, @popSessionValueChanged, true);
            app.popSession.Tag = 'popSession';
            app.popSession.Value = '--select subject--';
            app.nDimLabel = uilabel(app.figure1);
            app.nDimLabel.Text = 'nDim';
            app.edElec = uieditfield(app.figure1, 'text');
            app.edElec.ValueChangedFcn = createCallbackFcn(app, @edElecValueChanged, true);
            app.edElec.Tag = 'edElec';
            app.edElec.Value = '12';
            app.nClusLabel = uilabel(app.figure1);
            app.nClusLabel.Text = 'nClus';
            app.f1 = uieditfield(app.figure1, 'numeric');
            app.c1 = uieditfield(app.figure1, 'numeric');
            app.f2 = uieditfield(app.figure1, 'numeric');
            app.popSubject = uidropdown(app.figure1);
            app.popSubject.Items = {'--select subject--'};
            app.popSubject.ValueChangedFcn = createCallbackFcn(app, @popSubjectValueChanged, true);
            app.popSubject.Tag = 'popPatient';
            app.popSubject.Value = '--select subject--';
            app.c2 = uieditfield(app.figure1, 'numeric');
            app.pbUndo = uibutton(app.figure1, 'push');
            app.pbUndo.ButtonPushedFcn = createCallbackFcn(app, @pbUndoButtonPushed, true);
            app.pbUndo.Text = 'undo';
            app.framenoiseLabel = uilabel(app.figure1);
            app.framenoiseLabel.Text = 'frame noise';
            app.selectOFFButton = uibutton(app.figure1, 'push');
            app.selectOFFButton.Text = 'select: OFF';
            app.SelectedSpikeIDLabel = uilabel(app.figure1);
            app.SelectedSpikeIDLabel.Text = 'F#C# #';
            app.nSpks1 = uilabel(app.figure1);
            app.nSpks1.Text = '0';
            app.nSpks2 = uilabel(app.figure1);
            app.nSpks2.Text = '0';
            app.Ylabel3_2 = uilabel(app.figure1);
            app.Ylabel3_2.Tag = 'Ylabel3';
            app.Ylabel3_2.Text = 'dim3';
            app.nSpks3 = uilabel(app.figure1);
            app.nSpks3.Text = '0';
            app.nSpks4 = uilabel(app.figure1);
            app.nSpks4.Text = '0';
            app.nSpks5 = uilabel(app.figure1);
            app.nSpks5.Text = '0';
            app.nSpks6 = uilabel(app.figure1);
            app.nSpks6.Text = '0';
            app.nSpks7 = uilabel(app.figure1);
            app.nSpks7.Text = '0';
            app.nSpks8 = uilabel(app.figure1);
            app.nSpks8.Text = '0';
            app.nSpks9 = uilabel(app.figure1);
            app.nSpks9.Text = '0';
            app.nSpks10 = uilabel(app.figure1);
            app.nSpks10.Text = '0';
            app.nSpks11 = uilabel(app.figure1);
            app.nSpks11.Text = '0';
            app.nSpks12 = uilabel(app.figure1);
            app.nSpks12.Text = '0';
            app.nSpks13 = uilabel(app.figure1);
            app.nSpks13.Text = '0';
            app.nSpks14 = uilabel(app.figure1);
            app.nSpks14.Text = '0';
            app.nSpksNoise = uilabel(app.figure1);
            app.nSpksNoise.Text = '0';
            app.SelectedClusterIDLabel = uilabel(app.figure1);
            app.SelectedClusterIDLabel.Text = '#';
            app.pop1 = uieditfield(app.figure1, 'numeric');
            app.pop1.Value = 1;
            app.pop2 = uieditfield(app.figure1, 'numeric');
            app.pop2.Value = 2;
            app.pop3 = uieditfield(app.figure1, 'numeric');
            app.pop3.Value = 3;
            app.pop4 = uieditfield(app.figure1, 'numeric');
            app.pop4.Value = 4;
            app.pop5 = uieditfield(app.figure1, 'numeric');
            app.pop5.Value = 5;
            app.pop6 = uieditfield(app.figure1, 'numeric');
            app.pop6.Value = 6;
            app.pop7 = uieditfield(app.figure1, 'numeric');
            app.pop7.Value = 7;
            app.pop8 = uieditfield(app.figure1, 'numeric');
            app.pop8.Value = 8;
            app.pop9 = uieditfield(app.figure1, 'numeric');
            app.pop9.Value = 9;
            app.pop10 = uieditfield(app.figure1, 'numeric');
            app.pop10.Value = 10;
            app.pop11 = uieditfield(app.figure1, 'numeric');
            app.pop11.Value = 11;
            app.pop12 = uieditfield(app.figure1, 'numeric');
            app.pop12.Value = 12;
            app.pop13 = uieditfield(app.figure1, 'numeric');
            app.pop13.Value = 13;
            app.pop14 = uieditfield(app.figure1, 'numeric');
            app.pop14.Value = 14;
            app.multiunitLabel = uilabel(app.figure1);
            app.multiunitLabel.Text = 'multi-unit';
            app.pbRecoverSelectedCluster = uibutton(app.figure1, 'push');
            app.pbRecoverSelectedCluster.ButtonPushedFcn = createCallbackFcn(app, @pbRecoverSelectedClusterButtonPushed, true);
            app.pbRecoverSelectedCluster.Text = 'recover';
            app.statsButton = uibutton(app.figure1, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Text = 'stats';
            app.popSrtdLoad = uidropdown(app.figure1);
            app.popSrtdLoad.Items = {'--select subject--'};
            app.popSrtdLoad.ValueChangedFcn = createCallbackFcn(app, @popSrtdLoadValueChanged, true);
            app.popSrtdLoad.Tag = 'popSession';
            app.popSrtdLoad.Value = '--select subject--';
            app.pbRecFr = uibutton(app.figure1, 'push');
            app.pbRecFr.ButtonPushedFcn = createCallbackFcn(app, @pbRecFrButtonPushed, true);
            app.pbRecFr.Text = 'recover all';
            app.removeasLabel = uilabel(app.figure1);
            app.removeasLabel.Text = 'remove as:';
            app.pbRtRS = uibutton(app.figure1, 'push');
            app.pbRtRS.ButtonPushedFcn = createCallbackFcn(app, @pbRtRSButtonPushed, true);
            app.pbRtRS.Tag = 'pbLoad';
            app.pbRtRS.Text = 'revert to raw spikes';
            app.baseName = uilabel(app.figure1);
            app.baseName.Text = 'baseName';
            app.edFilenameAppend = uieditfield(app.figure1, 'text');
            app.matLabel = uilabel(app.figure1);
            app.matLabel.Text = '.mat';
            app.Label = uilabel(app.figure1);
            app.Label.Text = {'abc or _abc ----> RawSortDat_subj_sess_ch_Thresh_abc.mat '; '           *        ----> RawSortDat_subj_sess_ch_Thresh.mat (overwrites the RawSortDat)'; '         empty ----> RawSortDat_subj_sess_ch_Thresh.mat (cannot have tags in final export)'; '                      and AnalysisVars_subj_sess_ch_Thresh.mat '};
            app.removeasisolatedfirstLabel = uilabel(app.figure1);
            app.removeasisolatedfirstLabel.Text = '';
            app.pbRemoveasIso = uibutton(app.figure1, 'push');
            app.pbRemoveasIso.ButtonPushedFcn = createCallbackFcn(app, @pbRemoveasIsoButtonPushed, true);
            app.pbRemoveasIso.Tag = 'pbReassign';
            app.pbRemoveasIso.Text = 'isolated';
            app.pbRemoveasNoise = uibutton(app.figure1, 'push');
            app.pbRemoveasNoise.ButtonPushedFcn = createCallbackFcn(app, @pbRemoveasNoiseButtonPushed, true);
            app.pbRemoveasNoise.Tag = 'pbReassign';
            app.pbRemoveasNoise.Text = 'noise';
            app.removeList = uieditfield(app.figure1, 'text');
            app.pbRemoveasNoise_2 = uibutton(app.figure1, 'push');
            app.pbRemoveasNoise_2.ButtonPushedFcn = createCallbackFcn(app, @pbRemoveasNoise_2ButtonPushed, true);
            app.pbRemoveasNoise_2.Tag = 'pbReassign';
            app.pbRemoveasNoise_2.Text = 'noise';
            app.pop15 = uieditfield(app.figure1, 'text');
            app.reassigntoLabel = uilabel(app.figure1);
            app.reassigntoLabel.Text = 'reassign to ';
            app.pbExpAnalysisVars = uibutton(app.figure1, 'push');
            app.pbExpAnalysisVars.ButtonPushedFcn = createCallbackFcn(app, @pbExpAnalysisVarsButtonPushed, true);
            app.pbExpAnalysisVars.Tag = 'pbSave';
            app.pbExpAnalysisVars.Text = 'export analysis vars';
            app.removeclustersLabel = uilabel(app.figure1);
            app.removeclustersLabel.Text = 'remove clusters';
            app.asLabel = uilabel(app.figure1);
            app.asLabel.Text = 'as';
            app.isolatedLabel = uilabel(app.figure1);
            app.isolatedLabel.Text = 'isolated';
            app.frameclustersLabel = uilabel(app.figure1);
            app.frameclustersLabel.Text = 'frame clusters ';
            app.allframesclustersLabel = uilabel(app.figure1);
            app.allframesclustersLabel.Text = 'all frames clusters ';
            app.pbRemoveRefractoryViolations = uibutton(app.figure1, 'push');
            app.pbRemoveRefractoryViolations.ButtonPushedFcn = createCallbackFcn(app, @pbRemoveRefractoryViolationsButtonPushed, true);
            app.pbRemoveRefractoryViolations.Text = 'remove refractory violations';
            app.noiseRemovalModel = uibutton(app.figure1, 'push');
            app.noiseRemovalModel.ButtonPushedFcn = createCallbackFcn(app, @noiseRemovalModelPushed, true);
            app.noiseRemovalModel.Text = 'noiseRemovalModel';
            app.plotSpksInSignalButton = uibutton(app.figure1, 'push');
            app.plotSpksInSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plotSpksInSignalButtonPushed, true);
            app.plotSpksInSignalButton.Text = 'plotSpksInSignal';
            app.rasterButton = uibutton(app.figure1, 'push');
            app.rasterButton.ButtonPushedFcn = createCallbackFcn(app, @rasterButtonPushed, true);
            app.rasterButton.Text = 'raster';
            app.DropDownLabel = uilabel(app.figure1);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.figure1);
            app.rasterYDim = uidropdown(app.figure1);
            app.rasterYDim.ValueChangedFcn = createCallbackFcn(app, @rasterYDimValueChanged, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spikesortApp_GIT(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MattSpikeSortGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end